classdef Preprocessbackup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NoiceCovariancePanel            matlab.ui.container.Panel
        CalculateButton                 matlab.ui.control.Button
        RereferencingPanel              matlab.ui.container.Panel
        inputexamplesPanel              matlab.ui.container.Panel
        AverageofspecificchannelgroupsLabel  matlab.ui.control.Label
        AveragereferenceLabel           matlab.ui.control.Label
        SingleelectrodereferenceLabel   matlab.ui.control.Label
        DualelectrodereferenceLabel     matlab.ui.control.Label
        orA1A2Label                     matlab.ui.control.Label
        AverageLabel                    matlab.ui.control.Label
        A1A2Label                       matlab.ui.control.Label
        A1Label                         matlab.ui.control.Label
        OKButton_5                      matlab.ui.control.Button
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        ChooseparamsEditField           matlab.ui.control.EditField
        ChooseparamsEditFieldLabel      matlab.ui.control.Label
        TimeFrequencyDecompositionPanel  matlab.ui.container.Panel
        OKButton_4                      matlab.ui.control.Button
        OKButton_3                      matlab.ui.control.Button
        DownsamplingFacorEditField      matlab.ui.control.NumericEditField
        DownsamplingFacorEditFieldLabel  matlab.ui.control.Label
        ResamplingLabel                 matlab.ui.control.Label
        OKButton_2                      matlab.ui.control.Button
        VoicesperOctaveEditField        matlab.ui.control.NumericEditField
        VoicesperOctaveEditFieldLabel   matlab.ui.control.Label
        HzLabel_4                       matlab.ui.control.Label
        toLabel                         matlab.ui.control.Label
        ScalesEditField_2               matlab.ui.control.NumericEditField
        ScalesEditField                 matlab.ui.control.NumericEditField
        ScalesEditFieldLabel            matlab.ui.control.Label
        WaveletFamliyDropDown           matlab.ui.control.DropDown
        WaveletFamliyDropDownLabel      matlab.ui.control.Label
        NFFTWindowLengthEditField       matlab.ui.control.NumericEditField
        NFFTWindowLengthEditFieldLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        OverlapRatioEditField           matlab.ui.control.NumericEditField
        OverlapRatioEditFieldLabel      matlab.ui.control.Label
        WindowLengthEditField           matlab.ui.control.NumericEditField
        WindowLengthEditFieldLabel      matlab.ui.control.Label
        WindowDropDown                  matlab.ui.control.DropDown
        WindowDropDownLabel             matlab.ui.control.Label
        WaveletTransformLabel           matlab.ui.control.Label
        STFTLabel                       matlab.ui.control.Label
        ICAPanel                        matlab.ui.container.Panel
        OKButton                        matlab.ui.control.Button
        ChanneltypesorchannelindicesEditField  matlab.ui.control.EditField
        ChanneltypesorchannelindicesEditFieldLabel  matlab.ui.control.Label
        CommandlineoptiionsEditField    matlab.ui.control.EditField
        CommandlineoptiionsEditFieldLabel  matlab.ui.control.Label
        ICAalgorithmDropDown            matlab.ui.control.DropDown
        ICAalgorithmDropDownLabel       matlab.ui.control.Label
        ReordercomponentsbyvarianceCheckBox  matlab.ui.control.CheckBox
        SpectrumPanel                   matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        SpatialFilteringPanel           matlab.ui.container.Panel
        ERPrelatedPanel                 matlab.ui.container.Panel
        AverageTrialsLabel              matlab.ui.control.Label
        BaselineCorrectLabel            matlab.ui.control.Label
        ExtractTrialsLabel              matlab.ui.control.Label
        SetButton_3                     matlab.ui.control.Button
        SetButton_2                     matlab.ui.control.Button
        SetButton                       matlab.ui.control.Button
        MethodsButtonGroup              matlab.ui.container.ButtonGroup
        LaplacianButton                 matlab.ui.control.RadioButton
        InterpolateButton               matlab.ui.control.RadioButton
        RereferenceButton               matlab.ui.control.RadioButton
        ICAButton                       matlab.ui.control.RadioButton
        FrequencydomainFilteringPanel   matlab.ui.container.Panel
        OKButton_6                      matlab.ui.control.Button
        frequencydominSetbutten         matlab.ui.control.Button
        NotchEditField                  matlab.ui.control.NumericEditField
        NotchEditFieldLabel             matlab.ui.control.Label
        LowpassEditField                matlab.ui.control.NumericEditField
        LowpassEditFieldLabel           matlab.ui.control.Label
        HighpassEditField               matlab.ui.control.NumericEditField
        HighpassEditFieldLabel          matlab.ui.control.Label
        HzLabel_3                       matlab.ui.control.Label
        HzLabel_2                       matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        CheckBox_3                      matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox                        matlab.ui.control.CheckBox
    end


    properties (Access = private)
        data
        srate
        protocolinfo
        filteredEEG
        checkeddataindex
        SaveIndex=1  %保存数据索引
        Callingapp
    end

    methods (Access = private)

        function plotMultiChannelSpectrum(app, EEGdata,channelIndices)
            %绘制功率谱

            % 获取App中的UIAxes组件
            ax =app.UIAxes;

            % 清除当前内容并设置为新绘图
            cla(ax);  % 清除当前图形
            hold(ax, 'on');  % 启用图形叠加

            % 设置UIAxes属性（可选）
            title(ax, 'Multi-Channel Power Spectrum');
            xlabel(ax, 'Frequency (Hz)');
            ylabel(ax, 'Power (dB)');
            grid(ax, 'on');

            % 颜色循环（确保不同通道颜色区分）
            colors = lines(length(channelIndices));

            % 循环计算并绘制每个通道的频谱
            for i = 1:length(channelIndices)
                chanIdx = channelIndices(i);

                % 调用FFT计算函数（假设已实现computeChannelFFT）
                [freq, spectrum] = app.computeChannelFFT(EEGdata,chanIdx);

                % 绘制功率谱（dB单位）
                plot(ax, freq, 10*log10(spectrum), ...
                    'Color', colors(i,:), ...
                    'LineWidth', 1.5);
            end
            % 自动调整坐标轴范围
            axis(ax, 'tight');

            % 关闭图形叠加
            hold(ax, 'off');
        end

        function [freq, powerSpectrum] = computeChannelFFT(app, EEGdata,channelIdx)
            % 计算指定通道的FFT频谱
            % 输入:
            %   channelIdx - 通道索引 (1到app.chans)
            %   plotResults - 是否绘制频谱图 (true/false, 可选，默认为false)
            % 输出:
            %   freq - 频率轴 (Hz)
            %   powerSpectrum - 功率谱密度 (μV²/Hz)

            % 获取数据并去均值
            tempdata = EEGdata(channelIdx, :);
            tempdata = tempdata - mean(tempdata);

            % 确保数据长度为偶数（FFT对称性要求）
            if mod(length(tempdata), 2) ~= 0
                tempdata = tempdata(1:end-1); % 丢弃最后一个点
            end
            N = length(tempdata); % 此时N为偶数

            % 应用汉宁窗
            window = hann(N)'; % 注意转置确保维度匹配
            windowedData = tempdata .* window;

            % 计算FFT
            fftResult = fft(windowedData);
            P2 = abs(fftResult/N);

            % 明确转换为整数索引
            halfN = floor(N/2); % 或 fix(N/2)
            P1 = P2(1:halfN + 1);
            P1(2:end-1) = 2*P1(2:end-1);

            % 频率轴
            freq = app.srate*(0:halfN)/N;

            % 功率谱
            powerSpectrum = (P1.^2) / (sum(window.^2) * app.srate);

            % 限制频率范围
            validRange = freq >= 0.01 & freq <= 300;
            freq = freq(validRange);
            powerSpectrum = powerSpectrum(validRange);
        end

        function closeall(app)
            app.ICAPanel.Visible='off';
            app.TimeFrequencyDecompositionPanel.Visible='off';
            app.RereferencingPanel.Visible='off';
        end

        function firFilter = designFIRBandstop(app,Fs, Flow,Fhigh, Rs, N)
            % 设计FIR低通滤波器
            % 输入:
            %   Fs - 采样率 (Hz)
            %   Flow - 低频截止频率 (Hz)
            %   Fhigh - 高频截止频率 (Hz)
            %   Rp - 通带波纹 (dB)
            %   Rs - 阻带衰减 (dB)
            %   N - 滤波器阶数 (必须为偶数)
            % 输出:
            %   firFilter - FIR滤波器系数

            % 参数验证
            assert(Flow > 0, '低频截止频率必须大于0');
            assert(Fhigh > Flow, '高频截止频率必须大于低频截止频率');
            assert(Fhigh < Fs/2, '高频截止频率必须小于奈奎斯特频率(%.1f Hz)', Fs/2);
            assert(mod(N,2) == 0, '阶数N必须是偶数');

            % 归一化频率
            normFlow = Flow / (Fs/2);   % 归一化低频截止频率
            normFhigh = Fhigh / (Fs/2); % 归一化高频截止频率

            % 设计滤波器 (使用Kaiser窗方法)
            beta = app.kaiserbeta(Rs); % 计算Kaiser窗β参数
            firFilter = fir1(N, [normFlow, normFhigh], 'stop', kaiser(N+1, beta), 'noscale');

            % 可选：频率响应可视化
            app.visualizeFilterResponse1(firFilter, Fs, Flow, Fhigh);
        end

        function firFilter = designFIRHighpass(app,Fs, Fc, Rs, N)
            % 设计FIR低通滤波器
            % 输入:
            %   Fs - 采样率 (Hz)
            %   Fc - 截止频率 (Hz)
            %   Rp - 通带波纹 (dB)
            %   Rs - 阻带衰减 (dB)
            %   N - 滤波器阶数 (必须为偶数)
            % 输出:
            %   firFilter - FIR滤波器系数

            % 参数验证
            assert(Fc > 0, '截止频率必须大于0');
            assert(Fc < Fs/2, '截止频率必须小于奈奎斯特频率(%.1f Hz)', Fs/2);
            assert(mod(N,2) == 0, '阶数N必须是偶数');

            % 归一化频率
            normFc = Fc / (Fs/2); % 归一化截止频率 (0~1)

            % 设计滤波器 (使用Kaiser窗方法)
            beta = app.kaiserbeta(Rs); % 计算Kaiser窗β参数
            firFilter = fir1(N, normFc, 'high', kaiser(N+1, beta), 'noscale');

            % 可选：频率响应可视化
            app.visualizeFilterResponse2(firFilter, Fs, Fc);
        end

        function firFilter = designFIRLowpass(app,Fs, Fc, Rs, N)
            % 设计FIR低通滤波器
            % 输入:
            %   Fs - 采样率 (Hz)
            %   Fc - 截止频率 (Hz)
            %   Rp - 通带波纹 (dB)
            %   Rs - 阻带衰减 (dB)
            %   N - 滤波器阶数 (必须为偶数)
            % 输出:
            %   firFilter - FIR滤波器系数

            % 参数验证
            assert(Fc < Fs/2, '截止频率必须小于奈奎斯特频率(%.1f Hz)', Fs/2);
            assert(mod(N,2) == 0, '阶数N必须是偶数');

            % 归一化频率
            normFc = Fc / (Fs/2); % 归一化截止频率 (0~1)

            % 设计滤波器 (使用Kaiser窗方法)
            beta = app.kaiserbeta(Rs); % 计算Kaiser窗β参数
            firFilter = fir1(N, normFc, 'low', kaiser(N+1, beta), 'noscale');

            % 可选：频率响应可视化
            app.visualizeFilterResponse2(firFilter, Fs, Fc);
        end

        function firFilter = designFIRBandpass(app,Fs, Flow,Fhigh, Rs, N)
            % 设计FIR低通滤波器
            % 输入:
            %   Fs - 采样率 (Hz)
            %   Flow - 低频截止频率 (Hz)
            %   Fhigh - 高频截止频率 (Hz)
            %   Rp - 通带波纹 (dB)
            %   Rs - 阻带衰减 (dB)
            %   N - 滤波器阶数 (必须为偶数)
            % 输出:
            %   firFilter - FIR滤波器系数

            % 参数验证
            assert(Flow > 0, '低频截止频率必须大于0');
            assert(Fhigh > Flow, '高频截止频率必须大于低频截止频率');
            assert(Fhigh < Fs/2, '高频截止频率必须小于奈奎斯特频率(%.1f Hz)', Fs/2);
            assert(mod(N,2) == 0, '阶数N必须是偶数');

            % 归一化频率
            normFlow = Flow / (Fs/2);   % 归一化低频截止频率
            normFhigh = Fhigh / (Fs/2); % 归一化高频截止频率

            % 设计滤波器 (使用Kaiser窗方法)
            beta = app.kaiserbeta(Rs); % 计算Kaiser窗β参数
            firFilter = fir1(N, [normFlow, normFhigh], 'bandpass', kaiser(N+1, beta), 'noscale');

            % 可选：频率响应可视化
            app.visualizeFilterResponse1(firFilter, Fs, Flow, Fhigh);
        end

        function beta = kaiserbeta(app , Rs)
            % 根据阻带衰减计算Kaiser窗的β参数
            if Rs > 50
                beta = 0.1102 * (Rs - 8.7);
            elseif Rs >= 21
                beta = 0.5842 * (Rs - 21)^0.4 + 0.07886 * (Rs - 21);
            else
                beta = 0;
            end
        end

        function visualizeFilterResponse1( app ,b, Fs,  Flow, Fhigh)
            % 绘制滤波器频率响应
            figure;
            freqz(b, 1, 1024, Fs);
            % 标记截止频率
            hold on;
            plot([Flow Flow], [-200 5], 'r--');
            plot([Fhigh Fhigh], [-200 5], 'r--');
            text(Flow+5, -50, sprintf('Flow: %.1f Hz', Flow), 'Color', 'r');
            text(Fhigh+5, -50, sprintf('Fhigh: %.1f Hz', Fhigh), 'Color', 'r');

            % 标记阻带区域
            patch([Flow, Fhigh, Fhigh, Flow], [-200, -200, 5, 5], 'r', 'FaceAlpha', 0.1, 'EdgeColor', 'none');

        end

        function visualizeFilterResponse2( app, b, Fs, Fc)
            % 绘制滤波器频率响应
            figure;
            freqz(b, 1, 1024, Fs);

            % 标记截止频率
            hold on;
            plot([Fc Fc], [-200 5], 'r--');
            text(Fc+5, -50, sprintf('Cutoff: %.1f Hz', Fc), 'Color', 'r');
        end

        function [Flow, Fhigh] = calculateStopband(app, centerFreq, width)
            % 根据中心频率和宽度计算阻带范围
            Flow = centerFreq - width/2;
            Fhigh = centerFreq + width/2;

            % 确保频率不超出有效范围
            Flow = max(Flow, 0.1); % 最小0.1Hz
            Fhigh = min(Fhigh, app.srate/2 * 0.99); % 最大略小于奈奎斯特频率
        end

        function saveFilteredEEG(app,data , Fs , Fc, N, Rs)
            % 确保文件夹存在
            % 定义文件夹名称
            folderName = fullfile(app.protocolinfo.protocolPath, 'filtereddata');

            % 检查文件夹是否存在，不存在则创建
            if ~exist(folderName, 'dir')
                mkdir(folderName);
                disp(['文件夹已创建: ' fullfile(pwd, folderName)]);
            else
                disp(['文件夹已存在: ' fullfile(pwd, folderName)]);
            end
            SavePath = folderName;
            % 生成带索引的文件名
            filename = sprintf('%s/FiltEEG_%03d.mat', SavePath, app.SaveIndex);
            ID= sprintf('FiltEEG_%03d',  app.SaveIndex);
            % 保存数据（包含原始参数）
            Data = data;
            params = struct('srate', Fs, 'Fc', Fc, 'N', N, 'Rs', Rs);
            save(filename, 'Data', 'params','ID');

            % 更新索引
            app.SaveIndex = app.SaveIndex + 1;
            app.Callingapp.protocolnode{app.protocolinfo.No}.NodeData.ProcessedDataPath = folderName;
            filteddataNode = uitreenode(app.Callingapp.protocolnode{app.protocolinfo.No});
            filteddataNode.Text = ['Data: ',ID];
            filteddataNode.NodeData = filename;
            filteddataNode.ContextMenu = app.Callingapp.eegDataFileMenu;
            drawnow;
            %disp(app.Callingapp.protocolnode{app.protocolinfo.No}.NodeData);
            protocolPath = app.Callingapp.protocolnode{app.protocolinfo.No}.NodeData.protocolPath;
            infoFilePath = fullfile(protocolPath, 'protocol_info.mat');
            protocolInfo=app.Callingapp.protocolnode{app.protocolinfo.No}.NodeData;
            save(infoFilePath, 'protocolInfo');
           % disp(app.Callingapp.protocolnode{app.protocolinfo.No}.NodeData.FilteredDataPath);
            % 提示用户
            msg = sprintf('数据已保存为: %s\n', filename);
            uialert(app.UIFigure, msg, '保存成功');

        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGdata, srate, protocolinfo, mainapp)
            app.UIFigure.Name='Preprocessapp';
            app.Callingapp=mainapp;
            app.TimeFrequencyDecompositionPanel.Visible='off';
            app.HighpassEditField.Enable='off';
            app.LowpassEditField.Enable='off';
            app.NotchEditField.Enable='off';
            app.checkeddataindex=1:1:size(EEGdata,1);
            app.srate=srate;
            app.protocolinfo=protocolinfo;
            app.plotMultiChannelSpectrum(EEGdata,app.checkeddataindex)
            app.data=EEGdata;
        end

        % Callback function
        function SetButtonPushed(app, event)
            app.closeall();
            if app.ICAButton.Value==1
                app.ICAPanel.Visible='on';
                app.ICAPanel.Position=[628 312 438 221];
            end
            if  app.RereferenceButton.Value==1
                app.RereferencingPanel.Visible='on';
                app.RereferencingPanel.Position=[628 1 439 531];
            end

        end

        % Button pushed function: frequencydominSetbutten
        function frequencydominSetbuttenPushed(app, event)
            app.closeall();
            app.TimeFrequencyDecompositionPanel.Visible='on';
            app.TimeFrequencyDecompositionPanel.Position=[628 1 439 531];
        end

        % Selection changed function: MethodsButtonGroup
        function MethodsButtonGroupSelectionChanged(app, event)
            selectedButton = app.MethodsButtonGroup.SelectedObject;
            app.closeall();
            if selectedButton==app.ICAButton
                app.ICAPanel.Visible='on';
                app.ICAPanel.Position=[628 312 438 221];
            end
            if  selectedButton==app.RereferenceButton
                app.RereferencingPanel.Visible='on';
                app.RereferencingPanel.Position=[628 1 439 531];
            end
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value==1
                app.HighpassEditField.Enable='on';
            else
                app.HighpassEditField.Enable='off';
            end
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            if value==1
                app.LowpassEditField.Enable='on';
            else
                app.LowpassEditField.Enable='off';
            end

        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            value = app.CheckBox_3.Value;
            if value==1
                app.NotchEditField.Enable='on';
            else
                app.NotchEditField.Enable='off';
            end
        end

        % Button pushed function: OKButton_6
        function OKButton_6Pushed(app, event)
            if app.CheckBox.Value==1&&app.CheckBox_2.Value==0&&app.CheckBox_3.Value==0
                %highpass
                Fc=app.HighpassEditField.Value;
                Rs=40;N=100;
                firCoeff = app.designFIRHighpass(app.srate, Fc, Rs, N);
            elseif app.CheckBox.Value==0&&app.CheckBox_2.Value==1&&app.CheckBox_3.Value==0
                %lowpass
                Fc=app.LowpassEditField.Value;
                Rs=40;N=10;
                firCoeff = app.designFIRLowpass(app.srate, Fc, Rs, N);
            elseif app.CheckBox.Value==1&&app.CheckBox_2.Value==1&&app.CheckBox_3.Value==0 &&...
                    app.HighpassEditField.Value<app.LowpassEditField.Value
                %bandpass
                Fhigh =app.LowpassEditField.Value;
                Flow =app.HighpassEditField.Value;
                Fc=[Flow,Fhigh];
                Rs=40;N=100;
                firCoeff = app.designFIRBandpass(app.srate,  Flow, Fhigh, Rs, N);
            elseif app.CheckBox.Value==0&&app.CheckBox_2.Value==0&&app.CheckBox_3.Value==1
                %bandstop
                centerFreq=app.NotchEditField.Value;
                width = 4;
                [Flow, Fhigh] = app.calculateStopband(centerFreq, width);
                Fc=[Flow,Fhigh];
                Rs=60;N=120;
                firCoeff = app.designFIRBandstop(app.srate,  Flow, Fhigh, Rs, N);
            elseif app.CheckBox.Value==1&&app.CheckBox_2.Value==1&&app.CheckBox_3.Value==1
                %级联
            elseif app.CheckBox.Value==0&&app.CheckBox_2.Value==0&&app.CheckBox_3.Value==0
                uialert(app.UIFigure,'You did not choose any method','Selected a Method');
                return
            end

            % 应用滤波
            % 获取原始数据维度
            [nChannels, nTimePoints] = size(app.data);

            % 预分配滤波后数据矩阵
            app.filteredEEG = zeros(nChannels, nTimePoints);

            % 逐通道滤波
            for i = 1:nChannels
                % 对第i行数据进行零相位滤波
                app.filteredEEG(i, :) = filtfilt(firCoeff, 1, app.data(i, :));
            end

            app.saveFilteredEEG(app.filteredEEG,app.srate, Fc, N, Rs);

            app.plotMultiChannelSpectrum(app.filteredEEG,app.checkeddataindex);

        end

        % Button pushed function: OKButton_5
        function OKButton_5Pushed(app, event)
            if strcmp(app.ChooseparamsEditField.Value,'Average') 
                app.data=mean( app.data, 3);
            end
            app.saveFilteredEEG(app.data,app.srate, [], [],[]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FrequencydomainFilteringPanel = uipanel(app.UIFigure);
            app.FrequencydomainFilteringPanel.Title = 'Frequency-domain Filtering';
            app.CheckBox = uicheckbox(app.FrequencydomainFilteringPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox_2 = uicheckbox(app.FrequencydomainFilteringPanel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.FrequencydomainFilteringPanel);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.HzLabel = uilabel(app.FrequencydomainFilteringPanel);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.FrequencydomainFilteringPanel);
            app.HzLabel_2.Text = 'Hz';
            app.HzLabel_3 = uilabel(app.FrequencydomainFilteringPanel);
            app.HzLabel_3.Text = 'Hz';
            app.HighpassEditFieldLabel = uilabel(app.FrequencydomainFilteringPanel);
            app.HighpassEditFieldLabel.Text = 'High-pass';
            app.HighpassEditField = uieditfield(app.FrequencydomainFilteringPanel, 'numeric');
            app.LowpassEditFieldLabel = uilabel(app.FrequencydomainFilteringPanel);
            app.LowpassEditFieldLabel.Text = 'Low-pass';
            app.LowpassEditField = uieditfield(app.FrequencydomainFilteringPanel, 'numeric');
            app.NotchEditFieldLabel = uilabel(app.FrequencydomainFilteringPanel);
            app.NotchEditFieldLabel.Text = 'Notch';
            app.NotchEditField = uieditfield(app.FrequencydomainFilteringPanel, 'numeric');
            app.frequencydominSetbutten = uibutton(app.FrequencydomainFilteringPanel, 'push');
            app.frequencydominSetbutten.ButtonPushedFcn = createCallbackFcn(app, @frequencydominSetbuttenPushed, true);
            app.frequencydominSetbutten.Text = 'Time-Frequency Decomposition >>';
            app.OKButton_6 = uibutton(app.FrequencydomainFilteringPanel, 'push');
            app.OKButton_6.ButtonPushedFcn = createCallbackFcn(app, @OKButton_6Pushed, true);
            app.OKButton_6.Text = 'OK';
            app.SpatialFilteringPanel = uipanel(app.UIFigure);
            app.SpatialFilteringPanel.Title = 'Spatial Filtering';
            app.MethodsButtonGroup = uibuttongroup(app.SpatialFilteringPanel);
            app.MethodsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MethodsButtonGroupSelectionChanged, true);
            app.MethodsButtonGroup.Title = 'Methods';
            app.ICAButton = uiradiobutton(app.MethodsButtonGroup);
            app.ICAButton.Text = '     ICA';
            app.ICAButton.Value = true;
            app.RereferenceButton = uiradiobutton(app.MethodsButtonGroup);
            app.RereferenceButton.Text = '     Re-reference';
            app.InterpolateButton = uiradiobutton(app.MethodsButtonGroup);
            app.InterpolateButton.Text = '     Interpolate';
            app.LaplacianButton = uiradiobutton(app.MethodsButtonGroup);
            app.LaplacianButton.Text = '     Laplacian';
            app.ERPrelatedPanel = uipanel(app.SpatialFilteringPanel);
            app.ERPrelatedPanel.Title = 'ERP-related';
            app.SetButton = uibutton(app.ERPrelatedPanel, 'push');
            app.SetButton.Text = 'Set >>';
            app.SetButton_2 = uibutton(app.ERPrelatedPanel, 'push');
            app.SetButton_2.Text = 'Set >>';
            app.SetButton_3 = uibutton(app.ERPrelatedPanel, 'push');
            app.SetButton_3.Text = 'Set >>';
            app.ExtractTrialsLabel = uilabel(app.ERPrelatedPanel);
            app.ExtractTrialsLabel.Text = 'Extract Trials';
            app.BaselineCorrectLabel = uilabel(app.ERPrelatedPanel);
            app.BaselineCorrectLabel.Text = 'Baseline-Correct';
            app.AverageTrialsLabel = uilabel(app.ERPrelatedPanel);
            app.AverageTrialsLabel.Text = 'Average Trials';
            app.SpectrumPanel = uipanel(app.UIFigure);
            app.SpectrumPanel.Title = 'Spectrum';
            app.UIAxes = uiaxes(app.SpectrumPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ICAPanel = uipanel(app.UIFigure);
            app.ICAPanel.Title = 'ICA';
            app.ReordercomponentsbyvarianceCheckBox = uicheckbox(app.ICAPanel);
            app.ReordercomponentsbyvarianceCheckBox.Text = 'Reorder components by variance';
            app.ICAalgorithmDropDownLabel = uilabel(app.ICAPanel);
            app.ICAalgorithmDropDownLabel.Text = 'ICA algorithm';
            app.ICAalgorithmDropDown = uidropdown(app.ICAPanel);
            app.ICAalgorithmDropDown.Items = {'runica', 'binica'};
            app.ICAalgorithmDropDown.Value = 'runica';
            app.CommandlineoptiionsEditFieldLabel = uilabel(app.ICAPanel);
            app.CommandlineoptiionsEditFieldLabel.Text = 'Commandline optiions';
            app.CommandlineoptiionsEditField = uieditfield(app.ICAPanel, 'text');
            app.CommandlineoptiionsEditField.Value = '''extened'',1';
            app.ChanneltypesorchannelindicesEditFieldLabel = uilabel(app.ICAPanel);
            app.ChanneltypesorchannelindicesEditFieldLabel.Text = 'Channel type(s) or channel indices';
            app.ChanneltypesorchannelindicesEditField = uieditfield(app.ICAPanel, 'text');
            app.OKButton = uibutton(app.ICAPanel, 'push');
            app.OKButton.Text = 'OK';
            app.TimeFrequencyDecompositionPanel = uipanel(app.UIFigure);
            app.TimeFrequencyDecompositionPanel.Title = 'Time-Frequency Decomposition';
            app.STFTLabel = uilabel(app.TimeFrequencyDecompositionPanel);
            app.STFTLabel.Text = 'STFT';
            app.WaveletTransformLabel = uilabel(app.TimeFrequencyDecompositionPanel);
            app.WaveletTransformLabel.Text = 'Wavelet Transform';
            app.WindowDropDownLabel = uilabel(app.TimeFrequencyDecompositionPanel);
            app.WindowDropDownLabel.Text = 'Window';
            app.WindowDropDown = uidropdown(app.TimeFrequencyDecompositionPanel);
            app.WindowDropDown.Items = {'hann', 'hamming', 'gaussian', 'blackman'};
            app.WindowDropDown.Value = 'hann';
            app.WindowLengthEditFieldLabel = uilabel(app.TimeFrequencyDecompositionPanel);
            app.WindowLengthEditFieldLabel.Text = 'Window Length';
            app.WindowLengthEditField = uieditfield(app.TimeFrequencyDecompositionPanel, 'numeric');
            app.OverlapRatioEditFieldLabel = uilabel(app.TimeFrequencyDecompositionPanel);
            app.OverlapRatioEditFieldLabel.Text = 'Overlap Ratio';
            app.OverlapRatioEditField = uieditfield(app.TimeFrequencyDecompositionPanel, 'numeric');
            app.Label = uilabel(app.TimeFrequencyDecompositionPanel);
            app.Label.Text = '%';
            app.NFFTWindowLengthEditFieldLabel = uilabel(app.TimeFrequencyDecompositionPanel);
            app.NFFTWindowLengthEditFieldLabel.Text = 'NFFT(>Window Length)';
            app.NFFTWindowLengthEditField = uieditfield(app.TimeFrequencyDecompositionPanel, 'numeric');
            app.WaveletFamliyDropDownLabel = uilabel(app.TimeFrequencyDecompositionPanel);
            app.WaveletFamliyDropDownLabel.Text = 'Wavelet Famliy';
            app.WaveletFamliyDropDown = uidropdown(app.TimeFrequencyDecompositionPanel);
            app.WaveletFamliyDropDown.Items = {'morl', 'mesh', 'cmor'};
            app.WaveletFamliyDropDown.Value = 'morl';
            app.ScalesEditFieldLabel = uilabel(app.TimeFrequencyDecompositionPanel);
            app.ScalesEditFieldLabel.Text = 'Scales';
            app.ScalesEditField = uieditfield(app.TimeFrequencyDecompositionPanel, 'numeric');
            app.ScalesEditField_2 = uieditfield(app.TimeFrequencyDecompositionPanel, 'numeric');
            app.toLabel = uilabel(app.TimeFrequencyDecompositionPanel);
            app.toLabel.Text = 'to';
            app.HzLabel_4 = uilabel(app.TimeFrequencyDecompositionPanel);
            app.HzLabel_4.Text = 'Hz';
            app.VoicesperOctaveEditFieldLabel = uilabel(app.TimeFrequencyDecompositionPanel);
            app.VoicesperOctaveEditFieldLabel.Text = 'Voices per Octave';
            app.VoicesperOctaveEditField = uieditfield(app.TimeFrequencyDecompositionPanel, 'numeric');
            app.VoicesperOctaveEditField.Value = 32;
            app.OKButton_2 = uibutton(app.TimeFrequencyDecompositionPanel, 'push');
            app.OKButton_2.Text = 'OK';
            app.ResamplingLabel = uilabel(app.TimeFrequencyDecompositionPanel);
            app.ResamplingLabel.Text = 'Resampling';
            app.DownsamplingFacorEditFieldLabel = uilabel(app.TimeFrequencyDecompositionPanel);
            app.DownsamplingFacorEditFieldLabel.Text = ' Downsampling Facor';
            app.DownsamplingFacorEditField = uieditfield(app.TimeFrequencyDecompositionPanel, 'numeric');
            app.OKButton_3 = uibutton(app.TimeFrequencyDecompositionPanel, 'push');
            app.OKButton_3.Text = 'OK';
            app.OKButton_4 = uibutton(app.TimeFrequencyDecompositionPanel, 'push');
            app.OKButton_4.Text = 'OK';
            app.RereferencingPanel = uipanel(app.UIFigure);
            app.RereferencingPanel.Title = 'Re-referencing';
            app.ChooseparamsEditFieldLabel = uilabel(app.RereferencingPanel);
            app.ChooseparamsEditFieldLabel.Text = 'Choose params ';
            app.ChooseparamsEditField = uieditfield(app.RereferencingPanel, 'text');
            app.MethodDropDownLabel = uilabel(app.RereferencingPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.RereferencingPanel);
            app.MethodDropDown.Items = {'direct', 'online', 'robust'};
            app.MethodDropDown.Value = 'direct';
            app.OKButton_5 = uibutton(app.RereferencingPanel, 'push');
            app.OKButton_5.ButtonPushedFcn = createCallbackFcn(app, @OKButton_5Pushed, true);
            app.OKButton_5.Text = 'OK';
            app.inputexamplesPanel = uipanel(app.RereferencingPanel);
            app.inputexamplesPanel.Title = 'input examples';
            app.A1Label = uilabel(app.inputexamplesPanel);
            app.A1Label.Text = ''' A1 ''';
            app.A1A2Label = uilabel(app.inputexamplesPanel);
            app.A1A2Label.Text = '{ '' A1 '' , '' A2 '' }';
            app.AverageLabel = uilabel(app.inputexamplesPanel);
            app.AverageLabel.Text = ''' Average ''';
            app.orA1A2Label = uilabel(app.inputexamplesPanel);
            app.orA1A2Label.Text = '[ 1 , 3 , 5 ] or { '' A1 '' , '' A2 '' }';
            app.DualelectrodereferenceLabel = uilabel(app.inputexamplesPanel);
            app.DualelectrodereferenceLabel.Text = 'Dual-electrode reference';
            app.SingleelectrodereferenceLabel = uilabel(app.inputexamplesPanel);
            app.SingleelectrodereferenceLabel.Text = 'Single-electrode reference';
            app.AveragereferenceLabel = uilabel(app.inputexamplesPanel);
            app.AveragereferenceLabel.Text = 'Average reference';
            app.AverageofspecificchannelgroupsLabel = uilabel(app.inputexamplesPanel);
            app.AverageofspecificchannelgroupsLabel.Text = 'Average of specific channel groups';
            app.NoiceCovariancePanel = uipanel(app.UIFigure);
            app.NoiceCovariancePanel.Title = 'Noice-Covariance';
            app.CalculateButton = uibutton(app.NoiceCovariancePanel, 'push');
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Preprocessbackup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end