classdef SEAL_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        NewProjectMenu            matlab.ui.container.Menu
        LoadProjectMenu           matlab.ui.container.Menu
        NewprotocolMenu           matlab.ui.container.Menu
        ImportprotocolMenu        matlab.ui.container.Menu
        SaveMenu                  matlab.ui.container.Menu
        EditMenu                  matlab.ui.container.Menu
        GeneratetemplateMenu      matlab.ui.container.Menu
        ToolMenu                  matlab.ui.container.Menu
        PlotMenu                  matlab.ui.container.Menu
        EEGMenu                   matlab.ui.container.Menu
        CortexMenu                matlab.ui.container.Menu
        SourceMenu                matlab.ui.container.Menu
        EEGTimeSeriesMenu         matlab.ui.container.Menu
        BrainModelMenu            matlab.ui.container.Menu
        SourceImageMenu           matlab.ui.container.Menu
        HistoryMenu               matlab.ui.container.Menu
        HelpMenu                  matlab.ui.container.Menu
        OperationsPanel           matlab.ui.container.Panel
        RunBatchButton            matlab.ui.control.Button
        EvaluationButton          matlab.ui.control.Button
        SimulationButton          matlab.ui.control.Button
        ESIAnalysisButton         matlab.ui.control.Button
        VisualizationButton       matlab.ui.control.Button
        PreprocessButton          matlab.ui.control.Button
        StatusPanel               matlab.ui.container.Panel
        TextArea                  matlab.ui.control.TextArea
        ProjectPanel              matlab.ui.container.Panel
        FileTree                  matlab.ui.container.Tree
        ProtocolInformationPanel  matlab.ui.container.Panel
        DataTypeValue             matlab.ui.control.Label
        ProtocolPathValue         matlab.ui.control.Label
        DataType                  matlab.ui.control.Label
        ProtocolPath              matlab.ui.control.Label
        SamplingRateValue         matlab.ui.control.Label
        SamplingRateLabel         matlab.ui.control.Label
        OrientationValue          matlab.ui.control.Label
        HeadModelTypeValue        matlab.ui.control.Label
        GainFlagValue             matlab.ui.control.Label
        CortexFlagValue           matlab.ui.control.Label
        DataSizeValue             matlab.ui.control.Label
        ChannelsValue             matlab.ui.control.Label
        NumOrientationLabel       matlab.ui.control.Label
        HeadModelTypeLabel        matlab.ui.control.Label
        GainLabel                 matlab.ui.control.Label
        CortexLabel               matlab.ui.control.Label
        ChannelLabel              matlab.ui.control.Label
        DataSizeLabel             matlab.ui.control.Label
        ContextMenu               matlab.ui.container.ContextMenu
        CopyMenu                  matlab.ui.container.Menu
    end


    properties (Access = private)
        DialogApp_showEEG
        DialogApp_showcortex
        Dialogapp_selectAlgorithm
        DialogApp_newprotocol
        DialogApp_datacheck
        DialogApp_preprocess
        datanode={}
        Cortex={}               %导入后的Cortex数据
        L={}                    %导入后的lead-field Matrix数据
        Chanlocs={}             %导入后的channel locations数据
        OutputHistory           string  % 保存所有输出内容

    end

    properties (Access = public)
        projectNode ProjectNode % root of project tree
        ProjectPath             % path to save project folders
        DataMapping             % For load .mat data
        wasCancelled
        i
        newprotocol             string
        protocolnode={}         %树节点数组
        inode = 0               %树节点索引(自动累计)
        protocolmenu            %树节点上下文菜单
        cortexMenu
        eegDataFileMenu
        leadfieldMenu
        chanlocsMenu
        sessionMenu
        ResultDataFileMenu
        PreprocessedDataMenu
        SimuParamMenu
        firsttime=1
        DialogApp_showResult = {}
    end

    methods (Access = private)
        % 核心函数：向文本区域添加内容

        function populateProtocolNode(app, protocolNode, ~)

            if nargin<4
                updated = 0;
            end
            protocolInfo = protocolNode.NodeData;

            % Clear existing children before populating
            protocolNode.Children.delete;

            if isfield(protocolInfo, 'Cortex') && ~isempty(protocolInfo.Cortex)
                entry = protocolInfo.Cortex(end);
                if updated == 1
                    protocolInfo.Cortex(end).FilePath = ...
                        fullfile(protocolInfo.protocolPath, [entry.ID,'.mat']);
                    entry.FilePath = protocolInfo.Cortex(end).FilePath;
                end
                node = uitreenode(protocolNode, 'Text', ['Cortex: ', entry.ID], 'NodeData', entry.FilePath);
                node.ContextMenu = app.cortexMenu;
            end

            if isfield(protocolInfo, 'LeadField') && ~isempty(protocolInfo.LeadField)
                entry = protocolInfo.LeadField(end);
                if updated == 1
                    protocolInfo.LeadField(end).FilePath = ...
                        fullfile(protocolInfo.protocolPath, [entry.ID,'.mat']);
                    entry.FilePath = protocolInfo.LeadField(end).FilePath;
                end
                node = uitreenode(protocolNode, 'Text', ['LeadField: ', entry.ID], 'NodeData', entry.FilePath);
                node.ContextMenu = app.leadfieldMenu;
            end

            if isfield(protocolInfo, 'Chanlocs') && ~isempty(protocolInfo.Chanlocs)
                entry = protocolInfo.Chanlocs(end);
                if updated == 1
                    protocolInfo.Chanlocs(end).FilePath = ...
                        fullfile(protocolInfo.protocolPath, [entry.ID,'.mat']);
                    entry.FilePath = protocolInfo.Chanlocs(end).FilePath;
                end
                node = uitreenode(protocolNode, 'Text', ['Chanlocs: ', entry.ID], 'NodeData', entry.FilePath);
                node.ContextMenu = app.chanlocsMenu;%只有删除
            end

            if isfield(protocolInfo, 'SimuParam') && ~isempty(protocolInfo.SimuParam)
                dataArray = protocolInfo.SimuParam;
                for j = 1:numel(dataArray)
                    entry = dataArray(j);
                    node = uitreenode(protocolNode, 'Text', ['Simu: ', entry.ID], 'NodeData', entry.FilePath);
                    node.ContextMenu = app.SimuParamMenu;
                end
            end

            if isfield(protocolInfo, 'Data') && ~isempty(protocolInfo.Data)
                dataArray = protocolInfo.Data;
                for j = 1:numel(dataArray)
                    entry = dataArray(j);
                    if updated == 1
                        protocolInfo.Data(j).FilePath = ...
                            fullfile(protocolInfo.protocolPath, [entry.ID, '.mat']);
                        entry.FilePath = protocolInfo.Data(j).FilePath;
                    end
                    node = uitreenode(protocolNode, 'Text', ['Data: ', entry.ID], 'NodeData', entry.FilePath);
                    node.ContextMenu = app.eegDataFileMenu;
                end
            end

            if isfield(protocolInfo, 'ProcessedDataPath') && ~isempty(protocolInfo.ProcessedDataPath)
                % 获取文件夹中的所有文件
                fileList = dir(fullfile(protocolInfo.ProcessedDataPath, '*'));
                for j = 1:length(fileList)
                    % 跳过特殊目录项：. 和 ..
                    if strcmp(fileList(j).name, '.') || strcmp(fileList(j).name, '..')
                        continue;
                    end
                    % 创建新条目
                    Entry = struct();
                    % 设置文件路径
                    Entry.FilePath = fullfile(fileList(j).folder, fileList(j).name);

                    % 使用文件名作为ID
                    [~, name, ~] = fileparts(fileList(j).name);
                    Entry.ID = name;

                    node = uitreenode(protocolNode, 'Text', ['Data: ', Entry.ID], 'NodeData', Entry.FilePath);
                    node.ContextMenu = app.PreprocessedDataMenu;%只有删除
                end
            end

            if isfield(protocolInfo, 'ResultData') && ~isempty(protocolInfo.ResultData)
                %判断是否真实存在
                folderName = fullfile(protocolInfo.protocolPath, 'ResultData');
                fileList = dir(fullfile(protocolInfo.ResultPath, '*'));

                if ~exist(folderName, 'dir')
                    return
                elseif isempty(fileList)
                    return
                end

                %存在即导入
                for m=1:length(app.FileTree.Children)
                    children = app.FileTree.Children(m).Children;
                    for j=1:length(protocolInfo.ResultData)
                        for n = 1:length(children)
                            if strcmp(protocolInfo.ResultData(j).Source,children(n).Text)
                                node = uitreenode(children(n), ...
                                    'Text', protocolInfo.ResultData(j).ID ,'NodeData',protocolInfo.ResultData(j).filepath);
                                node.ContextMenu = app.ResultDataFileMenu;
                                continue
                            end
                        end
                    end
                end
                drawnow;
                return
            end

        end

        function ImportDataSelected(app, dataType)
            % Get the currently selected protocol node
            selectedNode = app.FileTree.SelectedNodes;

            if numel(selectedNode) > 1
                uialert(app.UIFigure, ['Please select only one protocol to...' ...
                    ' import data into.'], 'Multiple Protocols Selected');
                return;
            end
            if  ~isa(selectedNode.Parent,'matlab.ui.container.Tree')
                uialert(app.UIFigure, 'Click the protocol first', 'Protocol not Selected');
                return
            end

            protocolNode = selectedNode.NodeData;

            % ---Overwrite check for Cortex/Leadfield, one for each protocol---
            if strcmp(dataType, 'Cortex') || strcmp(dataType, 'Leadfield')|| strcmp(dataType, 'Chanlocs')
                if isvalid(protocolNode.(strcat(lower(dataType), "Node")))
                    question = ['This protocol already has a ' strrep(dataType, '_', ' ') ' file. Overwrite it?'];
                    selection = uiconfirm(app.UIFigure, question, 'Confirm Overwrite', 'Icon', 'warning');
                    if ~strcmp(selection, 'OK')
                        app.appendOutput('Import cancelled by user.');
                        return;
                    end

                end
            end

            % --- Define File Filters for the Dialog ---
            % This allows the user to specify the format.
            filterSpec = { ...
                '*.mat', 'MATLAB (.mat)'; ...
                '*.set', 'EEGLAB (.set)'; ...
                '*.mat', 'BrainStorm Cortex(.mat)';...
                '*.*',   'All Files (*.*)'};


            % Let user select the .mat file
            [fileName, filePath, filterIndex] = uigetfile(filterSpec, ['Select ', strrep(dataType, '_', ' '), ' File']);
            if fileName == 0
                app.appendOutput('Import data cancelled');
                return;
            end
            originalPath = fullfile(filePath, fileName);
            % --- Call the appropriate importer based on the selected filter ---
            try
                switch filterIndex
                    case 1 % MATLAB (.mat)
                        if strcmp(dataType, "Chanlocs")
                            protocolNode.openChanlocsFromData(originalPath);
                        end
                        if strcmp(dataType, "Cortex")
                            protocolNode.openCortexFromData(originalPath);
                        end
                        if strcmp(dataType, "Leadfield")
                            protocolNode.openLeadfieldFromData(originalPath);
                        end
                        if strcmp(dataType, "Data")
                            protocolNode.openDataFromData(originalPath);
                        end
%                         sourceData = load(originalPath);
%                         if numel(sourceData)==1
%                             sourceData = struct2cell(sourceData);
%                             sourceData = sourceData{1};
%                         end
%                         if ismatrix(sourceData)&&~isstruct(sourceData)
%                             sourceData = load(originalPath);
%                         end
%                         if isstruct(sourceData)
%                             sourceVariables = fieldnames(sourceData);
% 
%                         end
%                         app.DialogApp_datacheck = SEAL_DataCheck(app,dataType,sourceVariables);
%                         uiwait(app.DialogApp_datacheck.UIFigure);
%                         if app.wasCancelled == 1
%                             app.wasCancelled = 0;
%                             app.appendOutput(["Import data cancelled"]);
%                             return;
%                         end
% 
%                         [newDataStruct, metadata] = seal_importer(originalPath, dataType, 'mappings', app.DataMapping);
                    case 2 % EEGLAB (.set)
%                         [newDataStruct, metadata] = import_from_eeglab(app,originalPath);
                    case 3 % Brainstorm Cortex
%                         [newDataStruct, metadata] = import_from_bst_surface(app,originalPath);
                    otherwise % All files or unknown
                        % You could add more sophisticated format detection here
                        errordlg('File format not recognized. Please select a supported type.', 'Import Error');
                        return;
                end
            catch ME
                errordlg(['Failed to import file. Error: ', ME.message], 'Import Error');
                return;
            end
%             % Define the new path inside the protocol folder
%             if isfield(newDataStruct,'ID') && ~isempty(newDataStruct.ID)
%                 newBaseName = newDataStruct.ID;
%             else
%                 [~, originalName, ~] = fileparts(fileName);
%                 newBaseName = [lower(dataType) '_' originalName];
%             end
%             tempfilename = newBaseName;
%             newFilePath = fullfile(protocolPath, [newBaseName, '.mat']);
% 
%             if strcmp(dataType, 'Cortex') || strcmp(dataType, 'LeadField')||strcmp(dataType, 'Chanlocs')
%                 % overwrite single-entry types
%                 protocolInfo.(dataType).FilePath = newFilePath;
%                 protocolInfo.(dataType).ID = tempfilename;
%             else
%                 % for Data
%                 counter = 1;
%                 while exist(newFilePath, 'file')
%                     newFileName = [newBaseName '_' num2str(counter) '.mat'];
%                     newFilePath = fullfile(protocolPath, newFileName);
%                     counter = counter + 1;
%                     tempfilename = newFileName;
%                 end
% 
%                 % New information entry
%                 newEntry = struct();
%                 newEntry.FilePath = newFilePath;
%                 newEntry.ID = tempfilename; % Store the comment/name for display
%                 newEntry.SamplingRate = metadata.SamplingRate;
%                 newEntry.DataSize = metadata.DataSize;
% 
%                 % Append the new entry to a struct array for this data type
%                 if ~isfield(protocolInfo, dataType) || isempty(protocolInfo.(dataType))
%                     % If this is the first file of its type, create the array
%                     protocolInfo.(dataType) = newEntry;
%                 else
%                     % Otherwise, append to the existing array
%                     protocolInfo.(dataType)(end+1) = newEntry;
%                 end
%             end
%             metaset = fieldnames(metadata);
%             for imeta = 1:numel(metaset)
%                 if ~isempty(metadata.(metaset{imeta}))
%                     protocolInfo.metadata.(metaset{imeta}) = metadata.(metaset{imeta});
%                 end
%             end
% 
%             if strcmp(dataType, 'Cortex')
%                 app.Cortex{protocolNo}=newDataStruct;
%             elseif strcmp(dataType, 'LeadField')
%                 app.L{protocolNo}=newDataStruct;
%             elseif strcmp(dataType, 'Chanlocs')
%                 app.Chanlocs{protocolNo}=newDataStruct;
%             end
% 
%             % Save the standardized data and the updated info file
%             infoFilePath = fullfile(protocolPath, 'protocol_info.mat');
%             save(infoFilePath, 'protocolInfo');
%             save(newFilePath, '-struct', 'newDataStruct');
% 
%             selectedNode.NodeData = protocolInfo;
% 
%             % Refresh the view of this node
%             app.populateProtocolNode(selectedNode, protocolPath);
%             expand(selectedNode);
%             app.FileTreeSelectionChanged();
            app.flushFileTree();
            text = strcat(dataType, ' imported successfully for protocol ''', protocolNode.name, '''.');
            app.appendOutput(text);
        end

        function ImportSimulationData(app, dataType)
            % dataType: cortex, leadfield, simulation parameter(SimuParam)
            selectedNode = app.FileTree.SelectedNodes;

            if numel(selectedNode) > 1
                uialert(app.UIFigure, ['Please select only one protocol to...' ...
                    ' import data into.'], 'Multiple Protocols Selected');
                return;
            end
            if ~isa(selectedNode.Parent,'matlab.ui.container.Tree')
                uialert(app.UIFigure, 'Click the protocol first', 'Protocol not Selected');
                return
            end
            protocolPath = selectedNode.NodeData.protocolPath;
            protocolInfo = selectedNode.NodeData;
            filterSpec = { ...
                '*.mat', 'MATLAB (.mat)'; ...
                '*.mat', 'BrainStorm Cortex (.mat)';...
                '*.*',   'All Files (*.*)'};

            % Let user select the .mat file
            [fileName, filePath, filterIndex] = uigetfile(filterSpec, ['Select ', strrep(dataType, '_', ' '), ' File']);
            if fileName == 0
                app.appendOutput('Import data cancelled');
                return;
            end
            originalPath = fullfile(filePath, fileName);
            % --- Call the appropriate importer based on the selected filter ---
            try
                switch filterIndex
                    case 1 % MATLAB (.mat)
                        sourceData = load(originalPath);
                        if numel(sourceData)==1
                            sourceData = struct2cell(sourceData);
                            sourceData = sourceData{1};
                        end
                        if ismatrix(sourceData)&&~isstruct(sourceData)
                            sourceData = load(originalPath);
                        end
                        if strcmp(dataType, "SimuParam")
                            [newDataStruct, metadata] = load_SimuParam(app,originalPath);
                        else
                            if isstruct(sourceData)
                                sourceVariables = fieldnames(sourceData);
                            end
                            app.DialogApp_datacheck = SEAL_DataCheck(app,dataType,sourceVariables);
                            uiwait(app.DialogApp_datacheck.UIFigure);
                            if app.wasCancelled == 1
                                app.wasCancelled = 0;
                                app.appendOutput(["Import data cancelled"]);
                                return;
                            end
                            [newDataStruct, metadata] = seal_importer(originalPath, dataType, 'mappings', app.DataMapping);
                        end

                    case 2 % Brainstorm Cortex
                        [newDataStruct, metadata] = import_from_bst_surface(app,originalPath);
                    otherwise % All files or unknown
                        % You could add more sophisticated format detection here
                        errordlg('File format not recognized. Please select a supported type.', 'Import Error');
                        return;
                end
            catch ME
                errordlg(['Failed to import file. Error: ', ME.message], 'Import Error');
                return;
            end
            % Define the new path inside the protocol folder
            if isfield(newDataStruct,'ID') && ~isempty(newDataStruct.ID)
                newBaseName = newDataStruct.ID;
            else
                [~, originalName, ~] = fileparts(fileName);
                newBaseName = [lower(dataType) '_' originalName];
            end
            tempfilename = newBaseName;
            newFilePath = fullfile(protocolPath, [newBaseName, '.mat']);
            counter = 1;
            while exist(newFilePath, 'file')
                newFileName = [newBaseName '_' num2str(counter) '.mat'];
                newFilePath = fullfile(protocolPath, newFileName);
                counter = counter + 1;
                tempfilename = newFileName;
            end

            % New information entry
            newEntry = struct();
            newEntry.FilePath = newFilePath;
            newEntry.ID = tempfilename; % Store the comment/name for display
            %             newEntry.SamplingRate = metadata.SamplingRate;
            %             newEntry.DataSize = metadata.DataSize;

            % Append the new entry to a struct array for this data type
            if ~isfield(protocolInfo, dataType) || isempty(protocolInfo.(dataType))
                % If this is the first file of its type, create the array
                protocolInfo.(dataType) = newEntry;
            else
                % Otherwise, append to the existing array
                protocolInfo.(dataType)(end+1) = newEntry;
            end
            metaset = fieldnames(metadata);
            for imeta = 1:numel(metaset)
                if ~isempty(metadata.(metaset{imeta}))
                    protocolInfo.metadata.(metaset{imeta}) = metadata.(metaset{imeta});
                end
            end
            % Save the standardized data and the updated info file
            infoFilePath = fullfile(protocolPath, 'protocol_info.mat');
            save(infoFilePath, 'protocolInfo');
            save(newFilePath, '-struct', 'newDataStruct');

            selectedNode.NodeData = protocolInfo;

            % Refresh the view of this node
            app.populateProtocolNode(selectedNode, protocolPath);
            expand(selectedNode);
            app.FileTreeSelectionChanged();
            app.appendOutput([dataType, ' imported successfully for protocol ''', selectedNode.Text, '''.']);

        end

        function DeleteProtocolSelected(app, ~)
            selectedNodes = app.FileTree.SelectedNodes;
            if isempty(selectedNodes)
                uialert(app.UIFigure, 'Please select one or more protocols to delete.', 'No Selection');
                return;
            end
            numNodes = numel(selectedNodes);
            % --- Build the confirmation message ---
            if numNodes == 1
                question = ['Are you sure you want to permanently delete the protocol "', selectedNodes(1).Text, '" and all its data?'];
            else
                question = ['Are you sure you want to permanently delete the ', num2str(numNodes), ' selected protocols and all their data?'];
            end

            selection = uiconfirm(app.UIFigure, question, 'Confirm Deletion', 'Icon', 'warning');
            if strcmp(selection, 'OK')
                % --- Loop through all selected nodes and delete them ---
                for k = 1:numNodes
                    node = selectedNodes(k);
                    protocolPath = node.NodeData.protocolPath;
                    protocolName = node.Text;

                    if ~isempty(protocolPath) && exist(protocolPath, 'dir')
                        [status, msg] = rmdir(protocolPath, 's');
                        if status
                            app.appendOutput(['Protocol ''', protocolName, ''' deleted successfully.']);
                        else
                            uialert(app.UIFigure, ['Could not delete folder for ''', protocolName, '''. Reason: ', msg], 'Deletion Failed');
                        end
                    end
                end
                % Delete all selected nodes from the tree at once
                delete(selectedNodes);
                app.inode=app.inode -1;
            end
        end

        function DeleteDataFileSelected(app, dataType)
            selectedNode = app.FileTree.SelectedNodes;
            if isempty(selectedNode), return; end

            filePathToDelete = selectedNode.NodeData;

            if  strcmp(dataType, 'Results')
                protocolNode = selectedNode.Parent.Parent; % Get the top-level protocol node
            else
                protocolNode = selectedNode.Parent; % Get the top-level protocol node
            end

            protocolPath = protocolNode.NodeData.protocolPath;
            infoFilePath = fullfile(protocolPath, 'protocol_info.mat');

            question = ['Are you sure you want to delete the file: ', selectedNode.Text, '?'];
            selection = uiconfirm(app.UIFigure, question, 'Confirm Deletion', 'Icon', 'warning');

            if strcmp(selection, 'OK')
                % 1. Update protocol_info.mat
                protocolInfo = protocolNode.NodeData;

                if strcmp(dataType, 'Cortex') || strcmp(dataType, 'LeadField')|| strcmp(dataType, 'Chanlocs')
                    % For single-entry types, just clear the fields
                    protocolInfo.(dataType) = [];
                    protocolInfo.metadata.(dataType) = 'No';
                    if strcmp(dataType,'LeadField')
                        protocolInfo.metadata.HeadModelType = '....';
                        protocolInfo.metadata.Orientation = '....';
                    end
                elseif strcmp(dataType, 'Results')
                    for j=length(protocolInfo.ResultData):-1:1
                        if strcmp(protocolInfo.ResultData(j).ID,selectedNode.Text)
                            protocolInfo.ResultData(j)=[];
                        end
                    end
                else % For 'Data' (multi-entry)
                    % Find the entry to remove from the array
                    dataArray = protocolInfo.(dataType);
                    keepIdx = ~strcmp({dataArray.FilePath}, filePathToDelete);
                    protocolInfo.(dataType) = dataArray(keepIdx);
                    protocolInfo.metadata.DataSize = '....';
                    protocolInfo.metadata.SamplingRate = '....';
                end


                % 2. Delete the actual file from disk
                if exist(filePathToDelete, 'file'), delete(filePathToDelete); end
                app.appendOutput(['File ''', selectedNode.Text, ''' deleted.']);
                % 3. Refresh the UI
                save(infoFilePath, 'protocolInfo');
                protocolNode.NodeData = protocolInfo;
                app.populateProtocolNode(protocolNode, protocolPath);
                expand(protocolNode);
                app.FileTree.SelectedNodes = protocolNode;
                app.FileTreeSelectionChanged();

            end
        end

        function VisualizeDataSelected(app, vizType)
            selectedNode = app.FileTree.SelectedNodes;
            if isempty(selectedNode), return; end

            dataPath = selectedNode.NodeData;
            if strcmp(vizType,'Results')
                if all(cellfun(@(x) isempty(x) || isequal(x, 0) || isequal(x, false), app.DialogApp_showResult), 'all')
                    app.DialogApp_showResult = {};
                end
                potocolnode=selectedNode.Parent.Parent;
                No=potocolnode.NodeData.No;
                node=selectedNode.Parent;
                idx = find(cellfun(@(x) isequal(x, node), app.datanode));
                % 如果目标不在数组中，将其添加
                if isempty(idx)
                    app.datanode{end+1} = node;
                    idx = find(cellfun(@(x) isequal(x, node), app.datanode));
                end
            end
            %             Data=load(dataPath);%每次点击重新加载数据
            if strcmp(vizType,'EEG_TimeSeries')&&~isempty(app.DialogApp_showResult)
                idx = find(cellfun(@(x) isequal(x, selectedNode), app.datanode));
                if isempty(idx)
                    app.datanode{end+1} = node;
                    idx = find(cellfun(@(x) isequal(x, selectedNode), app.datanode));
                end
            end
            switch vizType
                case 'EEG_TimeSeries'
                    potocolnode=selectedNode.Parent;
                    protocolpath=potocolnode.NodeData.protocolPath;
                    if ~isempty(app.DialogApp_showResult)
                        app.DialogApp_showEEG=SEAL_showEEG(dataPath,...
                            protocolpath,...
                            app.DialogApp_showResult(idx,:), ...
                            false);
                            
                    else
                        app.DialogApp_showEEG=SEAL_showEEG(dataPath,...
                            protocolpath,...
                            {}, ...
                            false);
                            
                    end
                case 'EEG_Topo'
                    Data=load(dataPath);
                    topoplotX([],Data.chanlocs);
                    % plotEEGLabelsInside( [] ,Data.chanlocs , [], 'electrodes', 'labels', 'isInside', true);
                case 'Cortex'
                    app.DialogApp_showcortex=SEAL_showCortex(app,dataPath,[]);
                case 'Results'
                    app.DialogApp_showResult{idx,end+1}=SEAL_showCortex(app , app.Cortex{No}, dataPath);
                otherwise
                    uialert(app.UIFigure, ['Unknown visualization type: ' vizType], 'Error');
            end

        end

        function RunSourceAnalysisSelected(app, ~)
            selectedNode = app.FileTree.SelectedNodes;
            if isempty(selectedNode), return; end
            app.Dialogapp_selectAlgorithm=SEAL_selectAlgorithm(app,selectedNode,app.firsttime);


        end

        function [EEG_Data, metadata] = import_from_eeglab(~,filePath)
            % Converts an EEGLAB .set file to the SEAL EEG_Data structure
            if ~exist('pop_loadset', 'file')
                error('SEAL:DependencyError', 'EEGLAB function pop_loadset not found. Is EEGLAB in your MATLAB path?');
            end
            EEG = pop_loadset('filename', filePath);

            EEG_Data = struct();
            EEG_Data.Comment = EEG.setname;
            EEG_Data.F = double(EEG.data);
            EEG_Data.srate = EEG.srate;
            EEG_Data.time = EEG.times / 1000; % Convert ms to seconds
            EEG_Data.chanlocs = EEG.chanlocs;
            EEG_Data.events = EEG.event;
            EEG_Data.type = 'EEG';
            EEG_Data.ica = struct();
            metadata.DataType = 'EEG';
            metadata.DataSize = size(EEG.data);
            metadata.SamplingRate = EEG.srate;

        end

        function [Cortex, metadata] = import_from_bst_surface(~, filePath)
            % Converts a Brainstorm surface .mat file to the SEAL Cortex structure
            s = load(filePath);
            s = struct2cell(s); s = s{1};
            Cortex = struct();
            Cortex.Vertices = s.Vertices;
            Cortex.Faces = s.Faces;
            tIdx = strcmp('Structures',string(struct2table(s.Atlas).Name));
            Cortex.Structure = s.Atlas(tIdx);
            als_idx = setdiff(1:numel(s.Atlas), tIdx);
            if ~isempty(als_idx)
                Cortex.Atlas = s.Atlas(als_idx);
            end

            if isfield(s, 'VertConn'), Cortex.VertConn = s.VertConn; end
            if isfield(s, 'Comment'), Cortex.Comment = s.Comment; end
            if isfield(s, 'VertNormals'), Cortex.VertNormals = s.VertNormals; end
            if isfield(s, 'Curvature'), Cortex.Curvature = s.Curvature; end
            if isfield(s, 'SulciMap'), Cortex.SulciMap = s.SulciMap; end
            if isfield(s, 'tess2mri_interp'), Cortex.tess2mri_interp = s.tess2mri_interp; end
            if isfield(s, 'Reg'), Cortex.Reg = s.Reg; end
            metadata.Cortex = 'Yes';
        end


    end

    methods (Access = public)
        function appendOutput(app, text)
            % 拼接新内容（添加换行符模拟命令行）
            app.OutputHistory = app.OutputHistory + text + newline;

            % 更新文本区域显示
            app.TextArea.Value = app.OutputHistory;

            scroll(app.TextArea, 'bottom');
        end

        function flushFileTree(app)
            app.FileTree.Children.delete;
            
            for i=1:length(app.projectNode.children)
                protocolNode = app.projectNode.children(i);
                protocolView = uitreenode(app.FileTree);
                protocolView.Text = string(protocolNode.name);
                protocolView.ContextMenu = app.protocolmenu;
                protocolView.NodeData = protocolNode;
                if isvalid(protocolNode.chanlocsNode)
                    chanlocsView = uitreenode(protocolView);
                    chanlocsView.Text = string(protocolNode.chanlocsNode.name);
                    chanlocsView.ContextMenu = app.chanlocsMenu;
                    chanlocsView.NodeData = protocolNode.chanlocsNode;
                end
                if isvalid(protocolNode.cortexNode)
                    cortexView = uitreenode(protocolView);
                    cortexView.Text = string(protocolNode.cortexNode.name);
                    cortexView.ContextMenu = app.cortexMenu;
                    cortexView.NodeData = protocolNode.cortexNode;
                end
                if isvalid(protocolNode.leadfieldNode)
                    leadfieldView = uitreenode(protocolView);
                    leadfieldView.Text = string(protocolNode.leadfieldNode.name);
                    leadfieldView.ContextMenu = app.leadfieldMenu;
                    leadfieldView.NodeData = protocolNode.leadfieldNode;
                end
                for j=1:length(protocolNode.children)
                    sessionNode = protocolNode.children(j);
                    sessionView = uitreenode(protocolView);
                    sessionView.Text = string(sessionNode.name);
                    sessionView.ContextMenu = app.sessionMenu;
                    sessionView.NodeData = sessionNode;
                    for k=1:length(sessionNode.children)
                        dataNode = protocolNode.children(j);
                        dataView = uitreenode(sessionView);
                        dataView.Text = string(dataNode.name);
                        dataView.NodeData = dataNode;
                    end
                    expand(sessionView);
                end
                expand(protocolView);
            end
        end
        
        function scanAndLoadProtocols(app)
            app.FileTree.Children.delete;
            app.inode = 0;
            if isempty(app.ProjectPath), return; end

            items = dir(app.ProjectPath);
            subDirs = items([items.isdir]);

            for k = 1:length(subDirs)
                dirName = subDirs(k).name;
                if strcmp(dirName, '.') || strcmp(dirName, '..')
                    continue; 
                end

                protocolPath = fullfile(app.ProjectPath, dirName);
                
                if ~ismember('protocol_info.mat', {dir(protocolPath).name})
                    continue;
                end
                
                infoFile = fullfile(protocolPath, 'protocol_info.mat');
                CortexFileList = dir(fullfile(protocolPath, '*cortex*.mat'));
                LeadfieldFileList = dir(fullfile(protocolPath, '*leadfield*.mat'));
                ChanlocsFileList = dir(fullfile(protocolPath, '*chanlocs*.mat'));
                s = load(infoFile); s = s.protocolInfo;

                if exist(infoFile, 'file')
                    app.inode = app.inode + 1;
                    protocolNode = uitreenode(app.FileTree, 'Text', dirName, 'NodeData', s);
                    disp(s.No);
                    if ~isempty(CortexFileList)
                        CortexfilePath = fullfile(CortexFileList(1).folder, CortexFileList(1).name);
                        app.Cortex{s.No}=load(CortexfilePath);
                    end
                    if ~isempty(LeadfieldFileList)
                        LeadfieldfilePath = fullfile(LeadfieldFileList(1).folder, LeadfieldFileList(1).name);
                        app.L{s.No}=load(LeadfieldfilePath);
                    end
                    if ~isempty(ChanlocsFileList)
                        ChanlocsfilePath = fullfile(ChanlocsFileList(1).folder, ChanlocsFileList(1).name);
                        app.Chanlocs{s.No}=load(ChanlocsfilePath);
                    end

                    % *** THIS IS THE FIX ***
                    % Assign the context menu to the loaded protocol node
                    protocolNode.ContextMenu = app.protocolmenu;
                    app.protocolnode{s.No} = protocolNode;
                    app.populateProtocolNode(protocolNode, protocolPath);
                    expand(protocolNode);
                end
            end
            app.appendOutput([num2str(app.inode), ' protocol(s) loaded from project.']);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('Algorithms'));
            addpath(genpath('CoreFunctions'));
            addpath(genpath('External'));
            app.UIFigure.Name='SEAL';
            app.EEGTimeSeriesMenu.Enable='off';
            app.BrainModelMenu.Enable='off';
            app.SourceImageMenu.Enable='off';
            % -----setup the right-click context menu-----
            % 1. Main Protocol Menu (for the top-level protocol node)
            app.protocolmenu = uicontextmenu(app.UIFigure);
            uimenu(app.protocolmenu, 'Text', 'Import EEG Data...', 'MenuSelectedFcn', @(s,e) ImportDataSelected(app, 'Data'));
            uimenu(app.protocolmenu, 'Text', 'Import Cortex...', 'MenuSelectedFcn', @(s,e) ImportDataSelected(app, 'Cortex'));
            uimenu(app.protocolmenu, 'Text', 'Import Lead Field...', 'MenuSelectedFcn', @(s,e) ImportDataSelected(app, 'Leadfield'));
            uimenu(app.protocolmenu, 'Text', 'Import Channel Location...', 'MenuSelectedFcn', @(s,e) ImportDataSelected(app, 'Chanlocs'));
            uimenu(app.protocolmenu, 'Text', 'Delete Protocol(s)', 'Separator', 'on', 'MenuSelectedFcn', @(s,e) DeleteProtocolSelected(app, e));

            % 2. Cortex File Menu
            app.cortexMenu = uicontextmenu(app.UIFigure);
            uimenu(app.cortexMenu, 'Text', 'Visualize...', 'MenuSelectedFcn', @(s,e) VisualizeDataSelected(app, 'Cortex'));
            uimenu(app.cortexMenu, 'Text', 'Delete', 'Separator', 'on', 'MenuSelectedFcn', @(s,e) DeleteDataFileSelected(app, 'Cortex'));

            app.leadfieldMenu = uicontextmenu(app.UIFigure);
            uimenu(app.leadfieldMenu, 'Text', 'Delete', 'MenuSelectedFcn', @(s,e) DeleteDataFileSelected(app, 'LeadField'));

            app.chanlocsMenu = uicontextmenu(app.UIFigure);
            uimenu(app.chanlocsMenu, 'Text', 'Delete', 'MenuSelectedFcn', @(s,e) DeleteDataFileSelected(app, 'Chanlocs'));

            app.sessionMenu = uicontextmenu(app.UIFigure);
            uimenu(app.sessionMenu, 'Text', 'Delete', 'MenuSelectedFcn', @(s,e) DeleteDataFileSelected(app, 'Session'));

            % 5. EEG Data File Menu (for individual EEG files)
            app.eegDataFileMenu = uicontextmenu(app.UIFigure);
            uimenu(app.eegDataFileMenu, 'Text', 'Visualize Time Series...', 'MenuSelectedFcn', @(s,e) VisualizeDataSelected(app, 'EEG_TimeSeries'));
            uimenu(app.eegDataFileMenu, 'Text', 'Visualize Topography...', 'MenuSelectedFcn', @(s,e) VisualizeDataSelected(app, 'EEG_Topo'));
            uimenu(app.eegDataFileMenu, 'Text', 'Run Source Analysis...', 'Separator', 'on', 'MenuSelectedFcn', @(s,e) RunSourceAnalysisSelected(app, e));
            uimenu(app.eegDataFileMenu, 'Text', 'Delete', 'Separator', 'on', 'MenuSelectedFcn', @(s,e) DeleteDataFileSelected(app, 'Data'));

            app.ResultDataFileMenu = uicontextmenu(app.UIFigure);
            uimenu(app.ResultDataFileMenu, 'Text', 'Visualize...', 'Separator', 'on','MenuSelectedFcn', @(s,e) VisualizeDataSelected(app, 'Results'));
            uimenu(app.ResultDataFileMenu, 'Text', 'Delete', 'Separator', 'on','MenuSelectedFcn', @(s,e) DeleteDataFileSelected(app, 'Results'));
            app.OutputHistory="";
            app.wasCancelled = 0;

            app.PreprocessedDataMenu = uicontextmenu(app.UIFigure);
            uimenu(app.PreprocessedDataMenu, 'Text', 'Visualize Time Series...', 'MenuSelectedFcn', @(s,e) VisualizeDataSelected(app, 'EEG_TimeSeries'));
            uimenu(app.PreprocessedDataMenu, 'Text', 'Run Source Analysis...', 'Separator', 'on', 'MenuSelectedFcn', @(s,e) RunSourceAnalysisSelected(app, e));
            uimenu(app.PreprocessedDataMenu, 'Text', 'Delete', 'Separator', 'on', 'MenuSelectedFcn', @(s,e) DeleteDataFileSelected(app, 'Data'));

            app.SimuParamMenu = uicontextmenu(app.UIFigure);
            uimenu(app.SimuParamMenu, 'Text', 'Delete', 'MenuSelectedFcn', @(s,e) DeleteDataFileSelected(app, 'SimuParam'))

            if ispref('SEAL_Toolbox', 'ProjectPath')
                app.ProjectPath = getpref('SEAL_Toolbox', 'ProjectPath');
                try
                    app.projectNode = ProjectNode.openExisting(app.ProjectPath);
                    app.ProjectPanel.Title = strcat('Project:',app.projectNode.name);
                    app.appendOutput('Click the protocolnode to update protocol information!!');
                catch ME
                    app.appendOutput(strcat("Preference """, app.ProjectPath, " "" seems to be illegal."));
                end
                app.flushFileTree();
            else
                app.ProjectPath = "";
            end
%             if ~exist(app.ProjectPath, 'dir')
%                 mkdir(app.ProjectPath);
%             end
            
        end

        % Menu selected function: NewprotocolMenu
        function NewprotocolMenuSelected(app, event)
            app.DialogApp_newprotocol=SEAL_Newprotocol(app);
        end

        % Selection changed function: FileTree
        function FileTreeSelectionChanged(app, event)
            %选择的节点变化，信息需要更新
            %需要限制只能选择Protocol节点
            oldNodes = event.PreviousSelectedNodes;
            newNodes = event.SelectedNodes;
            
            % 取消之前选中的所有节点
            if ~isempty(oldNodes)
                for i = 1:length(oldNodes)
                    node = oldNodes(i);
                    nodeData = node.NodeData;
                    if ~isempty(nodeData) && ismethod(nodeData, 'deselect')
                        try
                            nodeData.deselect();
                        catch ME
                            warning('Failed to deselect node: %s', ME.message);
                        end
                    end
                end
            end
            
            % 选中新节点
            if ~isempty(newNodes)
                for i = 1:length(newNodes)
                    node = newNodes(i);
                    nodeData = node.NodeData;
                    if ~isempty(nodeData) && ismethod(nodeData, 'select')
                        try
                            nodeData.select();
                        catch ME
                            warning('Failed to select node: %s', ME.message);
                        end
                    end
                end
            end
%             selectedNodes = app.FileTree.SelectedNodes;
% 
%             if numel(selectedNodes)==1 && contains({selectedNodes.Text},'Data:')
%                 protocolInfo = selectedNodes.Parent.NodeData;
% 
%                 nodeData = selectedNodes.NodeData;
%                 if isempty(nodeData) || ~ischar(nodeData) || ~exist(nodeData, 'file')
%                     % This is a folder node or empty node, do nothing
%                     return;
%                 end
%                 dataArray = protocolInfo.Data;
%                 for iarray = 1:numel(dataArray)
%                     entry = dataArray(iarray);
%                     if strcmp(['Data: ',entry.ID], selectedNodes.Text)
%                         protocolInfo.metadata.DataSize = entry.DataSize;
%                         protocolInfo.metadata.SamplingRate = entry.SamplingRate;
%                         app.SamplingRateValue.Text = num2str(entry.SamplingRate);
%                         app.DataSizeValue.Text = join(string(entry.DataSize),' * ');
%                         break;
%                     end
%                 end
%                 selectedNodes.Parent.NodeData = protocolInfo;
%             end
% 
%             if numel(selectedNodes)==1 && isa(selectedNodes.Parent,'matlab.ui.container.Tree')
%                 metadata = selectedNodes.NodeData.metadata;
%                 protocolPath = selectedNodes.NodeData.protocolPath;
%                 app.ProtocolPathValue.Text = protocolPath;
%                 app.ProtocolPathValue.Tooltip = protocolPath;
%                 if isfield(metadata,'DataType') && ~isempty(metadata.DataType)
%                     app.DataTypeValue.Text = metadata.DataType;
%                 else
%                     app.DataTypeValue.Text = '....';
%                 end
%                 if isfield(metadata,'DataSize') && ~isempty(metadata.DataSize)
%                     app.DataSizeValue.Text = join(string(metadata.DataSize),' * ');
%                 else
%                     app.DataSizeValue.Text = '....';
%                 end
%                 if isfield(metadata,'Chanlocs') && ~isempty(metadata.Chanlocs)
%                     app.ChannelsValue.Text = metadata.Chanlocs;
%                 else
%                     app.ChannelsValue.Text = 'No';
%                 end
%                 if isfield(metadata,'SamplingRate') && ~isempty(metadata.SamplingRate)
%                     app.SamplingRateValue.Text = num2str(metadata.SamplingRate);
%                 else
%                     app.SamplingRateValue.Text = '....';
%                 end
%                 if isfield(metadata,'Cortex') && ~isempty(metadata.Cortex)
%                     app.CortexFlagValue.Text = metadata.Cortex;
%                 else
%                     app.CortexFlagValue.Text = 'No';
%                 end
%                 if isfield(metadata,'LeadField') && ~isempty(metadata.LeadField)
%                     app.GainFlagValue.Text = metadata.LeadField;
%                 else
%                     app.GainFlagValue.Text = 'No';
%                 end
%                 if isfield(metadata,'HeadModelType') && ~isempty(metadata.HeadModelType)
%                     app.HeadModelTypeValue.Text = metadata.HeadModelType;
%                 else
%                     app.HeadModelTypeValue.Text = '....';
%                 end
%                 if isfield(metadata,'Orientation') && ~isempty(metadata.Orientation)
%                     app.OrientationValue.Text = string(metadata.Orientation);
%                 else
%                     app.OrientationValue.Text = '....';
%                 end
        end

        % Callback function
        function ProjectManagerTabSizeChanged(app, event)
            position = app.ProjectManagerTab.Position;
        end

        % Menu selected function: NewProjectMenu
        function NewProjectMenuSelected(app, event)
            if isempty(app.ProjectPath)
                defaultPath = pwd; % If no project path exists, use current path as default.
            else
                [defaultPath, ~, ~] = fileparts(app.ProjectPath);
                
                % If on root path, set it as default.
                if isempty(defaultPath)
                    defaultPath = app.ProjectPath; % Otherwise, set location of last project as default.
                end
            end
            selectedPath = uigetdir(defaultPath, 'Select a location to create the new Project');

            if ~isequal(selectedPath, 0) && ~isempty(strtrim(selectedPath))
                
                defaultProjectName = findAvailableName(selectedPath);
                projectName = inputdlg('Enter a name for the new project:', 'New Project', [1 50], {defaultProjectName});
                
                if isempty(projectName)
                    app.appendOutput('Create Project cancelled.');
                    return;
                end
                
                projectName = strtrim(projectName{1});
                
                if isempty(projectName)
                    errordlg('Project name cannot be empty.', 'Invalid Project Name');
                    return;
                end
                
                newProjectPath = fullfile(selectedPath, projectName);
                
                if exist(newProjectPath, 'dir')
                    errordlg('A project with this name already exists in that location.', 'Project Exists');
                    return;
                end

                app.projectNode = ProjectNode.createNew(projectName, selectedPath);

                app.ProjectPanel.Title = ['Project: ', projectName];

%                 mkdir(newProjectPath);
                app.ProjectPath = newProjectPath;
                setpref('SEAL_Toolbox', 'ProjectPath', newProjectPath);
%                 app.scanAndLoadProtocols();
                app.appendOutput(['Create directory for Project: ', newProjectPath]);
            else
                app.appendOutput('Create Project cancelled.');
            end
        end

        % Menu selected function: ImportprotocolMenu
        function ImportprotocolMenuSelected(app, event)

            % Let user select a protocol folder from anywhere
            sourceProtocolPath = uigetdir(app.ProjectPath, 'Select Protocol Folder to Import');
            if sourceProtocolPath == 0, return; end

            % Validate it's a real protocol
            if ~exist(fullfile(sourceProtocolPath, 'protocol_info.mat'), 'file')
                uialert(app.UIFigure, 'The selected folder is not a valid SEAL protocol.', 'Invalid Folder');
                return;
            end

            % Check for naming conflicts in the current project
            [~, protocolName] = fileparts(sourceProtocolPath);
            targetProtocolPath = fullfile(app.ProjectPath, protocolName);

%             newProtocolName = protocolName;
%             while exist(targetProtocolPath, 'dir')
%                 answer = inputdlg(['A protocol named "', newProtocolName, '" already exists. Please provide a new name:'], ...
%                     'Protocol Name Conflict', [1 60], {['Copy_of_', newProtocolName]});
%                 if isempty(answer), return; end % User cancelled
%                 newProtocolName = answer{1};
%                 targetProtocolPath = fullfile(app.ProjectPath, newProtocolName);
%             end
% 
%             % Copy the entire folder into the current project
%             copyfile(sourceProtocolPath, targetProtocolPath);
%             app.appendOutput(['Copied protocol to: ', targetProtocolPath]);

            protocol = app.projectNode.openProtocol(sourceProtocolPath);
%             infoFilePath = fullfile(targetProtocolPath, 'protocol_info.mat');
%             s = load(infoFilePath);
%             protocolInfo = s.protocolInfo;
%             % If it was renamed, update the info file inside the NEW folder
%             if ~strcmp(protocolName, newProtocolName)
%                 protocolInfo.Name = newProtocolName;
%                 save(infoFilePath, 'protocolInfo');
%                 app.appendOutput(['Renamed imported protocol to: ', newProtocolName]);
%             end
% 
%             % Load the newly imported protocol into the tree
%             app.inode = app.inode + 1;
            app.flushFileTree();
            app.appendOutput(['Successfully import protocol: ', protocolName]);

        end

        % Menu selected function: LoadProjectMenu
        function LoadProjectMenuSelected(app, event)
            % Let user select an existing project folder
            selectedPath = uigetdir(app.ProjectPath, 'Select Project Folder');
            if selectedPath == 0
                app.appendOutput(['Load Project cancelled']);
                return;
            end

            app.projectNode = ProjectNode.openExisting(selectedPath);
% 
%             [~, projectName] = fileparts(selectedPath);
            app.ProjectPanel.Title = strcat("Project: ", app.projectNode.name);
            setpref('SEAL_Toolbox', 'ProjectPath', selectedPath);
            app.ProjectPath = app.projectNode.path;
            %app = scanAndLoadProtocols(app);
%             app.scanAndLoadProtocols();
            app.appendOutput(strcat('Project loaded: ', selectedPath));
        end

        % Button pushed function: PreprocessButton
        function PreprocessButtonPushed(app, event)
            selectedNode = app.FileTree.SelectedNodes;
            if isempty(selectedNode)||selectedNode.Parent==app.FileTree
                uialert(app.UIFigure,'Choose a DataNode!','Warning');
                return
            end

            dataPath = selectedNode.NodeData;

            protocolinfo = selectedNode.Parent.NodeData;
            % Data=load(dataPath);        %每次点击重新加载数据

            
            app.DialogApp_preprocess=SEAL_Preprocess( dataPath, protocolinfo , app);
        end

        % Menu selected function: CopyMenu
        function CopyMenuSelected(app, event)
            textToCopy = app.ProtocolPathValue.Text;

            % 复制到剪贴板
            clipboard('copy', textToCopy);

            % 提示用户
            app.appendOutput(['Copied text：' textToCopy]);
        end

        % Menu selected function: GeneratetemplateMenu
        function GeneratetemplateMenuSelected(app, event)
            CreateProcessTemplate(app.ProjectPath,'process1.m','protocolPath',app.FileTree.SelectedNodes.NodeData.protocolPath);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewProjectMenu = uimenu(app.FileMenu);
            app.NewProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @NewProjectMenuSelected, true);
            app.NewProjectMenu.Text = 'New Project';
            app.LoadProjectMenu = uimenu(app.FileMenu);
            app.LoadProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadProjectMenuSelected, true);
            app.LoadProjectMenu.Text = 'Load Project';
            app.NewprotocolMenu = uimenu(app.FileMenu);
            app.NewprotocolMenu.MenuSelectedFcn = createCallbackFcn(app, @NewprotocolMenuSelected, true);
            app.NewprotocolMenu.Text = 'New protocol';
            app.ImportprotocolMenu = uimenu(app.FileMenu);
            app.ImportprotocolMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportprotocolMenuSelected, true);
            app.ImportprotocolMenu.Text = 'Import protocol';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.GeneratetemplateMenu = uimenu(app.EditMenu);
            app.GeneratetemplateMenu.MenuSelectedFcn = createCallbackFcn(app, @GeneratetemplateMenuSelected, true);
            app.GeneratetemplateMenu.Text = 'Generate template';
            app.ToolMenu = uimenu(app.UIFigure);
            app.ToolMenu.Text = 'Tool';
            app.PlotMenu = uimenu(app.ToolMenu);
            app.PlotMenu.Text = 'Plot';
            app.EEGMenu = uimenu(app.PlotMenu);
            app.EEGMenu.Text = 'EEG';
            app.CortexMenu = uimenu(app.PlotMenu);
            app.CortexMenu.Text = 'Cortex';
            app.SourceMenu = uimenu(app.PlotMenu);
            app.SourceMenu.Text = 'Source';
            app.EEGTimeSeriesMenu = uimenu(app.ToolMenu);
            app.EEGTimeSeriesMenu.Text = 'EEG Time Series';
            app.BrainModelMenu = uimenu(app.ToolMenu);
            app.BrainModelMenu.Text = 'Brain Model';
            app.SourceImageMenu = uimenu(app.ToolMenu);
            app.SourceImageMenu.Text = 'Source Image';
            app.HistoryMenu = uimenu(app.UIFigure);
            app.HistoryMenu.Text = 'History';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.ProtocolInformationPanel = uipanel(app.UIFigure);
            app.ProtocolInformationPanel.Title = 'Protocol Information';
            app.DataSizeLabel = uilabel(app.ProtocolInformationPanel);
            app.DataSizeLabel.Text = 'Data Size';
            app.ChannelLabel = uilabel(app.ProtocolInformationPanel);
            app.ChannelLabel.Text = 'Chanlocs';
            app.CortexLabel = uilabel(app.ProtocolInformationPanel);
            app.CortexLabel.Text = 'Cortex';
            app.GainLabel = uilabel(app.ProtocolInformationPanel);
            app.GainLabel.Text = 'LeadField';
            app.HeadModelTypeLabel = uilabel(app.ProtocolInformationPanel);
            app.HeadModelTypeLabel.Text = 'HeadModelType';
            app.NumOrientationLabel = uilabel(app.ProtocolInformationPanel);
            app.NumOrientationLabel.Text = 'NumOrientation';
            app.ChannelsValue = uilabel(app.ProtocolInformationPanel);
            app.ChannelsValue.Text = 'No';
            app.DataSizeValue = uilabel(app.ProtocolInformationPanel);
            app.DataSizeValue.Text = '....';
            app.CortexFlagValue = uilabel(app.ProtocolInformationPanel);
            app.CortexFlagValue.Text = 'No';
            app.GainFlagValue = uilabel(app.ProtocolInformationPanel);
            app.GainFlagValue.Text = 'No';
            app.HeadModelTypeValue = uilabel(app.ProtocolInformationPanel);
            app.HeadModelTypeValue.Text = '....';
            app.OrientationValue = uilabel(app.ProtocolInformationPanel);
            app.OrientationValue.Text = '....';
            app.SamplingRateLabel = uilabel(app.ProtocolInformationPanel);
            app.SamplingRateLabel.Text = 'Sampling Rate';
            app.SamplingRateValue = uilabel(app.ProtocolInformationPanel);
            app.SamplingRateValue.Text = '....';
            app.ProtocolPath = uilabel(app.ProtocolInformationPanel);
            app.ProtocolPath.Text = 'Path';
            app.DataType = uilabel(app.ProtocolInformationPanel);
            app.DataType.Text = 'Data Type';
            app.ProtocolPathValue = uilabel(app.ProtocolInformationPanel);
            app.ProtocolPathValue.Text = '....';
            app.DataTypeValue = uilabel(app.ProtocolInformationPanel);
            app.DataTypeValue.Text = '....';
            app.ProjectPanel = uipanel(app.UIFigure);
            app.ProjectPanel.Title = 'Project';
            app.FileTree = uitree(app.ProjectPanel);
            app.FileTree.Multiselect = 'on';
            app.FileTree.SelectionChangedFcn = createCallbackFcn(app, @FileTreeSelectionChanged, true);
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Status';
            app.TextArea = uitextarea(app.StatusPanel);
            app.OperationsPanel = uipanel(app.UIFigure);
            app.OperationsPanel.Title = 'Operations';
            app.PreprocessButton = uibutton(app.OperationsPanel, 'push');
            app.PreprocessButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessButtonPushed, true);
            app.PreprocessButton.Text = 'Preprocess';
            app.VisualizationButton = uibutton(app.OperationsPanel, 'push');
            app.VisualizationButton.Text = 'Visualization';
            app.ESIAnalysisButton = uibutton(app.OperationsPanel, 'push');
            app.ESIAnalysisButton.Text = 'ESI Analysis';
            app.SimulationButton = uibutton(app.OperationsPanel, 'push');
            app.SimulationButton.Text = 'Simulation';
            app.EvaluationButton = uibutton(app.OperationsPanel, 'push');
            app.EvaluationButton.Text = 'Evaluation';
            app.RunBatchButton = uibutton(app.OperationsPanel, 'push');
            app.RunBatchButton.Text = 'Run Batch ';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.CopyMenu = uimenu(app.ContextMenu);
            app.CopyMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyMenuSelected, true);
            app.CopyMenu.Text = 'Copy';
            % Assign app.ContextMenu
            app.ProtocolPathValue.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SEAL_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end