classdef SEAL_Newprotocol < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CreateNewProtocolPanel         matlab.ui.container.Panel
        NewProtocolNameEditField       matlab.ui.control.EditField
        NewProtocolNameEditFieldLabel  matlab.ui.control.Label
        TypeButtonGroup                matlab.ui.container.ButtonGroup
        SimulationButton               matlab.ui.control.RadioButton
        RealDataAnalysisButton         matlab.ui.control.RadioButton
        CreateButton                   matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
    end


    properties (Access = private)
        Callingapp
        ProtocolName = ''
        ProtocolType = 'Real Data Analysis' % Default value
        WasCancelled = true % Assume cancelled until 'Create' is pressed
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.Callingapp=mainapp;
            app.Callingapp.inode = app.Callingapp.inode+1;
            
            defaultProtocolName = findAvailableName(app.Callingapp.ProjectPath, 'Protocol #');
            if isprop(app, 'NewProtocolNameEditField') && isvalid(app.NewProtocolNameEditField)
                app.NewProtocolNameEditField.Value = defaultProtocolName;
            end
            
            
%             protocolName = sprintf('Protocol_%d', app.Callingapp.inode);
            app.NewProtocolNameEditField.Value=defaultProtocolName;
           if ~isempty(mainapp)
                mainFigPos = mainapp.UIFigure.Position;
                projectPanelLeft = 4; % Corresponds to ProjectPanel position
                dialogWidth = 258;
                dialogHeight = 205;
                newX = mainFigPos(1) + projectPanelLeft;
                newY = mainFigPos(2) + (mainFigPos(4) - dialogHeight) / 2;
                app.UIFigure.Position = [newX, newY, dialogWidth, dialogHeight];
            else
                app.UIFigure.Position = [100 100 258 205];
            end
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
%             protocolName = sprintf('Protocol_%d', app.Callingapp.i);
            
            protocolName = app.NewProtocolNameEditField.Value;
            if isempty(strtrim(protocolName))
                errordlg('Protocol name cannot be empty.', 'Invalid Protocol Name');
                return;
            end
            
            newProtocolPath = fullfile(app.Callingapp.ProjectPath, protocolName);

            if exist(newProtocolPath, 'dir')
                errordlg(['A protocol named "', protocolName, '" already exists in the current base directory.'], 'Error: Protocol Exists');
                return;
            end
            mkdir(newProtocolPath);
            % Save a metadata file
            protocolInfo.Name = protocolName;
            protocolInfo.No=app.Callingapp.inode;
            protocolInfo.Type = 'ESI Analysis';
            protocolInfo.CreationDate = datetime('now');
            protocolInfo.protocolPath = newProtocolPath;
            protocolInfo.ProcessedDataPath = [];
            protocolInfo.metadata = struct('ID', [], 'DataType', [], 'DataSize', [], 'Chanlocs', [],...
                'SamplingRate', [], 'Cortex', [], 'LeadField', [], 'HeadModelType', [], 'Orientation', []);
            save(fullfile(newProtocolPath, 'protocol_info.mat'), 'protocolInfo');
            

            app.Callingapp.protocolnode{app.Callingapp.inode} = uitreenode(app.Callingapp.FileTree);
            app.Callingapp.protocolnode{app.Callingapp.inode}.Text =string(app.NewProtocolNameEditField.Value);
            app.Callingapp.protocolnode{app.Callingapp.inode}.ContextMenu=app.Callingapp.protocolmenu;
            app.Callingapp.protocolnode{app.Callingapp.inode}.NodeData = protocolInfo;
            app.Callingapp.appendOutput(['Successfully created new protocol: ''', protocolName, '''']);

            % create directory for new protocol
            delete(app);
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.Callingapp.inode = app.Callingapp.inode-1;
            
            app.Callingapp.appendOutput('New protocol creation cancelled.');
            delete(app);
%             uiwait(msgbox('New protocol creation cancelled.'));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CreateNewProtocolPanel = uipanel(app.UIFigure);
            app.CreateNewProtocolPanel.Title = 'Create New Protocol';
            app.CancelButton = uibutton(app.CreateNewProtocolPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CreateButton = uibutton(app.CreateNewProtocolPanel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.TypeButtonGroup = uibuttongroup(app.CreateNewProtocolPanel);
            app.TypeButtonGroup.Title = 'Type';
            app.RealDataAnalysisButton = uiradiobutton(app.TypeButtonGroup);
            app.RealDataAnalysisButton.Text = 'Real Data  Analysis';
            app.RealDataAnalysisButton.Value = true;
            app.SimulationButton = uiradiobutton(app.TypeButtonGroup);
            app.SimulationButton.Text = 'Simulation';
            app.NewProtocolNameEditFieldLabel = uilabel(app.CreateNewProtocolPanel);
            app.NewProtocolNameEditFieldLabel.Text = 'New Protocol Name';
            app.NewProtocolNameEditField = uieditfield(app.CreateNewProtocolPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SEAL_Newprotocol(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end