classdef SEAL_Preprocess < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InterceptthesignalsectionPanel  matlab.ui.container.Panel
        inputexamplesPanel_2            matlab.ui.container.Panel
        PointrangeLabel                 matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        signalsectionbutten             matlab.ui.control.Button
        ChoosepointsEditField           matlab.ui.control.EditField
        ChoosepointsEditFieldLabel      matlab.ui.control.Label
        LaplacianPanel                  matlab.ui.container.Panel
        LapMEditField                   matlab.ui.control.NumericEditField
        SphericalsplineordermEditField_2Label  matlab.ui.control.Label
        LapLambdaEditField              matlab.ui.control.NumericEditField
        LambdaEditFieldLabel            matlab.ui.control.Label
        LapUseCSDCheckBox               matlab.ui.control.CheckBox
        LapEdgeDropdown                 matlab.ui.control.DropDown
        EdgehandlingLabel               matlab.ui.control.Label
        LapOKButten                     matlab.ui.control.Button
        InterpolatePanel                matlab.ui.container.Panel
        InterpRadiusEditField           matlab.ui.control.NumericEditField
        InterpRadiusEditFieldLabel      matlab.ui.control.Label
        InterpKEditField                matlab.ui.control.NumericEditField
        InterpKEditFieldLabel           matlab.ui.control.Label
        UsetemplatemontageCheckBox      matlab.ui.control.CheckBox
        InterpolateMethodDropDown       matlab.ui.control.DropDown
        MethodDropDown_2Label           matlab.ui.control.Label
        interpolateOKButton             matlab.ui.control.Button
        BadchannelsEditField            matlab.ui.control.EditField
        BadchannelsEditFieldLabel       matlab.ui.control.Label
        NoiceCovariancePanel            matlab.ui.container.Panel
        CalculateButton                 matlab.ui.control.Button
        RereferencingPanel              matlab.ui.container.Panel
        inputexamplesPanel              matlab.ui.container.Panel
        AverageofspecificchannelgroupsLabel  matlab.ui.control.Label
        AveragereferenceLabel           matlab.ui.control.Label
        SingleelectrodereferenceLabel   matlab.ui.control.Label
        DualelectrodereferenceLabel     matlab.ui.control.Label
        orA1A2Label                     matlab.ui.control.Label
        AverageLabel                    matlab.ui.control.Label
        A1A2Label                       matlab.ui.control.Label
        A1Label                         matlab.ui.control.Label
        rereferenceOKButton             matlab.ui.control.Button
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        ChooseparamsEditField           matlab.ui.control.EditField
        ChooseparamsEditFieldLabel      matlab.ui.control.Label
        TimeFrequencyDecompositionPanel  matlab.ui.container.Panel
        STFTOKButton                    matlab.ui.control.Button
        WTOKButton                      matlab.ui.control.Button
        DownsamplingFacorEditField      matlab.ui.control.NumericEditField
        DownsamplingFacorEditFieldLabel  matlab.ui.control.Label
        ResamplingLabel                 matlab.ui.control.Label
        DSOKButton                      matlab.ui.control.Button
        VoicesperOctaveEditField        matlab.ui.control.NumericEditField
        VoicesperOctaveEditFieldLabel   matlab.ui.control.Label
        HzLabel_4                       matlab.ui.control.Label
        toLabel                         matlab.ui.control.Label
        ScalesEditField_2               matlab.ui.control.NumericEditField
        ScalesEditField                 matlab.ui.control.NumericEditField
        ScalesEditFieldLabel            matlab.ui.control.Label
        WaveletFamliyDropDown           matlab.ui.control.DropDown
        WaveletFamliyDropDownLabel      matlab.ui.control.Label
        NFFTWindowLengthEditField       matlab.ui.control.NumericEditField
        NFFTWindowLengthEditFieldLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        OverlapRatioEditField           matlab.ui.control.NumericEditField
        OverlapRatioEditFieldLabel      matlab.ui.control.Label
        WindowLengthEditField           matlab.ui.control.NumericEditField
        WindowLengthEditFieldLabel      matlab.ui.control.Label
        WindowDropDown                  matlab.ui.control.DropDown
        WindowDropDownLabel             matlab.ui.control.Label
        WaveletTransformLabel           matlab.ui.control.Label
        STFTLabel                       matlab.ui.control.Label
        ICAPanel                        matlab.ui.container.Panel
        ICAOKButton                     matlab.ui.control.Button
        ChanneltypesorchannelindicesEditField  matlab.ui.control.EditField
        ChanneltypesorchannelindicesEditFieldLabel  matlab.ui.control.Label
        CommandlineoptiionsEditField    matlab.ui.control.EditField
        CommandlineoptiionsEditFieldLabel  matlab.ui.control.Label
        ICAalgorithmDropDown            matlab.ui.control.DropDown
        ICAalgorithmDropDownLabel       matlab.ui.control.Label
        ReordercomponentsbyvarianceCheckBox  matlab.ui.control.CheckBox
        SpectrumPanel                   matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        SpatialFilteringPanel           matlab.ui.container.Panel
        ERPrelatedPanel                 matlab.ui.container.Panel
        AveragetrialsSetButton_4        matlab.ui.control.Button
        InterceptthesignalsectionLabel  matlab.ui.control.Label
        AverageTrialsLabel              matlab.ui.control.Label
        BaselineCorrectLabel            matlab.ui.control.Label
        ExtractTrialsLabel              matlab.ui.control.Label
        AveragetrialsSetButton_3        matlab.ui.control.Button
        Baselinesetbutten               matlab.ui.control.Button
        ExtracttraialsSetButton         matlab.ui.control.Button
        MethodsButtonGroup              matlab.ui.container.ButtonGroup
        LaplacianButton                 matlab.ui.control.RadioButton
        InterpolateButton               matlab.ui.control.RadioButton
        RereferenceButton               matlab.ui.control.RadioButton
        ICAButton                       matlab.ui.control.RadioButton
        FrequencydomainFilteringPanel   matlab.ui.container.Panel
        FrequencydomainOKButton         matlab.ui.control.Button
        frequencydominSetbutten         matlab.ui.control.Button
        NotchEditField                  matlab.ui.control.NumericEditField
        NotchEditFieldLabel             matlab.ui.control.Label
        LowpassEditField                matlab.ui.control.NumericEditField
        LowpassEditFieldLabel           matlab.ui.control.Label
        HighpassEditField               matlab.ui.control.NumericEditField
        HighpassEditFieldLabel          matlab.ui.control.Label
        HzLabel_3                       matlab.ui.control.Label
        HzLabel_2                       matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        NotchCheckBox                   matlab.ui.control.CheckBox
        LowpassCheckBox                 matlab.ui.control.CheckBox
        HighpassCheckBox                matlab.ui.control.CheckBox
    end


    properties (Access = private)
        data
        srate
        Type
        protocolinfo
        filteredEEG
        checkeddataindex
        SaveIndex=1  %保存数据索引
        Callingapp
        icaresult
        chanlocs={}
        chanLabels=[]
    end

    methods (Access = private)

        function plotMultiChannelSpectrum(app, EEGdata,channelIndices)
            %绘制功率谱

            % 获取App中的UIAxes组件
            ax =app.UIAxes;

            % 清除当前内容并设置为新绘图
            cla(ax);  % 清除当前图形
            hold(ax, 'on');  % 启用图形叠加

            % 设置UIAxes属性（可选）
            title(ax, 'Multi-Channel Power Spectrum');
            xlabel(ax, 'Frequency (Hz)');
            ylabel(ax, 'Power (dB)');
            grid(ax, 'on');

            % 颜色循环（确保不同通道颜色区分）
            colors = lines(length(channelIndices));

%             循环计算并绘制每个通道的频谱
            for i = 1:length(channelIndices)
                chanIdx = channelIndices(i);

                % 调用FFT计算函数（假设已实现computeChannelFFT）
                [freq, spectrum] = app.computeChannelFFT(EEGdata,chanIdx);

                % 绘制功率谱（dB单位）
                plot(ax, freq, 10*log10(spectrum), ...
                    'Color', colors(i,:), ...
                    'LineWidth', 1.5);
            end
            % 自动调整坐标轴范围
            axis(ax, 'tight');

            % 关闭图形叠加
            hold(ax, 'off');
        end

        function [freq, powerSpectrum] = computeChannelFFT(app, EEGdata,channelIdx)
            % 计算指定通道的FFT频谱
            % 输入:
            %   channelIdx - 通道索引 (1到app.chans)
            %   plotResults - 是否绘制频谱图 (true/false, 可选，默认为false)
            % 输出:
            %   freq - 频率轴 (Hz)
            %   powerSpectrum - 功率谱密度 (μV²/Hz)

            % 获取数据并去均值
            tempdata = EEGdata(channelIdx, :);
            tempdata = tempdata - mean(tempdata);

            % 确保数据长度为偶数（FFT对称性要求）
            if mod(length(tempdata), 2) ~= 0
                tempdata = tempdata(1:end-1); % 丢弃最后一个点
            end
            N = length(tempdata); % 此时N为偶数

            % 应用汉宁窗
            window = hann(N)'; % 注意转置确保维度匹配
            windowedData = tempdata .* window;

            % 计算FFT
            fftResult = fft(windowedData);
            P2 = abs(fftResult/N);

            % 明确转换为整数索引
            halfN = floor(N/2); % 或 fix(N/2)
            P1 = P2(1:halfN + 1);
            P1(2:end-1) = 2*P1(2:end-1);

            % 频率轴
            freq = app.srate*(0:halfN)/N;

            % 功率谱
            powerSpectrum = (P1.^2) / (sum(window.^2) * app.srate);

            % 限制频率范围
            validRange = freq >= 0.01 & freq <= 300;
            freq = freq(validRange);
            powerSpectrum = powerSpectrum(validRange);
        end

        function closeall(app)
            app.ICAPanel.Visible='off';
            app.TimeFrequencyDecompositionPanel.Visible='off';
            app.RereferencingPanel.Visible='off';
            app.InterpolatePanel.Visible='off';
            app.LaplacianPanel.Visible='off';
            app.InterceptthesignalsectionPanel.Visible='off';
        end

        function [Flow, Fhigh] = calculateStopband(app, centerFreq, width)
            % 根据中心频率和宽度计算阻带范围
            Flow = centerFreq - width/2;
            Fhigh = centerFreq + width/2;

            % 确保频率不超出有效范围
            Flow = max(Flow, 0.1); % 最小0.1Hz
            Fhigh = min(Fhigh, app.srate/2 * 0.99); % 最大略小于奈奎斯特频率
        end

        function saveFilteredEEG(app, data , Fc, N, Rs)
            filteredDataPath = fullfile(app.protocolinfo.protocolPath, 'filtereddata');
                
            if ~isfolder(filteredDataPath)
                mkdir(filteredDataPath);
                fprintf('Folder created: %s\n', filteredDataPath);
            else
                fprintf('Folder exists: %s\n', filteredDataPath);
            end
            
            defaultFileName = findAvailableName(filteredDataPath, 'Filt #.mat');
            
            userInput = inputdlg('Save filtered data as:', 'Confirm', [1 50], {defaultFileName});
            if isempty(userInput)
                return;
            end
            
            userFileName = strtrim(userInput{1});
            if isempty(userFileName)
                uialert(app.UIFigure, 'Filename cannot be empty', 'Invalid filename', 'Icon', 'warning');
                userFileName = defaultFileName;
            end
            
            [~, userFileName, ~] = fileparts(userFileName);
            
            filename = fullfile(filteredDataPath, [userFileName '.mat']);
            
            if exist(filename, 'file')
                response = questdlg(['File ' filename ' exists，do you wanna to overwrite it？'], ...
                                   'File exists', 'Overwrite', 'Cancel', 'Cancel');
                if strcmp(response, 'Cancel')
                    return;
                end
            end
            
            saveData = struct();
            saveData.data = data;
            saveData.params = struct('Fc', Fc, 'N', N, 'Rs', Rs);
            saveData.ID = userFileName;
            saveData.srate = app.srate;
            saveData.Type = app.Type;
            saveData.chanlocs = app.chanlocs;
            
%             [~, nT, nTr] = size(app.data);
%             segmentInfo = zeros(2, nTr + 1);
%             segmentInfo(:, 1) = [1; 1];
%             
%             segmentInfo(1, 2:end) = 2:(nTr + 1);
%             segmentInfo(2, 2:end) = (1:nTr) * nT;
%             
%             saveData.segmentInfo = segmentInfo;
            
            save(filename, '-struct', 'saveData');
            app.Callingapp.scanAndLoadProtocols()
            app.SaveIndex = app.SaveIndex + 1;

            app.Callingapp.protocolnode{app.protocolinfo.No}.NodeData.ProcessedDataPath = filteredDataPath;
            filteddataNode = uitreenode(app.Callingapp.protocolnode{app.protocolinfo.No});
            filteddataNode.Text = ['Data: ',userFileName];
            filteddataNode.NodeData = filename;
            filteddataNode.ContextMenu = app.Callingapp.PreprocessedDataMenu;
            drawnow;
            
            successMsg = sprintf('Data saved in: %s', filename);
            uialert(app.UIFigure, successMsg, 'Successfully saved', 'Icon', 'success');
        end
       

        function ica = runICA_FromGUI(app,data3d, alg, cmdopt_str, doReorder, chan_text)%需要EEGLAB中的runica和bunica函数
            % data3d: 通道 × 时间 × 试次

            disp('[STEP 1/6] 通道选择');
            nCh = size(data3d,1);
            chanind = app.parseChannelText(chan_text, nCh);
            disp(['  -> 已选择通道数：', num2str(numel(chanind))]);

            disp('[STEP 2/6] 展开为二维矩阵');
            X = reshape(data3d(chanind,:,:), numel(chanind), []);

            disp('[STEP 3/6] 估计数值秩');
            rankX = app.computeNumericRank(X, []);
            disp(['  -> rank = ', num2str(rankX)]);

            disp('[STEP 4/6] 解析命令行参数');
            extraArgs = app.parseNameValueString(cmdopt_str);
            disp(['  -> 附加参数个数：', num2str(numel(extraArgs))]);

            disp('[STEP 5/6] 运行 ICA');
            tic;
            ica = app.runICA_OnMatrix(X, alg, rankX, extraArgs);
            ica.alg = alg;
            ica.chanind = chanind;
            ica.rank = rankX;
            toc;

            if doReorder
                disp('[STEP 6/6] 按方差重排');
                ica = app.reorderIC_by_variance_matrix(ica);
                disp('  -> 已重排');
            else
                disp('[STEP 6/6] 跳过重排');
            end

            % 重塑到三维形式
            nT = size(data3d,2);
            nTr = size(data3d,3);
            nIC = size(ica.S,1);
            ica.S3d = reshape(ica.S, nIC, nT, nTr);

            disp('=== ICA 完成 ===');
        end

        function chanind = parseChannelText(app, chan_text, nMax)
            % 空字符串 => 全部通道
            if isempty(chan_text)
                chanind = 1:nMax; return;
            end
            chan_text = string(chan_text);
            parts = strtrim(split(chan_text, ','));
            idx = [];
            for k=1:numel(parts)
                tk = strtrim(parts{k});
                if contains(tk,'-')
                    ab = str2double(split(tk,'-'));
                    if numel(ab)==2 && all(isfinite(ab))
                        idx = [idx, ab(1):ab(2)]; %#ok<AGROW>
                    end
                else
                    v = str2double(tk);
                    if isfinite(v), idx = [idx, v]; end %#ok<AGROW>
                end
            end
            idx = unique(idx);
            chanind = idx(idx>=1 & idx<=nMax);
        end

        function idx = parseIndexString(app, s, nMax)
            parts = strtrim(split(s,','));
            idx = [];
            for k=1:numel(parts)
                tk = parts{k};
                if contains(tk,'-')
                    ab = str2double(split(tk,'-'));
                    if numel(ab)==2 && all(isfinite(ab))
                        idx = [idx, ab(1):ab(2)]; %#ok<AGROW>
                    end
                else
                    v = str2double(tk);
                    if isfinite(v), idx = [idx, v]; end %#ok<AGROW>
                end
            end
            idx = unique(idx);
            idx = idx(idx>=1 & idx<=nMax);
        end

        function r = computeNumericRank(app, X, tol)
            if nargin<2 || isempty(tol), tol = 1e-7; end
            [~,S,~] = svd(X, 'econ');
            s = diag(S);
            r = sum(s > tol * max(s));
        end

        function C = parseNameValueString(app, s)
            C = {};
            s = strtrim(s);
            if isempty(s), return; end
            parts = app.splitCommasRespectingQuotes(s);
            for k=1:numel(parts)
                tk = strtrim(parts{k});
                if (startsWith(tk,'''') && endsWith(tk,''''))
                    C{end+1} = tk(2:end-1); %#ok<AGROW>
                elseif (startsWith(tk,'"') && endsWith(tk,'"'))
                    C{end+1} = tk(2:end-1); %#ok<AGROW>
                else
                    val = str2double(tk);
                    if ~isnan(val)
                        C{end+1} = val; %#ok<AGROW>
                    else
                        C{end+1} = tk; %#ok<AGROW>
                    end
                end
            end
        end

        function parts = splitCommasRespectingQuotes(app, s)
            parts = {};
            buf = '';
            inq = false; qch = '';
            for i=1:numel(s)
                c = s(i);
                if ~inq && (c=='''' || c=='"')
                    inq = true; qch = c; buf = [buf c]; %#ok<AGROW>
                elseif inq && c==qch
                    inq = false; buf = [buf c]; %#ok<AGROW>
                elseif ~inq && c==','
                    parts{end+1} = buf; %#ok<AGROW>
                    buf = '';
                else
                    buf = [buf c]; %#ok<AGROW>
                end
            end
            if ~isempty(buf), parts{end+1} = buf; end
        end

        function ica = runICA_OnMatrix(app,X, alg, rankX, extraArgs)
            alg = validatestring(alg, {'runica','binica','fastica'}, mfilename, 'alg');
            switch alg
                case 'fastica'
                    args = [{'numOfIC', rankX, 'verbose', 'on'}, extraArgs];
                    [S, A, W] = fastica(X, args{:});
                    ica.S = S; ica.W = W; ica.A = A;

                case {'runica','binica'}
                    if strcmp(alg,'binica') && ~exist('binica','file')
                        warning('未找到 binica，改用 runica');
                        alg='runica';
                    end
                    fun = str2func(alg);
                    args = [{'pca', rankX}, extraArgs];
                    argsIn = {};
                    for i = 1:numel(args)
                        v = args{i};
                        while iscell(v) && numel(v)==1    % 递归去壳
                            v = v{1};
                        end
                        argsIn{end+1} = v;
                    end
                    [weights, sphere] = fun(X, argsIn{:});
                    W = weights*sphere;
                    S = W*X;
                    A = pinv(W);
                    ica.S = S; ica.W = W; ica.A = A;
            end
        end

        function ica = reorderIC_by_variance_matrix(app,ica)
            v = var(double(ica.S), 0, 2);
            [~, order] = sort(v, 'descend');
            ica.S = ica.S(order, :);
            ica.W = ica.W(order, :);
            ica.A = ica.A(:, order);
        end

        function w = buildWindow(app,name, N)
            name = lower(string(name));
            switch name
                case {'hann','hanning'}
                    w = hann(N,'periodic');
                case 'hamming'
                    w = hamming(N,'periodic');
                case 'blackman'
                    w = blackman(N,'periodic');
                case {'rect','rectwin','boxcar'}
                    w = rectwin(N);
                case 'kaiser'
                    beta = 6.76;          % 经验值；需要 GUI 可再做一个参数
                    w = kaiser(N, beta);
                otherwise
                    warning('未知窗 %s，改用 hann', name);
                    w = hann(N,'periodic');
            end
        end

        function wname = mapWaveletName(app,famUI)
            fam = lower(string(famUI));
            switch fam
                case {'morl','morlet'}   % GUI 里的 'morl' 映射为 MATLAB 的 'amor'
                    wname = 'amor';      % analytic Morlet
                case 'amor'
                    wname = 'amor';
                case 'morse'
                    wname = 'morse';
                case 'bump'
                    wname = 'bump';
                otherwise
                    warning('未知 Wavelet Family: %s，改用 amor', fam);
                    wname = 'amor';
            end
        end

   
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, datapath, protocolinfo, mainapp)
            app.UIFigure.Name='Preprocessapp';
            app.Callingapp=mainapp;
            addpath(genpath('CoreFunctions'));
            addpath(genpath('External'));
            app.TimeFrequencyDecompositionPanel.Visible='off';
            app.HighpassEditField.Enable='off';
            app.LowpassEditField.Enable='off';
            app.NotchEditField.Enable='off';
            datastruct=load(datapath);

            if ~isfield(datastruct,'data')
                uialert(app.UIFigure,'Choose a DataNode!','Warning');
                return
            end
            app.data=datastruct.data;
            app.Type=datastruct.Type;
            app.checkeddataindex=1:1:size(app.data,1);
            app.srate=datastruct.srate;
            app.protocolinfo=protocolinfo;
            app.plotMultiChannelSpectrum(app.data,app.checkeddataindex)
            if isfield(datastruct,'chanlocs')
                app.chanlocs = datastruct.chanlocs;
            end
            folderName = fullfile(app.protocolinfo.protocolPath, 'filtereddata');

            % 检查文件夹是否存在，不存在则创建
            if exist(folderName, 'dir')
                disp(['文件夹已存在: ' fullfile(pwd, folderName)]);
                % 获取该文件夹下所有内容
                fileList = dir(folderName);

                % 过滤掉 '.'、'..' 和子文件夹
                fileList = fileList(~[fileList.isdir]);

                % 计算文件索引
                app.SaveIndex = numel(fileList)+1;

                disp(['该文件夹中共有 ' num2str(app.SaveIndex)-1 ' 个文件。']);
            end
        end

        % Callback function
        function SetButtonPushed(app, event)
            app.closeall();
            if app.ICAButton.Value==1
                app.ICAPanel.Visible='on';
                app.ICAPanel.Position=[628 312 438 221];
            end
            if  app.RereferenceButton.Value==1
                app.RereferencingPanel.Visible='on';
                app.RereferencingPanel.Position=[628 1 439 531];
            end

        end

        % Button pushed function: frequencydominSetbutten
        function frequencydominSetbuttenPushed(app, event)
            app.closeall();
            app.TimeFrequencyDecompositionPanel.Visible='on';
            app.TimeFrequencyDecompositionPanel.Position=[628 1 439 531];
        end

        % Selection changed function: MethodsButtonGroup
        function MethodsButtonGroupSelectionChanged(app, event)
            selectedButton = app.MethodsButtonGroup.SelectedObject;
            app.closeall();
            if selectedButton==app.ICAButton
                app.ICAPanel.Visible='on';
                app.ICAPanel.Position=[628 312 438 221];
            end
            if  selectedButton==app.RereferenceButton
                app.RereferencingPanel.Visible='on';
                app.RereferencingPanel.Position=[628 1 439 531];
            end
            if  selectedButton==app.InterpolateButton
                app.InterpolatePanel.Visible='on';
                app.InterpolatePanel.Position=[628 1 439 531];
            end
            if  selectedButton==app.LaplacianButton
                app.LaplacianPanel.Visible='on';
                app.LaplacianPanel.Position=[628 1 439 531];
            end
        end

        % Value changed function: HighpassCheckBox
        function HighpassCheckBoxValueChanged(app, event)
            value = app.HighpassCheckBox.Value;
            if value==1
                app.HighpassEditField.Enable='on';
            else
                app.HighpassEditField.Enable='off';
            end
        end

        % Value changed function: LowpassCheckBox
        function LowpassCheckBoxValueChanged(app, event)
            value = app.LowpassCheckBox.Value;
            if value==1
                app.LowpassEditField.Enable='on';
            else
                app.LowpassEditField.Enable='off';
            end

        end

        % Value changed function: NotchCheckBox
        function NotchCheckBoxValueChanged(app, event)
            value = app.NotchCheckBox.Value;
            if value==1
                app.NotchEditField.Enable='on';
            else
                app.NotchEditField.Enable='off';
            end
        end

        % Button pushed function: FrequencydomainOKButton
        function FrequencydomainOKButtonPushed(app, event)
            firCoeffs = {}; % 滤波器系数细胞数组
            filterTypes = {}; % 记录每个滤波器的类型
            FcArray = {}; % 记录每个滤波器的截止频率
            NArray = {}; % 记录每个滤波器的阶数
            RsArray = {}; % 记录每个滤波器的阻带衰减
            
            % highpass
            if app.HighpassCheckBox.Value == 1
                Fc_high = app.HighpassEditField.Value;
                Rs_high = 40;
                N_high = 100;
                if Fc_high<=0
                    uialert(app.UIFigure,'The cut-off frequency must be greater than 0.');
                    return;
                end
                coeff_high = designFIRHighpass(app.srate, Fc_high, Rs_high, N_high);
                firCoeffs{end+1} = coeff_high;
                filterTypes{end+1} = 'highpass';
                FcArray{end+1} = Fc_high;
                NArray{end+1} = N_high;
                RsArray{end+1} = Rs_high;
            end
            
            % lowpass
            if app.LowpassCheckBox.Value == 1
                Fc_low = app.LowpassEditField.Value;
                Rs_low = 40;
                N_low = 10;
                if Fc_low<=0
                    uialert(app.UIFigure,'The cut-off frequency must be greater than 0.');
                    return;
                end
                coeff_low = designFIRLowpass(app.srate, Fc_low, Rs_low, N_low);
                firCoeffs{end+1} = coeff_low;
                filterTypes{end+1} = 'lowpass';
                FcArray{end+1} = Fc_low;
                NArray{end+1} = N_low;
                RsArray{end+1} = Rs_low;
            end
            
            % notch
            if app.NotchCheckBox.Value == 1
                centerFreq = app.NotchEditField.Value;
                width = 4;
                [Flow, Fhigh] = app.calculateStopband(centerFreq, width);
                Fc_notch = [Flow, Fhigh];
                Rs_notch = 60;
                N_notch = 120;
                coeff_notch = designFIRBandstop(app.srate, Flow, Fhigh, Rs_notch, N_notch);
                firCoeffs{end+1} = coeff_notch;
                filterTypes{end+1} = 'notch';
                FcArray{end+1} = Fc_notch;
                NArray{end+1} = N_notch;
                RsArray{end+1} = Rs_notch;
            end
            
            if isempty(firCoeffs)
                uialert(app.UIFigure,'You did not choose any method','Selected a Method');
                return;
            end
            
            if app.HighpassCheckBox.Value == 1 && app.LowpassCheckBox.Value == 1
                if app.HighpassEditField.Value >= app.LowpassEditField.Value
                    uialert(app.UIFigure, 'Highpass frequency must be less than lowpass frequency.', 'Invalid Filter Parameters');
                    return;
                end
            end
            
            [nChannels, ~] = size(app.data);
            app.filteredEEG = app.data;
            
            for i = 1:nChannels
                tempData = app.data(i, :);
                for j = 1:length(firCoeffs)
                    tempData = filtfilt(firCoeffs{j}, 1, tempData);
                end
                app.filteredEEG(i, :) = tempData;
            end
            
            for i = 1:length(filterTypes)
                if isscalar(FcArray{i})
                    fcStr = num2str(FcArray{i});
                else
                    fcStr = mat2str(FcArray{i});
                end
            end
            a=struct('filterTypes',filterTypes,'NArray',NArray,'FcArray',FcArray,'RsArray',RsArray);
            appendDataToMat(app.protocolinfo.protocolPath,'filters',a);
            app.saveFilteredEEG(app.filteredEEG, FcArray, NArray, RsArray);
            app.plotMultiChannelSpectrum(app.filteredEEG, app.checkeddataindex);    
        end

        % Button pushed function: rereferenceOKButton
        function rereferenceOKButtonPushed(app, event)
            try
                disp('=== [Re-reference] START ===');

                % 读取 GUI
                refText = string(app.ChooseparamsEditField.Value);   % 你的“Choose params”文本框
                method  = string(app.MethodDropDown.Value);          % 'direct' | 'median' | 'trimmed'

                % 基本检查
                if isempty(app.data) || ~isnumeric(app.data) || ndims(app.data) ~= 3
                    uialert(app.UIFigure,'app.data 必须是 (channels × time × trials) 三维矩阵','错误');
                    return;
                end

                % 检查并自动生成默认通道标签
                [nCh, ~, ~] = size(app.data);
                if ~isfield(app, 'chanLabels') || isempty(app.chanLabels)
                    app.chanLabels = arrayfun(@(x) sprintf('Ch%d', x), 1:nCh, 'UniformOutput', false);
                    disp('[Info] app.chanLabels 为空，已自动生成默认通道名 Ch1...ChN');
                end

                % 解析参考参数（文本 → 规范化的引用规范 refSpec）
                refSpec = parseRefSpec(refText, size(app.data,1), app.chanLabels);

                % 执行重参考
                tic;
                data_new = applyRereference(app.data, refSpec, method);
                t = toc;

                % 覆盖或另存
                app.data = data_new;

                % 反馈
                msg = sprintf('重参考完成：Method=%s | 类型=%s | 用时 %.2fs', ...
                    method, refSpec.type, t);
                disp(msg);
                uialert(app.UIFigure, msg, '完成');

                disp('=== [Re-reference] DONE ===');
                a= struct('refText',refText,'method', method);
                appendDataToMat(app.protocolinfo.protocolPath,'rereference',a);
            catch ME
                disp('[ERR] Re-reference 出错：');
                disp(getReport(ME,'extended','hyperlinks','off'));
                uialert(app.UIFigure, getReport(ME,'basic','hyperlinks','off'), '运行出错');
            end
        end

        % Button pushed function: ICAOKButton
        function ICAOKButtonPushed(app, event)
            try
                disp('=== [ICA] 启动 ===');
                % 读取 GUI 值
                alg        = app.ICAalgorithmDropDown.Value;      % 'runica'|'binica'|'fastica'
                cmdopt_str = strtrim(app.CommandlineoptiionsEditField.Value);   % 例：'extended',1
                doReorder  = app.ReordercomponentsbyvarianceCheckBox.Value;         % true/false
                chan_text  = strtrim(app.ChanneltypesorchannelindicesEditField.Value);     % 'EEG' 或 '1-64'
                disp(['[GUI] Algorithm: ', alg]);
                disp(['[GUI] Cmd options: ', cmdopt_str]);
                disp(['[GUI] Reorder by variance: ', string(doReorder)]);
                disp(['[GUI] Channel text: ', chan_text]);
                % 合法性检查
                if isempty(app.data) || ~isnumeric(app.data) || ndims(app.data)~=3
                    uialert(app.UIFigure,'app.data 需为通道×时间×试次的三维矩阵','错误');
                    return;
                end

                % 打印基本信息
                [nCh, nT, nTr] = size(app.data);
                fprintf('[DATA] chans=%d, time=%d, trials=%d\n', nCh, nT, nTr);
                tic;
                app.icaresult = app.runICA_FromGUI(app.data, alg, cmdopt_str, doReorder, chan_text);
                t = toc;

                % 提示完成
                msg = sprintf('ICA 完成：算法=%s | IC=%d | 用时 %.2fs', ...
                    app.icaresult.alg, size(app.icaresult.S,1), t);
                disp(msg);
                uialert(app.UIFigure, msg, '完成');

            catch ME
                % 友好错误提示
                disp(getReport(ME,'extended','hyperlinks','off'));

                uialert(app.UIFigure, getReport(ME,'basic','hyperlinks','off'), '运行出错');
            end

        end

        % Button pushed function: STFTOKButton
        function STFTOKButtonPushed(app, event)
            %STFT
            try
                disp('=== [STFT] START ===');

                % ------- 读取 GUI -------
                winName   = string(app.WindowDropDown.Value);            % 'hann' ...
                winLenInp = str2double(string(app.WindowLengthEditField.Value));   % 点数
                ovlpPct   = str2double(string(app.OverlapRatioEditField.Value));   % %
                nfftInp   = str2double(string(app.NFFTWindowLengthEditField.Value));           % 点数

                % ------- 基本检查 -------
                if isempty(app.data) || ~isnumeric(app.data) || ndims(app.data)~=3
                    uialert(app.UIFigure,'app.data 必须为 (channels × time × trials) 的三维矩阵','错误'); return;
                end
                if ~isprop(app,'srate') || isempty(app.srate) || ~isscalar(app.srate)
                    uialert(app.UIFigure,'请在 app.fs 中设置采样率（Hz）','错误'); return;
                end
                fs = app.srate;
                [nCh, nT, nTr] = size(app.data);
                fprintf('[DATA] chans=%d, time=%d, trials=%d, fs=%.3f Hz\n', nCh, nT, nTr, fs);


                % ------- 规范化/默认参数 -------
                % Window length
                if isempty(winLenInp) || ~isfinite(winLenInp) || winLenInp<=0
                    if nT < 128
                        winLen = max(8, floor(nT / 2));
                        warning('信号长度 %d < 128，窗口长度自动设为 %d 点。', nT, winLen);
                    else
                        winLen = max(128, round(0.5 * fs));
                    end
                else
                    winLen = round(winLenInp);
                    if winLen > nT
                        warning('窗口长度 (%d) 超过信号长度 (%d)，自动改为信号长度一半。', winLen, nT);
                        winLen = max(8, floor(nT / 2));
                    end
                end


                % Overlap
                if isempty(ovlpPct) || ~isfinite(ovlpPct) || ovlpPct<0, ovlpPct = 0; end
                ovlpPct = min(max(ovlpPct,0),95); % 限制 0~95%
                noverlap = floor((ovlpPct/100) * winLen);

                % NFFT
                if isempty(nfftInp) || ~isfinite(nfftInp) || nfftInp<=0
                    nfft = 2^nextpow2(winLen);
                else
                    nfft = round(nfftInp);
                end
                if nfft < winLen
                    warning('NFFT(%d) < WindowLength(%d). 自动改为 2^nextpow2(winLen)。', nfft, winLen);
                    nfft = 2^nextpow2(winLen);
                end

                % ------- 构造窗函数 -------
                win = app.buildWindow(winName, winLen);   % 见下方函数
                fprintf('[STFT] window=%s, winLen=%d, overlap=%d(%.0f%%), nfft=%d\n', ...
                    winName, winLen, noverlap, ovlpPct, nfft);

                % ------- 预跑一次获取 F/T 尺寸 -------
                x0 = squeeze(app.data(1,:,1));
                [S0,F,T] = spectrogram(x0, win, noverlap, nfft, fs, 'yaxis');
                nF = numel(F); nSeg = numel(T);

                % ------- 预分配 -------
                S = complex(zeros(nF, nSeg, nCh, nTr, 'like', S0));   % 复数 STFT
                disp('[STFT] 预分配完成，开始逐通道×试次计算...');

                % ------- 计算 -------
                tic;
                for ch = 1:nCh
                    for tr = 1:nTr
                        x = double(app.data(ch,:,tr));  % 1 × nT
                        [Si, ~, ~] = spectrogram(x, win, noverlap, nfft, fs, 'yaxis');
                        % 尺寸保护（极端参数差一列/行的情况）
                        if size(Si,1)~=nF || size(Si,2)~=nSeg
                            % 简单对齐：截断到较小尺寸
                            nf = min(nF,size(Si,1)); nt = min(nSeg,size(Si,2));
                            S(1:nf,1:nt,ch,tr) = Si(1:nf,1:nt);
                        else
                            S(:,:,ch,tr) = Si;
                        end
                    end
                    if mod(ch, max(1,round(nCh/10)))==0
                        fprintf('  -> 已完成通道 %d/%d\n', ch, nCh);
                    end
                end
                elapsed = toc;
                fprintf('[STFT] 计算完成，用时 %.2fs\n', elapsed);


                % ------- 简短反馈 -------
                msg = sprintf('STFT完成 | F=%d × T=%d × Ch=%d × Tr=%d', numel(F), numel(T), nCh, nTr);
                disp(msg);
                uialert(app.UIFigure, msg, '完成');

            catch ME
                disp('[ERR] STFT 出错：');
                disp(getReport(ME,'extended','hyperlinks','off'));
                uialert(app.UIFigure, getReport(ME,'basic','hyperlinks','off'), '运行出错');
            end

        end

        % Button pushed function: WTOKButton
        function WTOKButtonPushed(app, event)
            %小波变换
            try
                disp('=== [CWT] START ===');

                % ---- 读取 GUI ----
                famUI   = string(app.WaveletFamliyDropDown.Value);   % 'morl' ...
                fLoIn   = str2double(string(app.ScalesEditField.Value));   % Hz，下限
                fHiIn   = str2double(string(app.ScalesEditField_2.Value));  % Hz，上限
                vpoIn   = str2double(string(app.VoicesperOctaveEditField.Value)); % 默认 32

                % ---- 基本检查 ----
                if isempty(app.data) || ~isnumeric(app.data) || ndims(app.data)~=3
                    uialert(app.UIFigure,'app.data 必须为 (channels × time × trials) 的三维矩阵','错误'); return;
                end
                if ~isprop(app,'srate') || isempty(app.srate) || ~isscalar(app.srate)
                    uialert(app.UIFigure,'请在 app.fs 中设置采样率（Hz）','错误'); return;
                end
                fs = app.srate;
                [nCh, nT, nTr] = size(app.data);
                fprintf('[DATA] ch=%d, time=%d, trials=%d, fs=%.3f Hz\n', nCh, nT, nTr, fs);

                % ---- 规范参数 / 默认值 ----
                wname = app.mapWaveletName(famUI);     % 见下方函数
                if isempty(vpoIn) || ~isfinite(vpoIn) || vpoIn<=0, vpo = 32; else, vpo = round(vpoIn); end

                % 自动频率范围（使用 cwtfreqbounds）
                [fminAuto, fmaxAuto] = cwtfreqbounds(nT, fs, 'Wavelet', wname, 'VoicesPerOctave', vpo);
                if isempty(fLoIn) || ~isfinite(fLoIn) || fLoIn<=0, fLo = fminAuto; else, fLo = fLoIn; end
                if isempty(fHiIn) || ~isfinite(fHiIn) || fHiIn<=0, fHi = fmaxAuto; else, fHi = fHiIn; end
                if fLo >= fHi
                    warning('频率区间无效，改用自动区间。'); fLo=fminAuto; fHi=fmaxAuto;
                end
                fprintf('[CWT] wavelet=%s, VoicesPerOctave=%d, F=[%.3f %.3f] Hz\n', wname, vpo, fLo, fHi);

                % ---- 先对一个信号跑，获得尺寸 ----
                x0 = double(squeeze(app.data(1,:,1)));
                [WT0, F, T] = cwt(x0, fs, wname, ...
                    'VoicesPerOctave', vpo, ...
                    'FrequencyLimits', [fLo fHi]);
                nF = numel(F); nSeg = numel(T);

                % ---- 预分配：复数系数与功率 ----
                WT = complex(zeros(nF, nSeg, nCh, nTr, 'like', WT0));
                disp('[CWT] 预分配完成，开始逐通道×试次计算...');

                % ---- 主循环 ----
                tic;
                for ch = 1:nCh
                    for tr = 1:nTr
                        x = double(squeeze(app.data(ch,:,tr)));
                        [WTi, ~, ~] = cwt(x, fs, wname, ...
                            'VoicesPerOctave', vpo, ...
                            'FrequencyLimits', [fLo fHi]);
                        % 尺寸对齐（极端参数可能差 1）
                        nf = min(nF, size(WTi,1)); nt = min(nSeg, size(WTi,2));
                        WT(1:nf, 1:nt, ch, tr) = WTi(1:nf, 1:nt);
                    end
                    if mod(ch, max(1, round(nCh/10)))==0
                        fprintf('  -> 已完成通道 %d/%d\n', ch, nCh);
                    end
                end
                elapsed = toc;
                fprintf('[CWT] 计算完成，用时 %.2fs\n', elapsed);

                msg = sprintf('CWT完成 | F=%d × T=%d × Ch=%d × Tr=%d', numel(F), numel(T), nCh, nTr);
                disp(msg);
                uialert(app.UIFigure, msg, '完成');

            catch ME
                disp('[ERR] CWT 出错：');
                disp(getReport(ME,'extended','hyperlinks','off'));
                uialert(app.UIFigure, getReport(ME,'basic','hyperlinks','off'), '运行出错');
            end
        end

        % Button pushed function: DSOKButton
        function DSOKButtonPushed(app, event)
            try
                disp('=== [Resampling] START (Downsampling) ===');

                % 读取 GUI：降采样因子 D（>1）
                D = str2double(string(app.DownsamplingFacorEditField.Value));
                if isempty(D) || ~isfinite(D) || D <= 1
                    uialert(app.UIFigure,'Downsampling Factor 必须是 > 1 的数（例如 2、4、8）','参数错误');
                    return;
                end

                % 数据/采样率检查
                if isempty(app.data) || ~isnumeric(app.data) || ndims(app.data)~=3
                    uialert(app.UIFigure,'app.data 必须为 (channels × time × trials) 三维矩阵','错误'); return;
                end
                if ~isprop(app,'srate') || isempty(app.srate) || ~isscalar(app.srate)
                    uialert(app.UIFigure,'请在 app.fs 中设置采样率（Hz）','错误'); return;
                end
                app.srate;
                % 执行降采样
                tic;
                [data_ds, fs_ds] = applyDownsample_resample(app.data, app.srate, D);
                elapsed = toc;

                % 覆盖或另存
                app.data = data_ds;
                app.srate   = fs_ds;

                msg = sprintf('Downsample 完成：D=%g | 新采样率=%.6g Hz | 新长度=%d | 用时 %.2fs', ...
                    D, fs_ds, size(data_ds,2), elapsed);
                disp(msg);
                uialert(app.UIFigure, msg, '完成');
                disp('=== [Resampling] DONE ===');

            catch ME
                disp('[ERR] Downsample 出错：');
                disp(getReport(ME,'extended','hyperlinks','off'));
                uialert(app.UIFigure, getReport(ME,'basic','hyperlinks','off'), '运行出错');
            end
        end

        % Button pushed function: interpolateOKButton
        function interpolateOKButtonPushed(app, event)
            try
                assert(~isempty(app.data) && ndims(app.data)==3, 'app.data 需为 ch×t×tr');
                [nCh,~,~] = size(app.data);

                % 若没标签，自动生成
                if ~isprop(app,'chanLabels') || isempty(app.chanLabels)
                    app.chanLabels = arrayfun(@(k)sprintf('Ch%d',k),1:nCh,'uni',0);
                end

                % 若没 3D 坐标，尝试从极坐标补
                assert(isprop(app,'chanlocs') && ~isempty(app.chanlocs), '缺少 app.chanlocs（X/Y/Z 或 theta/phi）');


                % -------- Interpolate --------
                badText = string(app.BadchannelsEditField.Value);
                method  = lower(string(app.InterpolateMethodDropDown.Value));
                k       = max(1, round(str2double(string(app.InterpKEditField.Value))));
                radius  = str2double(string(app.InterpRadiusEditField.Value));
                useTpl  = app.UsetemplatemontageCheckBox.Value;

                badIdx  = parseChanList(badText, nCh, app.chanLabels);  % 见下
                assert(~isempty(badIdx),'未解析到坏道索引');

                app.data = run_interpolation(app.data, app.chanLocs, badIdx, method, k, radius, useTpl);
                uialert(app.UIFigure, sprintf('Interpolate 完成（%s），修复坏道数=%d',method,numel(badIdx)), '完成');


            catch ME
                disp(' 出错：');
                disp(getReport(ME,'extended','hyperlinks','off'));
                uialert(app.UIFigure, getReport(ME,'basic','hyperlinks','off'), '运行出错');
            end
        end

        % Button pushed function: LapOKButten
        function LapOKButtenButtonPushed(app, event)
            try
                assert(~isempty(app.data) && ndims(app.data)==3, 'app.data 需为 ch×t×tr');
                [nCh,~,~] = size(app.data);

                % 若没标签，自动生成
                if ~isprop(app,'chanLabels') || isempty(app.chanLabels)
                    app.chanLabels = arrayfun(@(k)sprintf('Ch%d',k),1:nCh,'uni',0);
                end

                % 若没 3D 坐标，尝试从极坐标补
                assert(isprop(app,'chanlocs') && ~isempty(app.chanlocs), '缺少 app.chanlocs（X/Y/Z 或 theta/phi）');

                m      = str2double(string(app.LapMEditField.Value));      if ~isfinite(m), m=4; end
                lambda = str2double(string(app.LapLambdaEditField.Value));  if ~isfinite(lambda), lambda=1e-5; end
                useCSD = app.LapUseCSDCheckBox.Value;
                edge   = string(app.LapEdgeDropdown.Value);

                app.data = run_laplacian(app.data, app.chanlocs, m, lambda, useCSD, edge);
                uialert(app.UIFigure, sprintf('Laplacian 完成（m=%g, λ=%g）',m,lambda), '完成');


            catch ME
                disp(' 出错：');
                disp(getReport(ME,'extended','hyperlinks','off'));
                uialert(app.UIFigure, getReport(ME,'basic','hyperlinks','off'), '运行出错');
            end

        end

        % Button pushed function: AveragetrialsSetButton_4
        function AveragetrialsSetButton_4Pushed(app, event)
            app.closeall();
            app.InterceptthesignalsectionPanel.Visible='on';
            app.InterceptthesignalsectionPanel.Position=[628 1 439 531];
        end

        % Button pushed function: signalsectionbutten
        function signalsectionbuttenButtonPushed(app, event)
            value=app.ChoosepointsEditField.Value;
            % 正则表达式解释：[-+]? (可选正负号) \d* (整数部分) \.? (可选小数点) \d+ (小数部分)
            numbers = str2double(regexp(value, '[-+]?\d*\.?\d+', 'match'));

            % 输出结果
            disp(numbers(1)); % 数字1
            disp(numbers(2)); % 数字2

            if contains(value, '[')
                % --- 情况 A: 方括号 [ ] ---点
                app.data=app.data(:,numbers(1):numbers(2));
                app.saveFilteredEEG(app.data,[],[],[]);
                % 在这里写方括号的专属逻辑
                % doSquareBracketLogic(nums);

            elseif contains(value, '{')
                % --- 情况 B: 花括号 { } ---时间
         
                % 在这里写花括号的专属逻辑
                % doCurlyBraceLogic(nums);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FrequencydomainFilteringPanel = uipanel(app.UIFigure);
            app.FrequencydomainFilteringPanel.Title = 'Frequency-domain Filtering';
            app.HighpassCheckBox = uicheckbox(app.FrequencydomainFilteringPanel);
            app.HighpassCheckBox.ValueChangedFcn = createCallbackFcn(app, @HighpassCheckBoxValueChanged, true);
            app.HighpassCheckBox.Text = '';
            app.LowpassCheckBox = uicheckbox(app.FrequencydomainFilteringPanel);
            app.LowpassCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowpassCheckBoxValueChanged, true);
            app.LowpassCheckBox.Text = '';
            app.NotchCheckBox = uicheckbox(app.FrequencydomainFilteringPanel);
            app.NotchCheckBox.ValueChangedFcn = createCallbackFcn(app, @NotchCheckBoxValueChanged, true);
            app.NotchCheckBox.Text = '';
            app.HzLabel = uilabel(app.FrequencydomainFilteringPanel);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.FrequencydomainFilteringPanel);
            app.HzLabel_2.Text = 'Hz';
            app.HzLabel_3 = uilabel(app.FrequencydomainFilteringPanel);
            app.HzLabel_3.Text = 'Hz';
            app.HighpassEditFieldLabel = uilabel(app.FrequencydomainFilteringPanel);
            app.HighpassEditFieldLabel.Text = 'High-pass';
            app.HighpassEditField = uieditfield(app.FrequencydomainFilteringPanel, 'numeric');
            app.LowpassEditFieldLabel = uilabel(app.FrequencydomainFilteringPanel);
            app.LowpassEditFieldLabel.Text = 'Low-pass';
            app.LowpassEditField = uieditfield(app.FrequencydomainFilteringPanel, 'numeric');
            app.NotchEditFieldLabel = uilabel(app.FrequencydomainFilteringPanel);
            app.NotchEditFieldLabel.Text = 'Notch';
            app.NotchEditField = uieditfield(app.FrequencydomainFilteringPanel, 'numeric');
            app.frequencydominSetbutten = uibutton(app.FrequencydomainFilteringPanel, 'push');
            app.frequencydominSetbutten.ButtonPushedFcn = createCallbackFcn(app, @frequencydominSetbuttenPushed, true);
            app.frequencydominSetbutten.Text = 'Time-Frequency Decomposition >>';
            app.FrequencydomainOKButton = uibutton(app.FrequencydomainFilteringPanel, 'push');
            app.FrequencydomainOKButton.ButtonPushedFcn = createCallbackFcn(app, @FrequencydomainOKButtonPushed, true);
            app.FrequencydomainOKButton.Text = 'OK';
            app.SpatialFilteringPanel = uipanel(app.UIFigure);
            app.SpatialFilteringPanel.Title = 'Spatial Filtering';
            app.MethodsButtonGroup = uibuttongroup(app.SpatialFilteringPanel);
            app.MethodsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MethodsButtonGroupSelectionChanged, true);
            app.MethodsButtonGroup.Title = 'Methods';
            app.ICAButton = uiradiobutton(app.MethodsButtonGroup);
            app.ICAButton.Text = '     ICA';
            app.ICAButton.Value = true;
            app.RereferenceButton = uiradiobutton(app.MethodsButtonGroup);
            app.RereferenceButton.Text = '     Re-reference';
            app.InterpolateButton = uiradiobutton(app.MethodsButtonGroup);
            app.InterpolateButton.Text = '     Interpolate';
            app.LaplacianButton = uiradiobutton(app.MethodsButtonGroup);
            app.LaplacianButton.Text = '     Laplacian';
            app.ERPrelatedPanel = uipanel(app.SpatialFilteringPanel);
            app.ERPrelatedPanel.Title = 'ERP-related';
            app.ExtracttraialsSetButton = uibutton(app.ERPrelatedPanel, 'push');
            app.ExtracttraialsSetButton.Text = 'Set >>';
            app.Baselinesetbutten = uibutton(app.ERPrelatedPanel, 'push');
            app.Baselinesetbutten.Text = 'Set >>';
            app.AveragetrialsSetButton_3 = uibutton(app.ERPrelatedPanel, 'push');
            app.AveragetrialsSetButton_3.Text = 'Set >>';
            app.ExtractTrialsLabel = uilabel(app.ERPrelatedPanel);
            app.ExtractTrialsLabel.Text = 'Extract Trials';
            app.BaselineCorrectLabel = uilabel(app.ERPrelatedPanel);
            app.BaselineCorrectLabel.Text = 'Baseline-Correct';
            app.AverageTrialsLabel = uilabel(app.ERPrelatedPanel);
            app.AverageTrialsLabel.Text = 'Average Trials';
            app.InterceptthesignalsectionLabel = uilabel(app.ERPrelatedPanel);
            app.InterceptthesignalsectionLabel.Text = 'Intercept the signal section';
            app.AveragetrialsSetButton_4 = uibutton(app.ERPrelatedPanel, 'push');
            app.AveragetrialsSetButton_4.ButtonPushedFcn = createCallbackFcn(app, @AveragetrialsSetButton_4Pushed, true);
            app.AveragetrialsSetButton_4.Text = 'Set >>';
            app.SpectrumPanel = uipanel(app.UIFigure);
            app.SpectrumPanel.Title = 'Spectrum';
            app.UIAxes = uiaxes(app.SpectrumPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ICAPanel = uipanel(app.UIFigure);
            app.ICAPanel.Title = 'ICA';
            app.ReordercomponentsbyvarianceCheckBox = uicheckbox(app.ICAPanel);
            app.ReordercomponentsbyvarianceCheckBox.Text = 'Reorder components by variance';
            app.ICAalgorithmDropDownLabel = uilabel(app.ICAPanel);
            app.ICAalgorithmDropDownLabel.Text = 'ICA algorithm';
            app.ICAalgorithmDropDown = uidropdown(app.ICAPanel);
            app.ICAalgorithmDropDown.Items = {'runica', 'binica'};
            app.ICAalgorithmDropDown.Value = 'runica';
            app.CommandlineoptiionsEditFieldLabel = uilabel(app.ICAPanel);
            app.CommandlineoptiionsEditFieldLabel.Text = 'Commandline optiions';
            app.CommandlineoptiionsEditField = uieditfield(app.ICAPanel, 'text');
            app.CommandlineoptiionsEditField.Value = '''extended'',1';
            app.ChanneltypesorchannelindicesEditFieldLabel = uilabel(app.ICAPanel);
            app.ChanneltypesorchannelindicesEditFieldLabel.Text = 'Channel type(s) or channel indices';
            app.ChanneltypesorchannelindicesEditField = uieditfield(app.ICAPanel, 'text');
            app.ICAOKButton = uibutton(app.ICAPanel, 'push');
            app.ICAOKButton.ButtonPushedFcn = createCallbackFcn(app, @ICAOKButtonPushed, true);
            app.ICAOKButton.Text = 'OK';
            app.TimeFrequencyDecompositionPanel = uipanel(app.UIFigure);
            app.TimeFrequencyDecompositionPanel.Title = 'Time-Frequency Decomposition';
            app.STFTLabel = uilabel(app.TimeFrequencyDecompositionPanel);
            app.STFTLabel.Text = 'STFT';
            app.WaveletTransformLabel = uilabel(app.TimeFrequencyDecompositionPanel);
            app.WaveletTransformLabel.Text = 'Wavelet Transform';
            app.WindowDropDownLabel = uilabel(app.TimeFrequencyDecompositionPanel);
            app.WindowDropDownLabel.Text = 'Window';
            app.WindowDropDown = uidropdown(app.TimeFrequencyDecompositionPanel);
            app.WindowDropDown.Items = {'hann', 'hamming', 'gaussian', 'blackman'};
            app.WindowDropDown.Value = 'hann';
            app.WindowLengthEditFieldLabel = uilabel(app.TimeFrequencyDecompositionPanel);
            app.WindowLengthEditFieldLabel.Text = 'Window Length';
            app.WindowLengthEditField = uieditfield(app.TimeFrequencyDecompositionPanel, 'numeric');
            app.OverlapRatioEditFieldLabel = uilabel(app.TimeFrequencyDecompositionPanel);
            app.OverlapRatioEditFieldLabel.Text = 'Overlap Ratio';
            app.OverlapRatioEditField = uieditfield(app.TimeFrequencyDecompositionPanel, 'numeric');
            app.Label = uilabel(app.TimeFrequencyDecompositionPanel);
            app.Label.Text = '%';
            app.NFFTWindowLengthEditFieldLabel = uilabel(app.TimeFrequencyDecompositionPanel);
            app.NFFTWindowLengthEditFieldLabel.Text = 'NFFT(>Window Length)';
            app.NFFTWindowLengthEditField = uieditfield(app.TimeFrequencyDecompositionPanel, 'numeric');
            app.WaveletFamliyDropDownLabel = uilabel(app.TimeFrequencyDecompositionPanel);
            app.WaveletFamliyDropDownLabel.Text = 'Wavelet Famliy';
            app.WaveletFamliyDropDown = uidropdown(app.TimeFrequencyDecompositionPanel);
            app.WaveletFamliyDropDown.Items = {'morl', 'mesh', 'cmor'};
            app.WaveletFamliyDropDown.Value = 'morl';
            app.ScalesEditFieldLabel = uilabel(app.TimeFrequencyDecompositionPanel);
            app.ScalesEditFieldLabel.Text = 'Scales';
            app.ScalesEditField = uieditfield(app.TimeFrequencyDecompositionPanel, 'numeric');
            app.ScalesEditField_2 = uieditfield(app.TimeFrequencyDecompositionPanel, 'numeric');
            app.toLabel = uilabel(app.TimeFrequencyDecompositionPanel);
            app.toLabel.Text = 'to';
            app.HzLabel_4 = uilabel(app.TimeFrequencyDecompositionPanel);
            app.HzLabel_4.Text = 'Hz';
            app.VoicesperOctaveEditFieldLabel = uilabel(app.TimeFrequencyDecompositionPanel);
            app.VoicesperOctaveEditFieldLabel.Text = 'Voices per Octave';
            app.VoicesperOctaveEditField = uieditfield(app.TimeFrequencyDecompositionPanel, 'numeric');
            app.VoicesperOctaveEditField.Value = 32;
            app.DSOKButton = uibutton(app.TimeFrequencyDecompositionPanel, 'push');
            app.DSOKButton.ButtonPushedFcn = createCallbackFcn(app, @DSOKButtonPushed, true);
            app.DSOKButton.Text = 'OK';
            app.ResamplingLabel = uilabel(app.TimeFrequencyDecompositionPanel);
            app.ResamplingLabel.Text = 'Resampling';
            app.DownsamplingFacorEditFieldLabel = uilabel(app.TimeFrequencyDecompositionPanel);
            app.DownsamplingFacorEditFieldLabel.Text = ' Downsampling Facor';
            app.DownsamplingFacorEditField = uieditfield(app.TimeFrequencyDecompositionPanel, 'numeric');
            app.WTOKButton = uibutton(app.TimeFrequencyDecompositionPanel, 'push');
            app.WTOKButton.ButtonPushedFcn = createCallbackFcn(app, @WTOKButtonPushed, true);
            app.WTOKButton.Text = 'OK';
            app.STFTOKButton = uibutton(app.TimeFrequencyDecompositionPanel, 'push');
            app.STFTOKButton.ButtonPushedFcn = createCallbackFcn(app, @STFTOKButtonPushed, true);
            app.STFTOKButton.Text = 'OK';
            app.RereferencingPanel = uipanel(app.UIFigure);
            app.RereferencingPanel.Title = 'Re-referencing';
            app.ChooseparamsEditFieldLabel = uilabel(app.RereferencingPanel);
            app.ChooseparamsEditFieldLabel.Text = 'Choose params ';
            app.ChooseparamsEditField = uieditfield(app.RereferencingPanel, 'text');
            app.MethodDropDownLabel = uilabel(app.RereferencingPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.RereferencingPanel);
            app.MethodDropDown.Items = {'direct', 'median', 'trimmed'};
            app.MethodDropDown.Value = 'direct';
            app.rereferenceOKButton = uibutton(app.RereferencingPanel, 'push');
            app.rereferenceOKButton.ButtonPushedFcn = createCallbackFcn(app, @rereferenceOKButtonPushed, true);
            app.rereferenceOKButton.Text = 'OK';
            app.inputexamplesPanel = uipanel(app.RereferencingPanel);
            app.inputexamplesPanel.Title = 'input examples';
            app.A1Label = uilabel(app.inputexamplesPanel);
            app.A1Label.Text = ''' A1 ''';
            app.A1A2Label = uilabel(app.inputexamplesPanel);
            app.A1A2Label.Text = '{ '' A1 '' , '' A2 '' }';
            app.AverageLabel = uilabel(app.inputexamplesPanel);
            app.AverageLabel.Text = ''' Average ''';
            app.orA1A2Label = uilabel(app.inputexamplesPanel);
            app.orA1A2Label.Text = '[ 1 , 3 , 5 ] or { '' A1 '' , '' A2 '' }';
            app.DualelectrodereferenceLabel = uilabel(app.inputexamplesPanel);
            app.DualelectrodereferenceLabel.Text = 'Dual-electrode reference';
            app.SingleelectrodereferenceLabel = uilabel(app.inputexamplesPanel);
            app.SingleelectrodereferenceLabel.Text = 'Single-electrode reference';
            app.AveragereferenceLabel = uilabel(app.inputexamplesPanel);
            app.AveragereferenceLabel.Text = 'Average reference';
            app.AverageofspecificchannelgroupsLabel = uilabel(app.inputexamplesPanel);
            app.AverageofspecificchannelgroupsLabel.Text = 'Average of specific channel groups';
            app.NoiceCovariancePanel = uipanel(app.UIFigure);
            app.NoiceCovariancePanel.Title = 'Noice-Covariance';
            app.CalculateButton = uibutton(app.NoiceCovariancePanel, 'push');
            app.CalculateButton.Text = 'Calculate';
            app.InterpolatePanel = uipanel(app.UIFigure);
            app.InterpolatePanel.Title = 'Interpolate';
            app.BadchannelsEditFieldLabel = uilabel(app.InterpolatePanel);
            app.BadchannelsEditFieldLabel.Text = 'Bad channels';
            app.BadchannelsEditField = uieditfield(app.InterpolatePanel, 'text');
            app.interpolateOKButton = uibutton(app.InterpolatePanel, 'push');
            app.interpolateOKButton.ButtonPushedFcn = createCallbackFcn(app, @interpolateOKButtonPushed, true);
            app.interpolateOKButton.Text = 'OK';
            app.MethodDropDown_2Label = uilabel(app.InterpolatePanel);
            app.MethodDropDown_2Label.Text = 'Method';
            app.InterpolateMethodDropDown = uidropdown(app.InterpolatePanel);
            app.InterpolateMethodDropDown.Items = {'spherical-spline', 'idw', 'nearest'};
            app.InterpolateMethodDropDown.Value = 'spherical-spline';
            app.UsetemplatemontageCheckBox = uicheckbox(app.InterpolatePanel);
            app.UsetemplatemontageCheckBox.Text = 'Use template montage';
            app.InterpKEditFieldLabel = uilabel(app.InterpolatePanel);
            app.InterpKEditFieldLabel.Text = 'InterpK';
            app.InterpKEditField = uieditfield(app.InterpolatePanel, 'numeric');
            app.InterpKEditField.Value = 4;
            app.InterpRadiusEditFieldLabel = uilabel(app.InterpolatePanel);
            app.InterpRadiusEditFieldLabel.Text = 'InterpRadius';
            app.InterpRadiusEditField = uieditfield(app.InterpolatePanel, 'numeric');
            app.InterpRadiusEditField.Value = 25;
            app.LaplacianPanel = uipanel(app.UIFigure);
            app.LaplacianPanel.Title = 'Laplacian';
            app.LapOKButten = uibutton(app.LaplacianPanel, 'push');
            app.LapOKButten.ButtonPushedFcn = createCallbackFcn(app, @LapOKButtenButtonPushed, true);
            app.LapOKButten.Text = 'OK';
            app.EdgehandlingLabel = uilabel(app.LaplacianPanel);
            app.EdgehandlingLabel.Text = 'Edge handling';
            app.LapEdgeDropdown = uidropdown(app.LaplacianPanel);
            app.LapEdgeDropdown.Items = {'spherical-spline', 'idw', 'nearest'};
            app.LapEdgeDropdown.Value = 'spherical-spline';
            app.LapUseCSDCheckBox = uicheckbox(app.LaplacianPanel);
            app.LapUseCSDCheckBox.Text = 'Use CSD toolbox if available';
            app.LambdaEditFieldLabel = uilabel(app.LaplacianPanel);
            app.LambdaEditFieldLabel.Text = 'Lambda';
            app.LapLambdaEditField = uieditfield(app.LaplacianPanel, 'numeric');
            app.LapLambdaEditField.Value = 1e-05;
            app.SphericalsplineordermEditField_2Label = uilabel(app.LaplacianPanel);
            app.SphericalsplineordermEditField_2Label.Text = 'Spherical spline order m';
            app.LapMEditField = uieditfield(app.LaplacianPanel, 'numeric');
            app.LapMEditField.Value = 4;
            app.InterceptthesignalsectionPanel = uipanel(app.UIFigure);
            app.InterceptthesignalsectionPanel.Title = 'Intercept the signal section';
            app.ChoosepointsEditFieldLabel = uilabel(app.InterceptthesignalsectionPanel);
            app.ChoosepointsEditFieldLabel.Text = 'Choose points';
            app.ChoosepointsEditField = uieditfield(app.InterceptthesignalsectionPanel, 'text');
            app.signalsectionbutten = uibutton(app.InterceptthesignalsectionPanel, 'push');
            app.signalsectionbutten.ButtonPushedFcn = createCallbackFcn(app, @signalsectionbuttenButtonPushed, true);
            app.signalsectionbutten.Text = 'OK';
            app.inputexamplesPanel_2 = uipanel(app.InterceptthesignalsectionPanel);
            app.inputexamplesPanel_2.Title = 'input examples';
            app.Label_2 = uilabel(app.inputexamplesPanel_2);
            app.Label_2.Text = '[100,200]';
            app.PointrangeLabel = uilabel(app.inputexamplesPanel_2);
            app.PointrangeLabel.Text = 'Point range';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SEAL_Preprocess(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end