classdef SEAL_selectAlgorithm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelectProtocolPanel             matlab.ui.container.Panel
        ProtocolDropDown                matlab.ui.control.DropDown
        SelectCortexPanel               matlab.ui.container.Panel
        CortexDropDown                  matlab.ui.control.DropDown
        SelectedDataPanel               matlab.ui.container.Panel
        DataTree                        matlab.ui.container.Tree
        AlgorithmsPanel                 matlab.ui.container.Panel
        AlgorithmsTree                  matlab.ui.container.CheckBoxTree
        MinimumL2_NormNode              matlab.ui.container.TreeNode
        MNENode                         matlab.ui.container.TreeNode
        dSPMNode                        matlab.ui.container.TreeNode
        sLORETANode                     matlab.ui.container.TreeNode
        eLORETANode                     matlab.ui.container.TreeNode
        LORETANode                      matlab.ui.container.TreeNode
        LAURANode                       matlab.ui.container.TreeNode
        BeamformerNode                  matlab.ui.container.TreeNode
        LCMVNode                        matlab.ui.container.TreeNode
        Model_CovNode                   matlab.ui.container.TreeNode
        DICSNode                        matlab.ui.container.TreeNode
        MCMVNode                        matlab.ui.container.TreeNode
        SparsemodelNode                 matlab.ui.container.TreeNode
        Lp_LqNode                       matlab.ui.container.TreeNode
        ChampagneNode                   matlab.ui.container.TreeNode
        fullstructurenoiseNode          matlab.ui.container.TreeNode
        diagonalnoiselearningNode       matlab.ui.container.TreeNode
        addspatialbasisNode             matlab.ui.container.TreeNode
        BlkChamNode                     matlab.ui.container.TreeNode
        add_priorNode                   matlab.ui.container.TreeNode
        TSChamNode                      matlab.ui.container.TreeNode
        SmoothChamNode                  matlab.ui.container.TreeNode
        IRESNode                        matlab.ui.container.TreeNode
        SISSY_L1Node                    matlab.ui.container.TreeNode
        FOCUSSNode                      matlab.ui.container.TreeNode
        SpatioTemporalNode              matlab.ui.container.TreeNode
        MxNENode                        matlab.ui.container.TreeNode
        irMxNENode                      matlab.ui.container.TreeNode
        SISSY_L21Node                   matlab.ui.container.TreeNode
        VSSI_LpNode                     matlab.ui.container.TreeNode
        FastIRESNode                    matlab.ui.container.TreeNode
        STOUTNode                       matlab.ui.container.TreeNode
        dMAPNode                        matlab.ui.container.TreeNode
        BESTIESNode                     matlab.ui.container.TreeNode
        SISTBFNode                      matlab.ui.container.TreeNode
        uSTARNode                       matlab.ui.container.TreeNode
        STARTSNode                      matlab.ui.container.TreeNode
        sSTARTSNode                     matlab.ui.container.TreeNode
        VSSI_GGDNode                    matlab.ui.container.TreeNode
        SelectLeadFieldPanel            matlab.ui.container.Panel
        LeadFieldDropDown               matlab.ui.control.DropDown
        GeneralSettingsPanel            matlab.ui.container.Panel
        RunSelectedAlgorithmsButton     matlab.ui.control.Button
        SelectPriorButton               matlab.ui.control.Button
        LoadPriorButton                 matlab.ui.control.Button
        NoiseWhiteningDropDown          matlab.ui.control.DropDown
        NoiseWhiteningDropDown_2Label   matlab.ui.control.Label
        DepthWeightingEditField         matlab.ui.control.NumericEditField
        DepthWeightingEditFieldLabel    matlab.ui.control.Label
        DipoleOrienationtDropDown       matlab.ui.control.DropDown
        DipoleOrienationtDropDownLabel  matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        ImportDataMenu                  matlab.ui.container.Menu
    end


    properties (Access = private)
        protocolinfo = {}
        CurrentProtocolNum
        Data
        Cortex = {}
        LeadField = {}
        LeadField_fixed = {}
        DialogApp_datacheck
        AdditionalDataNode = {}
        AdditionalDataNodeNum = 0
        AdditionalData={}
        verticalSpacing = 9
        horizontalSpacing = 9
        allPanels = {}
        originalheight
        originalwidth
        TrialIndices
        MinimumL2_NormUIFigure
        SpatioUIFigure
        NumOrientations=1
        DepthWeightExp=0.5
        Noisetag=1
        Callingapp
        CurrentID
        SaveIndex
        resultdata=struct();
        counters=zeros(50,1);
        trials = 1
    end

    properties (Access = public)
        MNESettingsPanel %自定义控件
        MNEsourceCovarianceEditField
        MNEsourceCovarianceLabel
        MNERegularizationParameterEditField
        MNERegularizationParameterLabel
        RegularizationParameter
        sourceCovariance
        dSPMSettingsPanel
        sLORETASettingsPanel
        eLORETASettingsPanel
        LORETASettingsPanel
        LAURASettingsPanel
        STARTSSettingsPanel
        STARTSNumTBFsEditField
        STARTSNumTBFsLabel
        NumTBFs = 4
        STARTSNeiborOrderEditField
        STARTSNeiborOrderLabel
        NeighborOrder = 2
        STARTLearnNoiseDropDown
        STARTSLearnNoiseLabel
        LearnNoise = true
        STARTNoiseObservationDropDown
        STARTSNoiseObservationLabel
        NoiseObservationMatrix
        SparseModeUIFigure
        blockChampagneSettingsPanel
        blockSNeiborOrderEditField
        blockSNeiborOrderLabel
        blockLearnNoiseDropDown
        blockSLearnNoiseLabel
        blockNoiseObservationDropDown
        blockNoiseObservationLabel
        blockSAtlasConstraintsEditField
        blockSAtlasConstraintsLabel
        uSTARSettingsPanel
        uSTARNumTBFsEditField
        uSTARNumTBFsLabel
        uSTARMicrostateMapsLabel
        uSTARMicrostateMapDropDown
        MicrostateMaps = []
        uSTARMicrostateOptionsLabel
        uSTARRunAlgorithmDropDown
        uSTARRunAlgorithmLabel
        uSTARRunAlgorithm= 'STAR'
        uSTARPriorTypeDropDown
        uSTARPriorTypeLabel
        importMicrostateOptionsButton
        importMicrostateMapsButton
        firsttime = 1

    end


    methods (Access = private)

        function populateProtocol(app, protocolInformation)
            app.CortexDropDown.Items={};
            app.LeadFieldDropDown.Items={};
            if isfield(protocolInformation, 'Cortex') && ~isempty(protocolInformation.Cortex)

                entry = protocolInformation.Cortex(end);
                app.CortexDropDown.Items={entry.ID};
                app.Cortex{app.CurrentProtocolNum}=load(entry.FilePath);
            end

            if isfield( protocolInformation, 'LeadField') && ~isempty( protocolInformation.LeadField)

                entry = protocolInformation.LeadField(end);
                app.LeadFieldDropDown.Items={entry.ID};
                app.LeadField{app.CurrentProtocolNum}=load(entry.FilePath);
                %初步判断NumOrientation
                [~, nCols] = size(app.LeadField{app.CurrentProtocolNum}.Gain);
                nVerts = size(app.Cortex{app.CurrentProtocolNum}.Vertices, 1);
                if nCols == nVerts
                    app.NumOrientations = 1;
                elseif nCols == 3 * nVerts
                    app.NumOrientations = 3;
                else
                    app.NumOrientations = NaN;
                    warning('Leadfield 列数 (%d) 与顶点数 (%d) 不匹配 (比例: %.2f)', ...
                        nCols, nVerts, nCols/nVerts);
                end

                if isfield(app.LeadField{app.CurrentProtocolNum},'GridAtlas')
                    app.LeadField_fixed{app.CurrentProtocolNum}.Gain=...
                        bst_gain_orient(app.LeadField{app.CurrentProtocolNum}.Gain, ...
                        app.LeadField{app.CurrentProtocolNum}.GridOrient, ...
                        app.LeadField{app.CurrentProtocolNum}.Atlas);
                    app.NumOrientations=1;
                elseif isfield(app.LeadField{app.CurrentProtocolNum},'GridOrient')
                    app.LeadField_fixed{app.CurrentProtocolNum}.Gain=...
                        bst_gain_orient(app.LeadField{app.CurrentProtocolNum}.Gain, ...
                        app.LeadField{app.CurrentProtocolNum}.GridOrient, ...
                        []);
                    app.NumOrientations=1;
                else
                    app.LeadField_fixed{app.CurrentProtocolNum}.Gain=app.LeadField{app.CurrentProtocolNum}.Gain;

                end


            end

            if isfield(protocolInformation, 'Data') && ~isempty(protocolInformation.Data)
                dataInfo = protocolInformation.Data;
                uitreenode(app.DataTree, 'Text', ['Data: ', dataInfo.ID], 'NodeData',dataInfo.FilePath);
            end


            if isfield(protocolInformation, 'ProcessedDataPath') && ~isempty(protocolInformation.ProcessedDataPath)
                % 获取文件夹中的所有文件
                fileList = dir(fullfile(protocolInformation.ProcessedDataPath, '*'));
                for j = 1:length(fileList)
                    % 跳过特殊目录项：. 和 ..
                    if strcmp(fileList(j).name, '.') || strcmp(fileList(j).name, '..')
                        continue;
                    end
                    % 创建新条目
                    Entry = struct();
                    % 设置文件路径
                    Entry.FilePath = fullfile(fileList(j).folder, fileList(j).name);

                    % 使用文件名作为ID
                    [~, name, ~] = fileparts(fileList(j).name);
                    Entry.ID = name;
                    uitreenode(app.DataTree, 'Text', ['Data: ', Entry.ID], 'NodeData', Entry.FilePath);

                end
            end
            if app.AdditionalDataNodeNum>0
                for i=1:app.AdditionalDataNodeNum
                    filename=app.AdditionalData{i}.filename;
                    filePath=app.AdditionalData{i}.filepath;
                    app.AdditionalDataNode{i}=uitreenode(app.DataTree,'Text',  filename, 'NodeData',filePath);
                end
            end
        end

        function applyLayout(app, panel,allvisiblePanels)
            % 收集可见面板
            visiblePanels = {};

            for i = 1:length(allvisiblePanels)

                visiblePanels{end+1} = allvisiblePanels{i};

            end

            if isempty(visiblePanels)
                return; % 没有可见面板，不执行布局
            end

            % 获取面板高度（假设所有面板宽度一致）
            panelWidth = visiblePanels{1}.Position(3); % 面板宽度
            panelHeights = cellfun(@(p) p.Position(4), visiblePanels);

            % 计算每列面板高度（包括间距）
            columnHeights = zeros(1, length(visiblePanels));
            columns = {};
            currentColumn = 1;
            columns{currentColumn} = [];
            % 按高度降序排序，保证大的面板在左边
            [sortedHeights, sortIdx] = sort(panelHeights, 'descend');
            visiblePanels = visiblePanels(sortIdx);

            % 第一列计算
            for i = 1:length(visiblePanels)
                % 如果当前列的高度小于UIFigure的高度-40（上下边距）
                if columnHeights(currentColumn) + sortedHeights(i)<= app.originalheight-2*app.verticalSpacing
                    columns{currentColumn}(end+1) = i;
                    columnHeights(currentColumn) = columnHeights(currentColumn) + sortedHeights(i) + app.verticalSpacing;
                else
                    % 当前列放不下，创建新列
                    currentColumn = currentColumn + 1;
                    % 确保新列被正确初始化
                    columns{currentColumn} = i; % 新列的第一个元素
                    columnHeights(currentColumn) = sortedHeights(i) + app.verticalSpacing;
                end
            end

            % 调整高度（加上最后一个面板的底部间距）
            columnHeights = columnHeights + app.verticalSpacing;

            %剔除多余列
            for i = length(columnHeights):-1:1
                if columnHeights(i)==9
                    columnHeights(i)=[];
                end
            end
            % 按列高度降序排序，高的列在左边
            [~, colOrder] = sort(columnHeights, 'descend');
            columns = columns(colOrder);

            % 计算UIFigure新宽度
            newWidth =  (panelWidth + app.horizontalSpacing) * length(columns) + app.horizontalSpacing;


            % 设置UIFigure新尺寸
            panel.Position = [panel.Position(1:2) newWidth app.originalheight];
            %   app.PanelContainer.Position = [1 1 newWidth newHeight];

            % 布局面板
            currentX = app.horizontalSpacing;

            for colIdx = 1:length(columns)
                colPanels = columns{colIdx};
                currentY = panel.Position(4) - app.verticalSpacing;

                for panelIdx = colPanels
                    p = visiblePanels{panelIdx};
                    pHeight = p.Position(4);

                    % 定位面板
                    p.Position = [currentX currentY-pHeight panelWidth pHeight];

                    % 更新当前列Y位置
                    currentY = currentY - pHeight - app.verticalSpacing;
                end

                % 移动到下一列
                currentX = currentX + panelWidth + app.horizontalSpacing;
            end
            panel.Visible='on';
        end

        function MNEsourceCovarianceValueChanged(app, ~)
            app.sourceCovariance = app.MNEsourceCovarianceEditField.value;
        end

        function MNERegularizationParameterEditFieldValueChanged(app, ~)
            app.RegularizationParameter = app.MNERegularizationParameterEditField.Value;
        end

        function closeMinL2FigureCallback(app, src, ~)
            delete(src);
            % 4. 重置属性
            app.MinimumL2_NormUIFigure = [];
        end

        function NodeDatas = removeRootNodes(app)
            checkedNodes = app.AlgorithmsTree.CheckedNodes;
            % 获取树的根节点
            rootNodes = app.AlgorithmsTree.Children;
            % 初始化结果数组
            isRootNode = false(size(checkedNodes));
            for i = 1:length(checkedNodes)
                % 检查节点是否是根节点
                if ismember(checkedNodes(i), rootNodes)
                    isRootNode(i) = true;
                end
            end

            indice = find(isRootNode);

            for i=length(indice):-1:1
                checkedNodes(indice(i)) = [];
            end

            NodeDatas=strings();
            %获取NodeData
            for i = 1:length(checkedNodes)
                if ~isempty(checkedNodes(i).NodeData)
                    NodeDatas(i)=checkedNodes(i).NodeData;
                end
            end
        end

        function results = deal_results(app,Source)
            temp = reshape(Source, 3, [], size(Source, 2));
            results = squeeze(sum(temp.^2, 1));    % 沿第一维（3行）计算平方和
        end

        function saveresults(app,results,Num,type)
            SavePath = fullfile(app.protocolinfo{app.CurrentProtocolNum}.protocolPath, 'ResultData');
            filename = sprintf('%s/%s_Results_%03d.mat', SavePath, type, Num);
            ID=sprintf('%s_Results_%03d', type, Num);

            app.resultdata(app.SaveIndex).filepath=filename;
            app.resultdata(app.SaveIndex).ID=['Result: ',ID];
            app.resultdata(app.SaveIndex).Source=app.CurrentID;
            save(filename, 'results', '-v7.3');


            %为结果创建节点
            children = app.Callingapp.FileTree.Children(app.CurrentProtocolNum).Children;
            for i = 1:length(children)
                if strcmp(app.resultdata(app.SaveIndex).Source,children(i).Text)
                    node = uitreenode(app.Callingapp.FileTree.Children(app.CurrentProtocolNum).Children(i), ...
                        'Text', app.resultdata(app.SaveIndex).ID ,'NodeData',app.resultdata(app.SaveIndex).filepath);
                    node.ContextMenu = app.Callingapp.ResultDataFileMenu;
                    drawnow;
                    return
                end
            end
        end

        function STARTSNumTBFsEditFieldValueChanged(app,~)
            app.NumTBFs=app.STARTSNumTBFsEditField.Value;
        end

        function STARTSNeiborOrderEditFieldValueChanged(app,~)
            app.NeighborOrder=app.STARTSNeiborOrderEditField.Value;
        end

        function STARTLearnNoiseDropDownValueChanged(app,~)
            app.LearnNoise=app.STARTLearnNoiseDropDown.Value;
        end

        function getIndex(app,filelist)
            for i=1:length(filelist)
                if contains(filelist(i).name, 'MNE')
                    app.counters(1)=app.counters(1)+1;
                elseif contains(filelist(i).name, 'STARTS')
                    app.counters(7)=app.counters(7)+1;
                elseif contains(filelist(i).name, 'BlockChampagne')
                    app.counters(8)=app.counters(8)+1;
                elseif contains(filelist(i).name, 'uSTAR')
                    app.counters(9)=app.counters(9)+1;
                end
                %为每一个都更新参数
            end
        end

        function uSTARRunAlgorithmDropDownValueChanged(app,~)
            app.uSTARRunAlgorithm=app.uSTARRunAlgorithmDropDown.Value;
        end

        function importMicrostateMapsButtonPushed(app,~)
            filterSpec = { ...
                '*.mat', 'MATLAB (.mat)'; ...
                '*.set', 'EEGLAB (.set)'; ...
                '*.*',   'All Files (*.*)'};

            [fileName, filePath,~] = uigetfile(filterSpec, ['Select ', strrep('Data', '_', ' '), ' File']);

            originalPath = fullfile(filePath, fileName);
            app.MicrostateMaps=load(originalPath);
            app.MicrostateMaps=struct2cell(app.MicrostateMaps);
            app.MicrostateMaps=app.MicrostateMaps{1};
            app.uSTARMicrostateMapDropDown.Items={fileName};
            app.uSTARMicrostateMapDropDown.Value=fileName;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, selectednode, firsttime)
            app.UIFigure.Name='Select Algorithms';
            app.Callingapp=mainapp;
            addpath(genpath('CoreFunctions'));
            addpath(genpath('Algorithms'));
            addpath(genpath('External'));
            protocolnode=selectednode.Parent;
            %需要转存才能访问
            app.originalheight=app.UIFigure.Position(4);
            app.originalwidth=app.UIFigure.Position(3);
            app.CurrentProtocolNum=protocolnode.NodeData.No;
            app.populateProtocol(protocolnode.NodeData) ;

            filetree=protocolnode.Parent;
            for i=1:length(filetree.Children)
                protocolNode=filetree.Children(i);
                app.protocolinfo{i}=protocolNode.NodeData;
                protocolname=app.protocolinfo{i}.Name;
                app.ProtocolDropDown.Items =  [ app.ProtocolDropDown.Items,{protocolname}];
            end
            app.MinimumL2_NormUIFigure = [];
            app.SpatioUIFigure = [];
            app.ProtocolDropDown.Value=app.ProtocolDropDown.Items(app.CurrentProtocolNum);
            
        end

        % Value changed function: ProtocolDropDown
        function ProtocolDropDownValueChanged(app, event)
            value = app.ProtocolDropDown.Value;
            for i=1:length(app.ProtocolDropDown.Items)
                if strcmp(value,app.protocolinfo{i}.Name)
                    app.CurrentProtocolNum = i;
                end
            end
            app.DataTree.Children.delete;
            app.populateProtocol(app.protocolinfo{app.CurrentProtocolNum});
        end

        % Menu selected function: ImportDataMenu
        function ImportDataMenuSelected(app, event)
            filterSpec = { ...
                '*.mat', 'MATLAB (.mat)'; ...
                '*.set', 'EEGLAB (.set)'; ...
                '*.*',   'All Files (*.*)'};

            [fileName, filePath,~] = uigetfile(filterSpec, ['Select ', strrep('Data', '_', ' '), ' File']);

            originalPath = fullfile(filePath, fileName);

            app.AdditionalDataNodeNum = app.AdditionalDataNodeNum + 1;
            app.AdditionalData{app.AdditionalDataNodeNum}.filename=fileName;
            app.AdditionalData{app.AdditionalDataNodeNum}.filepath=filePath;

            app.AdditionalDataNode{app.AdditionalDataNodeNum}= uitreenode(app.DataTree, ...
                'Text', ['Data: ', fileName], 'NodeData',originalPath);

        end

        % Callback function: AlgorithmsTree
        function AlgorithmsTreeCheckedNodesChanged(app, event)
            % 步骤4: 删除图形窗口本身
            delete(app.SparseModeUIFigure);
            delete(app.MinimumL2_NormUIFigure);
            delete(app.SpatioUIFigure);
            app.MinimumL2_NormUIFigure = [];
            app.SpatioUIFigure=[];

            NodeDatas = app.removeRootNodes();

            app.MinimumL2_NormUIFigure = uifigure('Visible', 'off');
            app.MinimumL2_NormUIFigure.AutoResizeChildren = 'off';
            app.MinimumL2_NormUIFigure.Position = ...
                [app.UIFigure.Position(1)+app.UIFigure.Position(3) app.UIFigure.Position(2) 563 264];
            app.MinimumL2_NormUIFigure.Name = 'MinimumL2_Norm Settings';
            app.MinimumL2_NormUIFigure.CloseRequestFcn = @(src,event) app.closeMinL2FigureCallback(src, event);

            app.SpatioUIFigure = uifigure('Visible', 'off');
            app.SpatioUIFigure.AutoResizeChildren = 'off';
            app.SpatioUIFigure.Position = ...
                [app.UIFigure.Position(1)+app.UIFigure.Position(3) app.UIFigure.Position(2) 563 264];
            app.SpatioUIFigure.Name = 'Spatio Temporal Settings';


            app.SparseModeUIFigure = uifigure('Visible', 'off');
            app.SparseModeUIFigure.AutoResizeChildren = 'off';
            app.SparseModeUIFigure.Position = ...
                [app.UIFigure.Position(1)+app.UIFigure.Position(3) app.UIFigure.Position(2) 563 264];
            app.SparseModeUIFigure.Name = 'SparseMode Settings';


            allsparsemodePanel={};
            allminimumL2NormPanels={};
            allspatioPanels={};
            %遍历NodeData
            for i=1:length(NodeDatas)
                switch NodeDatas(i)
                    case 'MNE'
                        app.MNESettingsPanel = uipanel(app.MinimumL2_NormUIFigure);
                        app.MNESettingsPanel.Title = 'MNE Settings';
                        app.MNESettingsPanel.FontWeight = 'bold';
                        app.MNESettingsPanel.Position = [381+180 10 174 145];
                        app.MNESettingsPanel.Visible = 'on';

                        allminimumL2NormPanels = [allminimumL2NormPanels,{app.MNESettingsPanel}];

                        app.MNEsourceCovarianceEditField = uieditfield(app.MNESettingsPanel);
                        app.MNEsourceCovarianceEditField.Position = [70 10 100 22];
                        app.MNEsourceCovarianceEditField.Value = 'depth_weighted';
                        app.MNEsourceCovarianceEditField.ValueChangedFcn = ...
                            createCallbackFcn(app, @MNEsourceCovarianceEditFieldValueChanged, true);
                        app.MNEsourceCovarianceLabel = uilabel(app.MNESettingsPanel);
                        app.MNEsourceCovarianceLabel.Position = [3 40 100 22];
                        app.MNEsourceCovarianceLabel.Text = 'SourceCovariance';

                        app.MNERegularizationParameterEditField = uieditfield(app.MNESettingsPanel,'numeric');
                        app.MNERegularizationParameterEditField.Position = [70 70 100 22];
                        app.MNERegularizationParameterEditField.Value = 0.1;
                        app.MNERegularizationParameterEditField.ValueChangedFcn = ...
                            createCallbackFcn(app, @MNERegularizationParameterEditFieldValueChanged, true);
                        app.MNERegularizationParameterLabel = uilabel(app.MNESettingsPanel);
                        app.MNERegularizationParameterLabel.Position = [3 100 150 22];
                        app.MNERegularizationParameterLabel.Text = 'RegularizationParameter';
                    case 'dSPM'
                        app.dSPMSettingsPanel = uipanel(app.MinimumL2_NormUIFigure);
                        app.dSPMSettingsPanel.Title = 'dSPM Settings';
                        app.dSPMSettingsPanel.FontWeight = 'bold';
                        app.dSPMSettingsPanel.Position = [381+180 10 174 100];
                        app.dSPMSettingsPanel.Visible='on';

                        allminimumL2NormPanels=[allminimumL2NormPanels,{app.dSPMSettingsPanel}];
                    case 'sLORETA'
                        app.sLORETASettingsPanel = uipanel(app.MinimumL2_NormUIFigure);
                        app.sLORETASettingsPanel.Title = 'sLORETA Settings';
                        app.sLORETASettingsPanel.FontWeight = 'bold';
                        app.sLORETASettingsPanel.Position = [381+180 10 174 52];
                        app.sLORETASettingsPanel.Visible='on';

                        allminimumL2NormPanels=[allminimumL2NormPanels,{app.sLORETASettingsPanel}];
                    case 'eLORETA'
                        app.eLORETASettingsPanel = uipanel(app.MinimumL2_NormUIFigure);
                        app.eLORETASettingsPanel.Title = 'eLORETA Settings';
                        app.eLORETASettingsPanel.FontWeight = 'bold';
                        app.eLORETASettingsPanel.Position = [381+180 10 174 150];
                        app.eLORETASettingsPanel.Visible='on';

                        allminimumL2NormPanels=[allminimumL2NormPanels,{app.eLORETASettingsPanel}];
                    case 'LORETA'
                        app.LORETASettingsPanel = uipanel(app.MinimumL2_NormUIFigure);
                        app.LORETASettingsPanel.Title = 'LORETA Settings';
                        app.LORETASettingsPanel.FontWeight = 'bold';
                        app.LORETASettingsPanel.Position = [381+180 10 174 75];
                        app.LORETASettingsPanel.Visible='on';

                        allminimumL2NormPanels=[allminimumL2NormPanels,{app.LORETASettingsPanel}];
                    case 'LAURA'
                        app.LAURASettingsPanel = uipanel(app.MinimumL2_NormUIFigure);
                        app.LAURASettingsPanel.Title = 'LAURA Settings';
                        app.LAURASettingsPanel.FontWeight = 'bold';
                        app.LAURASettingsPanel.Position = [381+180 10 174 175];
                        app.LAURASettingsPanel.Visible='on';

                        allminimumL2NormPanels=[allminimumL2NormPanels,{app.LAURASettingsPanel}];
                    case 'STARTS'%STARTS
                        app.STARTSSettingsPanel = uipanel(app.SpatioUIFigure);
                        app.STARTSSettingsPanel.Title = 'STARTS Settings';
                        app.STARTSSettingsPanel.FontWeight = 'bold';
                        app.STARTSSettingsPanel.Position = [381+180 10 174 226];
                        app.STARTSSettingsPanel.Visible = 'on';

                        allspatioPanels = [allspatioPanels,{app.STARTSSettingsPanel}];

                        app.STARTSNumTBFsEditField = uieditfield(app.STARTSSettingsPanel,'numeric');
                        app.STARTSNumTBFsEditField.Position = [70 10 100 22];
                        app.STARTSNumTBFsEditField.Value = 4 ;
                        app.STARTSNumTBFsEditField.ValueChangedFcn = ...
                            createCallbackFcn(app, @STARTSNumTBFsEditFieldValueChanged, true);
                        app.STARTSNumTBFsLabel = uilabel(app.STARTSSettingsPanel);
                        app.STARTSNumTBFsLabel.Position = [3 35 100 22];
                        app.STARTSNumTBFsLabel.Text = 'NumTBFs';

                        app.STARTSNeiborOrderEditField = uieditfield(app.STARTSSettingsPanel,'numeric');
                        app.STARTSNeiborOrderEditField.Position = [70 60 100 22];
                        app.STARTSNeiborOrderEditField.Value = 1;
                        app.STARTSNeiborOrderEditField.ValueChangedFcn = ...
                            createCallbackFcn(app, @STARTSNeiborOrderEditFieldValueChanged, true);
                        app.STARTSNeiborOrderLabel = uilabel(app.STARTSSettingsPanel);
                        app.STARTSNeiborOrderLabel.Position = [3 85 150 22];
                        app.STARTSNeiborOrderLabel.Text = 'NeiborOrder';

                        app.STARTLearnNoiseDropDown = uidropdown(app.STARTSSettingsPanel);
                        app.STARTLearnNoiseDropDown.Items = {'true'};
                        app.STARTLearnNoiseDropDown.ValueChangedFcn = ...
                            createCallbackFcn(app, @STARTLearnNoiseDropDownValueChanged, true);
                        app.STARTLearnNoiseDropDown.Position = [70 110 100 22];
                        app.STARTLearnNoiseDropDown.Value = 'true';
                        app.STARTSLearnNoiseLabel = uilabel(app.STARTSSettingsPanel);
                        app.STARTSLearnNoiseLabel.Position = [3 135 150 22];
                        app.STARTSLearnNoiseLabel.Text = 'LearnNoise';

                        app.STARTNoiseObservationDropDown = uidropdown(app.STARTSSettingsPanel);
                        app.STARTNoiseObservationDropDown.Items = {'eye'};
                        app.STARTNoiseObservationDropDown.ValueChangedFcn = ...
                            createCallbackFcn(app, @STARTNoiseObservationDropDownValueChanged, true);%回调尚未编写
                        app.STARTNoiseObservationDropDown.Position = [70 160 100 22];
                        app.STARTNoiseObservationDropDown.Value = 'eye';
                        app.STARTSNoiseObservationLabel = uilabel(app.STARTSSettingsPanel);
                        app.STARTSNoiseObservationLabel.Position = [3 185 150 22];
                        app.STARTSNoiseObservationLabel.Text = 'NoiseObservation';
                    case 'BlockChampagne' %blockChampagne //add_priorNode
                        app.blockChampagneSettingsPanel = uipanel(app.SparseModeUIFigure);
                        app.blockChampagneSettingsPanel.Title = 'BlockChampagne Settings';
                        app.blockChampagneSettingsPanel.FontWeight = 'bold';
                        app.blockChampagneSettingsPanel.Position = [381+180 10 174 226];
                        app.blockChampagneSettingsPanel.Visible = 'on';

                        allsparsemodePanel = [allsparsemodePanel,{app.blockChampagneSettingsPanel}];

                        app.blockSAtlasConstraintsEditField = uieditfield(app.blockChampagneSettingsPanel,'numeric');
                        app.blockSAtlasConstraintsEditField.Position = [70 10 100 22];
                        %app.blockSAtlasConstraintsEditField.Value = [];
                        app.blockSAtlasConstraintsEditField.ValueChangedFcn = ...
                            createCallbackFcn(app, @blockSAtlasConstraintsEditFieldValueChanged, true);
                        app.blockSAtlasConstraintsLabel = uilabel(app.blockChampagneSettingsPanel);
                        app.blockSAtlasConstraintsLabel.Position = [3 35 150 22];
                        app.blockSAtlasConstraintsLabel.Text = 'AtlasConstraints';

                        app.blockSNeiborOrderEditField = uieditfield(app.blockChampagneSettingsPanel,'numeric');
                        app.blockSNeiborOrderEditField.Position = [70 60 100 22];
                        app.blockSNeiborOrderEditField.Value = 1;
                        app.blockSNeiborOrderEditField.ValueChangedFcn = ...
                            createCallbackFcn(app, @blockSNeiborOrderEditFieldValueChanged, true);
                        app.blockSNeiborOrderLabel = uilabel(app.blockChampagneSettingsPanel);
                        app.blockSNeiborOrderLabel.Position = [3 85 150 22];
                        app.blockSNeiborOrderLabel.Text = 'NeiborOrder';

                        app.blockLearnNoiseDropDown = uidropdown(app.blockChampagneSettingsPanel);
                        app.blockLearnNoiseDropDown.Items = {'true'};
                        app.blockLearnNoiseDropDown.ValueChangedFcn = ...
                            createCallbackFcn(app, @blockLearnNoiseDropDownValueChanged, true);
                        app.blockLearnNoiseDropDown.Position = [70 110 100 22];
                        app.blockLearnNoiseDropDown.Value = 'true';
                        app.blockSLearnNoiseLabel = uilabel(app.blockChampagneSettingsPanel);
                        app.blockSLearnNoiseLabel.Position = [3 135 150 22];
                        app.blockSLearnNoiseLabel.Text = 'LearnNoise';

                        app.blockNoiseObservationDropDown = uidropdown(app.blockChampagneSettingsPanel);
                        app.blockNoiseObservationDropDown.Items = {'eye'};
                        app.blockNoiseObservationDropDown.ValueChangedFcn = ...
                            createCallbackFcn(app, @blockNoiseObservationDropDownValueChanged, true);%回调尚未编写
                        app.blockNoiseObservationDropDown.Position = [70 160 100 22];
                        app.blockNoiseObservationDropDown.Value = 'eye';
                        app.blockNoiseObservationLabel = uilabel(app.blockChampagneSettingsPanel);
                        app.blockNoiseObservationLabel.Position = [3 185 150 22];
                        app.blockNoiseObservationLabel.Text = 'NoiseObservation';
                    case 'uSTAR'
                        app.uSTARSettingsPanel = uipanel(app.SpatioUIFigure);
                        app.uSTARSettingsPanel.Title = 'uSTAR Settings';
                        app.uSTARSettingsPanel.FontWeight = 'bold';
                        app.uSTARSettingsPanel.Position = [381+180 10 174 226];
                        app.uSTARSettingsPanel.Visible = 'on';

                        allspatioPanels = [allspatioPanels,{app.uSTARSettingsPanel}];

                        app.uSTARNumTBFsEditField = uieditfield(app.uSTARSettingsPanel,'numeric');
                        app.uSTARNumTBFsEditField.Position = [70 10 100 22];
                        app.uSTARNumTBFsEditField.Value = 4 ;
                        app.uSTARNumTBFsEditField.ValueChangedFcn = ...
                            createCallbackFcn(app, @uSTARNumTBFsEditFieldValueChanged, true);
                        app.uSTARNumTBFsLabel = uilabel(app.uSTARSettingsPanel);
                        app.uSTARNumTBFsLabel.Position = [3 35 100 22];
                        app.uSTARNumTBFsLabel.Text = 'NumTBFs';

                        app.uSTARMicrostateMapsLabel = uilabel(app.uSTARSettingsPanel);
                        app.uSTARMicrostateMapsLabel.Position = [3 85 100 22];
                        app.uSTARMicrostateMapsLabel.Text = 'MicrostateMaps';
                        app.importMicrostateMapsButton = uibutton(app.uSTARSettingsPanel, 'push');
                        app.importMicrostateMapsButton.ButtonPushedFcn = ...
                            createCallbackFcn(app, @importMicrostateMapsButtonPushed, true);
                        app.importMicrostateMapsButton.Position = [90 85 22 22];
                        app.importMicrostateMapsButton.Text = '...';
                        app.uSTARMicrostateMapDropDown = uidropdown(app.uSTARSettingsPanel);
                        app.uSTARMicrostateMapDropDown.Items = {'none'};
                        app.uSTARMicrostateMapDropDown.ValueChangedFcn = ...
                            createCallbackFcn(app, @uSTARMicrostateMapDropDownValueChanged, true);
                        app.uSTARMicrostateMapDropDown.Position = [115 85 60 22];
                        app.uSTARMicrostateMapDropDown.Value = 'none';

                        app.uSTARMicrostateOptionsLabel = uilabel(app.uSTARSettingsPanel);
                        app.uSTARMicrostateOptionsLabel.Position = [3 60 150 22];
                        app.uSTARMicrostateOptionsLabel.Text = 'MicrostateOptions';
                        app.importMicrostateOptionsButton = uibutton(app.uSTARSettingsPanel, 'push');
                        app.importMicrostateOptionsButton.ButtonPushedFcn = ...
                            createCallbackFcn(app, @importMicrostateOptionsButtonPushed, true);
                        app.importMicrostateOptionsButton.Position = [110 60 22 22];
                        app.importMicrostateOptionsButton.Text = '...';

                        app.uSTARRunAlgorithmDropDown = uidropdown(app.uSTARSettingsPanel);
                        app.uSTARRunAlgorithmDropDown.Items = {'STAR','sSTARTS'};
                        app.uSTARRunAlgorithmDropDown.ValueChangedFcn = ...
                            createCallbackFcn(app, @uSTARRunAlgorithmDropDownValueChanged, true);
                        app.uSTARRunAlgorithmDropDown.Position = [70 110 100 22];
                        app.uSTARRunAlgorithmDropDown.Value = 'STAR';
                        app.uSTARRunAlgorithmLabel = uilabel(app.uSTARSettingsPanel);
                        app.uSTARRunAlgorithmLabel.Position = [3 135 150 22];
                        app.uSTARRunAlgorithmLabel.Text = 'RunAlgorithm';

                        app.uSTARPriorTypeDropDown = uidropdown(app.uSTARSettingsPanel);
                        app.uSTARPriorTypeDropDown.Items = {'Laplacian','LAURA'};
                        app.uSTARPriorTypeDropDown.ValueChangedFcn = ...
                            createCallbackFcn(app, @uSTARPriorTypeDropDownValueChanged, true);
                        app.uSTARPriorTypeDropDown.Position = [70 160 100 22];
                        app.uSTARPriorTypeDropDown.Value = 'Laplacian';
                        app.uSTARPriorTypeLabel = uilabel(app.uSTARSettingsPanel);
                        app.uSTARPriorTypeLabel.Position = [3 185 150 22];
                        app.uSTARPriorTypeLabel.Text = 'PriorType';

                end
            end
            app.applyLayout(app.SparseModeUIFigure,allsparsemodePanel);
            app.applyLayout(app.SpatioUIFigure,allspatioPanels);
            app.applyLayout(app.MinimumL2_NormUIFigure,allminimumL2NormPanels);
        end

        % Button pushed function: RunSelectedAlgorithmsButton
        function RunSelectedAlgorithmsButtonPushed(app, event)
            % 定义文件夹名称
            disp( app.CurrentProtocolNum) ;
            folderName = fullfile(app.protocolinfo{app.CurrentProtocolNum}.protocolPath, 'ResultData');
            processingInfo={};
%             struct('Algrithm',{},'RegularizationParameter',{},'DepthWeightExp',{}, ...
%                 'SourceCovariance',{},'NoiseCovariance',{},'NumOrientations',{},'NumTBFs',{});
            % 检查文件夹是否存在，不存在则创建
            if ~exist(folderName, 'dir')
                mkdir(folderName);
                disp(['文件夹已创建: ' fullfile(pwd, folderName)]);
            else
                disp(['文件夹已存在: ' fullfile(pwd, folderName)]);
            end
            SavePath = folderName;
            fileList = dir(fullfile(SavePath, '*.mat'));

            app.SaveIndex = length(fileList);
            if app.firsttime==1
                app.getIndex(fileList);
                app.firsttime=0;
            end

            if isfield(app.Callingapp.protocolnode{app.protocolinfo{app.CurrentProtocolNum}.No}.NodeData,'ResultData')
                app.resultdata=app.Callingapp.protocolnode{app.protocolinfo{app.CurrentProtocolNum}.No}.NodeData.ResultData;
            else
                app.resultdata=struct();
            end

            RegularizationParameterEditField=0.1;%默认正则化参数%每一个都有
            %噪声协方差矩阵
            if app.Noisetag==1
                NoiseCovariance=eye(size(app.Data,1),size(app.Data,1));
            elseif app.Noisetag==0
                NoiseCovariance=eye(size(app.Data,1),size(app.Data,1));%导入功能待做
            end


            % 通常我们取刺激发生前的基线数据（比如前 50ms 或 100ms）作为噪声
            % 假设 Data 的前 50 个点是基线
            BaselineData = app.Data(:, 1:50);

            % 计算噪声协方差矩阵 (C_noise)
            % cov 函数计算的是列的协方差，所以要转置一下
            C_noise_calculated = cov(BaselineData');

            scale_factor = 1e6; 
Data_scaled = app.Data * scale_factor;
C_noise_scaled=C_noise_calculated* (scale_factor^2); 
C_noise_scaled = C_noise_scaled + 0.05 * mean(diag(C_noise_scaled)) * eye(size(C_noise_scaled));
            app.sourceCovariance='depth_weighted';

            if isempty(app.DataTree.SelectedNodes)
                uialert(app.UIFigure,'You did not choose any DATA','Selected a data')
            end

            NodeDatas = app.removeRootNodes();

            %遍历NodeData

            for i=1:length(NodeDatas)
                switch NodeDatas(i)
                    case 'MNE'
                        [Source_MNE, ~] =seal_MNE(Data_scaled, ...
                            app.LeadField_fixed{app.CurrentProtocolNum}.Gain,...
                            'RegularizationParameter',RegularizationParameterEditField,...
                            'DepthWeightExp',app.DepthWeightExp,...
                            'SourceCovariance' , app.sourceCovariance,...
                            'NoiseCovariance', C_noise_scaled,...
                            'NumOrientations', app.NumOrientations);
                        if app.NumOrientations==3
                            result = app.deal_results(Source_MNE);
                        else
                            result = Source_MNE;
                        end
                        app.counters(1)=app.counters(1)+1;
                        app.SaveIndex = app.SaveIndex +1;
                        results = result;
                        app.saveresults(results,app.counters(1),'MNE')
                        currentStruct = struct();
                        currentStruct.Algrithm = 'MNE';
                        currentStruct.RegularizationParameter = RegularizationParameterEditField;
                        currentStruct.DepthWeightExp = app.DepthWeightExp;
                        currentStruct.SourceCovariance = app.sourceCovariance;
                        currentStruct.NoiseCovariance = C_noise_calculated;
                        currentStruct.NumOrientations = app.NumOrientations;
                        processingInfo{end+1}=currentStruct;
                    case 'dSPM'

                    case 'sLORETA'

                    case 'eLORETA'

                    case 'LORETA'

                    case 'LAURA'

                    case 'STARTS'
                        [Source_Estimate, ~] = seal_STARTS(app.Data, ...
                            app.LeadField_fixed{app.CurrentProtocolNum}.Gain,...
                            app.Cortex{app.CurrentProtocolNum},...
                            'NoiseCovariance',NoiseCovariance, ...
                            'NumTBFs',app.NumTBFs,...
                            'LearnNoise', app.LearnNoise,...
                            'NoiseObservationMatrix', eye(size(app.Data,1)),...
                            'NeighborOrder',app.NeighborOrder,...
                            'NumOrientations',app.NumOrientations);
                        result=Source_Estimate;
                        app.counters(7)=app.counters(7)+1;
                        app.SaveIndex = app.SaveIndex +1;

                        % 单个试验
                        results = result;

                        app.saveresults(results,app.counters(7),'STARTS')
                        currentStruct = struct();
                        currentStruct.Algrithm='STARTS';
                        currentStruct.NumTBFs=app.NumTBFs;
                        currentStruct.LearnNoise=app.LearnNoise;
                        currentStruct.NoiseObservationMatrix=eye(size(app.Data,1));
                        currentStruct.NeighborOrder=app.NeighborOrder;
                        currentStruct.NumOrientations=app.NumOrientations;
                        processingInfo{end+1}=currentStruct;
                    case 'BlockChampagne'
                        [Source_Estimate, ~] = seal_BlockChampagne(Data_scaled, ...
                            app.LeadField_fixed{app.CurrentProtocolNum}.Gain, ...
                            app.Cortex{app.CurrentProtocolNum},...  
                            'NoiseCovariance',C_noise_scaled, ...
                            'LearnNoise', app.LearnNoise,...
                            'NeighborOrder',app.NeighborOrder,...
                            'NumOrientations',app.NumOrientations ,...
                            'NoiseObservationMatrix', eye(size(app.Data,1)));

                        result=Source_Estimate;
                        app.counters(8)=app.counters(8)+1;
                        app.SaveIndex = app.SaveIndex +1;
                        results = result;
                        app.saveresults(results,app.counters(8),'BlockChampagne');
                        currentStruct = struct();
                        currentStruct.Algrithm='BlockChampagne';
                        currentStruct.LearnNoise=app.LearnNoise;
                        currentStruct.NoiseObservationMatrix=eye(size(app.Data,1));
                        currentStruct.NeighborOrder=app.NeighborOrder;
                        currentStruct.NumOrientations=app.NumOrientations;
                        processingInfo{end+1}=currentStruct;
                    case 'uSTAR'
                        [Source_Estimate, ~] = seal_uSTAR(Data_scaled, ...
                            app.LeadField_fixed{app.CurrentProtocolNum}.Gain, ...
                            app.Cortex{app.CurrentProtocolNum},...
                            'RunAlgorithm',app.uSTARRunAlgorithm,...
                            'MicrostateMaps',app.MicrostateMaps);
                        result=Source_Estimate;
                        app.counters(9)=app.counters(9)+1;
                        app.SaveIndex = app.SaveIndex +1;
                        results = result;
                        app.saveresults(results,app.counters(9),'uSTAR');
                        currentStruct = struct();
                        currentStruct.Algrithm='uSTAR';
                        currentStruct.RunAlgorithm=app.uSTARRunAlgorithm;
                        currentStruct.MicrostateMaps=app.MicrostateMaps;
                        processingInfo{end+1}=currentStruct;
                end
            end

       
            app.Callingapp.protocolnode{app.protocolinfo{app.CurrentProtocolNum}.No}.NodeData.ResultData = app.resultdata;
            app.Callingapp.protocolnode{app.protocolinfo{app.CurrentProtocolNum}.No}.NodeData.ResultPath = SavePath;
            protocolPath = app.Callingapp.protocolnode{app.protocolinfo{app.CurrentProtocolNum}.No}.NodeData.protocolPath;
            appendDataToMat(protocolPath,'ESIAlgrithms',processingInfo);
            infoFilePath = fullfile(protocolPath, 'protocol_info.mat');
            protocolInfo=app.Callingapp.protocolnode{app.protocolinfo{app.CurrentProtocolNum}.No}.NodeData;
            save(infoFilePath, 'protocolInfo');


        end

        % Selection changed function: DataTree
        function DataTreeSelectionChanged(app, event)
            selectedNodes = app.DataTree.SelectedNodes;
            data=load(selectedNodes.NodeData);
            app.CurrentID = selectedNodes.Text;
            if isfield(data,'data')
                data=data.data;
            elseif isfield(data,'Data')
                data=data.Data;
            end
            if ndims(data)==3
                app.trials = size(data,3);
                data= mean(data, 3);
            else
                app.trials = 1;
            end
            app.Data = data;
        end

        % Value changed function: DipoleOrienationtDropDown
        function DipoleOrienationtDropDownValueChanged(app, event)
            value = app.DipoleOrienationtDropDown.Value;
            if strcmp(value,'fixed')
                app.NumOrientations=1;
            elseif strcmp(value,'free')
                app.NumOrientations=3;
            end
        end

        % Value changed function: DepthWeightingEditField
        function DepthWeightingEditFieldValueChanged(app, event)
            value = app.DepthWeightingEditField.Value;
            app.DepthWeightExp=value;
        end

        % Value changed function: NoiseWhiteningDropDown
        function NoiseWhiteningDropDownValueChanged(app, event)
            value = app.NoiseWhiteningDropDown.Value;
            if strcmp(value,'identity')
                app.Noisetag=1;
            elseif strcmp(value,'data_cov')
                app.Noisetag=0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GeneralSettingsPanel = uipanel(app.UIFigure);
            app.GeneralSettingsPanel.Title = 'General Settings';
            app.DipoleOrienationtDropDownLabel = uilabel(app.GeneralSettingsPanel);
            app.DipoleOrienationtDropDownLabel.Text = 'Dipole Orienationt';
            app.DipoleOrienationtDropDown = uidropdown(app.GeneralSettingsPanel);
            app.DipoleOrienationtDropDown.Items = {'fixed', 'free'};
            app.DipoleOrienationtDropDown.ValueChangedFcn = createCallbackFcn(app, @DipoleOrienationtDropDownValueChanged, true);
            app.DipoleOrienationtDropDown.Value = 'fixed';
            app.DepthWeightingEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.DepthWeightingEditFieldLabel.Text = 'Depth Weighting';
            app.DepthWeightingEditField = uieditfield(app.GeneralSettingsPanel, 'numeric');
            app.DepthWeightingEditField.ValueChangedFcn = createCallbackFcn(app, @DepthWeightingEditFieldValueChanged, true);
            app.DepthWeightingEditField.Value = 0.5;
            app.NoiseWhiteningDropDown_2Label = uilabel(app.GeneralSettingsPanel);
            app.NoiseWhiteningDropDown_2Label.Text = 'Noise Whitening';
            app.NoiseWhiteningDropDown = uidropdown(app.GeneralSettingsPanel);
            app.NoiseWhiteningDropDown.Items = {'identity', 'data_cov', ''};
            app.NoiseWhiteningDropDown.ValueChangedFcn = createCallbackFcn(app, @NoiseWhiteningDropDownValueChanged, true);
            app.NoiseWhiteningDropDown.Value = 'identity';
            app.LoadPriorButton = uibutton(app.GeneralSettingsPanel, 'push');
            app.LoadPriorButton.Text = 'Load Prior';
            app.SelectPriorButton = uibutton(app.GeneralSettingsPanel, 'push');
            app.SelectPriorButton.Text = 'Select Prior';
            app.RunSelectedAlgorithmsButton = uibutton(app.GeneralSettingsPanel, 'push');
            app.RunSelectedAlgorithmsButton.ButtonPushedFcn = createCallbackFcn(app, @RunSelectedAlgorithmsButtonPushed, true);
            app.RunSelectedAlgorithmsButton.Text = 'Run Selected Algorithms';
            app.SelectLeadFieldPanel = uipanel(app.UIFigure);
            app.SelectLeadFieldPanel.Title = 'Select LeadField';
            app.LeadFieldDropDown = uidropdown(app.SelectLeadFieldPanel);
            app.LeadFieldDropDown.Items = {};
            app.LeadFieldDropDown.Value = {};
            app.AlgorithmsPanel = uipanel(app.UIFigure);
            app.AlgorithmsPanel.Title = 'Algorithms';
            app.AlgorithmsTree = uitree(app.AlgorithmsPanel, 'checkbox');
            app.MinimumL2_NormNode = uitreenode(app.AlgorithmsTree);
            app.MinimumL2_NormNode.Text = 'Minimum L2_Norm';
            app.MNENode = uitreenode(app.MinimumL2_NormNode);
            app.MNENode.NodeData = 'MNE';
            app.MNENode.Text = 'MNE';
            app.dSPMNode = uitreenode(app.MinimumL2_NormNode);
            app.dSPMNode.NodeData = 'dSPM';
            app.dSPMNode.Text = 'dSPM';
            app.sLORETANode = uitreenode(app.MinimumL2_NormNode);
            app.sLORETANode.NodeData = 'sLORETA';
            app.sLORETANode.Text = 'sLORETA';
            app.eLORETANode = uitreenode(app.MinimumL2_NormNode);
            app.eLORETANode.NodeData = 'eLORETA';
            app.eLORETANode.Text = 'eLORETA';
            app.LORETANode = uitreenode(app.MinimumL2_NormNode);
            app.LORETANode.NodeData = 'LORETA';
            app.LORETANode.Text = 'LORETA';
            app.LAURANode = uitreenode(app.MinimumL2_NormNode);
            app.LAURANode.NodeData = 'LAURA';
            app.LAURANode.Text = 'LAURA';
            app.BeamformerNode = uitreenode(app.AlgorithmsTree);
            app.BeamformerNode.Text = 'Beamformer';
            app.LCMVNode = uitreenode(app.BeamformerNode);
            app.LCMVNode.Text = 'LCMV';
            app.Model_CovNode = uitreenode(app.LCMVNode);
            app.Model_CovNode.Text = 'Model_Cov';
            app.DICSNode = uitreenode(app.BeamformerNode);
            app.DICSNode.Text = 'DICS';
            app.MCMVNode = uitreenode(app.BeamformerNode);
            app.MCMVNode.Text = 'MCMV';
            app.SparsemodelNode = uitreenode(app.AlgorithmsTree);
            app.SparsemodelNode.Text = 'Sparse model';
            app.Lp_LqNode = uitreenode(app.SparsemodelNode);
            app.Lp_LqNode.Text = 'Lp_Lq';
            app.ChampagneNode = uitreenode(app.SparsemodelNode);
            app.ChampagneNode.Text = 'Champagne';
            app.fullstructurenoiseNode = uitreenode(app.ChampagneNode);
            app.fullstructurenoiseNode.Text = 'full-structure noise';
            app.diagonalnoiselearningNode = uitreenode(app.ChampagneNode);
            app.diagonalnoiselearningNode.Text = 'diagonal noise learning';
            app.addspatialbasisNode = uitreenode(app.ChampagneNode);
            app.addspatialbasisNode.Text = 'add spatial basis';
            app.BlkChamNode = uitreenode(app.SparsemodelNode);
            app.BlkChamNode.Text = 'Blk-Cham';
            app.add_priorNode = uitreenode(app.BlkChamNode);
            app.add_priorNode.NodeData = 'BlockChampagne';
            app.add_priorNode.Text = 'add_prior';
            app.TSChamNode = uitreenode(app.SparsemodelNode);
            app.TSChamNode.Text = 'TS-Cham';
            app.SmoothChamNode = uitreenode(app.SparsemodelNode);
            app.SmoothChamNode.Text = 'Smooth-Cham';
            app.IRESNode = uitreenode(app.SparsemodelNode);
            app.IRESNode.Text = 'IRES';
            app.SISSY_L1Node = uitreenode(app.SparsemodelNode);
            app.SISSY_L1Node.Text = 'SISSY_L1';
            app.FOCUSSNode = uitreenode(app.SparsemodelNode);
            app.FOCUSSNode.Text = 'FOCUSS';
            app.SpatioTemporalNode = uitreenode(app.AlgorithmsTree);
            app.SpatioTemporalNode.Text = 'SpatioTemporal';
            app.MxNENode = uitreenode(app.SpatioTemporalNode);
            app.MxNENode.Text = 'MxNE';
            app.irMxNENode = uitreenode(app.SpatioTemporalNode);
            app.irMxNENode.Text = 'irMxNE';
            app.SISSY_L21Node = uitreenode(app.SpatioTemporalNode);
            app.SISSY_L21Node.Text = 'SISSY_L21';
            app.VSSI_LpNode = uitreenode(app.SpatioTemporalNode);
            app.VSSI_LpNode.Text = 'VSSI_Lp';
            app.FastIRESNode = uitreenode(app.SpatioTemporalNode);
            app.FastIRESNode.Text = 'Fast-IRES';
            app.STOUTNode = uitreenode(app.SpatioTemporalNode);
            app.STOUTNode.Text = 'STOUT';
            app.dMAPNode = uitreenode(app.SpatioTemporalNode);
            app.dMAPNode.Text = 'dMAP';
            app.BESTIESNode = uitreenode(app.SpatioTemporalNode);
            app.BESTIESNode.Text = 'BESTIES';
            app.SISTBFNode = uitreenode(app.SpatioTemporalNode);
            app.SISTBFNode.Text = 'SI-STBF';
            app.uSTARNode = uitreenode(app.SpatioTemporalNode);
            app.uSTARNode.NodeData = 'uSTAR';
            app.uSTARNode.Text = 'uSTAR';
            app.STARTSNode = uitreenode(app.SpatioTemporalNode);
            app.STARTSNode.NodeData = 'STARTS';
            app.STARTSNode.Text = 'STARTS';
            app.sSTARTSNode = uitreenode(app.SpatioTemporalNode);
            app.sSTARTSNode.Text = 'sSTARTS';
            app.VSSI_GGDNode = uitreenode(app.SpatioTemporalNode);
            app.VSSI_GGDNode.Text = 'VSSI_GGD';
            % Assign Checked Nodes
            app.AlgorithmsTree.CheckedNodesChangedFcn = createCallbackFcn(app, @AlgorithmsTreeCheckedNodesChanged, true);
            app.SelectedDataPanel = uipanel(app.UIFigure);
            app.SelectedDataPanel.Title = 'Selected Data';
            app.DataTree = uitree(app.SelectedDataPanel);
            app.DataTree.SelectionChangedFcn = createCallbackFcn(app, @DataTreeSelectionChanged, true);
            app.SelectCortexPanel = uipanel(app.UIFigure);
            app.SelectCortexPanel.Title = 'Select Cortex';
            app.CortexDropDown = uidropdown(app.SelectCortexPanel);
            app.CortexDropDown.Items = {};
            app.CortexDropDown.Value = {};
            app.SelectProtocolPanel = uipanel(app.UIFigure);
            app.SelectProtocolPanel.Title = 'Select Protocol';
            app.ProtocolDropDown = uidropdown(app.SelectProtocolPanel);
            app.ProtocolDropDown.Items = {};
            app.ProtocolDropDown.ValueChangedFcn = createCallbackFcn(app, @ProtocolDropDownValueChanged, true);
            app.ProtocolDropDown.Value = {};
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ImportDataMenu = uimenu(app.ContextMenu);
            app.ImportDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportDataMenuSelected, true);
            app.ImportDataMenu.Text = 'Import Data';
            % Assign app.ContextMenu
            app.DataTree.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SEAL_selectAlgorithm(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end