classdef SEAL_showCortex_result < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TimePointEditField       matlab.ui.control.NumericEditField
        EditFieldLabel           matlab.ui.control.Label
        SelectionModePanel       matlab.ui.container.Panel
        MultipleButton           matlab.ui.control.StateButton
        SingleButton             matlab.ui.control.StateButton
        ColorPanel               matlab.ui.container.Panel
        ModelLabel               matlab.ui.control.Label
        BackgroundLabel          matlab.ui.control.Label
        ModolColorDropDown       matlab.ui.control.DropDown
        BackgroundColorDropDown  matlab.ui.control.DropDown
        colorOKButton            matlab.ui.control.Button
        BEditField               matlab.ui.control.NumericEditField
        BEditFieldLabel          matlab.ui.control.Label
        GEditField               matlab.ui.control.NumericEditField
        GEditFieldLabel          matlab.ui.control.Label
        REditField               matlab.ui.control.NumericEditField
        REditFieldLabel          matlab.ui.control.Label
        RegionsPanel             matlab.ui.container.Panel
        AtlasDrop                matlab.ui.control.DropDown
        RegionTree               matlab.ui.container.CheckBoxTree
        RegionsNode              matlab.ui.container.TreeNode
        ControlPanel             matlab.ui.container.Panel
        ResetButton              matlab.ui.control.Button
        StructureButton          matlab.ui.control.Button
        RightButton              matlab.ui.control.Button
        LeftButton               matlab.ui.control.Button
        TranspSlider             matlab.ui.control.Slider
        TranspLabel              matlab.ui.control.Label
        SmoothSlider             matlab.ui.control.Slider
        SmoothLabel              matlab.ui.control.Label
        Transpedit               matlab.ui.control.NumericEditField
        smoothedit               matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        CallingApp
        cortexData
        smoothvalue=30
        smoothedVC
        transp = 1          %透明度
        handles1            %弹窗图像句柄
        isClosing = false;  % 标记是否正在关闭中
        hemisphere=''
        plothemIdx
        originalVertexColors
        originalVertices
        originalAxisLimits
        OffsetVertices ={}   %每个region的vertices
        sepVertices          %存储strcture后的vertices
        sepregionVertices={}  %存储strcture后的每个region的vertices
        alreadystructure = 0 % 0 未structure,1 已structure
        selectedVertices
        selectedVerticesNum = 0
        highlightedVertices=struct('vertexIndex', 0, 'originalColors', []);
        colorset
        AtlasIdx = 1
        ResultData

        TrialIndices
    end

    properties (Access = public)
        Poriod
        PoriodData
    end


    methods (Access = private)

        function RightorLeft(app , h )
            if isfield(app.handles1, 'Multimarker') && ~isempty(app.handles1.Multimarker)
                for i = 1:length(app.handles1.Multimarker)
                    if isvalid(app.handles1.Multimarker(i))
                        delete(app.handles1.Multimarker(i));
                    end
                end
                app.handles1.Multimarker = [];
            end
            % 清除旧标记
            if isfield(app.handles1, 'singlemarker')
                delete(app.handles1.singlemarker);
            end
            vc=app.cortexData.Vertices;
            app.originalVertices = vc;
            alphaValue = app.transp;
            tIdx = strcmp('Structures',string(struct2table(app.cortexData.Atlas).Name));
            switch h
                case 'reset'
                    Valpha = ones(size(vc,1),1)*alphaValue;
                    app.plothemIdx = 1:size(vc,1);
                    if isfield(app.handles1, 'regionPatches') && ~isempty(app.handles1.regionPatches)
                        for i = 1:length(app.handles1.regionPatches)
                            if isvalid(app.handles1.regionPatches(i))
                                delete(app.handles1.regionPatches(i));
                            end
                        end
                        app.handles1.regionPatches = [];
                    end
                    app.RegionTree.CheckedNodes=[];
                    set(app.handles1.hp, 'FaceVertexAlphaData', Valpha, 'Vertices', app.smoothedVC, ...
                        'FaceColor',[0.9,0.7,0.7]);
                    app.alreadystructure = 0 ;
                case'structrue'
                    if app.alreadystructure==1
                        return
                    end
                    app.plothemIdx = 1:size(vc,1);
                    Valpha = ones(size(vc,1),1)*alphaValue;

                    % === 同时显示左右脑分区，间距拉大 ===
                    % 获取左右脑顶点索引
                    leftIdx = app.cortexData.Atlas(tIdx).Scouts(1).Vertices;
                    rightIdx = app.cortexData.Atlas(tIdx).Scouts(2).Vertices;

                    % 计算分离距离（大脑宽度的30%）
                    separationDistance = range(app.originalVertices(:,2)) * 0.5;

                    % 计算移动向量
                    leftMove = [0, separationDistance/2, 0];
                    rightMove = [0,-separationDistance/2 , 0];

                    % 创建分离后的顶点位置
                    separatedVertices = app.smoothedVC;
                    separatedVertices(leftIdx, :) = separatedVertices(leftIdx, :) + leftMove;
                    separatedVertices(rightIdx, :) = separatedVertices(rightIdx, :) + rightMove;
                    set(app.handles1.hp, 'Vertices', separatedVertices, 'FaceVertexAlphaData', Valpha);
                    app.sepVertices=separatedVertices;
                    %------------------------如果已经有region显示--------------------------------
                    if ~isempty(app.RegionTree.CheckedNodes)

                        checkednodes=app.RegionTree.CheckedNodes;
                        %剔除父节点
                        indice = find(checkednodes == app.RegionsNode);
                        if ~isempty(indice)
                            checkednodes(indice) = [];
                        end

                        alphaData = ones(size(vc,1), 1)*0.4;
                        for i = 1:length(checkednodes)
                            nodeIndex=checkednodes(i).NodeData;
                            separatedVertices = app.OffsetVertices{nodeIndex};
                            separatedVertices(leftIdx, :) = separatedVertices(leftIdx, :) + leftMove;
                            separatedVertices(rightIdx, :) = separatedVertices(rightIdx, :) + rightMove;
                            set(app.handles1.regionPatches(nodeIndex), 'Vertices', separatedVertices, ...
                                'FaceVertexAlphaData', alphaData);
                            app.sepregionVertices{nodeIndex}= separatedVertices;
                        end
                    end

                    %----------------------------------------------------------------------------

                    % 计算新顶点范围
                    minVals = min(separatedVertices);
                    maxVals = max(separatedVertices);

                    % 计算扩展范围（增加10%的边界）
                    rangeX = maxVals(1) - minVals(1);
                    rangeY = maxVals(2) - minVals(2);
                    rangeZ = maxVals(3) - minVals(3);

                    paddingX = rangeX * 0.1;
                    paddingY = rangeY * 0.1;
                    paddingZ = rangeZ * 0.1;

                    newLimits = [minVals(1)-paddingX, maxVals(1)+paddingX, ...
                        minVals(2)-paddingY, maxVals(2)+paddingY, ...
                        minVals(3)-paddingZ, maxVals(3)+paddingZ];

                    % 设置新坐标轴范围
                    axis(app.handles1.axes, newLimits);
                case 'left'

                    app.plothemIdx = app.cortexData.Atlas(tIdx).Scouts(1).Vertices;
                    Valpha = zeros(size(vc,1),1);
                    Valpha(app.plothemIdx) = alphaValue;
                    if app.alreadystructure==1
                        set(app.handles1.hp, 'Vertices', app.sepVertices, 'FaceVertexAlphaData', Valpha, ...
                            'facealpha', 'interp','AlphaDataMapping','none');
                    else
                        set(app.handles1.hp, 'Vertices', app.smoothedVC, 'FaceVertexAlphaData', Valpha, ...
                            'facealpha', 'interp','AlphaDataMapping','none');
                    end
                    %------------------------如果已经有region显示--------------------------------
                    if ~isempty(app.RegionTree.CheckedNodes)
                        checkednodes=app.RegionTree.CheckedNodes;
                        for i = 1:length(checkednodes)
                            nodeIndex=checkednodes(i).NodeData;
                            Valpha(app.plothemIdx) = 0.4;
                            if app.alreadystructure==1
                                set(app.handles1.regionPatches(nodeIndex),'Vertices', app.sepregionVertices{nodeIndex}, ...
                                    'FaceVertexAlphaData', Valpha);
                            else
                                set(app.handles1.regionPatches(nodeIndex),'Vertices', app.OffsetVertices{nodeIndex}, ...
                                    'FaceVertexAlphaData', Valpha);
                            end

                        end
                    end
                    %----------------------------------------------------------------------------


                case 'right'
                    app.plothemIdx = app.cortexData.Atlas(tIdx).Scouts(2).Vertices;
                    Valpha = zeros(size(vc,1),1);
                    Valpha(app.plothemIdx) = alphaValue;
                    if app.alreadystructure==1
                        set(app.handles1.hp, 'Vertices', app.sepVertices, 'FaceVertexAlphaData', Valpha, ...
                            'facealpha', 'interp','AlphaDataMapping','none');
                    else
                        set(app.handles1.hp, 'Vertices', app.smoothedVC, 'FaceVertexAlphaData', Valpha, ...
                            'facealpha', 'interp','AlphaDataMapping','none');
                    end

                    %------------------------如果已经有region显示--------------------------------
                    if ~isempty(app.RegionTree.CheckedNodes)
                        checkednodes=app.RegionTree.CheckedNodes;
                        for i = 1:length(checkednodes)
                            nodeIndex=checkednodes(i).NodeData;
                            Valpha(app.plothemIdx) = 0.4;
                            if app.alreadystructure==1
                                set(app.handles1.regionPatches(nodeIndex),'Vertices', app.sepregionVertices{nodeIndex}, ...
                                    'FaceVertexAlphaData', Valpha);
                            else
                                set(app.handles1.regionPatches(nodeIndex),'Vertices', app.OffsetVertices{nodeIndex}, ...
                                    'FaceVertexAlphaData', Valpha);
                            end
                        end
                    end
                    %----------------------------------------------------------------------------
            end
        end

        function vertexClicked(app, src, event)
            % 获取点击位置
            clickedPoint = event.IntersectionPoint;

            %  获取所有顶点坐标
            vertices = get(src, 'Vertices');

            %  找到最近的顶点
            [vertexIndex, ~] = app.findClosestVertex(vertices, clickedPoint);
            %   isCtrlPressed = ismember('control', get(app.handles1.h, 'CurrentModifier'));

            if app.MultipleButton.Value==1
                % Ctrl+点击：多选模式

                % 检查是否已经选中了该顶点
                if ismember(vertexIndex, app.selectedVertices)
                    % 如果已选中，则取消选择
                    app.selectedVertices = setdiff(app.selectedVertices, vertexIndex);
                else
                    % 如果未选中，则添加选择
                    app.selectedVertices = union(app.selectedVertices, vertexIndex);
                end


                % 高亮所有选中的顶点
                app.selectedVerticesNum = 0;
                app.highlightMultipleVertices(app.selectedVertices);
            else
                % 清除现有的多选标记
                if isfield(app.handles1, 'Multimarker') && ~isempty(app.handles1.Multimarker)
                    for i = 1:length(app.handles1.Multimarker)
                        if isvalid(app.handles1.Multimarker(i))
                            delete(app.handles1.Multimarker(i));
                        end
                    end
                    app.handles1.Multimarker = [];
                end
                % 清除旧标记
                if isfield(app.handles1, 'singlemarker')
                    delete(app.handles1.singlemarker);
                end

                app.selectedVertices=vertexIndex;
                app.addVisualMarker(vertexIndex);
                disp('vertice selected');
            end
            %  更新信息显示
            % updateSelectionInfo(app, vertexIndex, clickedPoint);
        end

        function [vertexIndex, minDist] = findClosestVertex(app, vertices, point)
            % 计算所有顶点到点击点的距离
            dists = sum((vertices - point).^2, 2);

            % 找到最近的顶点
            [minDist, vertexIndex] = min(dists);
        end

        function highlightMultipleVertices(app,vertexIndex)
            % 获取顶点位置
            vertices = get(app.handles1.hp, 'Vertices');
            % 清除现有的多选标记
            if isfield(app.handles1, 'Multimarker') && ~isempty(app.handles1.Multimarker)
                for i = 1:length(app.handles1.Multimarker)
                    if isvalid(app.handles1.Multimarker(i))
                        delete(app.handles1.Multimarker(i));
                    end
                end
                app.handles1.Multimarker = [];
            end

            % 创建球体标记
            [x, y, z] = sphere(20);
            % 4. 动态计算标记大小（基于大脑尺寸）
            brainSize = max(range(vertices));
            r = brainSize * 0.01; % 大脑尺寸的2%

            hold(app.handles1.axes, 'on');
            for i=1:length(vertexIndex)
                vertexPos = vertices(vertexIndex(i), :);
                app.selectedVerticesNum = app.selectedVerticesNum + 1 ;
                marker(app.selectedVerticesNum) = surf(app.handles1.axes, ...
                    x*r + vertexPos(1), ...
                    y*r + vertexPos(2), ...
                    z*r + vertexPos(3), ...
                    'FaceColor', 'r', ...
                    'EdgeColor', 'none', ...
                    'FaceAlpha', 0.9, ...
                    'Tag', 'SelectionMarker');
            end
            hold(app.handles1.axes, 'off');
            app.handles1.Multimarker = marker;
        end


        function addVisualMarker(app, vertexIndex)
            % 获取顶点位置
            vertices = get(app.handles1.hp, 'Vertices');
            vertexPos = vertices(vertexIndex, :);

            % 创建球体标记
            [x, y, z] = sphere(20);
            % 4. 动态计算标记大小（基于大脑尺寸）
            brainSize = max(range(vertices));
            r = brainSize * 0.01; % 大脑尺寸的2%

            hold(app.handles1.axes, 'on');

            marker = surf(app.handles1.axes, ...
                x*r + vertexPos(1), ...
                y*r + vertexPos(2), ...
                z*r + vertexPos(3), ...
                'FaceColor', 'r', ...
                'EdgeColor', 'none', ...
                'FaceAlpha', 0.9, ...
                'Tag', 'SelectionMarker');

            hold(app.handles1.axes, 'off');
            app.handles1.singlemarker = marker;
        end

        function updateSelectionInfo(app, vertexIndex, clickedPoint)
            % 更新信息显示面板

            infoText = sprintf([...
                'Vertice ID: %d\n' ...
                'Coordinate: (%.2f, %.2f, %.2f)\n'], ...
                vertexIndex, ...
                clickedPoint(1), clickedPoint(2), clickedPoint(3));

        end

        function plotAtlasRegions(app, checkednodes)
            vc = get(app.handles1.hp,'Vertices');
            tri = get(app.handles1.hp, 'Faces');


            % 清除之前的区域面片
            if isfield(app.handles1, 'regionPatches') && ~isempty(app.handles1.regionPatches)
                for i = 1:length(app.handles1.regionPatches)
                    if isvalid(app.handles1.regionPatches(i))
                        delete(app.handles1.regionPatches(i));
                    end
                end
                app.handles1.regionPatches = [];
            end

            % 获取当前坐标轴
            ax = app.handles1.axes;

            % 为每个分区创建独立面片
            scouts = app.cortexData.Atlas(app.AtlasIdx).Scouts;
            regionPatches = gobjects(length(scouts), 1);

            % 计算偏移距离（基于大脑尺寸的动态比例）
            brainSize = max(range(vc));
            offsetDistance = brainSize * 0.01; % 2% 的偏移量
            offsetVertices = vc;

            for i = 1:length(checkednodes)
                nodeIndex=checkednodes(i).NodeData;
                regionVertices = scouts(nodeIndex).Vertices;

                % 验证顶点索引
                if isempty(regionVertices)
                    fprintf('Scout %d has no vertices, skipping.\n', nodeIndex);
                    continue;
                end

                % 移除无效索引
                validIdx = regionVertices > 0 & regionVertices <= size(vc, 1);
                if sum(validIdx) ~= numel(regionVertices)
                    fprintf('Scout %d has %d invalid vertices, skipping.\n', nodeIndex, numel(regionVertices)-sum(validIdx));
                    regionVertices = regionVertices(validIdx);
                end

                if isempty(regionVertices)
                    fprintf('No valid vertices for scout %d, skipping.\n', nodeIndex);
                    continue;
                end

                % 获取包含这些顶点的面
                [FacesInRegion, localFaces] = ismember(tri, regionVertices);
                FacesInRegion = any(FacesInRegion, 2);
                regionFaces = tri(FacesInRegion, :);
                localFaces = localFaces(FacesInRegion, :);
                if isempty(regionFaces)
                    fprintf('No faces found for scout %d, skipping.\n', nodeIndex);
                    continue;
                end
                InnerIdx = all(localFaces, 2);
                EdgeVertices = localFaces(~InnerIdx, :);
                EdgeVertices = unique(EdgeVertices(:));
                EdgeVertices(EdgeVertices==0) = [];
                scoutNormals = app.cortexData.Normals(regionVertices, :);
                offsetVertices(regionVertices, :) = vc(regionVertices, :) +...
                    scoutNormals* offsetDistance;
                if strcmp(app.hemisphere,'structrue')
                    app.sepregionVertices{nodeIndex}=offsetVertices;

                else
                    app.OffsetVertices{nodeIndex}=offsetVertices;

                end
                alphaData = ones(size(vc,1), 1)*0.4;
                alphaData(EdgeVertices) = 1;
                % 创建分区面片
                regionPatches(nodeIndex) = patch(ax, 'Vertices', offsetVertices, 'Faces', regionFaces, ...
                    'FaceVertexCData', repmat(scouts(nodeIndex).Color, size(vc,1), 1), 'FaceColor', 'flat',...
                    'EdgeColor', 'none', 'alphaDataMapping','none', 'edgeAlpha','interp', ...
                    'FaceAlpha', 'flat', 'FaceVertexAlphaData', alphaData,...  % 增加透明度以便看到底层
                    'Tag', sprintf('RegionPatch_%d', nodeIndex),'facelighting','gouraud'...
                    ,'specularstrength',0.2,'ambientstrength',0.5,'diffusestrength',0.5,...
                    'BackfaceLighting','lit','AmbientStrength',0.5,'SpecularExponent',1,...
                    'SpecularColorReflectance',0.5,'EdgeLighting','gouraud');
                set(regionPatches(nodeIndex), 'ButtonDownFcn', @(src, event) vertexClicked(app, src, event));
                material dull
                if strcmp(app.hemisphere,'right') || strcmp(app.hemisphere,'left')
                    Valpha = app.getAlphaVelue(app.hemisphere);
                    set(regionPatches(nodeIndex), 'FaceVertexAlphaData', Valpha);
                end
            end

            % 保存区域面片句柄
            app.handles1.regionPatches = regionPatches;
        end

        function Valpha = getAlphaVelue(app,side)
            vc=app.cortexData.Vertices;
            tIdx = strcmp('Structures',string(struct2table(app.cortexData.Atlas).Name));
            if strcmp(side,'right')
                app.plothemIdx = app.cortexData.Atlas(tIdx).Scouts(2).Vertices;
            else
                app.plothemIdx = app.cortexData.Atlas(tIdx).Scouts(1).Vertices;
            end
            Valpha = zeros(size(vc,1),1);
            Valpha(app.plothemIdx) = 0.4;
        end

        function closeFigureCallback(app, ~, ~)
            % 防止递归调用
            if app.isClosing, return; end

            % 标记关闭状态
            app.isClosing = true;
            if isfield(app.handles1, 'h') && isvalid(app.handles1.h)
                delete(app.handles1.h);
            end
            % 关闭 App
            idx = cellfun(@(x) isequal(x, app), app.CallingApp.DialogApp_showResult);
            app.CallingApp.DialogApp_showResult(idx) = [];

            delete(app.UIFigure);
        end

        %% 关闭 App 的回调函数
        function closeAppCallback(app, ~, ~)
            % 防止递归调用
            if app.isClosing, return; end

            % 标记关闭状态
            app.isClosing = true;
            % 关闭图像窗口
            if isfield(app.handles1, 'h') && isvalid(app.handles1.h)

                delete(app.handles1.h);
            end
            idx = cellfun(@(x) isequal(x, app), app.CallingApp.DialogApp_showResult);
            app.CallingApp.DialogApp_showResult(idx) = [];
            % 关闭 App
            delete(app.UIFigure);
        end

        function concatenated_data = concatenate_trials(app, data_3d)
            % 输入：data_3d - 三维数组（通道数 × 数据点 × 试次）
            % 输出：concatenated_data - 二维拼接矩阵（通道数 × 总数据点）
            % 说明：试次索引信息将存储在 app.TrialIndices 属性中

            % 获取数组尺寸
            [n_channels, n_points_per_trial, n_trials] = size(data_3d);

            % 预分配拼接后的二维矩阵
            total_points = n_points_per_trial * n_trials;
            concatenated_data = zeros(n_channels, total_points);

            % 初始化索引记录结构体
            app.TrialIndices = struct(...
                'trial_number', num2cell(1:n_trials), ...
                'start_index', num2cell(zeros(1, n_trials)), ...
                'end_index', num2cell(zeros(1, n_trials)) ...
                );


            % 循环处理每个试次
            for trial = 1:n_trials
                % 计算当前试次的起始和结束索引
                start_idx = (trial - 1) * n_points_per_trial + 1;
                end_idx = trial * n_points_per_trial;

                % 提取当前试次数据
                current_trial_data = data_3d(:, :, trial);

                % 将数据放入拼接矩阵
                concatenated_data(:, start_idx:end_idx) = current_trial_data;

                % 记录索引信息
                app.TrialIndices(trial).start_index = start_idx;
                app.TrialIndices(trial).end_index = end_idx;
            end

            % 添加元数据到索引结构
            app.TrialIndices(1).n_channels = n_channels;
            app.TrialIndices(1).points_per_trial = n_points_per_trial;
            app.TrialIndices(1).total_trials = n_trials;
            app.TrialIndices(1).total_points = total_points;
        end


    end


    methods (Access = public)

        function TimePointValueChanged(app)
            % disp(round(app.TimePointEditField.Value*str2double(app.CallingApp.SamplingRateValue.Text)))
            delete(app.handles1.h);
            screenSize = get(0, 'ScreenSize');
            app.handles1=PlotSource(app.ResultData(:,round(app.TimePointEditField.Value*str2double(app.CallingApp.SamplingRateValue.Text))), ...
                app.cortexData,'smooth',app.smoothvalue,'Position',[screenSize(3)*0.3,screenSize(4)*0.4 800, 600]);
            set(app.handles1.hp, 'ButtonDownFcn', @(src, event) vertexClicked(app, src, event));

            % 设置双向关闭回调
            % 当图像窗口关闭时关闭 App
            set(app.handles1.h, 'CloseRequestFcn', @(src, evt) app.closeAppCallback(src, evt));
        end


        function datachanged(app)
            delete(app.handles1.h);
            screenSize = get(0, 'ScreenSize');
            subMatrix = app.ResultData(:, app.Poriod(1):app.Poriod(2));

            % 计算每行的平方和
            app.PoriodData = sum(subMatrix.^2, 2);
            app.handles1=PlotSource(app.PoriodData, ...
                app.cortexData,'smooth',app.smoothvalue,'Position',[screenSize(3)*0.3,screenSize(4)*0.4 800, 600]);
            set(app.handles1.hp, 'ButtonDownFcn', @(src, event) vertexClicked(app, src, event));

            % 设置双向关闭回调
            % 当图像窗口关闭时关闭 App
            set(app.handles1.h, 'CloseRequestFcn', @(src, evt) app.closeAppCallback(src, evt));
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Cortexdata, ResultData)
            addpath(genpath('CoreFunctions'));
            app.UIFigure.Name='Show Brain Model';
            app.CallingApp=mainapp;
            app.cortexData=Cortexdata;
            app.SingleButton.Value=1;
            app.MultipleButton.Value=0;
            if ndims(ResultData)==3
                app.ResultData = app.concatenate_trials(ResultData);
            else
                app.ResultData = ResultData;
            end
            screenSize = get(0, 'ScreenSize');
            app.UIFigure.Position=[screenSize(3)*0.3+800,screenSize(4)*0.4+274,480 326];
            app.BEditField.Visible='off';app.GEditField.Visible='off';
            app.REditField.Visible='off';app.colorOKButton.Visible='off';
            app.BEditFieldLabel.Visible='off';app.GEditFieldLabel.Visible='off';
            app.REditFieldLabel.Visible='off';
            disp(['Cortexdata Successfully loaded ', num2str(length(fieldnames(app.cortexData))), ...
                ' variables from the MainApp']);
            app.SmoothSlider.Value=30;app.smoothedit.Value=30;%初始化smooth
            app.TranspSlider.Value=1;app.Transpedit.Value=1;%初始化transp
            app.TimePointEditField.Value=19;
            app.TimePointEditField.ValueChangedFcn = createCallbackFcn(app, @TimePointValueChanged, true);
            app.handles1=PlotSource(app.ResultData(:,app.TimePointEditField.Value) , app.cortexData ,'smooth',app.smoothvalue, ...
                'Position',[screenSize(3)*0.3,screenSize(4)*0.4 800, 600]);
            app.transp = 1;
            app.plothemIdx = 1:size(app.cortexData.Vertices,1);
            app.smoothedVC=getSmoothedVertices(app.cortexData.Vertices, app.cortexData.VertConn, app.smoothvalue);
            % 1. 确保已有大脑图形对象
            if ~isfield(app.handles1, 'hp') || ~isvalid(app.handles1.hp)
                error('Brain patch object not found or invalid');
            end


            %---------atlas-----------------------
            if isfield(app.cortexData, 'Atlas')
                num_als = numel(app.cortexData.Atlas);
                if num_als > 0
                    scouts = app.cortexData.Atlas(1).Scouts;
                    for i = 1:length(scouts)
                        regionNames = scouts(i).Region;
                        node = uitreenode(app.RegionsNode);
                        node.Text=regionNames;
                        node.NodeData = i;
                    end
                    app.RegionTree.CheckedNodes = [];
                    expand(app.RegionsNode);
                    als_list = {}; als_data = [];
                    for ials = 1:num_als
                        als_list{ials} = app.cortexData.Atlas(ials).Name;
                        als_data(ials) = ials;
                    end
                    app.AtlasDrop.Items = als_list;
                    app.AtlasDrop.ItemsData = als_data;

                end


            end
            %-------------------------------------------------------

            set(app.handles1.hp, 'ButtonDownFcn', @(src, event) vertexClicked(app, src, event));

            % 设置双向关闭回调
            % 当图像窗口关闭时关闭 App
            set(app.handles1.h, 'CloseRequestFcn', @(src, evt) app.closeAppCallback(src, evt));

            % 当 App 关闭时关闭图像窗口
            set(app.UIFigure, 'CloseRequestFcn', @(src, evt) app.closeFigureCallback(src, evt));
        end

        % Value changed function: SmoothSlider
        function SmoothSliderValueChanged(app, event)
            %传递新smoothvalue
            app.smoothvalue = app.SmoothSlider.Value;
            app.smoothedit.Value=app.SmoothSlider.Value;
            %计算新的顶点坐标
            app.smoothedVC=getSmoothedVertices(app.cortexData.Vertices, app.cortexData.VertConn, app.smoothvalue);
            %判断顶点坐标计算是否正确
            if any(isnan(app.smoothedVC(:))) || any(isinf(app.smoothedVC(:)))
                error('The smoothed vertices contain NaN or Inf and cannot be rendered');
            end
            % 更新顶点，不新建窗口
            set(app.handles1.hp, 'Vertices', app.smoothedVC);


        end

        % Value changed function: smoothedit
        function smootheditValueChanged(app, event)
            %传递新smoothvalue
            app.smoothvalue=app.smoothedit.Value;
            app.SmoothSlider.Value=app.smoothedit.Value;
            %计算新的顶点坐标
            app.smoothedVC=getSmoothedVertices(app.cortexData.Vertices, app.cortexData.VertConn, app.smoothvalue);
            %判断顶点坐标计算是否正确
            if any(isnan(app.smoothedVC(:))) || any(isinf(app.smoothedVC(:)))
                error('The smoothed vertices contain NaN or Inf and cannot be rendered');
            end
            % 更新顶点，不新建窗口
            set(app.handles1.hp, 'Vertices', app.smoothedVC);

        end

        % Value changed function: TranspSlider
        function TranspSliderValueChanged(app, event)
            app.transp = app.TranspSlider.Value;
            app.Transpedit.Value = app.TranspSlider.Value;
            Valpha = zeros(size(app.smoothedVC, 1), 1);
            Valpha(app.plothemIdx) = app.transp;
            set(app.handles1.hp, 'FaceVertexAlphaData', Valpha, ...
                'facealpha', 'interp' ,'AlphaDataMapping','none');
            %             set(app.handles1.hp, 'facealpha', app.transp);
        end

        % Value changed function: Transpedit
        function TranspeditValueChanged(app, event)
            app.transp = app.Transpedit.Value;
            app.TranspSlider.Value   =  app.Transpedit.Value;
            Valpha = zeros(size(app.smoothedVC, 1), 1);
            Valpha(app.plothemIdx) = app.transp;
            set(app.handles1.hp, 'FaceVertexAlphaData', Valpha, ...
                'facealpha', 'interp' ,'AlphaDataMapping','none');
            %             set(app.handles1.hp, 'facealpha', app.transp);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)

            app.hemisphere='right';
            app.RightorLeft(app.hemisphere);
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)

            app.hemisphere='left';
            app.RightorLeft(app.hemisphere);
        end

        % Button pushed function: StructureButton
        function StructureButtonPushed(app, event)

            app.hemisphere='structrue';

            app.RightorLeft(app.hemisphere);

            app.alreadystructure =1;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)

            app.hemisphere='reset';
            app.RightorLeft(app.hemisphere);
        end

        % Callback function: RegionTree
        function RegionTreeCheckedNodesChanged(app, event)
            checkedNodes = app.RegionTree.CheckedNodes;
            indice = find(checkedNodes == app.RegionsNode);
            if ~isempty(indice)
                checkedNodes(indice) = [];
            end
            app.plotAtlasRegions(checkedNodes);
        end

        % Value changed function: BackgroundColorDropDown
        function BackgroundColorDropDownValueChanged(app, event)
            value = app.BackgroundColorDropDown.Value;

            switch value
                case 'Costom'
                    app.BEditField.Visible='on';app.GEditField.Visible='on';
                    app.REditField.Visible='on';app.colorOKButton.Visible='on';
                    app.BEditFieldLabel.Visible='on';app.GEditFieldLabel.Visible='on';
                    app.REditFieldLabel.Visible='on';
                    app.colorset='background';
                    if strcmp( app.ModolColorDropDown.Value,'Costom')
                        app.colorset='both';
                    end
                case 'Black'
                    set(app.handles1.axes,'color','k', ...
                        'Xcolor','k','ycolor','k', ...
                        'zcolor','k');
                    set(app.handles1.h,'color','k');
                case 'White'
                    set(app.handles1.axes,'color','w', ...
                        'Xcolor','w','ycolor','w', ...
                        'zcolor','w');
                    set(app.handles1.h,'color','w');
                case 'Red'
                    set(app.handles1.axes,'color','r', ...
                        'Xcolor','r','ycolor','r', ...
                        'zcolor','r');
                    set(app.handles1.h,'color','r');
                case 'Yellow'
                    set(app.handles1.axes,'color','y', ...
                        'Xcolor','y','ycolor','y', ...
                        'zcolor','y');
                    set(app.handles1.h,'color','y');
                case 'Green'
                    set(app.handles1.axes,'color','g', ...
                        'Xcolor','g','ycolor','g', ...
                        'zcolor','g');
                    set(app.handles1.h,'color','g');
                case 'Magenta'
                    set(app.handles1.axes,'color','m', ...
                        'Xcolor','m','ycolor','m', ...
                        'zcolor','m');
                    set(app.handles1.h,'color','m');
                case 'Blue'
                    set(app.handles1.axes,'color','b', ...
                        'Xcolor','b','ycolor','b', ...
                        'zcolor','b');
                    set(app.handles1.h,'color','b');
                case 'Cyan'
                    set(app.handles1.axes,'color','c', ...
                        'Xcolor','c','ycolor','c', ...
                        'zcolor','c');
                    set(app.handles1.h,'color','c');
            end
        end

        % Button pushed function: colorOKButton
        function colorOKButtonPushed(app, event)
            r=app.REditField.Value;
            g=app.GEditField.Value;
            b=app.BEditField.Value;
            if strcmp( app.colorset,'background')
                set(app.handles1.axes,'color',[r,g,b], ...
                    'Xcolor',[r,g,b],'ycolor',[r,g,b], ...
                    'zcolor',[r,g,b]);
                set(app.handles1.h,'color',[r,g,b]);
            elseif strcmp( app.colorset,'model')

                set(app.handles1.hp, 'facecolor', [r g b]);
            elseif strcmp( app.colorset,'both')
                set(app.handles1.axes,'color',[r,g,b], ...
                    'Xcolor',[r,g,b],'ycolor',[r,g,b], ...
                    'zcolor',[r,g,b]);
                set(app.handles1.h,'color',[r,g,b]);
                set(app.handles1.hp, 'facecolor', [r g b]);
            end
            app.BEditField.Visible='off';app.GEditField.Visible='off';
            app.REditField.Visible='off';app.colorOKButton.Visible='off';
            app.BEditFieldLabel.Visible='off';app.GEditFieldLabel.Visible='off';
            app.REditFieldLabel.Visible='off';
        end

        % Value changed function: ModolColorDropDown
        function ModolColorDropDownValueChanged(app, event)
            value = app.ModolColorDropDown.Value;

            switch value
                case 'Costom'
                    app.BEditField.Visible='on';app.GEditField.Visible='on';
                    app.REditField.Visible='on';app.colorOKButton.Visible='on';
                    app.BEditFieldLabel.Visible='on';app.GEditFieldLabel.Visible='on';
                    app.REditFieldLabel.Visible='on';
                    app.colorset='model';
                    if strcmp( app.BackgroundColorDropDown.Value,'Costom')
                        app.colorset='both';
                    end
                case 'Black'
                    set(app.handles1.hp, 'facecolor','k');
                case 'White'
                    set(app.handles1.hp, 'facecolor','w');
                case 'Red'
                    set(app.handles1.hp, 'facecolor','r');
                case 'Yellow'
                    set(app.handles1.hp, 'facecolor','y');
                case 'Green'
                    set(app.handles1.hp, 'facecolor','g');
                case 'Magenta'
                    set(app.handles1.hp, 'facecolor','m');
                case 'Blue'
                    set(app.handles1.hp, 'facecolor','b');
                case 'Cyan'
                    set(app.handles1.hp, 'facecolor','c');
            end
        end

        % Value changed function: SingleButton
        function SingleButtonValueChanged(app, event)
            if app.MultipleButton.Value==1
                app.MultipleButton.Value=0;
            elseif app.MultipleButton.Value==0
                app.MultipleButton.Value=1;
            end
        end

        % Value changed function: MultipleButton
        function MultipleButtonValueChanged(app, event)
            if app.SingleButton.Value==1
                app.SingleButton.Value=0;
            elseif app.SingleButton.Value==0
                app.SingleButton.Value=1;
            end
        end

        % Value changed function: AtlasDrop
        function AtlasDropValueChanged(app, event)
            value = app.AtlasDrop.Value;
            scouts = app.cortexData.Atlas(value).Scouts;
            app.RegionsNode.Children.delete;
            for i = 1:length(scouts)
                regionNames = scouts(i).Region;
                node = uitreenode(app.RegionsNode);
                node.Text=regionNames;
                node.NodeData = i;
            end
            expand(app.RegionsNode);
            app.AtlasIdx = value;
            RegionTreeCheckedNodesChanged(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.smoothedit = uieditfield(app.ControlPanel, 'numeric');
            app.smoothedit.ValueChangedFcn = createCallbackFcn(app, @smootheditValueChanged, true);
            app.Transpedit = uieditfield(app.ControlPanel, 'numeric');
            app.Transpedit.ValueChangedFcn = createCallbackFcn(app, @TranspeditValueChanged, true);
            app.SmoothLabel = uilabel(app.ControlPanel);
            app.SmoothLabel.Text = 'Smooth(%)';
            app.SmoothSlider = uislider(app.ControlPanel);
            app.SmoothSlider.Limits = [1 100];
            app.SmoothSlider.MajorTicks = [1 20 40 60 80 100];
            app.SmoothSlider.ValueChangedFcn = createCallbackFcn(app, @SmoothSliderValueChanged, true);
            app.SmoothSlider.Value = 1;
            app.TranspLabel = uilabel(app.ControlPanel);
            app.TranspLabel.Text = 'Transp';
            app.TranspSlider = uislider(app.ControlPanel);
            app.TranspSlider.Limits = [0 1];
            app.TranspSlider.ValueChangedFcn = createCallbackFcn(app, @TranspSliderValueChanged, true);
            app.LeftButton = uibutton(app.ControlPanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.ControlPanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.StructureButton = uibutton(app.ControlPanel, 'push');
            app.StructureButton.ButtonPushedFcn = createCallbackFcn(app, @StructureButtonPushed, true);
            app.StructureButton.Text = 'Structure';
            app.ResetButton = uibutton(app.ControlPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RegionsPanel = uipanel(app.UIFigure);
            app.RegionsPanel.Title = 'Regions';
            app.RegionTree = uitree(app.RegionsPanel, 'checkbox');
            app.RegionTree.CheckedNodesChangedFcn = createCallbackFcn(app, @RegionTreeCheckedNodesChanged, true);
            app.RegionsNode = uitreenode(app.RegionTree);
            app.RegionsNode.Text = 'Regions';
            % Assign Checked Nodes
            app.RegionTree.CheckedNodes = [app.RegionsNode];
            app.AtlasDrop = uidropdown(app.RegionsPanel);
            app.AtlasDrop.Items = {'Atlas'};
            app.AtlasDrop.ValueChangedFcn = createCallbackFcn(app, @AtlasDropValueChanged, true);
            app.AtlasDrop.Value = 'Atlas';
            app.ColorPanel = uipanel(app.UIFigure);
            app.ColorPanel.Title = 'Color';
            app.REditFieldLabel = uilabel(app.ColorPanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.ColorPanel, 'numeric');
            app.GEditFieldLabel = uilabel(app.ColorPanel);
            app.GEditFieldLabel.Text = 'G';
            app.GEditField = uieditfield(app.ColorPanel, 'numeric');
            app.BEditFieldLabel = uilabel(app.ColorPanel);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.ColorPanel, 'numeric');
            app.colorOKButton = uibutton(app.ColorPanel, 'push');
            app.colorOKButton.ButtonPushedFcn = createCallbackFcn(app, @colorOKButtonPushed, true);
            app.colorOKButton.Text = 'OK';
            app.BackgroundColorDropDown = uidropdown(app.ColorPanel);
            app.BackgroundColorDropDown.Items = {'Black', 'White', 'Red', 'Blue', 'Green', 'Yellow', 'Cyan', 'Magenta', 'Costom'};
            app.BackgroundColorDropDown.ValueChangedFcn = createCallbackFcn(app, @BackgroundColorDropDownValueChanged, true);
            app.BackgroundColorDropDown.Value = 'Black';
            app.ModolColorDropDown = uidropdown(app.ColorPanel);
            app.ModolColorDropDown.Items = {'Black', 'White', 'Red', 'Blue', 'Green', 'Yellow', 'Cyan', 'Magenta', 'Costom'};
            app.ModolColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ModolColorDropDownValueChanged, true);
            app.ModolColorDropDown.Value = 'Black';
            app.BackgroundLabel = uilabel(app.ColorPanel);
            app.BackgroundLabel.Text = 'Background';
            app.ModelLabel = uilabel(app.ColorPanel);
            app.ModelLabel.Text = 'Model';
            app.SelectionModePanel = uipanel(app.UIFigure);
            app.SelectionModePanel.Title = 'Selection Mode';
            app.SingleButton = uibutton(app.SelectionModePanel, 'state');
            app.SingleButton.ValueChangedFcn = createCallbackFcn(app, @SingleButtonValueChanged, true);
            app.SingleButton.Text = 'Single';
            app.MultipleButton = uibutton(app.SelectionModePanel, 'state');
            app.MultipleButton.ValueChangedFcn = createCallbackFcn(app, @MultipleButtonValueChanged, true);
            app.MultipleButton.Text = 'Multiple';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.TimePointEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SEAL_showCortex_result(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end