classdef SEAL_showEEG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        ImportEventDataMenu     matlab.ui.container.Menu
        FiltersMenu             matlab.ui.container.Menu
        FFTMenu                 matlab.ui.container.Menu
        LowPassMenu             matlab.ui.container.Menu
        HighPassMenu            matlab.ui.container.Menu
        BandPassMenu            matlab.ui.container.Menu
        BandSuppressionMenu     matlab.ui.container.Menu
        FiltersMenu_2           matlab.ui.container.Menu
        HistoryMenu             matlab.ui.container.Menu
        Label                   matlab.ui.control.Label
        rangeend                matlab.ui.control.Label
        rangestart              matlab.ui.control.Label
        ControlPanel            matlab.ui.container.Panel
        SelectAllButton         matlab.ui.control.Button
        ClearButton             matlab.ui.control.Button
        WinlenthEditField       matlab.ui.control.NumericEditField
        WinlenthEditFieldLabel  matlab.ui.control.Label
        TimeEditField           matlab.ui.control.NumericEditField
        TimeEditFieldLabel      matlab.ui.control.Label
        StackingmodeButton      matlab.ui.control.StateButton
        biggerButton            matlab.ui.control.Button
        smallerButton           matlab.ui.control.Button
        RRButton                matlab.ui.control.Button
        LButton                 matlab.ui.control.Button
        RButton                 matlab.ui.control.Button
        LLButton                matlab.ui.control.Button
        MarkneweventPanel       matlab.ui.container.Panel
        EventLabelEditField     matlab.ui.control.EditField
        EventNameLabel          matlab.ui.control.Label
        AddEventButton          matlab.ui.control.Button
        EventListPanel          matlab.ui.container.Panel
        EventListListBox        matlab.ui.control.ListBox
        ChennalsPanel           matlab.ui.container.Panel
        ChannelTree             matlab.ui.container.CheckBoxTree
        UIAxes                  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        eegdata         % EEG 数据
        srate = 256     % 采样率
        spacing = 20    % 通道间距
        winlength = 1   % 窗口长度（秒）
        time =   0      % 当前时间(内)
        chans = 0       % 通道数
        frames = 0      % 时间点数，后续初始化
        dispchans = 0   % 显示通道数
        elecoffset = 0  % 垂直偏移
        DialogApp
        submean= 'off'
        eloc_file       %通道文件
        YLabels         %生成通道序号
        timeStart
        timeEnd
        EventData={}
        ChannelNode={}
        newCheckedData=[]%选择显示通道的索引
        eventStrings
        duidie=1
        SaveIndex = 1;  % 文件保存计数器
        SaveIndexrowdata = 1;  % 文件保存计数器
        SavePath = 'Data'; % 默认保存路径
        CallingApp2
        TrialIndices
        datapoints
        SelectedChannel = 0;       % 当前选中的通道索引
        EventMarkers = struct('latency', {}, 'type', {}); % 事件标记      
        LineHandles = [];          % 通道线条句柄
        TextHandles = [];          % 通道标签句柄
        EventLineHandles = [];     % 事件线句柄
        EventTextHandles = [];     % 事件文本句柄
        TimeSelectionLine;         % 时间选择线
        ChannelSelectionText;      % 通道选择文本
        OriginalYLim2              %大y
        segmentInfo
        StartChan (1,1) double = 1        % 当前起始通道（从1计）
        ChannelSlider matlab.ui.control.Slider  % 垂直滚动条引用
        % === 可调的幅值缩放参数 ===
        ScaleMode char = 'percentile';   % 选项: 'none'|'std'|'robust'|'minmax'|'zscore'|'percentile'
        ScalePercentiles double = [2 98]; % 百分位缩放用到的上下界
        ScaleTarget double = [];          % 目标半幅值(纵向)，留空则按 spacing 自动确定
        SavePathchanged= 0;
        SelDebounceTimer  % 一次性定时器，用于SelectionChanged去抖
        ClickHoldTimer            % 单次定时器：判断是否进入框选
        MouseDownTic uint64 = uint64(0);%记录按下时刻（tic）
        MouseDownPoint double = []% 记录按下时坐标 [x y]
    end

    properties (Access = public)
        currentSaveIndex = 1; % 当前结构体保存索引
        historydatamanu
        filteredDataHistory = struct('data', {}, 'params', {}, 'index', {}); % 历史记录结构体数组
        SelectedTime = 0;          % 当前选中的时间点（秒）
        IsSelecting = false;           % 是否正在选择时间段
        SelectionStart = [];            % 选择开始的时间点
        SelectionEnd = [];              % 选择结束的时间点
        SelectionRectangle = [];        % 选择矩形的图形句柄
        SelectedTimeRange = [];         % 存储选择的时间范围
    end

    methods (Access = private)

        function redrawPlot(app)
            % 绑定到 UIAxes，清空现有内容
            cla(app.UIAxes, 'reset');
            hold(app.UIAxes, 'on');
            app.LineHandles = []; % 清空线条句柄
            app.TextHandles = []; % 清空文本句柄
            % 计算当前窗口数据范围（全局帧s→窗口内相对位置）
            [lowlim, highlim] = app.calcWindowRange();

            % 绘制 EEG 通道数据（上层，ZData=1）
            app.plotEEGChannels(lowlim, highlim);
            if ~isempty(app.EventData)
                for i = 1:length(app.EventData)
                    eventTime = app.EventData(i).latency;
                    % 如果事件时间在当前显示范围内
                    if eventTime >= app.timeStart && eventTime <= app.timeEnd
                        % 绘制垂直线
                        lh = line(app.UIAxes, [eventTime, eventTime], app.UIAxes.YLim, ...
                            'Color', 'g', 'LineWidth', 1.5, 'LineStyle', '--');
                        app.EventLineHandles(end+1) = lh;

                        % 绘制事件标签
                        th2 = text(app.UIAxes, eventTime, app.UIAxes.YLim(2), app.EventData(i).type, ...
                            'Color', 'g', 'FontSize', 8, 'VerticalAlignment', 'bottom');
                        app.EventTextHandles(end+1) = th2;
                    end
                end
            end
            % 调整 UIAxes 范围和刻度
            %      app.adjustUIAxes(lowlim, highlim);
        end

        function [lowlim, highlim] = calcWindowRange(app)
            lowlim = round(app.time*app.srate  + 1);
            highlim = round(min((app.time + app.winlength)*app.srate  + 1, app.frames));
        end

        function initializeChannelLabels(app)
            % 若未传入通道文件，生成数字标签
            if isempty(app.eloc_file)
                app.chans = size(app.eegdata, 1);  % 总通道数 = 数据行数
                app.YLabels = cellstr(num2str((1:app.chans)'));  % 生成 {'1', '2', ..., 'N'}
            else
                % 若传入通道文件，使用其 labels 字段
                app.YLabels = {app.eloc_file.labels};
                app.chans = length(app.eloc_file);  % 总通道数 = 通道文件长度
            end
            for i = 1:app.chans
                app.ChannelNode{i}=uitreenode(app.ChannelTree);
                app.ChannelNode{i}.Text=app.YLabels{i};
                app.ChannelTree.CheckedNodes=[app.ChannelTree.CheckedNodes;app.ChannelNode{i}];
                app.ChannelNode{i}.NodeData=i;
            end

        end

        function plotEEGChannels(app, lowlim, highlim)
            data = app.eegdata;

            % ====== 决定可用通道索引（考虑筛选）======
            if isempty(app.newCheckedData)
                availIdx = 1:app.chans;            % 原始通道序号
                labelList = app.YLabels;           % 原始标签
                badMask = false(1, app.chans);
                if ~isempty(app.eloc_file) && isfield(app.eloc_file,'badchan')
                    badMask = logical([app.eloc_file.badchan]);
                end
            else
                availIdx = app.newCheckedData(:)'; % 子集里的“原始通道号”
                % 标签基于原始通道号
                if isempty(app.eloc_file)
                    labelList = cellfun(@(x) num2str(x), num2cell(availIdx), 'UniformOutput', false);
                    badMask = false(1, numel(availIdx));
                else
                    labelList = app.YLabels(availIdx);
                    if isfield(app.eloc_file,'badchan')
                        badMask = logical([app.eloc_file(availIdx).badchan]);
                    else
                        badMask = false(1, numel(availIdx));
                    end
                end
                % 同时把 data 过滤到子集（保持行与 availIdx 顺序一致）
                data = data(availIdx, :);
            end

            % ====== 基于 StartChan 选择“可见窗口”的通道 ======
            nAvail = numel(availIdx);
            app.dispchans = min(app.dispchans, nAvail);
            app.StartChan = min(max(1, app.StartChan), max(1, nAvail - app.dispchans + 1));
            visRange = app.StartChan : (app.StartChan + app.dispchans - 1);

            % 计算子均值（对当前可见的行）
            meandata = app.calcSubmean(data, lowlim, highlim);

            % 原有间距逻辑
            oldspacing = 580/app.dispchans;
            if app.duidie==1, app.spacing = oldspacing; else, app.spacing = 0; end

            % 时间轴
            app.timeStart = (lowlim - 1) / app.srate;
            app.timeEnd   = (highlim - 1) / app.srate;
            timePoints = linspace(app.timeStart, app.timeEnd, highlim - lowlim + 1);

            % 取“可见通道 × 当前窗”的矩阵，并做减均值
            winDataVis = data(visRange, lowlim:highlim) - meandata(visRange);
            %  按选择的模式进行幅值缩放（关键一步）
            [winDataVisScaled, ~] = app.applyChannelScaling(winDataVis);

            % 预分配句柄
            app.LineHandles = gobjects(app.dispchans,1);
            app.TextHandles = gobjects(app.dispchans,1);

            % 从下到上画（维持你原来的反向顺序）
            for i = 1:app.dispchans
                row = app.dispchans - i + 1;           % 可见区里的“从下往上”的行
                %chanData = data(row, lowlim:highlim) - meandata(row);
                yTrace = winDataVisScaled(row, :);   % 直接用缩放后的数据
                if app.duidie==1
                    yOffset = i * app.spacing + (app.dispchans + 1)*(oldspacing - app.spacing)/2 ...
                        + app.elecoffset*(oldspacing - app.spacing);
                else
                    yOffset=0;
                end
                lh = plot(app.UIAxes, timePoints,  yTrace  + yOffset, 'LineWidth', 1, 'Color', [0.5 0.5 0.5]);
                app.LineHandles(i) = lh;

                % 点击回调：把“屏内序号 i”或“原始通道号 availIdx(row)”都可以传
                origIdx = availIdx( visRange(i) );
                lh.ButtonDownFcn = @(src, event) app.channelClicked(origIdx, event);

                % 左侧标签（显示“原始通道号/名称”）
                textX = app.timeStart - 0.02 * (app.timeEnd - app.timeStart);
                textY = yOffset;
                th = text(app.UIAxes, textX, textY, labelList{visRange(row)}, ...
                    'HorizontalAlignment','right','Color','k','FontSize',8);
                app.TextHandles(i) = th;
                th.ButtonDownFcn = @(src, event) app.channelLabelClicked(origIdx);
            end

            % 恢复 spacing
            app.spacing = oldspacing;
            % 调整 UIAxes 范围和刻度
            app.adjustUIAxes(lowlim, highlim,winDataVisScaled);
            app.OriginalYLim2=app.UIAxes.YLim;
            % 绘制事件标记
            app.drawEventMarkers();

            % 如果存在选中的时间点，绘制时间选择线
            if app.SelectedTime > 0
                app.drawTimeSelectionLine();
            end

            % 高亮选中的通道
            app.highlightSelectedChannel();


            % 设置UIAxes的回调函数
            app.UIAxes.ButtonDownFcn = @(src, event) app.axesButtonDown(src, event);
            app.UIFigure.WindowButtonUpFcn = @(src, event) app.windowButtonUp(src, event);
            app.UIFigure.WindowButtonMotionFcn = @(src, event) app.windowButtonMotion(src, event);
            app.UIFigure.WindowScrollWheelFcn = @(src, event) app.windowScrollWheel(src, event);
        end

        function channelLabelClicked(app, channelIndex)

            % 当通道标签被点击时，选中该通道（适配勾选/筛选后索引）

            % 删除现有的时间选择线
            if ishandle(app.TimeSelectionLine)
                delete(app.TimeSelectionLine);
            end

            if ishandle(app.SelectionRectangle)
                delete(app.SelectionRectangle);
            end

            if ishandle(app.ChannelSelectionText)
                delete(app.ChannelSelectionText);
            end
            % ===== 获取当前可视通道的原始索引 =====
            if isempty(app.newCheckedData)
                availIdx = 1:app.chans;
            else
                availIdx = app.newCheckedData(:)';  % 子集通道的原始编号
            end

            visRange = availIdx(app.StartChan : ...
                min(app.StartChan + app.dispchans - 1, numel(availIdx)));

            % ===== 查找当前点击的标签对应哪一条曲线 =====
            localIdx = find(visRange == channelIndex, 1);

            if isempty(localIdx)
                % 没在当前显示范围内（例如点击了隐藏通道）
                app.SelectedChannel = NaN;
                return;
            end

            % ===== 设置选中通道（屏内行号）并高亮 =====
            app.SelectedChannel = localIdx;
            app.highlightSelectedChannel();


        end

        function channelClicked(app, channelIndex, event)
            if isempty(app.newCheckedData)
                availIdx = 1:app.chans;
            else
                availIdx = app.newCheckedData(:)';
            end
            visRange = availIdx(app.StartChan : min(app.StartChan + app.dispchans - 1, numel(availIdx)));

            % 找点击通道对应的行号
            localIdx = find(visRange == channelIndex, 1);

            if isempty(localIdx)
                % 点击了不在当前视图的通道（比如误点）
                app.SelectedChannel = NaN;
                return;
            end

            % 记录选中的“屏内序号”
            app.SelectedChannel = localIdx;
            app.highlightSelectedChannel();

            % 获取点击位置的时间
            cp = event.IntersectionPoint;
            app.SelectedTime = cp(1);

            app.drawTimeSelectionLine();
            timepoint=round(app.SelectedTime*app.srate);

            if timepoint>app.datapoints
                return
            end
            for i=1:length(app.CallingApp2)
                if ~isempty(app.CallingApp2{i}) && isvalid(app.CallingApp2{i})
                    app.CallingApp2{i}.TimePointEditField.Value=app.SelectedTime;
                    app.CallingApp2{i}.TimePointValueChanged();
                end
            end
        end

        function highlightSelectedChannel(app)
            % 高亮选中的通道，其他通道变淡

            if app.SelectedChannel > 0 && ~isempty(app.LineHandles)
                for i = 1:length(app.LineHandles)
                    if i == app.SelectedChannel
                        set(app.LineHandles(i), 'LineWidth', 2, 'Color', 'r');
                    else
                        set(app.LineHandles(i), 'LineWidth', 0.5, 'Color', [0.5 0.5 0.5]);
                    end
                end
            end
        end

        function recoverChannels(app)
            for i = 1:length(app.LineHandles)
                set(app.LineHandles(i), 'LineWidth', 1,'Color', [0.5 0.5 0.5]);
            end
        end

        function addTrialStartEvents(app)
            % 确保存在试次信息
            if isempty(app.TrialIndices)
                return;
            end

            % 遍历所有试次
            for trialIdx = 1:length(app.TrialIndices)
                % 计算试次开始时间（秒）
                startTime = (app.TrialIndices(trialIdx).start_index - 1) / app.srate;

                % 创建新事件
                newEvent.latency = startTime; % 事件时间（秒）
                newEvent.type = sprintf('TrialStart_%d', trialIdx); % 事件标签

                % 添加到事件列表
                app.EventMarkers(end+1) = newEvent;
            end

            % 更新事件显示
            app.updateEventList();

            % 重绘事件标记
            app.drawEventMarkers();
        end

        function updateEventList(app)
            % 生成事件字符串列表
            app.eventStrings = cell(1, length(app.EventMarkers));
            for i = 1:length(app.EventMarkers)
                app.eventStrings{i} = sprintf('%.3f s: %s', ...
                    app.EventMarkers(i).latency, ...
                    app.EventMarkers(i).type);
            end

            % 更新列表框
            app.EventListListBox.Items = app.eventStrings;
        end

        function drawEventMarkers(app)
            % 删除现有的事件标记句柄
            delete(app.EventLineHandles(ishandle(app.EventLineHandles)));
            delete(app.EventTextHandles(ishandle(app.EventTextHandles)));
            app.EventLineHandles = [];
            app.EventTextHandles = [];

            % 绘制事件标记
            for i = 1:length(app.EventMarkers)
                eventTime = app.EventMarkers(i).latency;
                % 如果事件时间在当前显示范围内
                if eventTime >= app.timeStart && eventTime <= app.timeEnd
                    % 绘制垂直线
                    lh = line(app.UIAxes, [eventTime, eventTime], app.UIAxes.YLim, ...
                        'Color', 'b', 'LineWidth', 1.5, 'LineStyle', '--');
                    app.EventLineHandles(end+1) = lh;

                    th2 = text(app.UIAxes, eventTime, app.UIAxes.YLim(2), app.EventMarkers(i).type, ...
                        'Color', 'b', 'FontSize', 8, 'VerticalAlignment', 'bottom');
                    app.EventTextHandles(end+1) = th2;
                end
            end
        end

        function adjustUIAxes(app, ~,~, winDataVisScaled)
            % X轴：窗口内帧范围
            app.UIAxes.XLim = [app.timeStart, app.timeEnd];
            xticks = app.timeStart:0.2:app.timeEnd;


            % 强制包含末端值（防止因步长舍入丢失）
            if xticks(end) ~= app.timeEnd
                xticks = [xticks, app.timeEnd];
            end
            app.UIAxes.XTick = unique(xticks); % 去重
            app.UIAxes.YTick=[];

            if app.dispchans > 0
                if app.duidie == 1
                    % 总高度：spacing * (显示通道数 + 1)；+1 给顶部预留一点空隙
                    totalHeight = app.spacing * (app.dispchans + 1);
                    app.UIAxes.YLim = [0, totalHeight];
                else
                    % 叠加模式：所有通道都在同一基线，给一个固定/自适应范围
                    % 方案 B（可选）：基于当前窗口数据自适应（略重）
                    visIdx = app.StartChan : min(app.StartChan + app.dispchans - 1, app.chans);
                    yTrace = winDataVisScaled( visIdx, :);

                    % 可用百分位避免尖峰
                    lo = prctile(yTrace(:), 2); hi = prctile(yTrace(:), 98);
                    pad = 0.1 * (hi - lo + eps);
                    app.UIAxes.YLim = [lo - pad, hi + pad];
                end
            end
            app.UIAxes.Units = 'normalized';            %归一化
            app.UIAxes.Position = [0.15 0.2 0.7 0.75];    % 根据App界面调整
            app.UIAxes.Box = 'off';                     % 不显示边框
        end

        function drawTimeSelectionLine(app)
            % 删除现有的时间选择线
            if ishandle(app.TimeSelectionLine)
                delete(app.TimeSelectionLine);
            end

            if ishandle(app.SelectionRectangle)
                delete(app.SelectionRectangle);
            end
            % 绘制时间选择线
            app.TimeSelectionLine = line(app.UIAxes, [app.SelectedTime, app.SelectedTime], app.OriginalYLim2, ...
                'Color', 'r', 'LineWidth', 1.5);
            app.TimeEditField.Value=app.SelectedTime;
            % 如果选中了通道，显示该时间点的电压值
            if app.SelectedChannel > 0 && ~isempty(app.LineHandles)
                % 计算该时间点对应的数据索引
                timeIndex = round((app.SelectedTime - app.timeStart) * app.srate) + 1;
                if timeIndex >= 1 && timeIndex <= size(app.eegdata, 2)
                    voltage = app.eegdata(app.SelectedChannel, timeIndex);
                    % 删除现有的文本
                    if ishandle(app.ChannelSelectionText)
                        delete(app.ChannelSelectionText);
                    end
                    % 在通道曲线上显示电压值
                    if ~ismember(app.SelectedChannel,app.newCheckedData)
                        return
                    end
                    yData = get(app.LineHandles(app.SelectedChannel), 'YData');
                    if length(yData) >= timeIndex
                        app.ChannelSelectionText = text(app.UIAxes, app.SelectedTime, ...
                            yData(timeIndex), ...
                            sprintf('%.2f μV', voltage), 'Color', 'r', 'BackgroundColor', 'w');
                    end
                end
            end
        end

        function axesButtonDown(app, src, ~)        
            cp = src.CurrentPoint;
            x = cp(1,1); y = cp(1,2);
            if ~strcmp(get(app.UIFigure,'SelectionType'),'normal')
                return; % 仅左键
            end

            % 记录按下
            app.MouseDownTic   = tic;
            app.MouseDownPoint = [x y];

            % 清理旧定时器
            if ~isempty(app.ClickHoldTimer) && isvalid(app.ClickHoldTimer)
                stop(app.ClickHoldTimer);
                delete(app.ClickHoldTimer);
            end

            % 初始化状态
            app.IsSelecting = false;
            app.SelectionStart = x;
            app.SelectionEnd   = x;

            % 启动一次性定时器（0.5s 后进入框选）
            app.ClickHoldTimer = timer( ...
                'ExecutionMode','singleShot', ...
                'StartDelay',0.5, ...
                'TimerFcn',@(~,~)beginDragSelect(app));
            start(app.ClickHoldTimer);
        end

        function windowButtonUp(app,~, ~)
            % 停止并删除定时器
            if ~isempty(app.ClickHoldTimer) && isvalid(app.ClickHoldTimer)
                stop(app.ClickHoldTimer);
                delete(app.ClickHoldTimer);
            end

            % 计算按下持续时间
            dt = 0;
            if app.MouseDownTic ~= 0
                dt = toc(app.MouseDownTic);
            end
            app.MouseDownTic = uint64(0);  % 用完清零

            % 若 <0.5s，认为单击
            if ~app.IsSelecting && dt < 0.5
                cp = app.UIAxes.CurrentPoint;
                x = cp(1,1);
                app.SelectedTime = x;
                if ishandle(app.TimeSelectionLine)
                    delete(app.TimeSelectionLine);
                end
                app.TimeSelectionLine = line(app.UIAxes, [x x], app.UIAxes.YLim, ...
                    'Color','r','LineWidth',1.5);
                app.TimeEditField.Value = app.SelectedTime;
            elseif app.IsSelecting
                % 正常框选逻辑（保留你原有的 highlightSelectedTimeRange）
                startTime = min(app.SelectionStart, app.SelectionEnd);
                endTime   = max(app.SelectionStart, app.SelectionEnd);
                app.SelectedTimeRange = [startTime endTime];
                app.highlightSelectedTimeRange();
            end
            % —— 统一的收尾复位 ——（防止后续鼠标移动继续改矩形）
            app.IsSelecting      = false;
            app.MouseDownTic     = uint64(0);
            app.MouseDownPoint   = [];
            app.SelectionStart   = [];
            app.SelectionEnd     = [];
            drawnow;
        end

        function highlightSelectedTimeRange(app)
            % 删除现有的高亮区域
            if ishandle(app.SelectionRectangle)
                delete(app.SelectionRectangle);
            end

            if ishandle(app.TimeSelectionLine)
                delete(app.TimeSelectionLine);
            end

            if ishandle(app.ChannelSelectionText)
                delete(app.ChannelSelectionText);
            end

            app.recoverChannels();
            % 绘制新的高亮区域
            if ~isempty(app.SelectedTimeRange)
                startTime = app.SelectedTimeRange(1);
                endTime = app.SelectedTimeRange(2);
                yLimits = app.UIAxes.YLim;

                x = [startTime, endTime, endTime, startTime];
                y = [yLimits(1), yLimits(1), yLimits(2), yLimits(2)];
                app.SelectionRectangle = patch(app.UIAxes, x, y, [0.9 0.9 0.9], ...
                    'EdgeColor', 'r', ...
                    'FaceAlpha', 0.3);
                app.rangestart.Text=sprintf('%.4f s', startTime);
                app.rangeend.Text=sprintf('%.4f s', endTime);
            end
            starttimepoint=round(startTime*app.srate);
            endtimepoint=round(endTime*app.srate);
            if starttimepoint>app.datapoints||endtimepoint>app.datapoints
                return
            end
            for i=1:length(app.CallingApp2)
                if ~isempty(app.CallingApp2{i}) && isvalid(app.CallingApp2{i})
                    app.CallingApp2{i}.Poriod = app.dealtime(startTime ,endTime);
                    app.CallingApp2{i}.datachanged();
                end
            end

        end

        function windowButtonMotion(app, ~, ~)
            if ~app.IsSelecting, return; end   % 只有框选模式才跟随

            cp = app.UIAxes.CurrentPoint;
            x = cp(1,1);
            yLimits = app.UIAxes.YLim;

            app.SelectionEnd = x;
            if ishandle(app.SelectionRectangle)
                xs = sort([app.SelectionStart, app.SelectionEnd]);
                set(app.SelectionRectangle, 'XData', [xs(1) xs(2) xs(2) xs(1)], ...
                    'YData', [yLimits(1) yLimits(1) yLimits(2) yLimits(2)]);
            end
        end

        function windowScrollWheel(app, ~, event)
            % 获取鼠标在坐标轴中的位置（像素单位）
            mousePos = get(app.UIFigure, 'CurrentPoint');
            axPos = getpixelposition(app.UIAxes);

            % 判断鼠标是否在坐标轴区域内
            isMouseInAxes = mousePos(1) >= axPos(1) && ...
                mousePos(1) <= axPos(1) + axPos(3) && ...
                mousePos(2) >= axPos(2) && ...
                mousePos(2) <= axPos(2) + axPos(4);

            if ~isMouseInAxes
                return; % 鼠标不在坐标轴内，直接退出
            end

            % 获取鼠标位置（优化性能）
            cp = app.UIAxes.CurrentPoint(1,1:2);
            if any(isnan(cp)) || isempty(cp), return; end

            % 动态缩放因子（根据当前范围调整）
            baseFactor = 1.1;
            currentXRange = diff(app.UIAxes.XLim);
            if currentXRange < 1  % 小范围时减小缩放幅度
                baseFactor = 1.05;
            end

            % 确定缩放方向
            zoomFactor = baseFactor;
            if event.VerticalScrollCount < 0  % 向下滚动
                zoomFactor = 1 / baseFactor;
            end

            % 计算鼠标在视图中的相对位置比例
            xRange = diff(app.UIAxes.XLim);
            yRange = diff(app.UIAxes.YLim);
            xRatio = (cp(1) - app.UIAxes.XLim(1)) / xRange; % X方向比例
            yRatio = (cp(2) - app.UIAxes.YLim(1)) / yRange; % Y方向比例

            % 以鼠标为中心计算新范围
            newXLim = cp(1) + [-xRatio, (1-xRatio)] * xRange * zoomFactor;
            newYLim = cp(2) + [-yRatio, (1-yRatio)] * yRange * zoomFactor;
            % 应用约束条件
            newXLim = app.constrainRange(newXLim, [0, app.timeEnd]);
            newYLim = app.constrainRange(newYLim, app.OriginalYLim2);

            % 更新视图
            app.UIAxes.XLim = newXLim;
            app.UIAxes.YLim = newYLim;
        end

        function lim = constrainRange(app,lim, validRange)
            % 确保范围有效
            minRange = 0.1 * diff(validRange);  % 最小显示范围
            lim(1) = max(validRange(1), min(lim(1), validRange(2)-minRange));
            lim(2) = min(validRange(2), max(lim(2), validRange(1)+minRange));
        end

        % 子函数：计算子均值（在 plotEEGChannels 中被调用）
        function meandata = calcSubmean(app, data, lowlim, highlim)
            if strcmpi(app.submean, 'on')  % 开启子均值：每个通道减去窗口内均值
                winData = data(:, lowlim:highlim);  % 当前窗口数据（channels × 窗口内时间点）
                meandata = mean(winData, 2);  % 按通道计算均值（沿时间维度，返回 channels × 1 向量）
            else  % 不开启：均值为0
                meandata = zeros(app.chans, 1);  % 全零向量，不影响原始数据
            end
        end

        function historyDataMenuSelected(app, src,~)
            filename=src.Text;
            data = load(src.UserData);  % 获取存储的文件名
            disp(['Selected file: ', filename]);
            app.eegdata=data.Data;
            app.redrawPlot();
        end

        function concatenated_data = concatenate_trials(app, data_3d)

            % 获取数组尺寸
            [n_channels, n_points_per_trial, n_trials] = size(data_3d);

            % 预分配拼接后的二维矩阵
            total_points = n_points_per_trial * n_trials;
            concatenated_data = zeros(n_channels, total_points);

            % 初始化索引记录结构体
            app.TrialIndices = struct(...
                'trial_number', num2cell(1:n_trials), ...
                'start_index', num2cell(zeros(1, n_trials)), ...
                'end_index', num2cell(zeros(1, n_trials)) ...
                );

            % 循环处理每个试次
            for trial = 1:n_trials
                % 计算当前试次的起始和结束索引
                start_idx = (trial - 1) * n_points_per_trial + 1;
                end_idx = trial * n_points_per_trial;

                % 提取当前试次数据
                current_trial_data = data_3d(:, :, trial);

                % 将数据放入拼接矩阵
                concatenated_data(:, start_idx:end_idx) = current_trial_data;

                % 记录索引信息
                app.TrialIndices(trial).start_index = start_idx;
                app.TrialIndices(trial).end_index = end_idx;
            end

            % 添加元数据到索引结构
            app.TrialIndices(1).n_channels = n_channels;
            app.TrialIndices(1).points_per_trial = n_points_per_trial;
            app.TrialIndices(1).total_trials = n_trials;
            app.TrialIndices(1).total_points = total_points;
        end

        function results = dealtime(app,startTime ,endTime)
            pointstart=round(startTime*app.srate);
            pointend=round(endTime*app.srate);
            results=[pointstart,pointend];
        end

        function addSeamEventsFromSegmentInfo(app)
            % 需要：app.segmentInfo 为 2×(nTr+1)
            % 第1行：段号（1 表示起点；2..nTr+1 表示第 k-1 个接缝后）
            % 第2行：拼接后的累积点数（起点为 1，接缝为 k*nT）
            if ~isprop(app, 'segmentInfo') || isempty(app.segmentInfo)
                return;
            end
            if size(app.segmentInfo,1) ~= 2 || size(app.segmentInfo,2) < 2
                return; % 没有接缝
            end
            if isempty(app.srate) || app.srate <= 0
                error('app.srate 未设置或非法。');
            end

            % 接缝样本点：跳过第一列 (起点)，只取第 2..end 列
            seamSamples = app.segmentInfo(2, 2:end);          % 1×nTr
            seamTimes   = seamSamples ./ app.srate;            % 换算为秒
            nSeams      = numel(seamTimes);

            % 追加到事件列表（避免重复：按类型名或时间近似匹配可去重）
            for k = 1:nSeams
                % 可选去重：若已有同一时间的事件，就跳过
                if ~isempty(app.EventMarkers)
                    latAll = [app.EventMarkers.latency];
                    if any(abs(latAll - seamTimes(k)) < 1e-9)
                        continue;
                    end
                end
                newEvent.latency = seamTimes(k);                      % 秒
                newEvent.type    = sprintf('Seam_%d (Trial %d end)', k, k);
                app.EventMarkers(end+1) = newEvent;
            end

            % 刷新列表与绘图
            app.updateEventList();
            app.drawEventMarkers();
        end

        function onChannelSliderChanging(app, val)
            % 当前可用通道与最大起始位

            maxStart = app.chans-60+1;

            % 反向映射：向下滑（val↓）=> StartChan↑
            startChan = maxStart - val + 1 ;
            app.StartChan = clamp(round(startChan), 1, maxStart);

            app.redrawPlot();
            % 删除现有的时间选择线
            if ishandle(app.TimeSelectionLine)
                delete(app.TimeSelectionLine);
            end

            if ishandle(app.SelectionRectangle)
                delete(app.SelectionRectangle);
            end

            if ishandle(app.ChannelSelectionText)
                delete(app.ChannelSelectionText);
            end
            app.recoverChannels();
        end

        function onChannelSliderChanged(app, val)

            maxStart =app.chans-60+1;

            % 反向映射（同上）
            startChan = maxStart -val + 1;
            app.StartChan = clamp(round(startChan), 1, maxStart);

            app.redrawPlot();
            % 删除现有的时间选择线
            if ishandle(app.TimeSelectionLine)
                delete(app.TimeSelectionLine);
            end

            if ishandle(app.SelectionRectangle)
                delete(app.SelectionRectangle);
            end

            if ishandle(app.ChannelSelectionText)
                delete(app.ChannelSelectionText);
            end
            app.recoverChannels();
        end

        function target = getTargetAmplitude(app)
            % 目标“半幅值”：让每条曲线大约占据本层间距的 35%
            if isempty(app.ScaleTarget)
                s = max(app.spacing, 580/app.dispchans);      % spacing 至少为 1
                target = 0.35 * s;

            else
                target = app.ScaleTarget;
            end
        end

        function [scaledWin, scaleInfo] = applyChannelScaling(app, winData)
            % winData: [nVisChan x nSamples]，是“当前窗口、已做减均值”的数据
            % scaledWin: 同尺寸，缩放后的数据
            % scaleInfo: 每个通道的缩放系数（用于需要显示gain时）
            target = app.getTargetAmplitude();
            nCh = size(winData,1);

            switch lower(app.ScaleMode)
                case 'none'
                    scaledWin = winData;
                    scaleInfo = ones(nCh,1);

                case 'std'
                    s = std(winData, 0, 2);  s(s==0)=1;
                    f = target ./ s;
                    scaledWin = winData .* f;
                    scaleInfo = f;

                case 'robust'
                    % 用 MAD 估计（更鲁棒）
                    s = 1.4826 * mad(winData, 1, 2);  s(s==0)=1;
                    f = target ./ s;
                    scaledWin = winData .* f;
                    scaleInfo = f;

                case 'minmax'
                    mn = min(winData,[],2); mx = max(winData,[],2);
                    rng = (mx - mn)/2;       rng(rng==0)=1;
                    centered = winData - (mn+mx)/2;
                    f = target ./ rng;
                    scaledWin = centered .* f;
                    scaleInfo = f;

                case 'zscore'
                    mu = mean(winData,2);
                    s  = std(winData,0,2);   s(s==0)=1;
                    z  = (winData - mu) ./ s;
                    scaledWin = z * target;
                    scaleInfo = (target ./ s);

                case 'percentile'
                    lo = prctile(winData, app.ScalePercentiles(1), 2);
                    hi = prctile(winData, app.ScalePercentiles(2), 2);
                    c  = (hi + lo)/2;
                    h  = (hi - lo)/2;        h(h==0)=1;
                    f  = target ./ h;
                    scaledWin = (winData - c) .* f;
                    scaleInfo = f;

                otherwise
                    scaledWin = winData;
                    scaleInfo = ones(nCh,1);
            end
        end

        function UIFigureCloseRequest(app,~)
            % ======= 删除 app.SavePath 中的所有文件 =======
            if exist(app.SavePath, 'dir')&&app.SavePathchanged==1
                files = dir(fullfile(app.SavePath, '*'));  % 获取所有文件和子文件夹
                for k = 1:length(files)
                    % 跳过 '.'、'..'、以及文件夹
                    if ~files(k).isdir
                        delete(fullfile(app.SavePath, files(k).name));
                    end
                end
                disp(['已清空文件夹内的文件：' app.SavePath]);
            else
                disp(['目录不存在：' app.SavePath]);
            end
            delete(app);
        end

        function applySelectionHighlight(app, origIdx)
            % 仍在已勾选？
            checked = app.ChannelTree.CheckedNodes;
            if isempty(checked), return; end
            checkedIdx = cellfun(@(n) n.NodeData, num2cell(checked));
            if ~ismember(origIdx, checkedIdx), return; end

            % 构造当前可用通道集合（全量或子集）
            if isempty(app.newCheckedData)
                availIdx = 1:app.chans;
            else
                availIdx = app.newCheckedData(:)';    % 原始通道号子集
            end

            % 该通道在可用集合中的位置
            posInAvail = find(availIdx == origIdx, 1);
            if isempty(posInAvail), return; end

            % 当前窗口（屏内可见）范围
            startPos = app.StartChan;
            endPos   = min(app.StartChan + app.dispchans - 1, numel(availIdx));
            if posInAvail < startPos || posInAvail > endPos
                return;  % 不在当前窗口，可选：自动滚入窗口后再高亮
            end

            % 上到下的本地序号
            localIdx  = posInAvail - startPos + 1;     % 1..dispchans（上→下）
            % 你是“自下而上”绘制，需反转成 LineHandles 的索引
            screenIdx = app.dispchans - localIdx + 1;  % 下→上

            app.SelectedChannel = screenIdx;
            app.highlightSelectedChannel();
        end

    end

    methods (Access = public)

        function [freq, powerSpectrum] = computeChannelFFT(app, EEGdata,channelIdx)
            % 计算指定通道的FFT频谱
            % 输入:
            %   channelIdx - 通道索引 (1到app.chans)
            %   plotResults - 是否绘制频谱图 (true/false, 可选，默认为false)
            % 输出:
            %   freq - 频率轴 (Hz)
            %   powerSpectrum - 功率谱密度 (μV²/Hz)

            % 获取数据并去均值
            data = EEGdata(channelIdx, :);
            data = data - mean(data);

            % 确保数据长度为偶数（FFT对称性要求）
            if mod(length(data), 2) ~= 0
                data = data(1:end-1); % 丢弃最后一个点
            end
            N = length(data); % 此时N为偶数

            % 应用汉宁窗
            window = hann(N)'; % 注意转置确保维度匹配
            windowedData = data .* window;

            % 计算FFT
            fftResult = fft(windowedData);
            P2 = abs(fftResult/N);

            % 明确转换为整数索引
            halfN = floor(N/2); % 或 fix(N/2)
            P1 = P2(1:halfN + 1);
            P1(2:end-1) = 2*P1(2:end-1);

            % 频率轴
            freq = app.srate*(0:halfN)/N;

            % 功率谱
            powerSpectrum = (P1.^2) / (sum(window.^2) * app.srate);

            % 限制频率范围
            validRange = freq >= 0.01 & freq <= 300;
            freq = freq(validRange);
            powerSpectrum = powerSpectrum(validRange);
        end

        function plotMultiChannelSpectrum(app, EEGdata,channelIndices)
            % 在同一图中绘制多个通道的频谱
            % 输入:
            %   channelIndices - 要分析的通道索引数组（如 [1, 3, 5]）

            % 创建新图形窗口
            fig = figure('Name', 'Multi-Channel Power Spectrum', ...
                'Position', [100 100 800 600]);
            ax = axes(fig);
            hold(ax, 'on');

            % 颜色循环（确保不同通道颜色区分）
            colors = lines(length(channelIndices));

            % 循环计算并绘制每个通道的频谱
            for i = 1:length(channelIndices)
                chanIdx = channelIndices(i);

                % 调用FFT计算函数（假设已实现computeChannelFFT）
                [freq, spectrum] = app.computeChannelFFT(EEGdata,chanIdx);

                % 绘制功率谱（dB单位）
                plot(ax, freq, 10*log10(spectrum), ...
                    'Color', colors(i,:), ...
                    'LineWidth', 1.5, ...
                    'DisplayName', sprintf('Ch%d: %s', chanIdx, app.YLabels{chanIdx}));
            end

            % 图形美化
            grid(ax, 'on');
            xlabel(ax, 'Frequency (Hz)');
            ylabel(ax, 'Power (dB)');
            title(ax, 'Multi-Channel EEG Power Spectrum');
            legend(ax, 'show', 'Location', 'northeastoutside');

            % 标记典型频段
            bandColors = {'r', 'g', 'm'};
            bands = [1 4; 4 8; 8 13]; % Delta, Theta, Alpha
            for b = 1:size(bands,1)
                x = [bands(b,1), bands(b,1), bands(b,2), bands(b,2)];
                y = [ax.YLim(1), ax.YLim(2), ax.YLim(2), ax.YLim(1)];
                patch(x, y, bandColors{b}, 'FaceAlpha', 0.1, 'EdgeColor', 'none');
            end

            % 添加频段标签
            text(2.5, ax.YLim(2)-3, 'Delta', 'Color', 'r');
            text(6, ax.YLim(2)-3, 'Theta', 'Color', 'g');
            text(10.5, ax.YLim(2)-3, 'Alpha', 'Color', 'm');
        end

        function saveFilteredEEG(app,Data , Fs , Fc, N, Rs)
            app.SavePath = fullfile(app.SavePath, 'Tempdata');
            app.SavePathchanged=1;
            % 如果不存在就创建
            if ~exist(app.SavePath, 'dir')
                mkdir(app.SavePath);
            end

            % 生成带索引的文件名
            filename = sprintf('%s/FiltEEG_%03d.mat', app.SavePath, app.SaveIndex);

            % 保存数据（包含原始参数）

            params = struct('Fs', Fs, 'Fc', Fc, 'N', N, 'Rs', Rs);
            save(filename, 'Data', 'params');

            % 更新索引
            app.SaveIndex = app.SaveIndex + 1;

            % 提示用户
            msg = sprintf('数据已保存为: %s\n', filename);
            uialert(app.UIFigure, msg, '保存成功');
            app.historydatamanu=uimenu(app.HistoryMenu);
            app.historydatamanu.Text=filename;
            app.historydatamanu.UserData = filename;  % 存储文件名
            app.historydatamanu.MenuSelectedFcn = @(src,event) historyDataMenuSelected(app, src, event);
        end

        function saveEEG(app,data)

            % 确保文件夹存在
            if ~exist(app.SavePath, 'dir')
                mkdir(app.SavePath);
            end

            % 生成带索引的文件名
            filename = sprintf('%s/EEG_%03d.mat', app.SavePath, app.SaveIndexrowdata);

            % 保存数据（包含原始参数）
            Data = data;
            save(filename, 'Data');

            % 更新索引
            app.SaveIndexrowdata = app.SaveIndexrowdata + 1;

            % 提示用户
            msg = sprintf('数据已保存为: %s\n', filename);
            uialert(app.UIFigure, msg, '保存成功');
            app.historydatamanu=uimenu(app.HistoryMenu);
            app.historydatamanu.Text=filename;
            app.historydatamanu.UserData = filename;  % 存储文件名
            app.historydatamanu.MenuSelectedFcn = @(src,event) historyDataMenuSelected(app, src, event);
        end

        function moveTimeSelection(app, delta)
            % 移动时间选择线
            if isempty(app.SelectedTime)
                % 如果没有选择时间点，使用当前时间
                app.SelectedTime = app.time;
            end

            % 计算新的时间点
            newTime = app.SelectedTime + delta;

            % 确保新时间点在有效范围内
            if newTime < 0
                newTime = 0;
            elseif newTime > app.frames/app.srate
                newTime = app.frames/app.srate;
            end

            % 更新选择的时间点
            app.SelectedTime = newTime;

            % 重绘时间选择线
            app.drawTimeSelectionLine();
        end

        function beginDragSelect(app)
            if isempty(app.MouseDownPoint) || (exist('app.MouseDownTic','var') && app.MouseDownTic==0)
                return; % 已经松开，别进入框选
            end
            % 超过0.5s，进入框选模式：创建选择矩形
            app.IsSelecting = true;

            % 删除旧图形
            if ishandle(app.SelectionRectangle), delete(app.SelectionRectangle); end
            if ishandle(app.TimeSelectionLine),  delete(app.TimeSelectionLine);  end
            if ishandle(app.ChannelSelectionText), delete(app.ChannelSelectionText); end

            yLimits = app.UIAxes.YLim;
            x0 = app.SelectionStart;
            app.SelectionRectangle = patch(app.UIAxes, ...
                [x0 x0 x0 x0], [yLimits(1) yLimits(1) yLimits(2) yLimits(2)], ...
                'r', 'EdgeColor','r', 'LineWidth',1, 'LineStyle','--', 'FaceColor','none');
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, datapath, protocolpath, resultapp, verticesignal)
            app.CallingApp2 = resultapp(~cellfun(@(x) isequal(x, 0)  || isempty(x) , resultapp));%剔除0元素
            addpath(genpath('CoreFunctions'));
            if ~verticesignal
                app.SavePath=protocolpath;
                protocolFile = fullfile(app.SavePath, 'protocol_info.mat');
                S = load(protocolFile);
                app.UIFigure.Name=S.protocolInfo.Data.ID;
                app.srate=S.protocolInfo.Data.SamplingRate;
                datastruct=load(datapath);
                if isfield(datastruct,'data')
                    Bdata=datastruct.data;
                elseif isfield(datastruct,'Data')
                    Bdata=datastruct.Data;
                else
                    return
                end
                if isfield(datastruct,'segmentInfo')
                    app.segmentInfo=datastruct.segmentInfo;
                    app.addSeamEventsFromSegmentInfo();
                end
            else
                Bdata=datapath.data;%点数据无地址
                app.srate=datapath.srate;
            end
            

            if ndims(Bdata)==3
                app.eegdata= app.concatenate_trials(Bdata);
            else
                app.eegdata= Bdata;
            end

            app.chans=size(app.eegdata,1);
            app.dispchans=min(app.chans,60);
            app.frames=size(app.eegdata,2);
            app.datapoints=size(Bdata,2);
            app.initializeChannelLabels();%初始化通道文件
            % 创建滑动条（竖向）
            if app.chans>60
                app.ChannelSlider = uislider(app.UIFigure, ...
                    'Orientation','vertical', ...
                    'Limits',[0, max(0, app.chans-60)+1], ...
                    'Value',max(0, app.chans-60+1), ...
                    'MinorTicks',[],'MajorTicks',[],...
                    'ValueChangingFcn',@(src,evt) onChannelSliderChanging(app, evt.Value), ...
                    'ValueChangedFcn', @(src,evt) onChannelSliderChanged(app, src.Value));
                %'MajorTicks',[], 'MinorTicks',[], ...
                % 定位（自行微调，单位像素）
                app.ChannelSlider.Position = [980, ...
                    150, 3, 500];
            end
            app.redrawPlot();
            if ndims(Bdata)==3
                app.addTrialStartEvents();
            end
            
            app.WinlenthEditField.Value=app.UIAxes.XLim(2)-app.UIAxes.XLim(1);
            app.winlength= app.WinlenthEditField.Value;

            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);


        end

        % Button pushed function: LLButton
        function LLButtonPushed(app, event)
            %<<
            step = app.winlength ;
            app.time =max(0, app.time - step);  % 向左滚动
            app.redrawPlot;  % 重绘图形
        end

        % Button pushed function: smallerButton
        function smallerButtonPushed(app, event)
            %+
            if app.winlength==app.frames/app.srate
                app.winlength=fix(app.winlength*10)/10;
                app.WinlenthEditField.Value = app.winlength;
                app.redrawPlot;
                return;
            end
            if app.winlength>0.1
                app.winlength=app.winlength-0.1;
                app.WinlenthEditField.Value = app.winlength;
                app.redrawPlot;
            else
                return;
            end
        end

        % Button pushed function: RRButton
        function RRButtonPushed(app, event)
            %>>
            step = app.winlength ;
            temp=app.time;
            app.time = min(app.frames/app.srate, app.time + step);  % 向左滚动
            if app.time==app.frames/app.srate
                app.time=temp;
                return
            end

            app.redrawPlot;  % 重绘图形
        end

        % Button pushed function: biggerButton
        function biggerButtonPushed(app, event)
            %-
            app.winlength=app.winlength+0.1;
            if app.winlength<app.frames/app.srate
                app.WinlenthEditField.Value = app.winlength;
                app.redrawPlot;
            else
                app.winlength=app.frames/app.srate;
                app.WinlenthEditField.Value = app.winlength;
                app.redrawPlot;
            end

        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            %>
            step = app.winlength/2 ;
            temp=app.time;
            app.time = min(app.frames/app.srate, app.time + step);  % 向左滚动
            if app.time==app.frames/app.srate
                app.time=temp;
                return
            end

            app.redrawPlot;  % 重绘图形
        end

        % Button pushed function: LButton
        function LButtonPushed(app, event)
            %<
            step = app.winlength/2 ;
            app.time = max(0, app.time - step);  % 向左滚动

            app.redrawPlot;  % 重绘图形
        end

        % Button pushed function: AddEventButton
        function AddEventButtonPushed(app, event)
            % 添加事件标记
            eventTime = app.TimeEditField.Value;
            eventLabel = app.EventLabelEditField.Value;

            if isempty(eventLabel)
                eventLabel = 'Event';
            end

            newEvent.latency = eventTime;
            newEvent.type = eventLabel;

            app.EventMarkers(end+1) = newEvent;
            app.redrawPlot();
            if ~isempty(app.EventData)

                for i = 1:length(app.EventData)
                    eventTime = app.EventData(i).latency;
                    % 如果事件时间在当前显示范围内
                    if eventTime >= app.timeStart && eventTime <= app.timeEnd
                        % 绘制垂直线
                        lh = line(app.UIAxes, [eventTime, eventTime], app.UIAxes.YLim, ...
                            'Color', 'g', 'LineWidth', 1.5, 'LineStyle', '--');
                        app.EventLineHandles(end+1) = lh;

                        % 绘制事件标签
                        th2 = text(app.UIAxes, eventTime, app.UIAxes.YLim(2), app.EventData(i).type, ...
                            'Color', 'g', 'FontSize', 8, 'VerticalAlignment', 'bottom');
                        app.EventTextHandles(end+1) = th2;
                    end
                end
            end
            % 更新事件列表框
            if ~isempty(app.EventData)
                eventDataConverted = struct('latency', num2cell([app.EventData.latency]), ...
                    'type', {app.EventData.type});
                allEvents = [app.EventMarkers, eventDataConverted];
                eventTimes = [allEvents.latency];
                [~, sortedIdx] = sort(eventTimes);
                sortedEvents = allEvents(sortedIdx);
                app.eventStrings = cell(1, length(sortedEvents));
                for i = 1:length(sortedEvents)
                    app.eventStrings{i} = sprintf('%.3f s: %s', sortedEvents(i).latency, sortedEvents(i).type);
                end
            else
                app.eventStrings = cell(1, length(app.EventMarkers));
                for i = 1:length(app.EventMarkers)
                    app.eventStrings{i} = sprintf('%.3f s: %s', app.EventMarkers(i).latency, app.EventMarkers(i).type);
                end
            end
            app.EventListListBox.Items = app.eventStrings;
        end

        % Menu selected function: ImportEventDataMenu
        function ImportEventDataMenuSelected(app, event)
            [fileName, filePath] = uigetfile('*.MAT', 'Select a Event MAT file');
            fullFileName = fullfile(filePath, fileName);

            if isequal(fileName, 0) || isequal(filePath, 0)
                uiwait(msgbox('User selected Cancel'));
                return;
            end %判断是否取消

            try
                myEventData = load(fullFileName);
                varNames = fieldnames(myEventData);
                app.EventData = myEventData.(varNames{1});

                disp(['Event Data Successfully loaded ', num2str(length(fieldnames(app.EventData))), ...
                    ' variables from the mat-file']);
                % 更新UI显示已成功导入的信息或其他逻辑...
            catch
                warning('Error loading file');
            end


            for i = 1:length(app.EventData)
                eventTime = app.EventData(i).latency;
                % 如果事件时间在当前显示范围内
                if eventTime >= app.timeStart && eventTime <= app.timeEnd
                    % 绘制垂直线
                    lh = line(app.UIAxes, [eventTime, eventTime], app.UIAxes.YLim, ...
                        'Color', 'g', 'LineWidth', 1.5, 'LineStyle', '--');
                    app.EventLineHandles(end+1) = lh;

                    % 绘制事件标签
                    th2 = text(app.UIAxes, eventTime, app.UIAxes.YLim(2), app.EventData(i).type, ...
                        'Color', 'g', 'FontSize', 8, 'VerticalAlignment', 'bottom');
                    app.EventTextHandles(end+1) = th2;
                end
            end
            if ~isempty(app.EventMarkers)
                eventDataConverted = struct('latency', num2cell([app.EventData.latency]), ...
                    'type', {app.EventData.type});
                allEvents = [app.EventMarkers, eventDataConverted];
                eventTimes = [allEvents.latency];
                [~, sortedIdx] = sort(eventTimes);
                sortedEvents = allEvents(sortedIdx);
                app.eventStrings = cell(1, length(sortedEvents));
                for i = 1:length(sortedEvents)
                    app.eventStrings{i} = sprintf('%.3f s: %s', sortedEvents(i).latency, sortedEvents(i).type);
                end
            else
                app.eventStrings = cell(1, length(app.EventData));
                for i = 1:length(app.EventData)
                    app.eventStrings{i} = sprintf('%.3f s: %s', app.EventData(i).latency, app.EventData(i).type);
                end
            end
            app.EventListListBox.Items = app.eventStrings;

        end

        % Value changed function: StackingmodeButton
        function StackingmodeButtonValueChanged(app, event)
            value = app.StackingmodeButton.Value;
            if value==1
                app.duidie=0;
                app.redrawPlot;
                for i = 1:length(app.TextHandles)
                    set(app.TextHandles(i), 'Visible', 'off');
                end
            else
                app.duidie=1;
                app.redrawPlot();
            end

        end

        % Callback function: ChannelTree
        function ChannelTreeCheckedNodesChanged(app, event)
            checkedNodes = app.ChannelTree.CheckedNodes;
            app.newCheckedData=[];
            app.newCheckedData=[app.newCheckedData,checkedNodes.NodeData];
            if ~ismember(app.ChannelTree.SelectedNodes.NodeData,app.newCheckedData)
                app.SelectedChannel=0;
            end
          
            app.dispchans=min(60,length(checkedNodes));
            app.spacing= 580/app.dispchans;
            app.redrawPlot();
      % 删除现有的时间选择线
            if ishandle(app.TimeSelectionLine)
                delete(app.TimeSelectionLine);
            end

            if ishandle(app.SelectionRectangle)
                delete(app.SelectionRectangle);
            end

            if ishandle(app.ChannelSelectionText)
                delete(app.ChannelSelectionText);
            end
        end

        % Menu selected function: FFTMenu
        function FFTMenuSelected(app, event)
            checkedNodes = app.ChannelTree.CheckedNodes;
            app.newCheckedData=[];
            app.newCheckedData=[app.newCheckedData,checkedNodes.NodeData];
            app.plotMultiChannelSpectrum(app.eegdata,app.newCheckedData);
        end

        % Menu selected function: LowPassMenu
        function LowPassMenuSelected(app, event)
            data=app.eegdata;
            if ~isempty(app.newCheckedData)
                data = data(app.newCheckedData, :);
            end
            checkedNodes = app.ChannelTree.CheckedNodes;
            app.newCheckedData=[];
            app.newCheckedData=[app.newCheckedData,checkedNodes.NodeData];
            app.DialogApp=lowpass(app,app.srate,data,app.newCheckedData);
        end

        % Menu selected function: HighPassMenu
        function HighPassMenuSelected(app, event)
            data=app.eegdata;
            if ~isempty(app.newCheckedData)
                data = data(app.newCheckedData, :);
            end
            checkedNodes = app.ChannelTree.CheckedNodes;
            app.newCheckedData=[];
            app.newCheckedData=[app.newCheckedData,checkedNodes.NodeData];
            app.DialogApp=highpass(app,app.srate,data,app.newCheckedData);
        end

        % Menu selected function: BandPassMenu
        function BandPassMenuSelected(app, event)
            data=app.eegdata;
            if ~isempty(app.newCheckedData)
                data = data(app.newCheckedData, :);
            end
            checkedNodes = app.ChannelTree.CheckedNodes;
            app.newCheckedData=[];
            app.newCheckedData=[app.newCheckedData,checkedNodes.NodeData];
            app.DialogApp=bandpass(app,app.srate,data,app.newCheckedData);
        end

        % Menu selected function: BandSuppressionMenu
        function BandSuppressionMenuSelected(app, event)
            data=app.eegdata;
            if ~isempty(app.newCheckedData)
                data = data(app.newCheckedData, :);
            end
            checkedNodes = app.ChannelTree.CheckedNodes;
            app.newCheckedData=[];
            app.newCheckedData=[app.newCheckedData,checkedNodes.NodeData];
            app.DialogApp=bandsuppress(app,app.srate,data,app.newCheckedData);
        end

        % Menu selected function: FiltersMenu_2
        function FiltersMenu_2Selected(app, event)
            data=app.eegdata;
            if ~isempty(app.newCheckedData)
                data = data(app.newCheckedData, :);
            end
            checkedNodes = app.ChannelTree.CheckedNodes;
            app.newCheckedData=[];
            app.newCheckedData=[app.newCheckedData,checkedNodes.NodeData];
            app.DialogApp=Filters(app,app.srate,data,app.newCheckedData);

            waitfor(app.DialogApp.UIFigure);

            filename = sprintf('FilteredEEG_%03d',app.currentSaveIndex);
            app.historydatamanu=uimenu(app.HistoryMenu);
            app.historydatamanu.Text=filename;
            app.historydatamanu.UserData=app.filteredDataHistory(end);
            app.historydatamanu.MenuSelectedFcn = @(src,event) historyDataMenuSelected(app, src, event);
            % 更新索引
            app.currentSaveIndex = app.currentSaveIndex + 1;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            % 处理键盘按键事件
            isAltPressed = any(strcmp(event.Modifier, 'alt'));

            if isAltPressed
                % Alt键被按下，处理组合键
                switch event.Key
                    case 'leftarrow'  % Alt+左箭头
                        app.moveTimeSelection(-0.001);  % 移动0.1秒

                    case 'rightarrow' % Alt+右箭头
                        app.moveTimeSelection(0.001);   % 移动0.1秒
                end
            else
                % 没有Alt键，处理普通按键
                switch event.Key
                    case 'leftarrow'  % 左箭头键
                        app.moveTimeSelection(-0.01);  % 移动0.01秒

                    case 'rightarrow' % 右箭头键
                        app.moveTimeSelection(0.01);   % 移动0.01秒
                end
            end

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.ChannelTree.CheckedNodes=[];

            app.newCheckedData=[];

            app.dispchans=0;
            % 删除现有的时间选择线
            if ishandle(app.TimeSelectionLine)
                delete(app.TimeSelectionLine);
            end

            if ishandle(app.SelectionRectangle)
                delete(app.SelectionRectangle);
            end
            % 删除现有的文本
            if ishandle(app.ChannelSelectionText)
                delete(app.ChannelSelectionText);
            end
            app.redrawPlot();
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            app.ChannelTree.CheckedNodes=[];
            app.newCheckedData=[];
            for i=1:length(app.ChannelNode)
                app.ChannelTree.CheckedNodes=[app.ChannelTree.CheckedNodes;app.ChannelNode{i}];
                app.newCheckedData=[app.newCheckedData,app.ChannelTree.CheckedNodes(i).NodeData];
            end
            app.dispchans=min(length(app.ChannelTree.CheckedNodes),60);
            disp(app.dispchans);
            app.spacing= 580/app.dispchans;
            app.redrawPlot();
        end

        % Selection changed function: ChannelTree
        function ChannelTreeSelectionChanged(app, event)
            selNodes = app.ChannelTree.SelectedNodes;
            if isempty(selNodes), return; end
            selNode  = selNodes(1);          % 若允许多选，取第一个
            origIdx  = selNode.NodeData;     % 原始通道号

            % 如果已有定时器在等，先停掉
            if ~isempty(app.SelDebounceTimer) && isvalid(app.SelDebounceTimer)
                stop(app.SelDebounceTimer);
                delete(app.SelDebounceTimer);
            end

            % 建立一次性定时器：极短延时（让 CheckedNodesChanged 先执行完）
            app.SelDebounceTimer = timer( ...
                'ExecutionMode','singleShot', ...
                'StartDelay', 0.2, ...                  % 10ms 足够，必要时可调大到 0.05
                'TimerFcn', @(~,~) app.applySelectionHighlight(origIdx));

            start(app.SelDebounceTimer);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ImportEventDataMenu = uimenu(app.FileMenu);
            app.ImportEventDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportEventDataMenuSelected, true);
            app.ImportEventDataMenu.Text = 'Import Event Data';
            app.FiltersMenu = uimenu(app.UIFigure);
            app.FiltersMenu.Text = 'Filters';
            app.FFTMenu = uimenu(app.FiltersMenu);
            app.FFTMenu.MenuSelectedFcn = createCallbackFcn(app, @FFTMenuSelected, true);
            app.FFTMenu.Text = 'FFT';
            app.LowPassMenu = uimenu(app.FiltersMenu);
            app.LowPassMenu.MenuSelectedFcn = createCallbackFcn(app, @LowPassMenuSelected, true);
            app.LowPassMenu.Text = 'Low Pass';
            app.HighPassMenu = uimenu(app.FiltersMenu);
            app.HighPassMenu.MenuSelectedFcn = createCallbackFcn(app, @HighPassMenuSelected, true);
            app.HighPassMenu.Text = 'High Pass';
            app.BandPassMenu = uimenu(app.FiltersMenu);
            app.BandPassMenu.MenuSelectedFcn = createCallbackFcn(app, @BandPassMenuSelected, true);
            app.BandPassMenu.Text = 'Band Pass';
            app.BandSuppressionMenu = uimenu(app.FiltersMenu);
            app.BandSuppressionMenu.MenuSelectedFcn = createCallbackFcn(app, @BandSuppressionMenuSelected, true);
            app.BandSuppressionMenu.Text = 'Band Suppression ';
            app.FiltersMenu_2 = uimenu(app.FiltersMenu);
            app.FiltersMenu_2.MenuSelectedFcn = createCallbackFcn(app, @FiltersMenu_2Selected, true);
            app.FiltersMenu_2.Text = 'Filters';
            app.HistoryMenu = uimenu(app.UIFigure);
            app.HistoryMenu.Text = 'History';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ChennalsPanel = uipanel(app.UIFigure);
            app.ChennalsPanel.Title = 'Chennals';
            app.ChannelTree = uitree(app.ChennalsPanel, 'checkbox');
            app.ChannelTree.SelectionChangedFcn = createCallbackFcn(app, @ChannelTreeSelectionChanged, true);
            % Assign Checked Nodes
            app.ChannelTree.CheckedNodesChangedFcn = createCallbackFcn(app, @ChannelTreeCheckedNodesChanged, true);
            app.EventListPanel = uipanel(app.UIFigure);
            app.EventListPanel.Title = 'Event List';
            app.EventListListBox = uilistbox(app.EventListPanel);
            app.EventListListBox.Items = {};
            app.EventListListBox.Value = {};
            app.MarkneweventPanel = uipanel(app.UIFigure);
            app.MarkneweventPanel.Title = 'Mark new event';
            app.AddEventButton = uibutton(app.MarkneweventPanel, 'push');
            app.AddEventButton.ButtonPushedFcn = createCallbackFcn(app, @AddEventButtonPushed, true);
            app.AddEventButton.Text = 'AddEvent';
            app.EventNameLabel = uilabel(app.MarkneweventPanel);
            app.EventNameLabel.Text = 'Event Name';
            app.EventLabelEditField = uieditfield(app.MarkneweventPanel, 'text');
            app.EventLabelEditField.Value = 'Event';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.LLButton = uibutton(app.ControlPanel, 'push');
            app.LLButton.ButtonPushedFcn = createCallbackFcn(app, @LLButtonPushed, true);
            app.LLButton.Text = '<<';
            app.RButton = uibutton(app.ControlPanel, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = '>';
            app.LButton = uibutton(app.ControlPanel, 'push');
            app.LButton.ButtonPushedFcn = createCallbackFcn(app, @LButtonPushed, true);
            app.LButton.Text = '<';
            app.RRButton = uibutton(app.ControlPanel, 'push');
            app.RRButton.ButtonPushedFcn = createCallbackFcn(app, @RRButtonPushed, true);
            app.RRButton.Text = '>>';
            app.smallerButton = uibutton(app.ControlPanel, 'push');
            app.smallerButton.ButtonPushedFcn = createCallbackFcn(app, @smallerButtonPushed, true);
            app.smallerButton.Text = '+';
            app.biggerButton = uibutton(app.ControlPanel, 'push');
            app.biggerButton.ButtonPushedFcn = createCallbackFcn(app, @biggerButtonPushed, true);
            app.biggerButton.Text = '-';
            app.StackingmodeButton = uibutton(app.ControlPanel, 'state');
            app.StackingmodeButton.ValueChangedFcn = createCallbackFcn(app, @StackingmodeButtonValueChanged, true);
            app.StackingmodeButton.Text = 'Stacking mode';
            app.TimeEditFieldLabel = uilabel(app.ControlPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.ControlPanel, 'numeric');
            app.WinlenthEditFieldLabel = uilabel(app.ControlPanel);
            app.WinlenthEditFieldLabel.Text = 'Winlenth';
            app.WinlenthEditField = uieditfield(app.ControlPanel, 'numeric');
            app.ClearButton = uibutton(app.ControlPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SelectAllButton = uibutton(app.ControlPanel, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.rangestart = uilabel(app.UIFigure);
            app.rangestart.Text = '';
            app.rangeend = uilabel(app.UIFigure);
            app.rangeend.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SEAL_showEEG(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end