classdef SEAL_DataCheck < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        ElementsPanel  matlab.ui.container.Panel
        GridLayout     matlab.ui.container.GridLayout
        ImportButton   matlab.ui.control.Button
        CancelButton   matlab.ui.control.Button
    end


    properties (Access =   private)
        Callingapp
        UIControls

    end


    methods (Access = private)

        function dropdownChanged(app, src, ~)
            % If user selects from dropdown, clear the corresponding edit field
            fieldName = src.Tag;
            if ~strcmp(src.Value, '(none)')
                app.UIControls.(fieldName).EditField.Value = '';
            end
        end

        function editFieldChanged(app, src, ~)
            % If user types in edit field, reset the corresponding dropdown
            fieldName = src.Tag;
            if ~isempty(src.Value)
                app.UIControls.(fieldName).DropDown.Value = '(none)';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, DataType, SourceVariables)

            app.Callingapp = mainapp;
            app.Callingapp.wasCancelled = 1;
            requiredFields = {};
            switch DataType
                case 'Data'
                    requiredFields = {'ID', 'data', 'srate', 'time', 'chanlocs', 'events', 'type'};
                    required_values = [0 1 1 0 0 0 0];
                case 'Cortex'
                    requiredFields = {'ID', 'Vertices', 'Faces', 'VertConn', 'Structure', 'Atlas', 'Normals'};
                    required_values = [0 0 0 0 0 0 0];
                case 'LeadField'
                    requiredFields = {'ID', 'Gain', 'Orientation', 'GridOrient', 'GridLoc', 'HeadModelType', 'Method'};
                    required_values = [0 0 1 0 0 1 0];
                case 'Chanlocs'
                    requiredFields = {'ID'};
                    required_values = [0];
            end

            numFields = numel(requiredFields);
            app.GridLayout.RowHeight = repmat({30}, 1, numFields);
            app.GridLayout.ColumnWidth = {'1x', '2x', 100, '2x'}; % 4 columns
            options = ['(none)'; string(SourceVariables)];


            for i = 1:numFields
                fieldName = requiredFields{i};
                isrequired = required_values(i);

                % 1. Create the label for the SEAL field
                uilabel(app.GridLayout, 'Text', [fieldName, ':']);

                % 2. Create the dropdown to select a source variable
                dd = uidropdown(app.GridLayout, 'Items', options, 'Tag', fieldName);

                % 3. Create the label for the literal value field
                uilabel(app.GridLayout, 'Text', 'or edit Value:', 'HorizontalAlignment', 'right');

                % 4. Create the edit field for literal input
                ef = uieditfield(app.GridLayout, 'text', 'Tag', fieldName);
                if isrequired == 1
                    ef.Value = 'required';
                end

                % Store handles for later access
                app.UIControls.(fieldName).DropDown = dd;
                app.UIControls.(fieldName).EditField = ef;

                % Add callbacks to handle interaction
                dd.ValueChangedFcn = @(src, event) app.dropdownChanged(src, event);
                ef.ValueChangedFcn = @(src, event) app.editFieldChanged(src, event);

                if ismember(fieldName, SourceVariables)
                    dd.Value = fieldName;
                    %ef = app.UIControls.(fieldName).EditField;

                    % 如果编辑框当前显示 "required"，则清空
                    if strcmpi(ef.Value, 'required')
                        ef.Value = '';
                    end
                end
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)

            delete(app.UIFigure);
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            mappings = struct();
            fieldNames = fieldnames(app.UIControls);
            for i = 1:numel(fieldNames)
                fieldName = fieldNames{i};
                controls = app.UIControls.(fieldName);
                if ~strcmp(controls.DropDown.Value, '(none)')
                    % User selected a variable from the dropdown
                    mappings.(fieldName) = controls.DropDown.Value;
                elseif ~isempty(controls.EditField.Value)
                    % User entered a literal value
                    literalValue = controls.EditField.Value;
                    % Try to convert to a number, otherwise treat as string
                    numValue = str2double(literalValue);
                    if ~isnan(numValue)
                        mappings.(fieldName) = numValue;
                    else
                        mappings.(fieldName) = literalValue;
                    end
                end
            end
            app.Callingapp.wasCancelled = 0;
            app.Callingapp.DataMapping = mappings;
            app.Callingapp.appendOutput("Import data successfully");
            delete(app.UIFigure);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ElementsPanel = uipanel(app.UIFigure);
            app.ElementsPanel.Title = 'Elements';
            app.GridLayout = uigridlayout(app.ElementsPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SEAL_DataCheck(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end