classdef SEAL_Simulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        OKButton                     matlab.ui.control.Button
        SourceListPanel              matlab.ui.container.Panel
        SourceTable                  matlab.ui.control.Table
        SensorLevelSettingPanel      matlab.ui.container.Panel
        SetBackgroundActivityButton  matlab.ui.control.Button
        SimulateNoiseButton          matlab.ui.control.Button
        LoadRealisticNoiseButton     matlab.ui.control.Button
        SourceLevelSettingPanel      matlab.ui.container.Panel
        AddSourceButton              matlab.ui.control.Button
        SourcePatchGenerationPanel   matlab.ui.container.Panel
        SeedVoxelsEditField          matlab.ui.control.NumericEditField
        SeedVoxelsEditField_2Label   matlab.ui.control.Label
        PowerDecayEditField          matlab.ui.control.EditField
        PowerDecayEditFieldLabel     matlab.ui.control.Label
        DecayModelDropDown           matlab.ui.control.DropDown
        DecayModelDropDownLabel      matlab.ui.control.Label
        PatchAreamm2EditField        matlab.ui.control.EditField
        PatchAreamm2EditFieldLabel   matlab.ui.control.Label
        SelectfromcortexButton       matlab.ui.control.Button
        SelectfromAtlasButton        matlab.ui.control.Button
        LoadButton                   matlab.ui.control.Button
        RandButton                   matlab.ui.control.Button
        SourceTimeSeriesPanel        matlab.ui.container.Panel
        SamplingRateEditField        matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel   matlab.ui.control.Label
        PreviewButton                matlab.ui.control.Button
        AddCheckBox                  matlab.ui.control.CheckBox
        Pink_noiseCheckBox           matlab.ui.control.CheckBox
        ARCheckBox                   matlab.ui.control.CheckBox
        Rest_2CheckBox               matlab.ui.control.CheckBox
        Rest_sinCheckBox             matlab.ui.control.CheckBox
        NMM_spikeCheckBox            matlab.ui.control.CheckBox
        Shap_spikeCheckBox           matlab.ui.control.CheckBox
        Gauss_PulseCheckBox          matlab.ui.control.CheckBox
        ERP_GaborCheckBox            matlab.ui.control.CheckBox
        ERSPCheckBox                 matlab.ui.control.CheckBox
        ERPCheckBox                  matlab.ui.control.CheckBox
        TimeRangeEditField           matlab.ui.control.EditField
        TimeRangeEditFieldLabel      matlab.ui.control.Label
        SourceListMenu               matlab.ui.container.ContextMenu
        deleteMenu                   matlab.ui.container.Menu
    end

    
    properties (Access = private)
        Callingapp
        ProtocolNode
        L_fixed             %leadfieldmatrix
        NumOrientations     %方向数1/3
        numChannels         %通道数
        numSources          %等于Vertices
        PreviewSignal = []       %生成信号预览
        TimeRange           %生成信号范围
        srate               %生成波形采样率   
        freq                %生成波形频率
        simulatedEEG        %生成的纯净的EEG信号
        Noise            %生成的带噪声的EEG信号

        Params_Rest_sin
        Params_ERP
        Params_Gauss_Pulse
        Params_Shap_spike
        Params_NMM_spike
        Params_ERSP
        Params_ERP_Gabor
        Params_Rest_2
        Params_AR
        Params_Pink_noise

        V_background

        SourceData = struct('Vertices', {}, 'Waveform', {}, 'SourceName', {});%源活动列表
    end
    
    properties (Access = public)
        handles1            %Cortex图形句柄
        selectedVertices = []
        selectedVerticesNum
    end

    methods (Access = private)

        function enforceSingleSelection(app, activeBox)
            % 1. 把你所有的 10 个刺激复选框打包成一个数组
            % 注意：这里的名字必须和你 App Designer 里组件的名字一模一样
            allBoxes = [app.ERPCheckBox, app.ERSPCheckBox, app.Rest_sinCheckBox, ...
                app.Rest_2CheckBox, app.ARCheckBox, app.ERP_GaborCheckBox, ...
                app.Gauss_PulseCheckBox, app.Shap_spikeCheckBox, ...
                app.NMM_spikeCheckBox, app.Pink_noiseCheckBox];

            % 2. 遍历这个数组，除了当前激活的这个，其他的全部设为 false
            for i = 1:length(allBoxes)
                if allBoxes(i) ~= activeBox
                    allBoxes(i).Value = false;
                end
            end
        end

        function clearAllSelections(app)
            % 1. 把你所有的 10 个刺激复选框打包成一个数组
            allBoxes = [app.ERPCheckBox, app.ERSPCheckBox, app.Rest_sinCheckBox, ...
                app.Rest_2CheckBox, app.ARCheckBox, app.ERP_GaborCheckBox, ...
                app.Gauss_PulseCheckBox, app.Shap_spikeCheckBox, ...
                app.NMM_spikeCheckBox, app.Pink_noiseCheckBox];

            % 2. 遍历这个数组，无差别地把所有选项设为 false
            for i = 1:length(allBoxes)
                allBoxes(i).Value = false;
            end

            % 3. (极其重要) 清空底层的参数和预览信号
            app.PreviewSignal = [];
            % 如果有预览图，在这里清空坐标轴
            if isprop(app, 'UIAxes')
                cla(app.UIAxes);
                title(app.UIAxes, 'Waiting for signal generation...');
            end
        end

        function updatePreviewSignal(app)
            % 1. 初始化一个全 0 的时间轴和信号向量
            % 这里确保即使什么都没选，也能有个空底子
            t = app.TimeRange(1) : 1/app.srate : app.TimeRange(2) - 1/app.srate;
            totalSignal = zeros(size(t));

            % 2. 检查 "Add" 勾选框状态
            isAddMode = app.AddCheckBox.Value;

            % 3. 逐个计算激活的波形并合成

            % --- 1. 正弦波 (Rest_sin) ---
            if app.Rest_sinCheckBox.Value
                s_sin = app.Params_Rest_sin.Amp * sin(2*pi*app.Params_Rest_sin.Freq * t) .* (t >= 0);
                if isAddMode; totalSignal = totalSignal + s_sin; else; totalSignal = s_sin; end
            end

            % --- 2. 诱发电位 (ERP) ---
            if app.ERPCheckBox.Value
                s_erp = app.Params_ERP.Amp * exp(-((t - app.Params_ERP.Lat).^2) / (2 * app.Params_ERP.Wid^2)) .* (t >= 0);
                if isAddMode; totalSignal = totalSignal + s_erp; else; totalSignal = s_erp; end
            end

            % --- 3. 高斯脉冲 (Gauss_Pulse) ---
            if app.Gauss_PulseCheckBox.Value
                s_gauss = app.Params_Gauss_Pulse.Amp * exp(-((t - app.Params_Gauss_Pulse.Lat).^2) / (2 * app.Params_Gauss_Pulse.Wid^2)) .* (t >= 0);
                if isAddMode; totalSignal = totalSignal + s_gauss; else; totalSignal = s_gauss; end
            end

            % --- 4. 尖峰放电 (Shap_spike) ---
            if app.Shap_spikeCheckBox.Value
                % 固定一个极窄的波宽 (0.005s) 来模拟 spike
                s_spike = app.Params_Shap_spike.Amp * exp(-((t - app.Params_Shap_spike.Lat).^2) / (2 * 0.005^2)) .* (t >= 0);
                if isAddMode; totalSignal = totalSignal + s_spike; else; totalSignal = s_spike; end
            end

            % --- 5. NMM 脉冲 (NMM_spike) ---
            if app.NMM_spikeCheckBox.Value
                lat = app.Params_NMM_spike.Lat;
                tau = app.Params_NMM_spike.Tau;
                amp = app.Params_NMM_spike.Amp;

                t_shift = t - lat;
                s_nmm = zeros(size(t));
                idx = t_shift >= 0; % NMM 特有的非对称波形，必须严格截断
                s_nmm(idx) = amp * (t_shift(idx) / tau) .* exp(1 - t_shift(idx) / tau);

                if isAddMode; totalSignal = totalSignal + s_nmm; else; totalSignal = s_nmm; end
            end

            % --- 6. 事件相关谱扰动 (ERSP) ---
            if app.ERSPCheckBox.Value
                f = app.Params_ERSP.Freq;
                lat = app.Params_ERSP.Lat;
                wid = app.Params_ERSP.Wid;
                amp = app.Params_ERSP.Amp;
                s_ersp = amp * sin(2*pi*f*t) .* exp(-((t - lat).^2) / (2 * wid^2)) .* (t >= 0);
                if isAddMode; totalSignal = totalSignal + s_ersp; else; totalSignal = s_ersp; end
            end

            % --- 7. Gabor 斑块 (ERP_Gabor) ---
            if app.ERP_GaborCheckBox.Value
                f = app.Params_ERP_Gabor.Freq;
                lat = app.Params_ERP_Gabor.Lat;
                wid = app.Params_ERP_Gabor.Wid;
                amp = app.Params_ERP_Gabor.Amp;
                s_gabor = amp * sin(2*pi*f*t) .* exp(-((t - lat).^2) / (2 * wid^2)) .* (t >= 0);
                if isAddMode; totalSignal = totalSignal + s_gabor; else; totalSignal = s_gabor; end
            end

            % --- 8. 双频静息态 (Rest_2) ---
            if app.Rest_2CheckBox.Value
                f1 = app.Params_Rest_2.Freq1;
                f2 = app.Params_Rest_2.Freq2;
                amp = app.Params_Rest_2.Amp;
                s_rest2 = ((amp/2) * sin(2*pi*f1*t) + ((amp/2) * sin(2*pi*f2*t))) .* (t >= 0);
                if isAddMode; totalSignal = totalSignal + s_rest2; else; totalSignal = s_rest2; end
            end

            % --- 9. AR 噪声 (AR) ---
            if app.ARCheckBox.Value
                coeff = app.Params_AR.Coeff;
                amp = app.Params_AR.Amp;
                ar_noise = filter(1, [1, -coeff], randn(size(t)));
                s_ar = amp * (ar_noise / std(ar_noise)) .* (t >= 0);
                if isAddMode; totalSignal = totalSignal + s_ar; else; totalSignal = s_ar; end
            end

            % --- 10. 粉红噪声 (Pink_noise) ---
            if app.Pink_noiseCheckBox.Value
                amp = app.Params_Pink_noise.Amp;
                white_noise = randn(size(t));
                pink = detrend(cumsum(white_noise));
                s_pink = amp * (pink / std(pink)) .* (t >= 0);
                if isAddMode; totalSignal = totalSignal + s_pink; else; totalSignal = s_pink; end
            end

            % 4. 最后赋值给全局变量
            app.PreviewSignal = totalSignal* 1e-9;

        end

        function sourceWindowCloseCallback(app, src, ~)
            % src 是即将关闭的 figure 句柄

            % 1. 检查是否有选点，可以在这里做最后的确认或记录
            if ~isempty(app.selectedVertices)
                fprintf('窗口关闭，最终选中了 %d 个顶点。\n', length(app.selectedVertices));
                % 如果你需要把索引同步到主界面的某个特定地方，在这里写
                % app.SomeOtherField.Value = ...
            else
                disp('窗口关闭，未选中任何顶点。');
            end

            % 2. 恢复主界面的鼠标指针（如果之前改成了 crosshair）
            set(app.UIFigure, 'Pointer', 'arrow');

            % 3. 清理 app.handles1 中的引用，防止“无效句柄”报错
            % 这样主程序就知道 3D 窗口已经不在了
            app.handles1 = [];

            % 4. 必须手动执行 delete(src)，否则窗口关不掉
            delete(src);

            disp('3D 交互窗口已安全关闭。');
        end

        function clean_EEG = computeCleanEEG(app)
            % 1. 如果没有源，直接返回空
            if isempty(app.SourceData)
                clean_EEG = [];
                return;
            end

              
            % 以第一个源的波形长度作为画布的基准大小
            numPoints = length(app.SourceData(1).Waveform);
            clean_EEG = zeros(app.numChannels, numPoints);

            % --- 3. 融合所有靶源 (Target Sources) ---
            for i = 1:length(app.SourceData)
                % 提取 Leadfield 子集
                L_sub = app.L_fixed(:, app.SourceData(i).Vertices);

                current_wave = app.SourceData(i).Waveform(:)';

                % 计算当前源在头皮上的投影: [Channels x 1] * [1 x T] = [Channels x T]
                source_EEG = sum(L_sub, 2) * current_wave;

                min_t = min(size(clean_EEG, 2), size(source_EEG, 2));

                % 只把重合的部分加起来，多出来的尾巴直接切掉
                clean_EEG(:, 1:min_t) = clean_EEG(:, 1:min_t) + source_EEG(:, 1:min_t);
            end

            % --- 4. 融入背景脑活动 (Background Activity) ---
            if isprop(app, 'V_background') && ~isempty(app.V_background)
                % 这里同样使用了防御 2 的截断逻辑
                min_t = min(size(clean_EEG, 2), size(app.V_background, 2));
                clean_EEG(:, 1:min_t) = clean_EEG(:, 1:min_t) + app.V_background(:, 1:min_t);
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, ProtocolNode)
            app.UIFigure.Name='Simulation';
            app.Callingapp = mainapp;
            %% 调整app窗口位置
            if ~isempty(app.Callingapp) && isvalid(app.Callingapp)
                % 1. 获取主窗口的位置信息 [Left, Bottom, Width, Height]
                mainPos = app.Callingapp.UIFigure.Position;
                
                % 2. 获取当前子窗口的自身大小
                childWidth = app.UIFigure.Position(3);
                childHeight = app.UIFigure.Position(4);
                
                % 3. 核心计算：让子窗口的中心对准主窗口的中心
                % 新的左边距 = 主窗口的左边距 + (主窗口宽度 - 子窗口宽度) / 2
                newLeft = mainPos(1) + (mainPos(3) - childWidth) / 2;
                
                % 新的下边距 = 主窗口的下边距 + (主窗口高度 - 子窗口高度) / 2
                newBottom = mainPos(2) + (mainPos(4) - childHeight) / 2;
                
                % 4. 强制刷新子窗口的位置
                app.UIFigure.Position = [newLeft, newBottom, childWidth, childHeight];
                
            else
                % 兜底防御：如果直接单独运行了这个子 App（没有主窗口），那就让它在屏幕中央显示
                movegui(app.UIFigure, 'center');
            end
            %%
            app.ProtocolNode = ProtocolNode;
            nColumns_L=size(ProtocolNode.leadfieldNode.data,2);
            nPhysicalSources = size(ProtocolNode.cortexNode.data.Vertices, 1);
            nd = nColumns_L / nPhysicalSources;
            if nd == 1
                app.L_fixed = reduceLeadfield(ProtocolNode.leadfieldNode.data, 1);
            elseif nd == 3
                app.L_fixed = reduceLeadfield(ProtocolNode.leadfieldNode.data, 3,ProtocolNode.leadfieldNode.leadfieldInfo.normals);
            elseif nd ~= 1 && nd ~= 3
                error('SEAL:Algorithm:DimensionMismatch', ...
                    '导联场维度异常！\n导联场列数 (%d) 必须是皮层节点数 (%d) 的 1 倍 (固定朝向) 或 3 倍 (自由朝向)。\n请检查头模型与导联场是否匹配！', ...
                    nColumns_L, nPhysicalSources);
            end
            app.NumOrientations = nd;
            
            app.numChannels = size(app.L_fixed,1);
            app.numSources = nPhysicalSources;
            timeRangeStr = app.TimeRangeEditField.Value;
            timeRange = str2num(timeRangeStr);

            % 防止用户乱输入，比如输入了 '[A, B]' 或者只输入了一个数字 '[-1]'
            if isempty(timeRange) || length(timeRange) ~= 2
                % 如果解析失败或长度不对，弹出错误提示并终止运行
                uialert(app.UIFigure, '时间范围格式错误！请使用形如 [-1, 1] 的格式。', '输入错误', 'Icon', 'error');
                return; % 终止后续的回调代码执行
            end
            % 确保起始时间小于结束时间
            if timeRange(1) >= timeRange(2)
                uialert(app.UIFigure, '起始时间必须小于结束时间！', '逻辑错误', 'Icon', 'warning');
                return;
            end
            app.TimeRange = timeRange;
            app.srate = app.SamplingRateEditField.Value;
            app.OKButton.Enable = 'off';
        end

        % Value changed function: Rest_sinCheckBox
        function Rest_sinCheckBoxValueChanged(app, event)
            if app.Rest_sinCheckBox.Value == true
                if app.AddCheckBox.Value == false
                    % 传入 app 和当前这个复选框的句柄
                    enforceSingleSelection(app, app.Rest_sinCheckBox);
                end

                % 2. 弹窗获取参数并存入 app 属性
                answer = inputdlg({'频率(Hz):','幅值（nAm）:'}, '设置', [1 50], {'1000','5'});
                if ~isempty(answer)
                    app.Params_Rest_sin.Freq = str2double(answer{1});
                    app.Params_Rest_sin.Amp = str2double(answer{2});
                else
                    app.Rest_sinCheckBox.Value = false;
                end
            end

            % 3. 无论勾选还是取消，都让“大脑”重新算一遍
            updatePreviewSignal(app);
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            % 看看生成的源信号是什么样子的 (可选)
            figure; 
            t = app.TimeRange(1) : 1/app.srate : app.TimeRange(2) - 1/app.srate;
            plot(t, app.PreviewSignal); 
            xlabel('Time (s)');
        end

        % Button pushed function: RandButton
        function RandButtonPushed(app, event)
            SeedNum = app.SeedVoxelsEditField.Value;
            seedVoxelIdx = randperm(app.numSources, SeedNum);
            disp(['选中的随机顶点 ID: ', num2str(seedVoxelIdx(:)')]);

            app.selectedVertices = seedVoxelIdx;
        end

        % Button pushed function: SimulateNoiseButton
        function SimulateNoiseButtonPushed(app, event)
            clean_EEG = computeCleanEEG(app);
            if isempty(clean_EEG)
                uialert(app.UIFigure, '请先添加至少一个源！', '缺少参考信号');
                return;
            end

            disp('正在根据纯净信号总功率生成传感器噪声...');

   
            answer = inputdlg('请输入目标信噪比 (dB):', '噪声参数设置', [1 35], {'10'});
            if isempty(answer)
                return;  % 用户取消
            end

            snr_db = str2double(answer{1});
            if isnan(snr_db)
                uialert(app.UIFigure, '请输入有效的数值！', '输入错误');
                return;
            end

            [~, numPoints] = size(clean_EEG);
       

            % 3. 计算功率与缩放
            signal_power = mean(clean_EEG(:).^2);
            noise_power = signal_power / (10^(snr_db / 10));
            noise_std = sqrt(noise_power);

           
                app.Noise = noise_std * randn(app.numChannels, numPoints);
       

            disp(['✅ 噪声生成完毕！目标 SNR: ', num2str(snr_db), ' dB']);

        end

        % Button pushed function: SetBackgroundActivityButton
        function SetBackgroundActivityButtonPushed(app, event)
            clean_EEG = computeCleanEEG(app);
            if isempty(clean_EEG)
                uialert(app.UIFigure, '请先添加至少一个源！', '缺少参考信号');
                return;
            end

            % 弹窗获取背景噪声 SNR
            answer = inputdlg(...
                {'背景脑活动信噪比 (dB):'}, ...
                '背景活动参数设置', [1 40], {'10'});
            if isempty(answer)
                return;
            end

            snr_db = str2double(answer{1});
            
            if isnan(snr_db) 
                uialert(app.UIFigure, '请输入有效的数值！', '输入错误');
                return;
            end

            % --- 1. 排除靶源顶点 ---
            allTargetVertices = [];
            if isprop(app, 'SourceData') && ~isempty(app.SourceData)
                for i = 1:length(app.SourceData)
                    allTargetVertices = [allTargetVertices, app.SourceData(i).Vertices(:)'];
                end
            end
            if isprop(app, 'selectedVertices') && ~isempty(app.selectedVertices)
                allTargetVertices = [allTargetVertices, app.selectedVertices(:)'];
            end
            allTargetVertices = unique(allTargetVertices);

            % --- 2. 构建候选池 ---
            availablePool = setdiff(1:app.numSources, allTargetVertices);
            numBgSources = max(round(0.2 * app.numSources), 100);
            numBgSources = min(numBgSources, length(availablePool));

            % --- 3. 随机采样背景源 ---
            randomIndex = randperm(length(availablePool), numBgSources);
            bgIndices = availablePool(randomIndex);
            L_bg = app.L_fixed(:, bgIndices);

            t = app.TimeRange(1) : 1/app.srate : app.TimeRange(2) - 1/app.srate;
            numTimePoints = length(t);
            
            raw_noise = randn(numBgSources, numTimePoints);
            pink_noise = detrend(cumsum(raw_noise, 2)')';
            pink_noise = pink_noise ./ std(pink_noise, 0, 2);
            bg_all = L_bg * pink_noise;


            % --- 5. 根据 SNR 计算缩放系数 ---
            signal_power = mean(clean_EEG(:).^2);
            target_noise_power = signal_power / (10^(snr_db / 10));
            current_noise_power = mean(bg_all(:).^2);

            scale = sqrt(target_noise_power / current_noise_power);
            app.V_background = bg_all * scale;

        end

        % Value changed function: ERPCheckBox
        function ERPCheckBoxValueChanged(app, event)
            value = app.ERPCheckBox.Value;
            if value == true
                if app.AddCheckBox.Value == false
                    enforceSingleSelection(app, app.ERPCheckBox);
                end
                answer = inputdlg({'潜伏期 Latency (s):', '波宽 Width (s):', '幅值 (nAm):'}, 'ERP 设置', [1 50], {'0.3', '0.05', '1000'});
                if ~isempty(answer)
                    app.Params_ERP.Lat = str2double(answer{1});
                    app.Params_ERP.Wid = str2double(answer{2});
                    app.Params_ERP.Amp = str2double(answer{3});
                else
                    app.ERPCheckBox.Value = false;
                end
            end
            updatePreviewSignal(app);
        end

        % Value changed function: Gauss_PulseCheckBox
        function Gauss_PulseCheckBoxValueChanged(app, event)
            if app.Gauss_PulseCheckBox.Value == true
                if app.AddCheckBox.Value == false
                    enforceSingleSelection(app, app.Gauss_PulseCheckBox);
                end
                answer = inputdlg({'潜伏期 (s):', '波宽 (s):', '幅值 (nAm):'}, '高斯脉冲设置', [1 50], {'0.3', '1000', '5'});
                if ~isempty(answer)
                    app.Params_Gauss_Pulse.Lat = str2double(answer{1});
                    app.Params_Gauss_Pulse.Wid = str2double(answer{2});
                    app.Params_Gauss_Pulse.Amp = str2double(answer{3});
                else
                    app.Gauss_PulseCheckBox.Value = false;
                end
            end
            updatePreviewSignal(app);
        end

        % Value changed function: Shap_spikeCheckBox
        function Shap_spikeCheckBoxValueChanged(app, event)
            if app.Shap_spikeCheckBox.Value == true
                if app.AddCheckBox.Value == false
                    enforceSingleSelection(app, app.Shap_spikeCheckBox);
                end
                answer = inputdlg({'潜伏期 Latency (s):', '幅值 (nAm):'}, '尖峰放电设置', [1 50], {'0.1', '1000'});
                if ~isempty(answer)
                    app.Params_Shap_spike.Lat = str2double(answer{1});
                    app.Params_Shap_spike.Amp = str2double(answer{2});
                else
                    app.Shap_spikeCheckBox.Value = false;
                end
            end
            updatePreviewSignal(app);
        end

        % Value changed function: NMM_spikeCheckBox
        function NMM_spikeCheckBoxValueChanged(app, event)
            if app.NMM_spikeCheckBox.Value == true
                if app.AddCheckBox.Value == false
                    enforceSingleSelection(app, app.NMM_spikeCheckBox);
                end
                answer = inputdlg({'潜伏期 (s):', '时间常数 Tau (s):', '幅值 (nAm):'}, 'NMM 脉冲设置', [1 50], {'0.1', '0.02', '1000'});
                if ~isempty(answer)
                    app.Params_NMM_spike.Lat = str2double(answer{1});
                    app.Params_NMM_spike.Tau = str2double(answer{2});
                    app.Params_NMM_spike.Amp = str2double(answer{3});
                else
                    app.NMM_spikeCheckBox.Value = false;
                end
            end
            updatePreviewSignal(app);
        end

        % Value changed function: ERSPCheckBox
        function ERSPCheckBoxValueChanged(app, event)
            if app.ERSPCheckBox.Value == true
                if app.AddCheckBox.Value == false
                    enforceSingleSelection(app, app.ERSPCheckBox);
                end
                answer = inputdlg({'中心频率 (Hz):', '爆发时间 (s):', '包络宽度 (s):', '幅值 (nAm):'}, 'ERSP 设置', [1 50], {'40', '0.2', '0.1', '1000'});
                if ~isempty(answer)
                    app.Params_ERSP.Freq = str2double(answer{1});
                    app.Params_ERSP.Lat  = str2double(answer{2});
                    app.Params_ERSP.Wid  = str2double(answer{3});
                    app.Params_ERSP.Amp  = str2double(answer{4});
                else
                    app.ERSPCheckBox.Value = false;
                end
            end
            updatePreviewSignal(app);

        end

        % Value changed function: ERP_GaborCheckBox
        function ERP_GaborCheckBoxValueChanged(app, event)
            if app.ERP_GaborCheckBox.Value == true
                if app.AddCheckBox.Value == false
                    enforceSingleSelection(app, app.ERP_GaborCheckBox);
                end
                answer = inputdlg({'中心频率 (Hz):', '爆发时间 (s):', '高斯窗宽 (s):', '幅值 (nAm):'}, 'Gabor 斑块设置', [1 50], {'40', '0.2', '0.1', '1000'});
                if ~isempty(answer)
                    app.Params_ERP_Gabor.Freq = str2double(answer{1});
                    app.Params_ERP_Gabor.Lat  = str2double(answer{2});
                    app.Params_ERP_Gabor.Wid  = str2double(answer{3});
                    app.Params_ERP_Gabor.Amp  = str2double(answer{4});
                else
                    app.ERP_GaborCheckBox.Value = false;
                end
            end
            updatePreviewSignal(app);s

        end

        % Value changed function: Rest_2CheckBox
        function Rest_2CheckBoxValueChanged(app, event)
            if app.Rest_2CheckBox.Value == true
                if app.AddCheckBox.Value == false
                    enforceSingleSelection(app, app.Rest_2CheckBox);
                end
                answer = inputdlg({'频率 1 (Hz):', '频率 2 (Hz):', '总幅值 (nAm):'}, '双频静息态设置', [1 50], {'10', '20', '1000'});
                if ~isempty(answer)
                    app.Params_Rest_2.Freq1 = str2double(answer{1});
                    app.Params_Rest_2.Freq2 = str2double(answer{2});
                    app.Params_Rest_2.Amp   = str2double(answer{3});
                else
                    app.Rest_2CheckBox.Value = false;
                end
            end
            updatePreviewSignal(app);
        end

        % Value changed function: ARCheckBox
        function ARCheckBoxValueChanged(app, event)
            if app.ARCheckBox.Value == true
                if app.AddCheckBox.Value == false
                    enforceSingleSelection(app, app.ARCheckBox);
                end
                answer = inputdlg({'AR 平滑系数 (0-1):', '幅值 (nAm):'}, 'AR 噪声设置', [1 50], {'0.9', '5'});
                if ~isempty(answer)
                    app.Params_AR.Coeff = str2double(answer{1});
                    app.Params_AR.Amp   = str2double(answer{2});
                else
                    app.ARCheckBox.Value = false;
                end
            end
            updatePreviewSignal(app);
        end

        % Value changed function: Pink_noiseCheckBox
        function Pink_noiseCheckBoxValueChanged(app, event)
            if app.Pink_noiseCheckBox.Value == true
                if app.AddCheckBox.Value == false
                    enforceSingleSelection(app, app.Pink_noiseCheckBox);
                end
                answer = inputdlg({'噪声幅值级别 (nAm):'}, '粉红噪声设置', [1 50], {'5'});
                if ~isempty(answer)
                    app.Params_Pink_noise.Amp = str2double(answer{1});
                else
                    app.Pink_noiseCheckBox.Value = false;
                end
            end
            updatePreviewSignal(app);
        end

        % Button pushed function: SelectfromcortexButton
        function SelectfromcortexButtonPushed(app, event)
            app.handles1=PlotSource([],app.ProtocolNode.cortexNode.data,'smooth',30, ...
                    'Position',[app.UIFigure.Position(1)+app.UIFigure.Position(3) app.UIFigure.Position(2)+app.UIFigure.Position(4)-600 800, 600]);

            bind3DInteraction(app.handles1.h, app.handles1.axes,  @(src,evt) vertexClicked(app,src,evt));

            app.handles1.h.UserData = 1;
            app.handles1.h.CloseRequestFcn = @(src, event) sourceWindowCloseCallback(app, src, event);
            set(app.handles1.h, 'Pointer', 'crosshair');

        end

        % Button pushed function: AddSourceButton
        function AddSourceButtonPushed(app, event)
            % 1. 拦截错误：检查是否选了点、是否生成了波形
            if isempty(app.selectedVertices)
                uialert(app.UIFigure, '请先在 3D 大脑上选择至少一个顶点！', '缺少空间信息');
                return;
            end
            if isempty(app.PreviewSignal)
                uialert(app.UIFigure, '请先在左侧配置并生成刺激波形！', '缺少时间信息');
                return;
            end

           % --- 2. 识别当前选中的刺激类型 (完美支持 Add 叠加模式) ---
           selectedWaves = {}; % 用一个空的 cell 数组来收集所有打勾的名字

           allBoxes = [app.ERPCheckBox, app.ERSPCheckBox, app.Rest_sinCheckBox, ...
               app.Rest_2CheckBox, app.ARCheckBox, app.ERP_GaborCheckBox, ...
               app.Gauss_PulseCheckBox, app.Shap_spikeCheckBox, ...
               app.NMM_spikeCheckBox, app.Pink_noiseCheckBox];

           for i = 1:length(allBoxes)
               if allBoxes(i).Value == true
                   % 把当前打勾的波形名字塞进集合里
                   selectedWaves{end+1} = allBoxes(i).Text;
               end
           end

           % 拼接字符串：把收集到的名字用 ' + ' 连起来
           if isempty(selectedWaves)
               waveType = 'Custom'; % 防呆设计：万一都没勾但有信号
           else
               % 如果选了两个，就会变成比如 "ERP + Pink_noise"
               waveType = strjoin(selectedWaves, ' + ');
           end

            % --- 3. 打包当前源的信息 ---
            sourceCount = length(app.SourceData) + 1;

            patchArea = str2double(app.PatchAreamm2EditField.Value);       % 10 mm²
            decayModel = app.DecayModelDropDown.Value;      % 'Gaussian'
            powerDecay = str2double(app.PowerDecayEditField.Value);     % 10 (%)

            if patchArea > 0
                [patchVerts, patchWeights] = expandToPatch(...
                    app.selectedVertices, ...
                    app.ProtocolNode.cortexNode.data.Vertices, ...     % 你的皮层网格坐标 [V×3]
                    patchArea, decayModel, powerDecay);
            else
                % 面积为0，退化为点源
                patchVerts = app.selectedVertices;
                patchWeights = ones(1, length(app.selectedVertices));
            end

            normalization_factor = 1 / sum(patchWeights);

            % 生成 [Vertices x Time] 的专属斑块矩阵
            % 矩阵乘法：[V x 1] * [1 x T] = [V x T]
            patchWaveformMatrix = (patchWeights(:) * normalization_factor) * app.PreviewSignal;

            % --- 4. 打包源信息 ---
            newSource.No = sourceCount;
            newSource.NumVertices = length(patchVerts);
            newSource.WaveType = waveType;
            newSource.Waveform = patchWaveformMatrix;
            newSource.Vertices = app.selectedVertices;              % 选择顶点
            newSource.Weights = patchWeights;                       % 对应的衰减权重
            newSource.SeedVertices = app.selectedVertices;          % 保留原始种子点
            newSource.PatchArea = patchArea;
            newSource.DecayModel = decayModel;
            newSource.PowerDecay = powerDecay;
            % 存入后台数据仓库
            if isempty(app.SourceData)
                app.SourceData = newSource;
            else
                app.SourceData(sourceCount) = newSource;
            end

            % --- 4. 刷新多列 Table UI 显示 ---
            % 构建一个 Nx3 的 Cell 矩阵，严格对应你的列名：No. | Vertices | Stimulus
            displayData = cell(sourceCount, 3);
            for i = 1:sourceCount
                displayData{i, 1} = num2str(app.SourceData(i).No);             % 序号
                displayData{i, 2} = num2str(app.SourceData(i).NumVertices);    % 顶点数
                displayData{i, 3} = app.SourceData(i).WaveType;       % 刺激类型
            end

            % 推送到表格界面
            app.SourceTable.Data = displayData;

            app.SamplingRateEditField.Enable = 'off';       % 锁定采样率

            app.TimeRangeEditField.Enable = 'off';
            app.OKButton.Enable = 'on';
            app.handles1 = [];

            % 重置后台选点和 UI 数字
            app.selectedVertices = [];
            app.SeedVoxelsEditField.Value = 0;

            % 取消波形勾选，清空预览图
            clearAllSelections(app);

        end

        % Menu selected function: deleteMenu
        function deleteMenuSelected(app, event)
            drawnow;
            % 1. 获取当前选中的是哪一行
            % 因为我们把表格设为了 'row' 选择模式，所以 Selection 存的就是行号
            selectedRow = app.SourceTable.Selection;

            % 防呆检查：如果没选中任何行，直接返回
            if isempty(selectedRow)
                return;
            end

            % 2. 友好的二次确认弹窗 (防止手滑)
            sourceName = app.SourceTable.Data{selectedRow, 3}; % 获取要删的刺激类型名字
            confirmMsg = sprintf('确定要删除第 %d 个源 (%s) 吗？', selectedRow, sourceName);
            selection = uiconfirm(app.UIFigure, confirmMsg, '确认删除', ...
                'Options', {'确定删除', '取消'}, 'DefaultOption', 2, 'Icon', 'warning');

            if strcmp(selection, '取消')
                return;
            end

            % --- 3. 核心：从后台数据仓库中精准击杀 ---
            app.SourceData(selectedRow) = []; % 直接把这一行赋值为空，MATLAB会自动缩减数组

            % --- 4. 刷新前端显示与序号重排 ---
            if isempty(app.SourceData)
                % 终极情况：如果所有的源都被删光了，彻底清空表格
                app.SourceTable.Data = cell(0, 3);

                app.SamplingRateEditField.Enable = 'on';
                app.TimeRangeEditField.Enable = 'on';
                app.OKButton.Enable = "off";
                disp('所有源已清空，全局参数已重新解锁。');
            else
                % 常规情况：删除了中间的某个源，需要把后面的源序号往前顶
                nSources = length(app.SourceData);
                displayData = cell(nSources, 3);

                for i = 1:nSources
                    % 1. 修正后台的序号记录
                    app.SourceData(i).No = i;
                    % 2. 重新构建前端展示用的字符串矩阵
                    displayData{i, 1} = num2str(i);
                    displayData{i, 2} = num2str(app.SourceData(i).NumVertices);
                    displayData{i, 3} = app.SourceData(i).WaveType;
                end

                % 推送到表格界面
                app.SourceTable.Data = displayData;
                disp(['已删除指定源，当前剩余 ', num2str(nSources), ' 个源。']);
            end
            app.SourceTable.Selection = [];
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % 1. 调用公共函数获取纯净底信号 (靶源 + 背景)
            final_EEG = computeCleanEEG(app);
            if isempty(final_EEG)
                uialert(app.UIFigure, 'Source List 为空，请先配置并 Add Source！', '操作无效');
                return;
            end

            % 2. 检查并叠加传感器白噪声 (Sensor Noise)
            % 这里我们默认用户如果改了 SNR，已经点过了 Simulate Noise，所以直接拿 app.Noise 用
            if isprop(app, 'Noise') && ~isempty(app.Noise)
                % 取第一个 Trial 来做演示和截断防御
                noise_trial1 = app.Noise(:,:,1);
                min_t = min(size(final_EEG, 2), size(noise_trial1, 2));

                final_EEG(:, 1:min_t) = final_EEG(:, 1:min_t) + noise_trial1(:, 1:min_t);
                disp('✅ 成功注入传感器噪声 (Sensor Noise)。');
            else
                disp('ℹ️ 未检测到噪声矩阵，将输出无传感器噪声的纯净 EEG。');
            end

            % 3. 保存最终结果并清理 UI
            app.simulatedEEG.data = final_EEG;
            app.simulatedEEG.srate = app.SamplingRateEditField.Value;
            app.PreviewSignal = [];
            filterSpec = { ...
                '*.mat', 'MATLAB'; ...
                '*.*',   'All Files'};
            [filename, path] = uiputfile(filterSpec, "Select a location to save results.");
            savePath = fullfile(path, filename);

            saveData(savePath, app.simulatedEEG);

            app.ProtocolNode.openDataFromData(savePath);

            app.Callingapp.flushFileTree();

            % 4. 绘图展示
            disp('🎉 脑电仿真全部完成！');
            figure('Name', 'Final Simulated Scalp EEG', 'Color', 'w');
            t = linspace(app.TimeRange(1), app.TimeRange(2), size(final_EEG, 2));
            plot(t, final_EEG', 'LineWidth', 0.8);
            set(gca, 'FontSize', 12);
            xlabel('Time (s)', 'FontWeight', 'bold'); ylabel('Amplitude (V)', 'FontWeight', 'bold');
            title('Final Simulated Scalp EEG', 'FontWeight', 'bold'); grid on;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
         % 1. 定义当前头模型的最大顶点数 (作为越界检查的合法天花板)
            % 请确保 app.numSources 在导入头模型时已经正确赋值
            if ~isprop(app, 'numSources') || isempty(app.numSources)
                uialert(app.UIFigure, '请先加载头模型 (Cortex/Leadfield)！', '操作被拒绝', 'Icon', 'warning');
                return;
            end
            maxVertices = app.numSources;

            % 2. 弹窗要求用户输入
            prompt = {sprintf('请输入顶点索引 (1 - %d):\n支持空格/逗号分隔，或使用 1:10 语法', maxVertices)};
            dlgtitle = 'Load Vertices';
            dims = [1 50];
            definput = {'1'}; % 默认占位符
            
            answer = inputdlg(prompt, dlgtitle, dims, definput);
            
            % 3. 防线一：用户点击了“取消”或关闭了弹窗
            if isempty(answer)
                return; % 静默退出，不打扰用户
            end
            
            inputStr = answer{1};
            
            % 4. 防线二：将字符串转换为数字数组
            % 极其推荐使用 str2num 而不是 str2double，因为 str2num 可以完美解析 '1:100' 或 '1,2,3'
            idxArray = str2num(inputStr); %#ok<ST2NM> 
            
            if isempty(idxArray)
                uialert(app.UIFigure, '输入格式不合法！\n请输入纯数字，例如: 15, 20, 25 或 10:50', ...
                    '解析失败', 'Icon', 'error');
                return;
            end
            
            % 5. 防线三：小数防御 (顶点 ID 必须是整数)
            if any(floor(idxArray) ~= idxArray)
                uialert(app.UIFigure, '顶点索引不能包含小数！', '输入非法', 'Icon', 'error');
                return;
            end
            
            % 6. 防线四：范围防御 (不能小于 1，不能大于总顶点数)
            if any(idxArray < 1) || any(idxArray > maxVertices)
                errorMsg = sprintf('索引越界！\n输入的顶点必须在 1 到 %d 之间。', maxVertices);
                uialert(app.UIFigure, errorMsg, '越界错误', 'Icon', 'error');
                return;
            end
            
            idxArray = unique(idxArray(:)'); 
            
            app.selectedVertices = idxArray; 
            
            % 可选：在命令窗口打印日志，方便科研调试
            disp(['✅ 成功加载 ', num2str(length(idxArray)), ' 个顶点。']);


        end

        % Button pushed function: SelectfromAtlasButton
        function SelectfromAtlasButtonPushed(app, event)
            if ~isprop(app, 'ProtocolNode') || isempty(app.ProtocolNode.cortexNode.data)
                uialert(app.UIFigure, '请先加载大脑皮层 (Cortex) 模型！', '未检测到模型', 'Icon', 'warning');
                return;
            end
            cortexData = app.ProtocolNode.cortexNode.data;

            % 2. 🛡️ 核心安检：检测是否存在 Atlas 字段且不为空
            if ~isfield(cortexData, 'Atlas') || isempty(cortexData.Atlas)
                uialert(app.UIFigure, '当前加载的皮层模型不包含 Atlas (脑图谱) 数据！\n请确保导入的是包含 Scout 划分的 Brainstorm Surface 文件。', ...
                    '无图谱数据', 'Icon', 'error');
                return;
            end

            atlasStruct = cortexData.Atlas;
            atlasNames = {atlasStruct.Name}; % 提取所有可用图谱的名称 (如 Desikan-Killiany, Destrieux)

            % 3. 图谱选择向导 (如果文件里打包了多个不同精度的图谱)
            if length(atlasNames) > 1
                [atlasIdx, tf] = listdlg('ListString', atlasNames, ...
                    'SelectionMode', 'single', ...
                    'PromptString', '请选择要使用的脑图谱划分标准:', ...
                    'Name', '选择图谱 (Atlas)', ...
                    'ListSize', [300, 150]);
                if ~tf
                    return; % 用户取消
                end
            else
                atlasIdx = 1; % 如果只有一个，直接默认使用
            end

            selectedAtlas = atlasStruct(atlasIdx);

            % 4. 🛡️ 次级安检：检测选中的图谱里是否有脑区 (Scouts)
            if ~isfield(selectedAtlas, 'Scouts') || isempty(selectedAtlas.Scouts)
                uialert(app.UIFigure, ['选中的图谱 "', selectedAtlas.Name, '" 中未划分具体的脑区 (Scouts)！'], ...
                    '图谱为空', 'Icon', 'error');
                return;
            end

            % 5. 脑区选择向导 (核心交互)
            scoutNames = {selectedAtlas.Scouts.Label}; % 提取所有脑区的英文/拼音名字
            [scoutIdx, tf2] = listdlg('ListString', scoutNames, ...
                'SelectionMode', 'multiple', ... % 🌟 允许按住 Ctrl 或 Shift 多选！
                'PromptString', {'请选择目标脑区 (ROI):', '(支持按住 Ctrl 多选合并)'}, ...
                'Name', ['选择脑区 - ' selectedAtlas.Name], ...
                'ListSize', [350, 400]);

            if ~tf2
                return; % 用户取消
            end

            % 6. 提取并合并所有选中脑区的顶点
            allSelectedVertices = [];
            for i = 1:length(scoutIdx)
                idx = scoutIdx(i);
                % Brainstorm 的 Scout.Vertices 默认就是该脑区包含的顶点 ID 数组
                allSelectedVertices = [allSelectedVertices, selectedAtlas.Scouts(idx).Vertices(:)'];
            end

            % 去重并转为行向量 (防止两个脑区在边界上有重叠顶点)
            allSelectedVertices = unique(allSelectedVertices);       
            app.selectedVertices = allSelectedVertices;
            
            % 在控制台打印详细日志，方便科研复查
            disp(['✅ 从 Atlas 成功提取脑区，共计 ', num2str(length(allSelectedVertices)), ' 个顶点。']);
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SourceLevelSettingPanel = uipanel(app.UIFigure);
            app.SourceLevelSettingPanel.Title = 'Source Level Setting';
            app.SourceTimeSeriesPanel = uipanel(app.SourceLevelSettingPanel);
            app.SourceTimeSeriesPanel.Title = 'Source Time Series';
            app.TimeRangeEditFieldLabel = uilabel(app.SourceTimeSeriesPanel);
            app.TimeRangeEditFieldLabel.Text = 'Time Range';
            app.TimeRangeEditField = uieditfield(app.SourceTimeSeriesPanel, 'text');
            app.TimeRangeEditField.Value = '[-1, 1]';
            app.ERPCheckBox = uicheckbox(app.SourceTimeSeriesPanel);
            app.ERPCheckBox.ValueChangedFcn = createCallbackFcn(app, @ERPCheckBoxValueChanged, true);
            app.ERPCheckBox.Text = 'ERP';
            app.ERSPCheckBox = uicheckbox(app.SourceTimeSeriesPanel);
            app.ERSPCheckBox.ValueChangedFcn = createCallbackFcn(app, @ERSPCheckBoxValueChanged, true);
            app.ERSPCheckBox.Text = 'ERSP';
            app.ERP_GaborCheckBox = uicheckbox(app.SourceTimeSeriesPanel);
            app.ERP_GaborCheckBox.ValueChangedFcn = createCallbackFcn(app, @ERP_GaborCheckBoxValueChanged, true);
            app.ERP_GaborCheckBox.Text = 'ERP_Gabor';
            app.Gauss_PulseCheckBox = uicheckbox(app.SourceTimeSeriesPanel);
            app.Gauss_PulseCheckBox.ValueChangedFcn = createCallbackFcn(app, @Gauss_PulseCheckBoxValueChanged, true);
            app.Gauss_PulseCheckBox.Text = 'Gauss_Pulse';
            app.Shap_spikeCheckBox = uicheckbox(app.SourceTimeSeriesPanel);
            app.Shap_spikeCheckBox.ValueChangedFcn = createCallbackFcn(app, @Shap_spikeCheckBoxValueChanged, true);
            app.Shap_spikeCheckBox.Text = 'Shap_spike';
            app.NMM_spikeCheckBox = uicheckbox(app.SourceTimeSeriesPanel);
            app.NMM_spikeCheckBox.ValueChangedFcn = createCallbackFcn(app, @NMM_spikeCheckBoxValueChanged, true);
            app.NMM_spikeCheckBox.Text = 'NMM_spike';
            app.Rest_sinCheckBox = uicheckbox(app.SourceTimeSeriesPanel);
            app.Rest_sinCheckBox.ValueChangedFcn = createCallbackFcn(app, @Rest_sinCheckBoxValueChanged, true);
            app.Rest_sinCheckBox.Text = 'Rest_sin';
            app.Rest_2CheckBox = uicheckbox(app.SourceTimeSeriesPanel);
            app.Rest_2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Rest_2CheckBoxValueChanged, true);
            app.Rest_2CheckBox.Text = 'Rest_2';
            app.ARCheckBox = uicheckbox(app.SourceTimeSeriesPanel);
            app.ARCheckBox.ValueChangedFcn = createCallbackFcn(app, @ARCheckBoxValueChanged, true);
            app.ARCheckBox.Text = 'AR';
            app.Pink_noiseCheckBox = uicheckbox(app.SourceTimeSeriesPanel);
            app.Pink_noiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @Pink_noiseCheckBoxValueChanged, true);
            app.Pink_noiseCheckBox.Text = 'Pink_noise';
            app.AddCheckBox = uicheckbox(app.SourceTimeSeriesPanel);
            app.AddCheckBox.Text = 'Add';
            app.PreviewButton = uibutton(app.SourceTimeSeriesPanel, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.SamplingRateEditFieldLabel = uilabel(app.SourceTimeSeriesPanel);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateEditField = uieditfield(app.SourceTimeSeriesPanel, 'numeric');
            app.SamplingRateEditField.Value = 100;
            app.SourcePatchGenerationPanel = uipanel(app.SourceLevelSettingPanel);
            app.SourcePatchGenerationPanel.Title = ' Source Patch Generation';
            app.RandButton = uibutton(app.SourcePatchGenerationPanel, 'push');
            app.RandButton.ButtonPushedFcn = createCallbackFcn(app, @RandButtonPushed, true);
            app.RandButton.Text = 'Rand';
            app.LoadButton = uibutton(app.SourcePatchGenerationPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SelectfromAtlasButton = uibutton(app.SourcePatchGenerationPanel, 'push');
            app.SelectfromAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfromAtlasButtonPushed, true);
            app.SelectfromAtlasButton.Text = 'Select from Atlas';
            app.SelectfromcortexButton = uibutton(app.SourcePatchGenerationPanel, 'push');
            app.SelectfromcortexButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfromcortexButtonPushed, true);
            app.SelectfromcortexButton.Text = 'Select from cortex';
            app.PatchAreamm2EditFieldLabel = uilabel(app.SourcePatchGenerationPanel);
            app.PatchAreamm2EditFieldLabel.Text = 'Patch Area/mm2';
            app.PatchAreamm2EditField = uieditfield(app.SourcePatchGenerationPanel, 'text');
            app.PatchAreamm2EditField.Value = '10';
            app.DecayModelDropDownLabel = uilabel(app.SourcePatchGenerationPanel);
            app.DecayModelDropDownLabel.Text = 'Decay Model';
            app.DecayModelDropDown = uidropdown(app.SourcePatchGenerationPanel);
            app.DecayModelDropDown.Items = {'Gaussian', 'Linear', 'Flat', 'Exponential'};
            app.DecayModelDropDown.Value = 'Gaussian';
            app.PowerDecayEditFieldLabel = uilabel(app.SourcePatchGenerationPanel);
            app.PowerDecayEditFieldLabel.Text = 'Power Decay/%';
            app.PowerDecayEditField = uieditfield(app.SourcePatchGenerationPanel, 'text');
            app.PowerDecayEditField.Value = '10';
            app.SeedVoxelsEditField_2Label = uilabel(app.SourcePatchGenerationPanel);
            app.SeedVoxelsEditField_2Label.Text = 'Seed Voxels';
            app.SeedVoxelsEditField = uieditfield(app.SourcePatchGenerationPanel, 'numeric');
            app.SeedVoxelsEditField.Value = 1;
            app.AddSourceButton = uibutton(app.SourceLevelSettingPanel, 'push');
            app.AddSourceButton.ButtonPushedFcn = createCallbackFcn(app, @AddSourceButtonPushed, true);
            app.AddSourceButton.Text = 'Add Source';
            app.SensorLevelSettingPanel = uipanel(app.UIFigure);
            app.SensorLevelSettingPanel.Title = 'Sensor Level Setting';
            app.LoadRealisticNoiseButton = uibutton(app.SensorLevelSettingPanel, 'push');
            app.LoadRealisticNoiseButton.Text = 'Load Realistic Noise';
            app.SimulateNoiseButton = uibutton(app.SensorLevelSettingPanel, 'push');
            app.SimulateNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateNoiseButtonPushed, true);
            app.SimulateNoiseButton.Text = 'Simulate Noise';
            app.SetBackgroundActivityButton = uibutton(app.SensorLevelSettingPanel, 'push');
            app.SetBackgroundActivityButton.ButtonPushedFcn = createCallbackFcn(app, @SetBackgroundActivityButtonPushed, true);
            app.SetBackgroundActivityButton.Text = 'Set Background Activity';
            app.SourceListPanel = uipanel(app.UIFigure);
            app.SourceListPanel.Title = 'Source List';
            app.SourceTable = uitable(app.SourceListPanel);
            app.SourceTable.ColumnName = {'No.'; 'Vertices'; 'Stimulus'};
            app.SourceTable.RowName = {};
            app.SourceTable.SelectionType = 'row';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.SourceListMenu = uicontextmenu(app.UIFigure);
            app.deleteMenu = uimenu(app.SourceListMenu);
            app.deleteMenu.MenuSelectedFcn = createCallbackFcn(app, @deleteMenuSelected, true);
            app.deleteMenu.Text = 'delete';
            % Assign app.SourceListMenu
            app.SourceTable.ContextMenu = app.SourceListMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SEAL_Simulation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end