classdef SEAL_selectAlgorithm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GeneralSettingsPanel            matlab.ui.container.Panel
        RunSelectedAlgorithmsButton     matlab.ui.control.Button
        SelectPriorButton               matlab.ui.control.Button
        LoadPriorButton                 matlab.ui.control.Button
        NoiseWhiteningDropDown          matlab.ui.control.DropDown
        NoiseWhiteningDropDown_2Label   matlab.ui.control.Label
        DepthWeightingEditField         matlab.ui.control.NumericEditField
        DepthWeightingEditFieldLabel    matlab.ui.control.Label
        DipoleOrienationtDropDown       matlab.ui.control.DropDown
        DipoleOrienationtDropDownLabel  matlab.ui.control.Label
        AlgorithmsPanel                 matlab.ui.container.Panel
        AlgorithmsTree                  matlab.ui.container.CheckBoxTree
        MinimumL2_NormNode              matlab.ui.container.TreeNode
        MNENode                         matlab.ui.container.TreeNode
        dSPMNode                        matlab.ui.container.TreeNode
        sLORETANode                     matlab.ui.container.TreeNode
        eLORETANode                     matlab.ui.container.TreeNode
        LORETANode                      matlab.ui.container.TreeNode
        LAURANode                       matlab.ui.container.TreeNode
        BeamformerNode                  matlab.ui.container.TreeNode
        LCMVNode                        matlab.ui.container.TreeNode
        Model_CovNode                   matlab.ui.container.TreeNode
        DICSNode                        matlab.ui.container.TreeNode
        MCMVNode                        matlab.ui.container.TreeNode
        SparsemodelNode                 matlab.ui.container.TreeNode
        Lp_LqNode                       matlab.ui.container.TreeNode
        ChampagneNode                   matlab.ui.container.TreeNode
        fullstructurenoiseNode          matlab.ui.container.TreeNode
        diagonalnoiselearningNode       matlab.ui.container.TreeNode
        addspatialbasisNode             matlab.ui.container.TreeNode
        BlkChamNode                     matlab.ui.container.TreeNode
        add_priorNode                   matlab.ui.container.TreeNode
        TSChamNode                      matlab.ui.container.TreeNode
        SmoothChamNode                  matlab.ui.container.TreeNode
        IRESNode                        matlab.ui.container.TreeNode
        SISSY_L1Node                    matlab.ui.container.TreeNode
        FOCUSSNode                      matlab.ui.container.TreeNode
        SpatioTemporalNode              matlab.ui.container.TreeNode
        MxNENode                        matlab.ui.container.TreeNode
        irMxNENode                      matlab.ui.container.TreeNode
        SISSY_L21Node                   matlab.ui.container.TreeNode
        VSSI_LpNode                     matlab.ui.container.TreeNode
        FastIRESNode                    matlab.ui.container.TreeNode
        STOUTNode                       matlab.ui.container.TreeNode
        dMAPNode                        matlab.ui.container.TreeNode
        BESTIESNode                     matlab.ui.container.TreeNode
        SISTBFNode                      matlab.ui.container.TreeNode
        uSTARNode                       matlab.ui.container.TreeNode
        STARTSNode                      matlab.ui.container.TreeNode
        sSTARTSNode                     matlab.ui.container.TreeNode
        VSSI_GGDNode                    matlab.ui.container.TreeNode
        ContextMenu                     matlab.ui.container.ContextMenu
        ImportDataMenu                  matlab.ui.container.Menu
    end


    properties (Access = private)
        protocolinfo = {}
        Data
        Cortex = {}
        LeadField = {}
        LeadField_fixed = {}
        DialogApp_datacheck
        AdditionalDataNode = {}
        AdditionalDataNodeNum = 0
        AdditionalData={}
        verticalSpacing = 9
        horizontalSpacing = 9
        allPanels = {}
        originalheight
        originalwidth
        TrialIndices
        MinimumL2_NormUIFigure
        SpatioUIFigure
        NumOrientations=3
        DepthWeightExp=0.5
        Noisetag=1
        Callingapp
        CurrentID
        SaveIndex
        protocolNode ProtocolNode
        sessionNode SessionNode
        dataNode DataNode
        resultdata=struct();
        counters=zeros(50,1);
        trials = 1
        SavePath
        srate
    end

    properties (Access = public)
        MNESettingsPanel %自定义控件
        MNEsourceCovarianceEditField
        MNEsourceCovarianceLabel
        MNERegularizationParameterEditField
        MNERegularizationParameterLabel
        RegularizationParameter
        sourceCovariance
        dSPMSettingsPanel
        sLORETASettingsPanel
        eLORETASettingsPanel
        LORETASettingsPanel
        LAURASettingsPanel
        STARTSSettingsPanel
        STARTSNumTBFsEditField
        STARTSNumTBFsLabel
        NumTBFs = 4
        STARTSNeiborOrderEditField
        STARTSNeiborOrderLabel
        NeighborOrder = 2
        STARTLearnNoiseDropDown
        STARTSLearnNoiseLabel
        LearnNoise = false
        STARTNoiseObservationDropDown
        STARTSNoiseObservationLabel
        NoiseObservationMatrix
        SparseModeUIFigure
        blockChampagneSettingsPanel
        blockSNeiborOrderEditField
        blockSNeiborOrderLabel
        blockLearnNoiseDropDown
        blockSLearnNoiseLabel
        blockNoiseObservationDropDown
        blockNoiseObservationLabel
        blockSAtlasConstraintsEditField
        blockSAtlasConstraintsLabel
        uSTARSettingsPanel
        uSTARNumTBFsEditField
        uSTARNumTBFsLabel
        uSTARMicrostateMapsLabel
        uSTARMicrostateMapDropDown
        MicrostateMaps = []
        uSTARMicrostateOptionsLabel
        uSTARRunAlgorithmDropDown
        uSTARRunAlgorithmLabel
        uSTARRunAlgorithm= 'STAR'
        uSTARPriorTypeDropDown
        uSTARPriorTypeLabel
        importMicrostateOptionsButton
        importMicrostateMapsButton
        firsttime = 1

    end


    methods (Access = private)
        function applyLayout(app, panel,allvisiblePanels)
            % 收集可见面板
            visiblePanels = {};

            for i = 1:length(allvisiblePanels)

                visiblePanels{end+1} = allvisiblePanels{i};

            end

            if isempty(visiblePanels)
                return; % 没有可见面板，不执行布局
            end

            % 获取面板高度（假设所有面板宽度一致）
            panelWidth = visiblePanels{1}.Position(3); % 面板宽度
            panelHeights = cellfun(@(p) p.Position(4), visiblePanels);

            % 计算每列面板高度（包括间距）
            columnHeights = zeros(1, length(visiblePanels));
            columns = {};
            currentColumn = 1;
            columns{currentColumn} = [];
            % 按高度降序排序，保证大的面板在左边
            [sortedHeights, sortIdx] = sort(panelHeights, 'descend');
            visiblePanels = visiblePanels(sortIdx);

            % 第一列计算
            for i = 1:length(visiblePanels)
                % 如果当前列的高度小于UIFigure的高度-40（上下边距）
                if columnHeights(currentColumn) + sortedHeights(i)<= app.originalheight-2*app.verticalSpacing
                    columns{currentColumn}(end+1) = i;
                    columnHeights(currentColumn) = columnHeights(currentColumn) + sortedHeights(i) + app.verticalSpacing;
                else
                    % 当前列放不下，创建新列
                    currentColumn = currentColumn + 1;
                    % 确保新列被正确初始化
                    columns{currentColumn} = i; % 新列的第一个元素
                    columnHeights(currentColumn) = sortedHeights(i) + app.verticalSpacing;
                end
            end

            % 调整高度（加上最后一个面板的底部间距）
            columnHeights = columnHeights + app.verticalSpacing;

            %剔除多余列
            for i = length(columnHeights):-1:1
                if columnHeights(i)==9
                    columnHeights(i)=[];
                end
            end
            % 按列高度降序排序，高的列在左边
            [~, colOrder] = sort(columnHeights, 'descend');
            columns = columns(colOrder);

            % 计算UIFigure新宽度
            newWidth =  (panelWidth + app.horizontalSpacing) * length(columns) + app.horizontalSpacing;


            % 设置UIFigure新尺寸
            panel.Position = [panel.Position(1:2) newWidth app.originalheight];
            %   app.PanelContainer.Position = [1 1 newWidth newHeight];

            % 布局面板
            currentX = app.horizontalSpacing;

            for colIdx = 1:length(columns)
                colPanels = columns{colIdx};
                currentY = panel.Position(4) - app.verticalSpacing;

                for panelIdx = colPanels
                    p = visiblePanels{panelIdx};
                    pHeight = p.Position(4);

                    % 定位面板
                    p.Position = [currentX currentY-pHeight panelWidth pHeight];

                    % 更新当前列Y位置
                    currentY = currentY - pHeight - app.verticalSpacing;
                end

                % 移动到下一列
                currentX = currentX + panelWidth + app.horizontalSpacing;
            end
            panel.Visible='on';
        end

        function MNEsourceCovarianceValueChanged(app, ~)
            app.sourceCovariance = app.MNEsourceCovarianceEditField.value;
        end

        function MNERegularizationParameterEditFieldValueChanged(app, ~)
            app.RegularizationParameter = app.MNERegularizationParameterEditField.value;
        end

        function closeMinL2FigureCallback(app, src, ~)
            delete(src);
            % 4. 重置属性
            app.MinimumL2_NormUIFigure = [];
        end

        function NodeDatas = getOnlyLeafNodes(app)
            % 获取所有被勾选的节点
            checkedNodes = app.AlgorithmsTree.CheckedNodes;

            % 如果没有任何节点被勾选，直接返回空字符串数组
            if isempty(checkedNodes)
                NodeDatas = strings(0);
                return;
            end

            % 1. 核心判断逻辑：找出所有“叶子节点”
            % isempty(n.Children) 如果为 true，说明它没有下级，是最底层的节点
            isLeaf = arrayfun(@(n) isempty(n.Children), checkedNodes);

            % 2. 过滤掉所有根节点和中间节点，只保留叶子节点
            leafNodes = checkedNodes(isLeaf);

            % 3. 提取保留下来的 NodeData
            NodeDatas = strings(0); % 初始化空字符串数组
            count = 1;

            for i = 1:length(leafNodes)
                if ~isempty(leafNodes(i).NodeData)
                    % 强制转换为 string 存入数组，防止 char 数组拼接报错
                    NodeDatas(count) = string(leafNodes(i).NodeData);
                    count = count + 1;
                end
            end
        end
        function results = deal_results(app ,Source)%  3V*T 数据降维 -> V*T
            temp = reshape(Source, 3, [], size(Source, 2));
            results = squeeze(sqrt(sum(temp.^2, 1)));    % 沿第一维（3行）计算平方和
        end

        function STARTSNumTBFsEditFieldValueChanged(app,~)
            app.NumTBFs=app.STARTSNumTBFsEditField.Value;
        end

        function STARTSNeiborOrderEditFieldValueChanged(app,~)
            app.NeighborOrder=app.STARTSNeiborOrderEditField.Value;
        end

        function STARTLearnNoiseDropDownValueChanged(app,~)
            app.LearnNoise=app.STARTLearnNoiseDropDown.Value;
        end

        function getIndex(app,filelist)
            for i=1:length(filelist)
                if contains(filelist(i).name, 'MNE')
                    app.counters(1)=app.counters(1)+1;
                elseif contains(filelist(i).name, 'STARTS')
                    app.counters(7)=app.counters(7)+1;
                elseif contains(filelist(i).name, 'BlockChampagne')
                    app.counters(8)=app.counters(8)+1;
                elseif contains(filelist(i).name, 'uSTAR')
                    app.counters(9)=app.counters(9)+1;
                end
                %为每一个都更新参数
            end
        end

        function uSTARRunAlgorithmDropDownValueChanged(app,~)
            app.uSTARRunAlgorithm=app.uSTARRunAlgorithmDropDown.Value;
        end

        function importMicrostateMapsButtonPushed(app,~)
            filterSpec = { ...
                '*.mat', 'MATLAB (.mat)'; ...
                '*.set', 'EEGLAB (.set)'; ...
                '*.*',   'All Files (*.*)'};

            [fileName, filePath,~] = uigetfile(filterSpec, ['Select ', strrep('Data', '_', ' '), ' File']);

            originalPath = fullfile(filePath, fileName);
            app.MicrostateMaps=load(originalPath);
            app.MicrostateMaps=struct2cell(app.MicrostateMaps);
            app.MicrostateMaps=app.MicrostateMaps{1};
            app.uSTARMicrostateMapDropDown.Items={fileName};
            app.uSTARMicrostateMapDropDown.Value=fileName;
        end
    
        function concatenated_data = concatenate_trials(app, data_3d)

            % 获取数组尺寸
            [n_channels, n_points_per_trial, n_trials] = size(data_3d);

            % 预分配拼接后的二维矩阵
            total_points = n_points_per_trial * n_trials;
            concatenated_data = zeros(n_channels, total_points);

            % 循环处理每个试次
            for trial = 1:n_trials
                % 计算当前试次的起始和结束索引
                start_idx = (trial - 1) * n_points_per_trial + 1;
                end_idx = trial * n_points_per_trial;

                % 提取当前试次数据
                current_trial_data = data_3d(:, :, trial);

                % 将数据放入拼接矩阵
                concatenated_data(:, start_idx:end_idx) = current_trial_data;


            end

        end


        function closeAllWindows(app)
            if isvalid(app.MinimumL2_NormUIFigure), delete(app.MinimumL2_NormUIFigure); end
            if isvalid(app.SpatioUIFigure), delete(app.SpatioUIFigure); end
            if isvalid(app.SparseModeUIFigure), delete(app.SparseModeUIFigure); end
            delete(app.UIFigure);
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, selectednode, firsttime)
            app.UIFigure.Name='Select Algorithms';
            app.Callingapp=mainapp;
%%调整app窗口位置
            if ~isempty(app.Callingapp) && isvalid(app.Callingapp)
                % 1. 获取主窗口的位置信息 [Left, Bottom, Width, Height]
                mainPos = app.Callingapp.UIFigure.Position;
                
                % 2. 获取当前子窗口的自身大小
                childWidth = app.UIFigure.Position(3);
                childHeight = app.UIFigure.Position(4);
                
                % 3. 核心计算：让子窗口的中心对准主窗口的中心
                % 新的左边距 = 主窗口的左边距 + (主窗口宽度 - 子窗口宽度) / 2
                newLeft = mainPos(1) + (mainPos(3) - childWidth) / 2;
                
                % 新的下边距 = 主窗口的下边距 + (主窗口高度 - 子窗口高度) / 2
                newBottom = mainPos(2) + (mainPos(4) - childHeight) / 2;
                
                % 4. 强制刷新子窗口的位置
                app.UIFigure.Position = [newLeft, newBottom, childWidth, childHeight];
                
            else
                % 兜底防御：如果直接单独运行了这个子 App（没有主窗口），那就让它在屏幕中央显示
                movegui(app.UIFigure, 'center');
            end
%%
            app.dataNode = selectednode.NodeData;
            app.srate = app.dataNode.srate;
            tempdata = app.dataNode.data; 

            if ndims(tempdata)==3
                app.Data= app.concatenate_trials(tempdata);
            else
                app.Data= tempdata;
            end

            app.sessionNode = selectednode.NodeData.parent;
            app.protocolNode=selectednode.NodeData.parent.parent;
            %需要转存才能访问
            app.originalheight=app.UIFigure.Position(4);
            app.originalwidth=app.UIFigure.Position(3);
            app.MinimumL2_NormUIFigure = [];
            app.SpatioUIFigure = [];
        end

        % Menu selected function: ImportDataMenu
        function ImportDataMenuSelected(app, event)
            filterSpec = { ...
                '*.mat', 'MATLAB (.mat)'; ...
                '*.set', 'EEGLAB (.set)'; ...
                '*.*',   'All Files (*.*)'};

            [fileName, filePath,~] = uigetfile(filterSpec, ['Select ', strrep('Data', '_', ' '), ' File']);

            originalPath = fullfile(filePath, fileName);

            app.AdditionalDataNodeNum = app.AdditionalDataNodeNum + 1;
            app.AdditionalData{app.AdditionalDataNodeNum}.filename=fileName;
            app.AdditionalData{app.AdditionalDataNodeNum}.filepath=filePath;

            app.AdditionalDataNode{app.AdditionalDataNodeNum}= uitreenode(app.DataTree, ...
                'Text', ['Data: ', fileName], 'NodeData',originalPath);

        end

        % Callback function: AlgorithmsTree
        function AlgorithmsTreeCheckedNodesChanged(app, event)
            % 步骤4: 删除图形窗口本身
            delete(app.SparseModeUIFigure);
            delete(app.MinimumL2_NormUIFigure);
            delete(app.SpatioUIFigure);
            app.MinimumL2_NormUIFigure = [];
            app.SpatioUIFigure=[];

            NodeDatas = app.getOnlyLeafNodes();

            app.MinimumL2_NormUIFigure = uifigure('Visible', 'off');
            app.MinimumL2_NormUIFigure.AutoResizeChildren = 'off';
            app.MinimumL2_NormUIFigure.Position = ...
                [app.UIFigure.Position(1)+app.UIFigure.Position(3) app.UIFigure.Position(2) 563 264];
            app.MinimumL2_NormUIFigure.Name = 'MinimumL2_Norm Settings';
            app.MinimumL2_NormUIFigure.CloseRequestFcn = @(src,event) app.closeMinL2FigureCallback(src, event);

            app.SpatioUIFigure = uifigure('Visible', 'off');
            app.SpatioUIFigure.AutoResizeChildren = 'off';
            app.SpatioUIFigure.Position = ...
                [app.UIFigure.Position(1)+app.UIFigure.Position(3) app.UIFigure.Position(2) 563 264];
            app.SpatioUIFigure.Name = 'Spatio Temporal Settings';


            app.SparseModeUIFigure = uifigure('Visible', 'off');
            app.SparseModeUIFigure.AutoResizeChildren = 'off';
            app.SparseModeUIFigure.Position = ...
                [app.UIFigure.Position(1)+app.UIFigure.Position(3) app.UIFigure.Position(2) 563 264];
            app.SparseModeUIFigure.Name = 'SparseMode Settings';


            allsparsemodePanel={};
            allminimumL2NormPanels={};
            allspatioPanels={};
            %遍历NodeData
            for i=1:length(NodeDatas)
                switch NodeDatas(i)
                    case 'MNE'
                        app.MNESettingsPanel = uipanel(app.MinimumL2_NormUIFigure);
                        app.MNESettingsPanel.Title = 'MNE Settings';
                        app.MNESettingsPanel.FontWeight = 'bold';
                        app.MNESettingsPanel.Position = [381+180 10 174 145];
                        app.MNESettingsPanel.Visible = 'on';

                        allminimumL2NormPanels = [allminimumL2NormPanels,{app.MNESettingsPanel}];

                        app.MNEsourceCovarianceEditField = uieditfield(app.MNESettingsPanel);
                        app.MNEsourceCovarianceEditField.Position = [70 10 100 22];
                        app.MNEsourceCovarianceEditField.Value = 'depth_weighted';
                        app.MNEsourceCovarianceEditField.ValueChangedFcn = ...
                            createCallbackFcn(app, @MNEsourceCovarianceEditFieldValueChanged, true);
                        app.MNEsourceCovarianceLabel = uilabel(app.MNESettingsPanel);
                        app.MNEsourceCovarianceLabel.Position = [3 40 100 22];
                        app.MNEsourceCovarianceLabel.Text = 'SourceCovariance';

                        app.MNERegularizationParameterEditField = uieditfield(app.MNESettingsPanel,'numeric');
                        app.MNERegularizationParameterEditField.Position = [70 70 100 22];
                        app.MNERegularizationParameterEditField.Value = 0.1;
                        app.MNERegularizationParameterEditField.ValueChangedFcn = ...
                            createCallbackFcn(app, @MNERegularizationParameterEditFieldValueChanged, true);
                        app.MNERegularizationParameterLabel = uilabel(app.MNESettingsPanel);
                        app.MNERegularizationParameterLabel.Position = [3 100 150 22];
                        app.MNERegularizationParameterLabel.Text = 'RegularizationParameter';
                    case 'dSPM'
                        app.dSPMSettingsPanel = uipanel(app.MinimumL2_NormUIFigure);
                        app.dSPMSettingsPanel.Title = 'dSPM Settings';
                        app.dSPMSettingsPanel.FontWeight = 'bold';
                        app.dSPMSettingsPanel.Position = [381+180 10 174 100];
                        app.dSPMSettingsPanel.Visible='on';

                        allminimumL2NormPanels=[allminimumL2NormPanels,{app.dSPMSettingsPanel}];
                    case 'sLORETA'
                        app.sLORETASettingsPanel = uipanel(app.MinimumL2_NormUIFigure);
                        app.sLORETASettingsPanel.Title = 'sLORETA Settings';
                        app.sLORETASettingsPanel.FontWeight = 'bold';
                        app.sLORETASettingsPanel.Position = [381+180 10 174 52];
                        app.sLORETASettingsPanel.Visible='on';

                        allminimumL2NormPanels=[allminimumL2NormPanels,{app.sLORETASettingsPanel}];
                    case 'eLORETA'
                        app.eLORETASettingsPanel = uipanel(app.MinimumL2_NormUIFigure);
                        app.eLORETASettingsPanel.Title = 'eLORETA Settings';
                        app.eLORETASettingsPanel.FontWeight = 'bold';
                        app.eLORETASettingsPanel.Position = [381+180 10 174 150];
                        app.eLORETASettingsPanel.Visible='on';

                        allminimumL2NormPanels=[allminimumL2NormPanels,{app.eLORETASettingsPanel}];
                    case 'LORETA'
                        app.LORETASettingsPanel = uipanel(app.MinimumL2_NormUIFigure);
                        app.LORETASettingsPanel.Title = 'LORETA Settings';
                        app.LORETASettingsPanel.FontWeight = 'bold';
                        app.LORETASettingsPanel.Position = [381+180 10 174 75];
                        app.LORETASettingsPanel.Visible='on';

                        allminimumL2NormPanels=[allminimumL2NormPanels,{app.LORETASettingsPanel}];
                    case 'LAURA'
                        app.LAURASettingsPanel = uipanel(app.MinimumL2_NormUIFigure);
                        app.LAURASettingsPanel.Title = 'LAURA Settings';
                        app.LAURASettingsPanel.FontWeight = 'bold';
                        app.LAURASettingsPanel.Position = [381+180 10 174 175];
                        app.LAURASettingsPanel.Visible='on';

                        allminimumL2NormPanels=[allminimumL2NormPanels,{app.LAURASettingsPanel}];
                    case 'STARTS'%STARTS
                        app.STARTSSettingsPanel = uipanel(app.SpatioUIFigure);
                        app.STARTSSettingsPanel.Title = 'STARTS Settings';
                        app.STARTSSettingsPanel.FontWeight = 'bold';
                        app.STARTSSettingsPanel.Position = [10 10 174 226];
                        app.STARTSSettingsPanel.Visible = 'on';

                        allspatioPanels = [allspatioPanels,{app.STARTSSettingsPanel}];

                        app.STARTSNumTBFsEditField = uieditfield(app.STARTSSettingsPanel,'numeric');
                        app.STARTSNumTBFsEditField.Position = [70 10 100 22];
                        app.STARTSNumTBFsEditField.Value = 4 ;
                        app.STARTSNumTBFsEditField.ValueChangedFcn = ...
                            createCallbackFcn(app, @STARTSNumTBFsEditFieldValueChanged, true);
                        app.STARTSNumTBFsLabel = uilabel(app.STARTSSettingsPanel);
                        app.STARTSNumTBFsLabel.Position = [3 35 100 22];
                        app.STARTSNumTBFsLabel.Text = 'NumTBFs';

                        app.STARTSNeiborOrderEditField = uieditfield(app.STARTSSettingsPanel,'numeric');
                        app.STARTSNeiborOrderEditField.Position = [70 60 100 22];
                        app.STARTSNeiborOrderEditField.Value = 1;
                        app.STARTSNeiborOrderEditField.ValueChangedFcn = ...
                            createCallbackFcn(app, @STARTSNeiborOrderEditFieldValueChanged, true);
                        app.STARTSNeiborOrderLabel = uilabel(app.STARTSSettingsPanel);
                        app.STARTSNeiborOrderLabel.Position = [3 85 150 22];
                        app.STARTSNeiborOrderLabel.Text = 'NeiborOrder';

                        app.STARTLearnNoiseDropDown = uidropdown(app.STARTSSettingsPanel);
                        app.STARTLearnNoiseDropDown.Items = {'true'};
                        app.STARTLearnNoiseDropDown.ValueChangedFcn = ...
                            createCallbackFcn(app, @STARTLearnNoiseDropDownValueChanged, true);
                        app.STARTLearnNoiseDropDown.Position = [70 110 100 22];
                        app.STARTLearnNoiseDropDown.Value = 'true';
                        app.STARTSLearnNoiseLabel = uilabel(app.STARTSSettingsPanel);
                        app.STARTSLearnNoiseLabel.Position = [3 135 150 22];
                        app.STARTSLearnNoiseLabel.Text = 'LearnNoise';

                        app.STARTNoiseObservationDropDown = uidropdown(app.STARTSSettingsPanel);
                        app.STARTNoiseObservationDropDown.Items = {'eye'};
                        app.STARTNoiseObservationDropDown.ValueChangedFcn = ...
                            createCallbackFcn(app, @STARTNoiseObservationDropDownValueChanged, true);%回调尚未编写
                        app.STARTNoiseObservationDropDown.Position = [70 160 100 22];
                        app.STARTNoiseObservationDropDown.Value = 'eye';
                        app.STARTSNoiseObservationLabel = uilabel(app.STARTSSettingsPanel);
                        app.STARTSNoiseObservationLabel.Position = [3 185 150 22];
                        app.STARTSNoiseObservationLabel.Text = 'NoiseObservation';
                    case 'BlockChampagne' %blockChampagne //add_priorNode
                        app.blockChampagneSettingsPanel = uipanel(app.SparseModeUIFigure);
                        app.blockChampagneSettingsPanel.Title = 'BlockChampagne Settings';
                        app.blockChampagneSettingsPanel.FontWeight = 'bold';
                        app.blockChampagneSettingsPanel.Position = [381+180 10 174 226];
                        app.blockChampagneSettingsPanel.Visible = 'on';

                        allsparsemodePanel = [allsparsemodePanel,{app.blockChampagneSettingsPanel}];

                        app.blockSAtlasConstraintsEditField = uieditfield(app.blockChampagneSettingsPanel,'numeric');
                        app.blockSAtlasConstraintsEditField.Position = [70 10 100 22];
                        %app.blockSAtlasConstraintsEditField.Value = [];
                        app.blockSAtlasConstraintsEditField.ValueChangedFcn = ...
                            createCallbackFcn(app, @blockSAtlasConstraintsEditFieldValueChanged, true);
                        app.blockSAtlasConstraintsLabel = uilabel(app.blockChampagneSettingsPanel);
                        app.blockSAtlasConstraintsLabel.Position = [3 35 150 22];
                        app.blockSAtlasConstraintsLabel.Text = 'AtlasConstraints';

                        app.blockSNeiborOrderEditField = uieditfield(app.blockChampagneSettingsPanel,'numeric');
                        app.blockSNeiborOrderEditField.Position = [70 60 100 22];
                        app.blockSNeiborOrderEditField.Value = 1;
                        app.blockSNeiborOrderEditField.ValueChangedFcn = ...
                            createCallbackFcn(app, @blockSNeiborOrderEditFieldValueChanged, true);
                        app.blockSNeiborOrderLabel = uilabel(app.blockChampagneSettingsPanel);
                        app.blockSNeiborOrderLabel.Position = [3 85 150 22];
                        app.blockSNeiborOrderLabel.Text = 'NeiborOrder';

                        app.blockLearnNoiseDropDown = uidropdown(app.blockChampagneSettingsPanel);
                        app.blockLearnNoiseDropDown.Items = {'true'};
                        app.blockLearnNoiseDropDown.ValueChangedFcn = ...
                            createCallbackFcn(app, @blockLearnNoiseDropDownValueChanged, true);
                        app.blockLearnNoiseDropDown.Position = [70 110 100 22];
                        app.blockLearnNoiseDropDown.Value = 'true';
                        app.blockSLearnNoiseLabel = uilabel(app.blockChampagneSettingsPanel);
                        app.blockSLearnNoiseLabel.Position = [3 135 150 22];
                        app.blockSLearnNoiseLabel.Text = 'LearnNoise';

                        app.blockNoiseObservationDropDown = uidropdown(app.blockChampagneSettingsPanel);
                        app.blockNoiseObservationDropDown.Items = {'eye'};
                        app.blockNoiseObservationDropDown.ValueChangedFcn = ...
                            createCallbackFcn(app, @blockNoiseObservationDropDownValueChanged, true);%回调尚未编写
                        app.blockNoiseObservationDropDown.Position = [70 160 100 22];
                        app.blockNoiseObservationDropDown.Value = 'eye';
                        app.blockNoiseObservationLabel = uilabel(app.blockChampagneSettingsPanel);
                        app.blockNoiseObservationLabel.Position = [3 185 150 22];
                        app.blockNoiseObservationLabel.Text = 'NoiseObservation';
                    case 'uSTAR'
                        app.uSTARSettingsPanel = uipanel(app.SpatioUIFigure);
                        app.uSTARSettingsPanel.Title = 'uSTAR Settings';
                        app.uSTARSettingsPanel.FontWeight = 'bold';
                        app.uSTARSettingsPanel.Position = [381+180 10 174 226];
                        app.uSTARSettingsPanel.Visible = 'on';

                        allspatioPanels = [allspatioPanels,{app.uSTARSettingsPanel}];

                        app.uSTARNumTBFsEditField = uieditfield(app.uSTARSettingsPanel,'numeric');
                        app.uSTARNumTBFsEditField.Position = [70 10 100 22];
                        app.uSTARNumTBFsEditField.Value = 4 ;
                        app.uSTARNumTBFsEditField.ValueChangedFcn = ...
                            createCallbackFcn(app, @uSTARNumTBFsEditFieldValueChanged, true);
                        app.uSTARNumTBFsLabel = uilabel(app.uSTARSettingsPanel);
                        app.uSTARNumTBFsLabel.Position = [3 35 100 22];
                        app.uSTARNumTBFsLabel.Text = 'NumTBFs';

                        app.uSTARMicrostateMapsLabel = uilabel(app.uSTARSettingsPanel);
                        app.uSTARMicrostateMapsLabel.Position = [3 85 100 22];
                        app.uSTARMicrostateMapsLabel.Text = 'MicrostateMaps';
                        app.importMicrostateMapsButton = uibutton(app.uSTARSettingsPanel, 'push');
                        app.importMicrostateMapsButton.ButtonPushedFcn = ...
                            createCallbackFcn(app, @importMicrostateMapsButtonPushed, true);
                        app.importMicrostateMapsButton.Position = [90 85 22 22];
                        app.importMicrostateMapsButton.Text = '...';
                        app.uSTARMicrostateMapDropDown = uidropdown(app.uSTARSettingsPanel);
                        app.uSTARMicrostateMapDropDown.Items = {'none'};
                        app.uSTARMicrostateMapDropDown.ValueChangedFcn = ...
                            createCallbackFcn(app, @uSTARMicrostateMapDropDownValueChanged, true);
                        app.uSTARMicrostateMapDropDown.Position = [115 85 60 22];
                        app.uSTARMicrostateMapDropDown.Value = 'none';

                        app.uSTARMicrostateOptionsLabel = uilabel(app.uSTARSettingsPanel);
                        app.uSTARMicrostateOptionsLabel.Position = [3 60 150 22];
                        app.uSTARMicrostateOptionsLabel.Text = 'MicrostateOptions';
                        app.importMicrostateOptionsButton = uibutton(app.uSTARSettingsPanel, 'push');
                        app.importMicrostateOptionsButton.ButtonPushedFcn = ...
                            createCallbackFcn(app, @importMicrostateOptionsButtonPushed, true);
                        app.importMicrostateOptionsButton.Position = [110 60 22 22];
                        app.importMicrostateOptionsButton.Text = '...';

                        app.uSTARRunAlgorithmDropDown = uidropdown(app.uSTARSettingsPanel);
                        app.uSTARRunAlgorithmDropDown.Items = {'STAR','sSTARTS'};
                        app.uSTARRunAlgorithmDropDown.ValueChangedFcn = ...
                            createCallbackFcn(app, @uSTARRunAlgorithmDropDownValueChanged, true);
                        app.uSTARRunAlgorithmDropDown.Position = [70 110 100 22];
                        app.uSTARRunAlgorithmDropDown.Value = 'STAR';
                        app.uSTARRunAlgorithmLabel = uilabel(app.uSTARSettingsPanel);
                        app.uSTARRunAlgorithmLabel.Position = [3 135 150 22];
                        app.uSTARRunAlgorithmLabel.Text = 'RunAlgorithm';

                        app.uSTARPriorTypeDropDown = uidropdown(app.uSTARSettingsPanel);
                        app.uSTARPriorTypeDropDown.Items = {'Laplacian','LAURA'};
                        app.uSTARPriorTypeDropDown.ValueChangedFcn = ...
                            createCallbackFcn(app, @uSTARPriorTypeDropDownValueChanged, true);
                        app.uSTARPriorTypeDropDown.Position = [70 160 100 22];
                        app.uSTARPriorTypeDropDown.Value = 'Laplacian';
                        app.uSTARPriorTypeLabel = uilabel(app.uSTARSettingsPanel);
                        app.uSTARPriorTypeLabel.Position = [3 185 150 22];
                        app.uSTARPriorTypeLabel.Text = 'PriorType';

                end
            end
            app.applyLayout(app.SparseModeUIFigure,allsparsemodePanel);
            app.applyLayout(app.SpatioUIFigure,allspatioPanels);
            app.applyLayout(app.MinimumL2_NormUIFigure,allminimumL2NormPanels);
        end

        % Button pushed function: RunSelectedAlgorithmsButton
        function RunSelectedAlgorithmsButtonPushed(app, event)

            % ========== 1. 准备阶段（不变） ==========
            app.SavePath = fullfile(app.sessionNode.path, "Results");
            if ~isfolder(app.SavePath)
                mkdir(app.SavePath);
            end
            N_all = numel(app.Data); % 获取矩阵总元素个数 (Channels * Time)

            % --- 核心计算 (二选一，性能一样极速) ---
            % 纯正 F 范数写法：
            avg_power_point = (norm(app.Data, 'fro')^2) / N_all;

            disp(['单点平均能量: ', num2str(avg_power_point)]);

            if avg_power_point < 10
                % 【情况 A：能量太弱】
                % 设定一个安全的目标能量，比如 10~100 的中值 50
                target_power = 50;

                % 核心数学原理：数据倍数 = sqrt(目标能量 / 当前能量)
                scale_factor = sqrt(target_power / avg_power_point);

                % 整体乘法缩放矩阵
                app.Data = app.Data * scale_factor;
                disp(['⚠️ 能量过小，已将矩阵整体放大。缩放系数: ', num2str(scale_factor)]);
            end
            %             app.Data= app.Data *1000000;
            RegularizationParameterEditField = 0.1;
            NoiseCovariance = eye(size(app.Data, 1));
            app.sourceCovariance = 'depth_weighted';

            nColumns_L = size(app.protocolNode.leadfieldNode.data, 2);
            nPhysicalSources = size(app.protocolNode.cortexNode.data.Vertices, 1);
            nd = nColumns_L / nPhysicalSources;
            if nd ~= 1 && nd ~= 3
                error('SEAL:Algorithm:DimensionMismatch', ...
                    '导联场维度异常！');
            end
            app.NumOrientations = nd;

            NodeDatas = app.getOnlyLeafNodes();

            % ========== 2. 只弹一次文件夹选择 ==========
            savePath = uigetdir(app.SavePath, '选择结果保存文件夹');
            if isequal(savePath, 0)
                return;  % 用户取消
            end

            % ========== 3. 构建公共参数（避免重复） ==========
            baseStruct = struct();
            baseStruct.NumOrientations = app.NumOrientations;
            baseStruct.srate = app.srate;
            baseStruct.ResultFolderPath = savePath;

            % ========== 4. 进度条 ==========
            totalAlgs = length(NodeDatas);
            hWait = uiprogressdlg(app.UIFigure, ...
                'Title', '正在运行源分析...', ...
                'Message', '准备中...', ...
                'Cancelable', 'on');

            % ========== 5. 主循环 ==========
            for i = 1:totalAlgs
                % 检查用户是否取消
                if hWait.CancelRequested
                    break;
                end

                algName = NodeDatas(i);
                hWait.Value = (i - 1) / totalAlgs;
                hWait.Message = sprintf('正在运行 %s (%d/%d)...', algName, i, totalAlgs);

                result = [];           % 每轮重置
                currentStruct = baseStruct;  % 从公共模板复制

                switch algName
                    case 'MNE'
                        [Source_MNE, ~] = seal_MNE(app.Data, ...
                            app.protocolNode.leadfieldNode.data, ...
                            'RegularizationParameter', RegularizationParameterEditField, ...
                            'DepthWeightExp', app.DepthWeightExp, ...
                            'SourceCovariance', app.sourceCovariance, ...
                            'NoiseCovariance', NoiseCovariance, ...
                            'NumOrientations', app.NumOrientations);
                        if app.NumOrientations == 1
                            result = Source_MNE;
                        else
                            result = app.deal_results(Source_MNE);
                        end
                        currentStruct.Algorithm = 'seal_MNE';
                        currentStruct.RegularizationParameter = RegularizationParameterEditField;
                        currentStruct.DepthWeightExp = app.DepthWeightExp;
                        currentStruct.SourceCovariance = app.sourceCovariance;
                        currentStruct.NoiseCovariance = NoiseCovariance;

                    case 'STARTS'
                        [Source_Estimate, ~] = seal_STARTS(app.Data, ...
                            app.protocolNode.leadfieldNode.data, ...
                            app.protocolNode.cortexNode.data, ...
                            'NoiseCovariance', NoiseCovariance, ...
                            'NumTBFs', app.NumTBFs, ...
                            'LearnNoise', app.LearnNoise, ...
                            'NoiseObservationMatrix', eye(size(app.Data, 1)), ...
                            'NeighborOrder', app.NeighborOrder, ...
                            'NumOrientations', app.NumOrientations);
                        if app.NumOrientations == 1
                            result = Source_Estimate;
                        else
                            result = app.deal_results(Source_Estimate);
                        end
                        currentStruct.Algorithm = 'seal_STARTS';
                        currentStruct.NumTBFs = app.NumTBFs;
                        currentStruct.LearnNoise = app.LearnNoise;
                        currentStruct.NeighborOrder = app.NeighborOrder;

                    case 'BlockChampagne'
                        [Source_Estimate, ~] = seal_BlockChampagne(app.Data, ...
                            app.protocolNode.leadfieldNode.data, ...
                            app.protocolNode.cortexNode.data, ...
                            'NoiseCovariance', NoiseCovariance, ...
                            'LearnNoise', false, ...
                            'NeighborOrder', app.NeighborOrder, ...
                            'NumOrientations', app.NumOrientations, ...
                            'NoiseObservationMatrix', eye(size(app.Data, 1)));
                        if app.NumOrientations == 1
                            result = Source_Estimate;
                        else
                            result = app.deal_results(Source_Estimate);
                        end
                        currentStruct.Algorithm = 'seal_BlockChampagne';
                        currentStruct.LearnNoise = app.LearnNoise;
                        currentStruct.NeighborOrder = app.NeighborOrder;

                    case 'uSTAR'
                        [Source_Estimate, ~] = seal_uSTAR(app.Data, ...
                            app.protocolNode.leadfieldNode.data, ...
                            app.protocolNode.cortexNode.data, ...
                            'RunAlgorithm', app.uSTARRunAlgorithm, ...
                            'MicrostateMaps', app.MicrostateMaps);
                        if app.NumOrientations == 1
                            result = Source_Estimate;
                        else
                            result = app.deal_results(Source_Estimate);
                        end
                        currentStruct.Algorithm = 'seal_uSTAR';
                        currentStruct.RunAlgorithm = app.uSTARRunAlgorithm;
                        currentStruct.MicrostateMaps = app.MicrostateMaps;

                    otherwise
                        warning('算法 "%s" 尚未实现，已跳过。', algName);
                        continue;
                end

                % ========== 6. 自动命名保存 ==========
                if ~isempty(result)
                    % 文件名格式: 数据名_算法名_时间戳
                    timestamp = datestr(now, 'yyyymmdd_HHMMss');
                    fileName = sprintf('%s_%s_%s.mat', app.dataNode.name, algName, timestamp);
                    fullPath = fullfile(savePath, fileName);

                    currentStruct.name = sprintf('%s_%s', app.dataNode.name, algName);
                    saveData(fullPath, result);
                    app.dataNode.setResultPath(fullPath);

                    node = app.sessionNode.openResultFromData(fullPath, currentStruct);

                    if app.dataNode.dataInfo.isSimu ==1
                        node.resultInfo.SimuSourcePath = app.dataNode.dataInfo.dataPath;
                        node.resultInfo.isSimu = 1;%resultNode is generated from simulation
                    end
                    app.dataNode.save();
                    node.save();
                end
            end

            % ========== 7. 收尾 ==========
            close(hWait);
            app.Callingapp.flushFileTree();  % 只刷新一次

            uialert(app.UIFigure, ...
                sprintf('源分析完成！共运行 %d 个算法。\n结果保存于:\n%s', totalAlgs, savePath), ...
                '运行完成', 'Icon', 'success', ...
                 'CloseFcn', @(~,~) closeAllWindows(app));

        end

        % Value changed function: DipoleOrienationtDropDown
        function DipoleOrienationtDropDownValueChanged(app, event)
            value = app.DipoleOrienationtDropDown.Value;
            if strcmp(value,'fixed')
                app.NumOrientations=1;
            elseif strcmp(value,'free')
                app.NumOrientations=3;
            end
        end

        % Value changed function: DepthWeightingEditField
        function DepthWeightingEditFieldValueChanged(app, event)
            value = app.DepthWeightingEditField.Value;
            app.DepthWeightExp=value;
        end

        % Value changed function: NoiseWhiteningDropDown
        function NoiseWhiteningDropDownValueChanged(app, event)
            value = app.NoiseWhiteningDropDown.Value;
            if strcmp(value,'identity')
                app.Noisetag=1;
            elseif strcmp(value,'data_cov')
                app.Noisetag=0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.AlgorithmsPanel = uipanel(app.GridLayout);
            app.AlgorithmsPanel.Title = 'Algorithms';
            app.AlgorithmsTree = uitree(app.AlgorithmsPanel, 'checkbox');
            app.MinimumL2_NormNode = uitreenode(app.AlgorithmsTree);
            app.MinimumL2_NormNode.Text = 'Minimum L2_Norm';
            app.MNENode = uitreenode(app.MinimumL2_NormNode);
            app.MNENode.NodeData = 'MNE';
            app.MNENode.Text = 'MNE';
            app.dSPMNode = uitreenode(app.MinimumL2_NormNode);
            app.dSPMNode.NodeData = 'dSPM';
            app.dSPMNode.Text = 'dSPM';
            app.sLORETANode = uitreenode(app.MinimumL2_NormNode);
            app.sLORETANode.NodeData = 'sLORETA';
            app.sLORETANode.Text = 'sLORETA';
            app.eLORETANode = uitreenode(app.MinimumL2_NormNode);
            app.eLORETANode.NodeData = 'eLORETA';
            app.eLORETANode.Text = 'eLORETA';
            app.LORETANode = uitreenode(app.MinimumL2_NormNode);
            app.LORETANode.NodeData = 'LORETA';
            app.LORETANode.Text = 'LORETA';
            app.LAURANode = uitreenode(app.MinimumL2_NormNode);
            app.LAURANode.NodeData = 'LAURA';
            app.LAURANode.Text = 'LAURA';
            app.BeamformerNode = uitreenode(app.AlgorithmsTree);
            app.BeamformerNode.Text = 'Beamformer';
            app.LCMVNode = uitreenode(app.BeamformerNode);
            app.LCMVNode.Text = 'LCMV';
            app.Model_CovNode = uitreenode(app.LCMVNode);
            app.Model_CovNode.Text = 'Model_Cov';
            app.DICSNode = uitreenode(app.BeamformerNode);
            app.DICSNode.Text = 'DICS';
            app.MCMVNode = uitreenode(app.BeamformerNode);
            app.MCMVNode.Text = 'MCMV';
            app.SparsemodelNode = uitreenode(app.AlgorithmsTree);
            app.SparsemodelNode.Text = 'Sparse model';
            app.Lp_LqNode = uitreenode(app.SparsemodelNode);
            app.Lp_LqNode.Text = 'Lp_Lq';
            app.ChampagneNode = uitreenode(app.SparsemodelNode);
            app.ChampagneNode.Text = 'Champagne';
            app.fullstructurenoiseNode = uitreenode(app.ChampagneNode);
            app.fullstructurenoiseNode.Text = 'full-structure noise';
            app.diagonalnoiselearningNode = uitreenode(app.ChampagneNode);
            app.diagonalnoiselearningNode.Text = 'diagonal noise learning';
            app.addspatialbasisNode = uitreenode(app.ChampagneNode);
            app.addspatialbasisNode.Text = 'add spatial basis';
            app.BlkChamNode = uitreenode(app.SparsemodelNode);
            app.BlkChamNode.Text = 'Blk-Cham';
            app.add_priorNode = uitreenode(app.BlkChamNode);
            app.add_priorNode.NodeData = 'BlockChampagne';
            app.add_priorNode.Text = 'add_prior';
            app.TSChamNode = uitreenode(app.SparsemodelNode);
            app.TSChamNode.Text = 'TS-Cham';
            app.SmoothChamNode = uitreenode(app.SparsemodelNode);
            app.SmoothChamNode.Text = 'Smooth-Cham';
            app.IRESNode = uitreenode(app.SparsemodelNode);
            app.IRESNode.Text = 'IRES';
            app.SISSY_L1Node = uitreenode(app.SparsemodelNode);
            app.SISSY_L1Node.Text = 'SISSY_L1';
            app.FOCUSSNode = uitreenode(app.SparsemodelNode);
            app.FOCUSSNode.Text = 'FOCUSS';
            app.SpatioTemporalNode = uitreenode(app.AlgorithmsTree);
            app.SpatioTemporalNode.Text = 'SpatioTemporal';
            app.MxNENode = uitreenode(app.SpatioTemporalNode);
            app.MxNENode.Text = 'MxNE';
            app.irMxNENode = uitreenode(app.SpatioTemporalNode);
            app.irMxNENode.Text = 'irMxNE';
            app.SISSY_L21Node = uitreenode(app.SpatioTemporalNode);
            app.SISSY_L21Node.Text = 'SISSY_L21';
            app.VSSI_LpNode = uitreenode(app.SpatioTemporalNode);
            app.VSSI_LpNode.Text = 'VSSI_Lp';
            app.FastIRESNode = uitreenode(app.SpatioTemporalNode);
            app.FastIRESNode.Text = 'Fast-IRES';
            app.STOUTNode = uitreenode(app.SpatioTemporalNode);
            app.STOUTNode.Text = 'STOUT';
            app.dMAPNode = uitreenode(app.SpatioTemporalNode);
            app.dMAPNode.Text = 'dMAP';
            app.BESTIESNode = uitreenode(app.SpatioTemporalNode);
            app.BESTIESNode.Text = 'BESTIES';
            app.SISTBFNode = uitreenode(app.SpatioTemporalNode);
            app.SISTBFNode.Text = 'SI-STBF';
            app.uSTARNode = uitreenode(app.SpatioTemporalNode);
            app.uSTARNode.NodeData = 'uSTAR';
            app.uSTARNode.Text = 'uSTAR';
            app.STARTSNode = uitreenode(app.SpatioTemporalNode);
            app.STARTSNode.NodeData = 'STARTS';
            app.STARTSNode.Text = 'STARTS';
            app.sSTARTSNode = uitreenode(app.SpatioTemporalNode);
            app.sSTARTSNode.Text = 'sSTARTS';
            app.VSSI_GGDNode = uitreenode(app.SpatioTemporalNode);
            app.VSSI_GGDNode.Text = 'VSSI_GGD';
            % Assign Checked Nodes
            app.AlgorithmsTree.CheckedNodesChangedFcn = createCallbackFcn(app, @AlgorithmsTreeCheckedNodesChanged, true);
            app.GeneralSettingsPanel = uipanel(app.GridLayout);
            app.GeneralSettingsPanel.Title = 'General Settings';
            app.DipoleOrienationtDropDownLabel = uilabel(app.GeneralSettingsPanel);
            app.DipoleOrienationtDropDownLabel.Text = 'Dipole Orienationt';
            app.DipoleOrienationtDropDown = uidropdown(app.GeneralSettingsPanel);
            app.DipoleOrienationtDropDown.Items = {'fixed', 'free'};
            app.DipoleOrienationtDropDown.ValueChangedFcn = createCallbackFcn(app, @DipoleOrienationtDropDownValueChanged, true);
            app.DipoleOrienationtDropDown.Value = 'fixed';
            app.DepthWeightingEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.DepthWeightingEditFieldLabel.Text = 'Depth Weighting';
            app.DepthWeightingEditField = uieditfield(app.GeneralSettingsPanel, 'numeric');
            app.DepthWeightingEditField.ValueChangedFcn = createCallbackFcn(app, @DepthWeightingEditFieldValueChanged, true);
            app.DepthWeightingEditField.Value = 0.5;
            app.NoiseWhiteningDropDown_2Label = uilabel(app.GeneralSettingsPanel);
            app.NoiseWhiteningDropDown_2Label.Text = 'Noise Whitening';
            app.NoiseWhiteningDropDown = uidropdown(app.GeneralSettingsPanel);
            app.NoiseWhiteningDropDown.Items = {'identity', 'data_cov', ''};
            app.NoiseWhiteningDropDown.ValueChangedFcn = createCallbackFcn(app, @NoiseWhiteningDropDownValueChanged, true);
            app.NoiseWhiteningDropDown.Value = 'identity';
            app.LoadPriorButton = uibutton(app.GeneralSettingsPanel, 'push');
            app.LoadPriorButton.Text = 'Load Prior';
            app.SelectPriorButton = uibutton(app.GeneralSettingsPanel, 'push');
            app.SelectPriorButton.Text = 'Select Prior';
            app.RunSelectedAlgorithmsButton = uibutton(app.GeneralSettingsPanel, 'push');
            app.RunSelectedAlgorithmsButton.ButtonPushedFcn = createCallbackFcn(app, @RunSelectedAlgorithmsButtonPushed, true);
            app.RunSelectedAlgorithmsButton.Text = 'Run Selected Algorithms';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ImportDataMenu = uimenu(app.ContextMenu);
            app.ImportDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportDataMenuSelected, true);
            app.ImportDataMenu.Text = 'Import Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SEAL_selectAlgorithm(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end