classdef SEAL_showEEG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FiltersMenu                     matlab.ui.container.Menu
        FFTMenu                         matlab.ui.container.Menu
        LowPassMenu                     matlab.ui.container.Menu
        HighPassMenu                    matlab.ui.container.Menu
        BandPassMenu                    matlab.ui.container.Menu
        BandSuppressionMenu             matlab.ui.container.Menu
        FiltersMenu_2                   matlab.ui.container.Menu
        ControlPanel                    matlab.ui.container.Panel
        RangeEditField                  matlab.ui.control.EditField
        RangeEditFieldLabel             matlab.ui.control.Label
        ScaleEditField                  matlab.ui.control.NumericEditField
        ScaleEditFieldLabel             matlab.ui.control.Label
        WinlenthEditField               matlab.ui.control.NumericEditField
        WinlenthEditFieldLabel          matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        rangeend                        matlab.ui.control.Label
        rangestart                      matlab.ui.control.Label
        biggerButton                    matlab.ui.control.Button
        smallerButton                   matlab.ui.control.Button
        LLButton                        matlab.ui.control.Button
        RRButton                        matlab.ui.control.Button
        RButton                         matlab.ui.control.Button
        LButton                         matlab.ui.control.Button
        SelectedTimeEditField           matlab.ui.control.NumericEditField
        SelectedTimeEditField_2Label_2  matlab.ui.control.Label
        StackingmodeButton              matlab.ui.control.StateButton
        MarkneweventPanel               matlab.ui.container.Panel
        EventLabelEditField             matlab.ui.control.EditField
        EventNameLabel                  matlab.ui.control.Label
        AddEventButton                  matlab.ui.control.Button
        EventListPanel                  matlab.ui.container.Panel
        EventListListBox                matlab.ui.control.ListBox
        ChennalsPanel                   matlab.ui.container.Panel
        SelectAllButton                 matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        ChannelTree                     matlab.ui.container.CheckBoxTree
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        eegdata         % EEG 数据
        srate = 256     % 采样率
        spacing = 20    % 通道间距
        winlength = 1   % 窗口长度（秒）
        time =   0      % 当前时间(区间左)
        chans = 0       % 通道数
        frames = 0      % 时间点数，后续初始化
        dispchans = 0   % 显示通道数
        elecoffset = 0  % 垂直偏移
        DialogApp       %调用滤波器app
        submean= 'off'
        eloc_file       %通道文件
        YLabels         %生成通道序号
        timeStart       %区间左
        timeEnd         %区间右
        dataNode
        ChannelNode={}
        chanlocs
        CheckedData=[]          %选择显示通道的索引
        eventStrings            %标记列表元素名称   
        duidie=1                %堆叠标签
        SaveIndex = 1;          % 文件保存计数器
        SaveIndexrowdata = 1;   % 文件保存计数器
        SavePath = 'Data';      % 默认保存路径
        CallingAppResult             %联动结果显示
        TrialIndices
        datapoints
        SelectedChannel = 0;       % 当前选中的通道索引
        EventMarkers = struct('latency', {}, 'type', {}); % 事件标记      
        EventData = struct('latency', {}, 'type', {}); % 自定义事件标记     
        LineHandles = [];          % 通道线条句柄
        TextHandles = [];          % 通道标签句柄
        EventLineHandles = [];     % 事件线句柄
        EventTextHandles = [];     % 事件文本句柄
        TimeSelectionLine;         % 时间选择线
        ChannelSelectionText;      % 通道选择文本
        OriginalYLim2              %大y
        segmentInfo                 %信号分割信息
        StartChan (1,1) double = 1        % 当前起始通道（从1计）
        ChannelSlider matlab.ui.control.Slider  % 垂直滚动条引用

        % === 可调的幅值缩放参数 ===
        ScaleMode char = 'global';   % 选项: 'none'|'std'|'robust'|'minmax'|'zscore'|'percentile'
        ScaleFactor =0                    %缩放因子
        ScalePercentiles double = [2 98]; % 百分位缩放用到的上下界
        ScaleTarget double = [];          % 目标半幅值(纵向)，留空则按 spacing 自动确定
        %SavePathchanged= 0;
        SelDebounceTimer  % 一次性定时器，用于SelectionChanged去抖
        ClickHoldTimer            % 单次定时器：判断是否进入框选
        MouseDownTic uint64 = uint64(0);%记录按下时刻（tic）
        MouseDownPoint double = []% 记录按下时坐标 [x y]

        AutoScaleFactor = []   % 基于整段数据一次性算好的自动缩放系数
    end

    properties (Access = public)
        currentSaveIndex = 1; % 当前结构体保存索引
        historydatamanu
        filteredDataHistory = struct('data', {}, 'params', {}, 'index', {}); % 历史记录结构体数组
        SelectedTime = 0;               % 当前选中的时间点（秒）
        IsSelecting = false;            % 是否正在选择时间段
        SelectionStart = [];            % 选择开始的时间点
        SelectionEnd = [];              % 选择结束的时间点
        SelectionRectangle = [];        % 选择矩形的图形句柄
        SelectedTimeRange = [];         % 存储选择的时间范围
    end

    methods (Access = private)

        function redrawPlot(app)
            % 绑定到 UIAxes，清空现有内容
            cla(app.UIAxes, 'reset');
            hold(app.UIAxes, 'on');
            app.LineHandles = []; % 清空线条句柄
            app.TextHandles = []; % 清空文本句柄
            % 计算当前窗口数据范围（全局帧s→窗口内相对位置）
            [lowlim, highlim] = app.calcWindowRange();

            % 绘制 EEG 通道数据（上层，ZData=1）
            app.plotEEGChannels(lowlim, highlim);
            % 绘制自定义实践
            if ~isempty(app.EventData)
                for i = 1:length(app.EventData)
                    eventTime = app.EventData(i).latency;

                    if isempty(eventTime) || ~isnumeric(eventTime) || ~isscalar(eventTime) || ~isfinite(eventTime)
                        continue;
                    end

                    evType = app.EventData(i).type;
                    if isempty(evType)
                        evType = '';
                    elseif ~ischar(evType)
                        evType = char(string(evType));
                    end

                    if eventTime >= app.timeStart && eventTime <= app.timeEnd
                        lh = line(app.UIAxes, [eventTime, eventTime], app.UIAxes.YLim, ...
                            'Color', 'g', 'LineWidth', 1.5, 'LineStyle', '--');
                        app.EventLineHandles(end+1) = lh;

                        th2 = text(app.UIAxes, eventTime, app.UIAxes.YLim(2), evType, ...
                            'Color', 'g', 'FontSize', 8, 'VerticalAlignment', 'bottom');
                        app.EventTextHandles(end+1) = th2;
                    end
                end
            end
        
        end

        function [lowlim, highlim] = calcWindowRange(app)   %计算点数的上下限
            lowlim = round(app.time*app.srate  + 1);
            highlim = round(min((app.time + app.winlength)*app.srate  + 1, app.frames));
        end

        function initializeChannelLabels(app)
            % 1. 确立绝对基准：总通道数永远等于“数据矩阵的行数”
            app.chans = size(app.eegdata, 1);

            % 2. 检查数据对象中是否带了 chanlocs 信息，并且包含 'labels' 字段
            if isempty(app.chanlocs) || ~isfield(app.chanlocs, 'labels')
                % 回退方案：生成默认标签，例如 {'CH 1', 'CH 2', ...}
                app.YLabels = arrayfun(@(x) sprintf('CH %d', x), 1:app.chans, 'UniformOutput', false);
            else
                % 提取 labels 元胞数组 (直接从刚刚导入存好的 app.chanlocs 里拿)
                extractedLabels = {app.chanlocs.labels};

                % 防御性编程：检查维度是否匹配
                if length(extractedLabels) == app.chans
                    % 完美匹配
                    app.YLabels = extractedLabels;
                elseif length(extractedLabels) > app.chans
                    % 通道信息比数据多（常见于删除了坏道但未更新 chanlocs）
                    warning('通道信息(%d)多于数据通道(%d)，自动截取前 %d 个标签。', ...
                        length(extractedLabels), app.chans, app.chans);
                    app.YLabels = extractedLabels(1:app.chans);
                else
                    % 通道信息比数据少（数据异常或截断错误）
                    warning('通道信息数量不足！回退至默认编号。');
                    app.YLabels = arrayfun(@(x) sprintf('CH %d', x), 1:app.chans, 'UniformOutput', false);
                end
            end

            % 3. 构建左侧 UI 树节点 (ChannelTree)
            for i = 1:app.chans
                app.ChannelNode{i} = uitreenode(app.ChannelTree);
                app.ChannelNode{i}.Text = app.YLabels{i};
                % 默认将所有节点勾选
                app.ChannelTree.CheckedNodes = [app.ChannelTree.CheckedNodes; app.ChannelNode{i}];
                % 记录它在原始数据矩阵中的行号，方便后续反向索引！
                app.ChannelNode{i}.NodeData = i;
            end
        end

        function plotEEGChannels(app, lowlim, highlim)
            data = app.eegdata;
            % 在 plotEEGChannels 的 data 赋值之后加这段
            dataRange = max(abs(data(:)));
            if dataRange < 1e-3
                % 数据可能是伏特，转微伏
                data = data * 1e6;
           
            elseif dataRange < 1
                % 数据可能是毫伏，转微伏
                data = data * 1e3;
            end
            % ====== 决定可用通道索引 ======
            if isempty(app.CheckedData)
                availIdx = 1:app.chans;            % 原始通道索引序号
                labelList = app.YLabels;           % 原始标签
                badMask = false(1, app.chans);
                if ~isempty(app.eloc_file) && isfield(app.eloc_file,'badchan')
                    badMask = logical([app.eloc_file.badchan]);
                end
            else
                availIdx = app.CheckedData(:)'; % 子集里的“原始通道号”
                % 标签基于原始通道号
                if isempty(app.eloc_file)
                    labelList = cellfun(@(x) num2str(x), num2cell(availIdx), 'UniformOutput', false);
                    badMask = false(1, numel(availIdx));
                else
                    labelList = app.YLabels(availIdx);
                    if isfield(app.eloc_file,'badchan')
                        badMask = logical([app.eloc_file(availIdx).badchan]);
                    else
                        badMask = false(1, numel(availIdx));
                    end
                end
                % 同时把 data 过滤到子集（保持行与 availIdx 顺序一致）
                data = data(availIdx, :);
            end

            % ====== 基于 StartChan 选择“可见窗口”的通道 ======
            nAvail = numel(availIdx);      %总通道数
            app.dispchans = min(app.dispchans, nAvail);     %展示通道数量
            app.StartChan = min(max(1, app.StartChan), max(1, nAvail - app.dispchans + 1));     %起始展示通道索引（上方第一）
            visRange = app.StartChan : (app.StartChan + app.dispchans - 1);              %展示通道索引集

            % 计算子均值（对当前可见的行）
            meandata = app.calcSubmean(data, lowlim, highlim);

            % 原有间距逻辑
            oldspacing = 580/app.dispchans;
            if app.duidie==1, app.spacing = oldspacing; else, app.spacing = 0; end

            % 时间轴
            app.timeStart = (lowlim - 1) / app.srate;
            app.timeEnd   = (highlim - 1) / app.srate;
            timePoints = linspace(app.timeStart, app.timeEnd, highlim - lowlim + 1);

            % 取“可见通道 × 当前窗”的矩阵，并做减均值
            winDataVis = data(visRange, lowlim:highlim) - meandata(visRange);
            %  按选择的模式进行幅值缩放（关键一步）
            [winDataVisScaled, ~] = app.applyChannelScaling(winDataVis, app.ScaleFactor);
            % 仅用于 YLim 参考:始终按“自动缩放”算一份,与用户 ScaleFactor 解耦
            [winDataVisRef,    ~] = app.applyChannelScaling(winDataVis, 0);
            % 预分配句柄
            app.LineHandles = gobjects(app.dispchans,1);
            app.TextHandles = gobjects(app.dispchans,1);

            % 从下到上画
            for i = 1:app.dispchans
                row = app.dispchans - i + 1;           % 可见区里的“从下往上”的行
                %chanData = data(row, lowlim:highlim) - meandata(row);
                yTrace = winDataVisScaled(row, :);   % 直接用缩放后的数据
                if app.duidie==1
                    yOffset = i * app.spacing + (app.dispchans + 1)*(oldspacing - app.spacing)/2 ...
                        + app.elecoffset*(oldspacing - app.spacing);
                else
                    yOffset=0;
                end
                lh = plot(app.UIAxes, timePoints,  yTrace  + yOffset, 'LineWidth', 1, 'Color', [0.5 0.5 0.5]);
                app.LineHandles(i) = lh;

                % 点击回调：把“屏内序号 i”或“原始通道号 availIdx(row)”都可以传
                origIdx = availIdx( visRange(i) );
                lh.ButtonDownFcn = @(src, event) app.channelClicked(origIdx, event);

                % 左侧标签（显示“原始通道号/名称”）
                textX = app.UIAxes.XLim(1)-(0.02 * diff(app.UIAxes.XLim));
                textY = yOffset;
                th = text(app.UIAxes, textX, textY, labelList{visRange(row)}, ...
                    'HorizontalAlignment','right','Color','k','FontSize',8);
                app.TextHandles(i) = th;
                %th.ButtonDownFcn = @(src, event) app.channelLabelClicked(origIdx);
            end

            % 恢复 spacing
            app.spacing = oldspacing;
            % 调整 UIAxes 范围和刻度
            app.adjustUIAxes(lowlim, highlim, winDataVisRef);
            app.OriginalYLim2 = app.UIAxes.YLim;
            % 绘制事件标记
            app.drawEventMarkers();

            % 如果存在选中的时间点，绘制时间选择线
            if app.SelectedTime > 0
                app.drawTimeSelectionLine();
            end

            % 高亮选中的通道
            %app.highlightSelectedChannel();

            % 设置UIAxes的回调函数
            app.UIAxes.ButtonDownFcn = @(src, event) app.axesButtonDown(src, event);
            app.UIFigure.WindowButtonUpFcn = @(src, event) app.windowButtonUp(src, event);
            app.UIFigure.WindowButtonMotionFcn = @(src, event) app.windowButtonMotion(src, event);
            app.UIFigure.WindowScrollWheelFcn = @(src, event) app.windowScrollWheel(src, event);
        end
     
        function highlightSelectedChannel(app)
            % 高亮选中的通道，其他通道变淡

            if app.SelectedChannel > 0 && ~isempty(app.LineHandles)
                for i = 1:length(app.LineHandles)
                    if i == app.SelectedChannel
                        set(app.LineHandles(i), 'LineWidth', 2, 'Color', 'r');
                    else
                        set(app.LineHandles(i), 'LineWidth', 0.5, 'Color', [0.5 0.5 0.5]);
                    end
                end
            end
        end

        function channelClicked(app, channelIndex, event)


            % 获取点击位置的时间
            cp = event.IntersectionPoint;
            app.SelectedTime = cp(1);

            app.drawTimeSelectionLine();
            x = cp(1); y = cp(2);

            if ~strcmp(get(app.UIFigure,'SelectionType'),'normal')
                return; % 仅左键
            end

            % 记录按下
            app.MouseDownTic   = tic;
            app.MouseDownPoint = [x y];

            % 清理旧定时器
            if ~isempty(app.ClickHoldTimer) && isvalid(app.ClickHoldTimer)
                stop(app.ClickHoldTimer);
                delete(app.ClickHoldTimer);
            end
            %清理旧选择线和选择框
            if ishandle(app.TimeSelectionLine)
                delete(app.TimeSelectionLine);
            end

            if ishandle(app.SelectionRectangle)
                delete(app.SelectionRectangle);
            end
            % 初始化状态
            app.IsSelecting = false;
            app.SelectionStart = x;
            app.SelectionEnd   = x;

            % 启动一次性定时器（0.3s 后进入框选）
            app.ClickHoldTimer = timer( ...
                'ExecutionMode','singleShot', ...
                'StartDelay',0.3, ...
                'TimerFcn',@(~,~)beginDragSelect(app));
            start(app.ClickHoldTimer);
        end

        function recoverChannels(app)
            for i = 1:length(app.LineHandles)
                set(app.LineHandles(i), 'LineWidth', 1,'Color', [0.5 0.5 0.5]);
            end
        end

        function addTrialStartEvents(app)
            if isempty(app.TrialIndices)
                return;
            end

            nTrials = length(app.TrialIndices);
            baseUr  = numel(app.EventMarkers);

            for trialIdx = 1:nTrials
                startSample = app.TrialIndices(trialIdx).start_index;
                startTime   = (startSample - 1) / app.srate;

                newEvent = struct( ...
                    'type',     sprintf('TrialStart_%d', trialIdx), ...
                    'latency',  startTime, ...
                    'duration', 0, ...
                    'urevent',  baseUr + trialIdx, ...
                    'epoch',    trialIdx ...
                    );

                % 去重
                if ~isempty(app.EventMarkers)
                    latAll  = [app.EventMarkers.latency];
                    typeAll = {app.EventMarkers.type};
                    if any(abs(latAll - startTime) < 1e-9 & strcmp(typeAll, newEvent.type))
                        continue;
                    end
                end

                app.EventMarkers = app.appendEvent(app.EventMarkers, newEvent);
            end

            % 统一按 latency 排序
            if ~isempty(app.EventMarkers)
                [~, idx] = sort([app.EventMarkers.latency]);
                app.EventMarkers = app.EventMarkers(idx);
            end

            app.updateEventList();
            app.drawEventMarkers();
        end

        function updateEventList(app)
            % 生成事件字符串列表
            app.eventStrings = cell(1, length(app.EventMarkers));
            for i = 1:length(app.EventMarkers)
                app.eventStrings{i} = sprintf('%.3f s: %s', ...
                    app.EventMarkers(i).latency, ...
                    app.EventMarkers(i).type);
            end

            % 更新列表框
            app.EventListListBox.Items = app.eventStrings;
        end

        function drawEventMarkers(app)
            % 删除现有的事件标记句柄
            delete(app.EventLineHandles(ishandle(app.EventLineHandles)));
            delete(app.EventTextHandles(ishandle(app.EventTextHandles)));
            app.EventLineHandles = [];
            app.EventTextHandles = [];

            if isempty(app.EventMarkers)
                return;
            end

            app.EventLineHandles = gobjects(length(app.EventMarkers),1);
            app.EventTextHandles = gobjects(length(app.EventMarkers),1);

            for i = 1:length(app.EventMarkers)
                eventTime = app.EventMarkers(i).latency;

                % 防御性检查:必须是非空、有限的标量数值
                if isempty(eventTime) || ~isnumeric(eventTime) || ~isscalar(eventTime) || ~isfinite(eventTime)
                    continue;
                end

                % 确保 type 是字符串
                evType = app.EventMarkers(i).type;
                if isempty(evType)
                    evType = '';
                elseif ~ischar(evType)
                    evType = char(string(evType));
                end

                if eventTime >= app.timeStart && eventTime <= app.timeEnd
                    lh = line(app.UIAxes, [eventTime, eventTime], app.UIAxes.YLim, ...
                        'Color', 'b', 'LineWidth', 1.5, 'LineStyle', '--');
                    app.EventLineHandles(i) = lh;

                    th2 = text(app.UIAxes, eventTime, app.UIAxes.YLim(2), evType, ...
                        'Color', 'b', 'FontSize', 8, 'VerticalAlignment', 'bottom');
                    app.EventTextHandles(i) = th2;
                end
            end
        end

        function adjustUIAxes(app, ~,~, winDataVisRef)
            % X 轴
            app.UIAxes.XLim = [app.timeStart, app.timeEnd];
            xticks = app.timeStart:0.2:app.timeEnd;
            if xticks(end) ~= app.timeEnd
                xticks = [xticks, app.timeEnd];
            end
            app.UIAxes.XTick = unique(xticks);
            app.UIAxes.YTick = [];

            if app.dispchans > 0
                if app.duidie == 1
                    % 非堆叠:按 spacing 定高
                    totalHeight = app.spacing * (app.dispchans + 1);
                    app.UIAxes.YLim = [0, totalHeight];
                else
                    % 堆叠:用“自动缩放参考”的百分位定 YLim
                    % —— 这样用户改 Scale 时波形会可见变化,YLim 不会跟着漂
                    lo = prctile(winDataVisRef(:), 2);
                    hi = prctile(winDataVisRef(:), 98);
                    pad = 0.1 * (hi - lo + eps);
                    if hi > lo
                        app.UIAxes.YLim = [lo - pad, hi + pad];
                    end
                end
            end

            app.UIAxes.Units    = 'normalized';
            app.UIAxes.Position = [0.15 0.2 0.7 0.75];
            app.UIAxes.Box      = 'off';
        end

        function drawTimeSelectionLine(app)
            % 删除现有的时间选择线
            if ishandle(app.TimeSelectionLine)
                delete(app.TimeSelectionLine);
            end

            if ishandle(app.SelectionRectangle)
                delete(app.SelectionRectangle);
            end
            % 绘制时间选择线
            app.TimeSelectionLine = line(app.UIAxes, [app.SelectedTime, app.SelectedTime], app.OriginalYLim2, ...
                'Color', 'r', 'LineWidth', 1.5);
            app.SelectedTimeEditField.Value=app.SelectedTime;

            timepoint=round(app.SelectedTime*app.srate) + 1;

            %调整结果显示
            if timepoint>app.datapoints
                return
            end

            targetFigs = findall(0, 'Type', 'figure', 'Tag', 'SEAL_ResultWindow');
            
            % 3. 如果找到了，挨个通知它们
            for i = 1:length(targetFigs)
                try
                    % 从 UserData 中掏出那个 showresultapp 的实例名片
                    app.CallingAppResult = targetFigs(i).UserData;
                    
                    % 防御性检查并单向发送指令
                    if isvalid(app.CallingAppResult)
                        app.CallingAppResult.TimePointValueChanged(app.SelectedTime);
                    end
                catch
                    disp('Datachange failed.Please select the data in first trial and try again.');
                end
            end


        end

        function axesButtonDown(app, src, ~)        
            cp = src.CurrentPoint;
            x = cp(1,1); y = cp(1,2);
            if ~strcmp(get(app.UIFigure,'SelectionType'),'normal')
                return; % 仅左键
            end

            % 记录按下
            app.MouseDownTic   = tic;
            app.MouseDownPoint = [x y];

            % 清理旧定时器
            if ~isempty(app.ClickHoldTimer) && isvalid(app.ClickHoldTimer)
                stop(app.ClickHoldTimer);
                delete(app.ClickHoldTimer);
            end
            %清理旧选择线和选择框
            if ishandle(app.TimeSelectionLine)
                delete(app.TimeSelectionLine);
            end

            if ishandle(app.SelectionRectangle)
                delete(app.SelectionRectangle);
            end
            % 初始化状态
            app.IsSelecting = false;
            app.SelectionStart = x;
            app.SelectionEnd   = x;

            % 启动一次性定时器（0.3s 后进入框选）
            app.ClickHoldTimer = timer( ...
                'ExecutionMode','singleShot', ...
                'StartDelay',0.3, ...
                'TimerFcn',@(~,~)beginDragSelect(app));
            start(app.ClickHoldTimer);
        end

        function windowButtonUp(app,~, ~)

            cp_fig = app.UIFigure.CurrentPoint;   % 像素坐标
            axPos  = getpixelposition(app.UIAxes, true); % 相对 figure 的像素位置

            isInAxes = ...
                cp_fig(1) >= axPos(1) && ...
                cp_fig(1) <= axPos(1)+axPos(3) && ...
                cp_fig(2) >= axPos(2) && ...
                cp_fig(2) <= axPos(2)+axPos(4);

            if ~isInAxes
                return   % 不在坐标区，直接退出
            end
            % 停止并删除定时器
            if ~isempty(app.ClickHoldTimer) && isvalid(app.ClickHoldTimer)
                stop(app.ClickHoldTimer);
                delete(app.ClickHoldTimer);
            end
             %清理旧选择线和选择框
            if ishandle(app.TimeSelectionLine)
                delete(app.TimeSelectionLine);
            end

            if ishandle(app.SelectionRectangle)
                delete(app.SelectionRectangle);
            end
            % 计算按下持续时间
            dt = 0;
            if app.MouseDownTic ~= 0
                dt = toc(app.MouseDownTic);
            end
            app.MouseDownTic = uint64(0);  % 用完清零

            % 若 <0.5s，认为单击
            if ~app.IsSelecting && dt < 0.3
                cp = app.UIAxes.CurrentPoint;
                x = cp(1,1);
                app.SelectedTime = x;
                if ishandle(app.TimeSelectionLine)
                    delete(app.TimeSelectionLine);
                end
                app.TimeSelectionLine = line(app.UIAxes, [x x], app.UIAxes.YLim, ...
                    'Color','r','LineWidth',1.5);
                app.SelectedTimeEditField.Value = app.SelectedTime;
            elseif app.IsSelecting
                % 正常框选逻辑（保留你原有的 highlightSelectedTimeRange）
                startTime = min(app.SelectionStart, app.SelectionEnd);
                endTime   = max(app.SelectionStart, app.SelectionEnd);
                app.SelectedTimeRange = [startTime endTime];
                app.highlightSelectedTimeRange();
            end
            % —— 统一的收尾复位 ——（防止后续鼠标移动继续改矩形）
            app.IsSelecting      = false;
            app.MouseDownTic     = uint64(0);
            app.MouseDownPoint   = [];
            app.SelectionStart   = [];
            app.SelectionEnd     = [];
            drawnow;
        end

        function highlightSelectedTimeRange(app)
            % 删除现有的高亮区域
            if ishandle(app.SelectionRectangle)
                delete(app.SelectionRectangle);
            end

            if ishandle(app.TimeSelectionLine)
                delete(app.TimeSelectionLine);
            end

            if ishandle(app.ChannelSelectionText)
                delete(app.ChannelSelectionText);
            end

            app.recoverChannels();
            % 绘制新的高亮区域
            if ~isempty(app.SelectedTimeRange)
                startTime = app.SelectedTimeRange(1);
                endTime = app.SelectedTimeRange(2);
                yLimits = app.UIAxes.YLim;

                x = [startTime, endTime, endTime, startTime];
                y = [yLimits(1), yLimits(1), yLimits(2), yLimits(2)];
                app.SelectionRectangle = patch(app.UIAxes, x, y, [0.9 0.9 0.9], ...
                    'EdgeColor', 'r', ...
                    'FaceAlpha', 0.3);
                app.rangestart.Text=sprintf('%.4f s', startTime);
                app.rangeend.Text=sprintf('%.4f s', endTime);
                app.SelectedTimeEditField.Value = 0;
            end
            starttimepoint=round(startTime*app.srate)+1;
            endtimepoint=round(endTime*app.srate)+1;
            if starttimepoint>app.datapoints||endtimepoint>app.datapoints
                return
            end
            
            targetFigs = findall(0, 'Type', 'figure', 'Tag', 'SEAL_ResultWindow');
            
            % 3. 如果找到了，挨个通知它们
            for i = 1:length(targetFigs)
                try
                    app.CallingAppResult = targetFigs(i).UserData;
                    
                    if isvalid(app.CallingAppResult)
                        app.CallingAppResult.datachanged(starttimepoint,endtimepoint);
                    end
                catch
                    disp('Datachange failed. Please select the data in first trial and try again.');
                end
            end
        end

        function windowButtonMotion(app, ~, ~)
            if ~app.IsSelecting, return; end   % 只有框选模式才跟随

            cp = app.UIAxes.CurrentPoint;
            x = cp(1,1);
            yLimits = app.UIAxes.YLim;

            app.SelectionEnd = x;
            if ishandle(app.SelectionRectangle)
                xs = sort([app.SelectionStart, app.SelectionEnd]);
                set(app.SelectionRectangle, 'XData', [xs(1) xs(2) xs(2) xs(1)], ...
                    'YData', [yLimits(1) yLimits(1) yLimits(2) yLimits(2)]);
            end
        end

        function windowScrollWheel(app, ~, event)
            % 获取鼠标在坐标轴中的位置（像素单位）
            mousePos = get(app.UIFigure, 'CurrentPoint');
            axPos = getpixelposition(app.UIAxes);

            % 判断鼠标是否在坐标轴区域内
            isMouseInAxes = mousePos(1) >= axPos(1) && ...
                mousePos(1) <= axPos(1) + axPos(3) && ...
                mousePos(2) >= axPos(2) && ...
                mousePos(2) <= axPos(2) + axPos(4);

            if ~isMouseInAxes
                return; % 鼠标不在坐标轴内，直接退出
            end

            % 获取鼠标位置（优化性能）
            cp = app.UIAxes.CurrentPoint(1,1:2);
            if any(isnan(cp)) || isempty(cp), return; end

            % 动态缩放因子（根据当前范围调整）
            baseFactor = 1.1;
            currentXRange = diff(app.UIAxes.XLim);
            if currentXRange < 1  % 小范围时减小缩放幅度
                baseFactor = 1.05;
            end

            % 确定缩放方向
            zoomFactor = baseFactor;
            if event.VerticalScrollCount < 0  % 向下滚动
                zoomFactor = 1 / baseFactor;
            end

            % 计算鼠标在视图中的相对位置比例
            xRange = diff(app.UIAxes.XLim);
            yRange = diff(app.UIAxes.YLim);
            xRatio = (cp(1) - app.UIAxes.XLim(1)) / xRange; % X方向比例
            yRatio = (cp(2) - app.UIAxes.YLim(1)) / yRange; % Y方向比例

            % 以鼠标为中心计算新范围
            newXLim = cp(1) + [-xRatio, (1-xRatio)] * xRange * zoomFactor;
            newYLim = cp(2) + [-yRatio, (1-yRatio)] * yRange * zoomFactor;
            % 应用约束条件
            newXLim = app.constrainRange(newXLim, [0, app.timeEnd]);
            newYLim = app.constrainRange(newYLim, app.OriginalYLim2);

            % 更新视图
            app.UIAxes.XLim = newXLim;
            app.UIAxes.YLim = newYLim;


            %更新标签位置
            xLeft = app.UIAxes.XLim(1);
            xOffset = 0.02 * diff(app.UIAxes.XLim);
            for i = 1:numel(app.TextHandles)
                if isvalid(app.TextHandles(i))
                    pos = app.TextHandles(i).Position;
                    pos(1) = xLeft - xOffset;   % 贴在当前左边界
                    app.TextHandles(i).Position = pos;
                end
            end

            for i = 1:numel(app.EventTextHandles)
                if isa(app.EventTextHandles(i),'matlab.graphics.primitive.Text')
                    if isvalid(app.EventTextHandles(i))
                        pos = app.EventTextHandles(i).Position;
                        pos(2) = app.UIAxes.YLim(2);   % 贴在当前左边界
                        app.EventTextHandles(i).Position = pos;
                    end
                end
            end

        end

        function lim = constrainRange(app,lim, validRange)
            % 确保范围有效
            minRange = 0.1 * diff(validRange);  % 最小显示范围
            lim(1) = max(validRange(1), min(lim(1), validRange(2)-minRange));
            lim(2) = min(validRange(2), max(lim(2), validRange(1)+minRange));
        end
       
        function meandata = calcSubmean(app, data, lowlim, highlim)
            if strcmpi(app.submean, 'on')  % 开启子均值：每个通道减去窗口内均值
                minData = data(:, lowlim:highlim);  % 当前窗口数据（channels × 窗口内时间点）
                meandata = mean(minData, 2);  % 按通道计算均值（沿时间维度，返回 channels × 1 向量）
            else  % 不开启：均值为0
                meandata = zeros(app.chans, 1);  % 全零向量，不影响原始数据
            end
        end

        function historyDataMenuSelected(app, src,~)
            filename=src.Text;
            data = load(src.UserData);  % 获取存储的文件名
            disp(['Selected file: ', filename]);
            app.eegdata=data.Data;
            app.redrawPlot();
        end

        function concatenated_data = concatenate_trials(app, data_3d)

            % 获取数组尺寸
            [n_channels, n_points_per_trial, n_trials] = size(data_3d);

            % 预分配拼接后的二维矩阵
            total_points = n_points_per_trial * n_trials;
            concatenated_data = zeros(n_channels, total_points);

            % 初始化索引记录结构体
            app.TrialIndices = struct(...
                'trial_number', num2cell(1:n_trials), ...
                'start_index', num2cell(zeros(1, n_trials)), ...
                'end_index', num2cell(zeros(1, n_trials)) ...
                );

            % 循环处理每个试次
            for trial = 1:n_trials
                % 计算当前试次的起始和结束索引
                start_idx = (trial - 1) * n_points_per_trial + 1;
                end_idx = trial * n_points_per_trial;

                % 提取当前试次数据
                current_trial_data = data_3d(:, :, trial);

                % 将数据放入拼接矩阵
                concatenated_data(:, start_idx:end_idx) = current_trial_data;

                % 记录索引信息
                app.TrialIndices(trial).start_index = start_idx;
                app.TrialIndices(trial).end_index = end_idx;
            end

            % 添加元数据到索引结构
            app.TrialIndices(1).n_channels = n_channels;
            app.TrialIndices(1).points_per_trial = n_points_per_trial;
            app.TrialIndices(1).total_trials = n_trials;
            app.TrialIndices(1).total_points = total_points;
        end

        function addSeamEventsFromSegmentInfo(app)
            if ~isprop(app, 'segmentInfo') || isempty(app.segmentInfo)
                return;
            end
            if size(app.segmentInfo,1) ~= 2 || size(app.segmentInfo,2) < 2
                return;
            end
            if isempty(app.srate) || app.srate <= 0
                error('app.srate 未设置或非法。');
            end

            seamSamples = app.segmentInfo(2, 2:end);
            seamTimes   = (seamSamples - 1) ./ app.srate;
            nSeams      = numel(seamTimes);

            baseUr = numel(app.EventMarkers);

            for k = 1:nSeams
                if ~isempty(app.EventMarkers)
                    latAll = [app.EventMarkers.latency];
                    if any(abs(latAll - seamTimes(k)) < 1e-9)
                        continue;
                    end
                end

                newEvent = struct( ...
                    'type',     sprintf('Seam_%d (Trial %d end)', k, k), ...
                    'latency',  seamTimes(k), ...
                    'duration', 0, ...
                    'urevent',  baseUr + k, ...
                    'epoch',    [] ...
                    );

                app.EventMarkers = app.appendEvent(app.EventMarkers, newEvent);
            end

            app.updateEventList();
            app.drawEventMarkers();
        end

        function onChannelSliderChanging(app, val)
            % 当前可用通道与最大起始位

            maxStart = app.chans-60+1;

            % 反向映射：向下滑（val↓）=> StartChan↑
            startChan = maxStart - val + 1 ;
            app.StartChan = seal_clamp(round(startChan), 1, maxStart);

            app.redrawPlot();
            % 删除现有的时间选择线
            if ishandle(app.TimeSelectionLine)
                delete(app.TimeSelectionLine);
            end

            if ishandle(app.SelectionRectangle)
                delete(app.SelectionRectangle);
            end

            if ishandle(app.ChannelSelectionText)
                delete(app.ChannelSelectionText);
            end
            app.recoverChannels();
        end

        function onChannelSliderChanged(app, val)

            maxStart =app.chans-60+1;

            % 反向映射（同上）
            startChan = maxStart -val + 1;
            app.StartChan = seal_clamp(round(startChan), 1, maxStart);

            app.redrawPlot();
            % 删除现有的时间选择线
            if ishandle(app.TimeSelectionLine)
                delete(app.TimeSelectionLine);
            end

            if ishandle(app.SelectionRectangle)
                delete(app.SelectionRectangle);
            end

            if ishandle(app.ChannelSelectionText)
                delete(app.ChannelSelectionText);
            end
            app.recoverChannels();
        end

        function target = getTargetAmplitude(app)
            % 目标“半幅值”：让每条曲线大约占据本层间距的 35%
            if isempty(app.ScaleTarget)
                s = max(app.spacing, 580/app.dispchans);      % spacing 至少为 1
                target = 0.35 * s;

            else
                target = app.ScaleTarget;
            end
        end

   function [scaledWin, scaleInfo] = applyChannelScaling(app, winData, f)
    target = app.getTargetAmplitude();
    nCh = size(winData, 1);

    % f=0 保留为"自动"的历史含义,等价于相对倍率 1
    if f == 0
        rel = 1;
    else
        rel = f;
    end

    switch lower(app.ScaleMode)
        case 'none'
            scaledWin = winData * rel;
            scaleInfo = rel * ones(nCh, 1);

        case 'std'
            s = std(winData, 0, 2);  s(s == 0) = 1;
            fVec = seal_clampScale((target ./ s) * rel);
            scaledWin = winData .* fVec;
            scaleInfo = fVec;

        case 'minmax'
            mn = min(winData, [], 2); mx = max(winData, [], 2);
            rng = (mx - mn) / 2;     rng(rng == 0) = 1;
            centered = winData - (mn + mx) / 2;
            fVec = seal_clampScale((target ./ rng) * rel);
            scaledWin = centered .* fVec;
            scaleInfo = fVec;

        case 'zscore'
            mu = mean(winData, 2);
            s  = std(winData, 0, 2);  s(s == 0) = 1;
            fVec = seal_clampScale((target ./ s) * rel);
            scaledWin = (winData - mu) .* fVec;
            scaleInfo = fVec;

        case 'percentile'
            lo = prctile(winData, app.ScalePercentiles(1), 2);
            hi = prctile(winData, app.ScalePercentiles(2), 2);
            c  = (hi + lo) / 2;
            h  = (hi - lo) / 2;  h(h == 0) = 1;
            fVec = seal_clampScale((target ./ h) * rel);
            scaledWin = (winData - c) .* fVec;
            scaleInfo = fVec;

        case 'global'
            % 自动基准:优先用缓存;没有再即时算一份
            if ~isempty(app.AutoScaleFactor)
                base = app.AutoScaleFactor;
            else
                allData = winData(:);
                lo = prctile(allData, app.ScalePercentiles(1));
                hi = prctile(allData, app.ScalePercentiles(2));
                h  = (hi - lo) / 2;
                if h == 0, h = 1; end
                base = target / h;
            end
            fScalar = base * rel;
            scaledWin = winData * fScalar;
            scaleInfo = fScalar * ones(nCh, 1);

        otherwise
            scaledWin = winData * rel;
            scaleInfo = rel * ones(nCh, 1);
    end
end
   
%         function UIFigureCloseRequest(app,~)
%             % ======= 删除 app.SavePath 中的所有文件 =======
%             if exist(app.SavePath, 'dir')&&app.SavePathchanged==1
%                 files = dir(fullfile(app.SavePath, '*'));  % 获取所有文件和子文件夹
%                 for k = 1:length(files)
%                     % 跳过 '.'、'..'、以及文件夹
%                     if ~files(k).isdir
%                         delete(fullfile(app.SavePath, files(k).name));
%                     end
%                 end
%                 disp(['已清空文件夹内的文件：' app.SavePath]);
%             else
%                 disp(['目录不存在：' app.SavePath]);
%             end
%             delete(app);
%         end

        function applySelectionHighlight(app, origIdx)
            % 仍在已勾选？
            checked = app.ChannelTree.CheckedNodes;
            if isempty(checked), return; end
            checkedIdx = cellfun(@(n) n.NodeData, num2cell(checked));
            if ~ismember(origIdx, checkedIdx), return; end

            % 构造当前可用通道集合（全量或子集）
            if isempty(app.CheckedData)
                availIdx = 1:app.chans;
            else
                availIdx = app.CheckedData(:)';    % 原始通道号子集
            end

            % 该通道在可用集合中的位置
            posInAvail = find(availIdx == origIdx, 1);
            if isempty(posInAvail), return; end

            % 当前窗口（屏内可见）范围
            startPos = app.StartChan;
            endPos   = min(app.StartChan + app.dispchans - 1, numel(availIdx));
            if posInAvail < startPos || posInAvail > endPos
                return;  % 不在当前窗口，可选：自动滚入窗口后再高亮
            end

            % 上到下的本地序号
            localIdx  = posInAvail - startPos + 1;     % 1..dispchans（上→下）
            % 你是“自下而上”绘制，需反转成 LineHandles 的索引
            screenIdx = app.dispchans - localIdx + 1;  % 下→上

            app.SelectedChannel = screenIdx;
            app.highlightSelectedChannel();
        end

        function value = askNumericInput(app, prompt, defaultVal)

            d = uifigure('Name','输入数值','Position',[500 500 260 130]);

            uilabel(d, ...
                'Text', prompt, ...
                'Position',[30 80 200 22]);

            ef = uieditfield(d,'numeric', ...
                'Position',[30 55 200 22], ...
                'Value', defaultVal);

            value = [];

            btnOK = uibutton(d, ...
                'Text','确定', ...
                'Position',[40 15 70 25], ...
                'ButtonPushedFcn', @(btn,event)onOK());

            btnCancel = uibutton(d, ...
                'Text','取消', ...
                'Position',[140 15 70 25], ...
                'ButtonPushedFcn', @(btn,event)onCancel());

            d.CloseRequestFcn = @(src,event)onCancel();

            uiwait(d);

            if isvalid(d)
                delete(d);
            end

            % --- 内部函数 ---
            function onOK()
                value = ef.Value;
                uiresume(d);
            end

            function onCancel()
                value = [];
                uiresume(d);
            end
        end
    
    end

    methods (Access = public)

        function [freq, powerSpectrum] = computeChannelFFT(app, EEGdata,channelIdx)
            % 计算指定通道的FFT频谱
            % 输入:
            %   channelIdx - 通道索引 (1到app.chans)
            %   plotResults - 是否绘制频谱图 (true/false, 可选，默认为false)
            % 输出:
            %   freq - 频率轴 (Hz)
            %   powerSpectrum - 功率谱密度 (μV²/Hz)

            % 获取数据并去均值
            data = EEGdata(channelIdx, :);
            data = data - mean(data);

            % 确保数据长度为偶数（FFT对称性要求）
            if mod(length(data), 2) ~= 0
                data = data(1:end-1); % 丢弃最后一个点
            end
            N = length(data); % 此时N为偶数

            % 应用汉宁窗
            window = hann(N)'; % 注意转置确保维度匹配
            windowedData = data .* window;

            % 计算FFT
            fftResult = fft(windowedData);
            P2 = abs(fftResult/N);

            % 明确转换为整数索引
            halfN = floor(N/2); % 或 fix(N/2)
            P1 = P2(1:halfN + 1);
            P1(2:end-1) = 2*P1(2:end-1);

            % 频率轴
            freq = app.srate*(0:halfN)/N;

            % 功率谱
            powerSpectrum = (P1.^2) / (sum(window.^2) * app.srate);

            % 限制频率范围
            validRange = freq >= 0.01 & freq <= 300;
            freq = freq(validRange);
            powerSpectrum = powerSpectrum(validRange);
        end

        function plotMultiChannelSpectrum(app, EEGdata,channelIndices)
            % 在同一图中绘制多个通道的频谱
            % 输入:
            %   channelIndices - 要分析的通道索引数组（如 [1, 3, 5]）

            % 创建新图形窗口
            fig = figure('Name', 'Multi-Channel Power Spectrum', ...
                'Position', [100 100 800 600]);
            ax = axes(fig);
            hold(ax, 'on');

            % 颜色循环（确保不同通道颜色区分）
            colors = lines(length(channelIndices));

            % 循环计算并绘制每个通道的频谱
            for i = 1:length(channelIndices)
                chanIdx = channelIndices(i);

                % 调用FFT计算函数（假设已实现computeChannelFFT）
                [freq, spectrum] = app.computeChannelFFT(EEGdata,chanIdx);

                % 绘制功率谱（dB单位）
                plot(ax, freq, 10*log10(spectrum), ...
                    'Color', colors(i,:), ...
                    'LineWidth', 1.5, ...
                    'DisplayName', sprintf('Ch%d: %s', chanIdx, app.YLabels{chanIdx}));
            end

            % 图形美化
            grid(ax, 'on');
            xlabel(ax, 'Frequency (Hz)');
            ylabel(ax, 'Power (dB)');
            title(ax, 'Multi-Channel EEG Power Spectrum');
            legend(ax, 'show', 'Location', 'northeastoutside');

            % 标记典型频段
            bandColors = {'r', 'g', 'm'};
            bands = [1 4; 4 8; 8 13]; % Delta, Theta, Alpha
            for b = 1:size(bands,1)
                x = [bands(b,1), bands(b,1), bands(b,2), bands(b,2)];
                y = [ax.YLim(1), ax.YLim(2), ax.YLim(2), ax.YLim(1)];
                patch(x, y, bandColors{b}, 'FaceAlpha', 0.1, 'EdgeColor', 'none');
            end

            % 添加频段标签
            text(2.5, ax.YLim(2)-3, 'Delta', 'Color', 'r');
            text(6, ax.YLim(2)-3, 'Theta', 'Color', 'g');
            text(10.5, ax.YLim(2)-3, 'Alpha', 'Color', 'm');
        end

        function moveTimeSelection(app, delta)
            % 移动时间选择线
            if isempty(app.SelectedTime)
                % 如果没有选择时间点，使用当前时间
                app.SelectedTime = app.time;
            end

            % 计算新的时间点
            newTime = app.SelectedTime + delta;

            % 确保新时间点在有效范围内
            if newTime < 0
                newTime = 0;
            elseif newTime > app.frames/app.srate
                newTime = app.frames/app.srate;
            end

            % 更新选择的时间点
            app.SelectedTime = newTime;

            % 重绘时间选择线
            app.drawTimeSelectionLine();
        end

        function beginDragSelect(app)
            if isempty(app.MouseDownPoint) || (exist('app.MouseDownTic','var') && app.MouseDownTic==0)
                return; % 已经松开，别进入框选
            end
            % 超过0.3s，进入框选模式：创建选择矩形
            app.IsSelecting = true;

            % 删除旧图形
            if ishandle(app.SelectionRectangle), delete(app.SelectionRectangle); end
            if ishandle(app.TimeSelectionLine),  delete(app.TimeSelectionLine);  end
            if ishandle(app.ChannelSelectionText), delete(app.ChannelSelectionText); end

            yLimits = app.UIAxes.YLim;
            x0 = app.SelectionStart;
            app.SelectionRectangle = patch(app.UIAxes, ...
                [x0 x0 x0 x0], [yLimits(1) yLimits(1) yLimits(2) yLimits(2)], ...
                'r', 'EdgeColor','r', 'LineWidth',1, 'LineStyle','--', 'FaceColor','none');
        end


        function computeAutoScaleFactor(app)
            % 基于完整 eegdata(或当前勾选通道子集)计算一次 scale,缓存起来
            data = app.eegdata;
            % 单位归一化(与 plotEEGChannels 保持一致)
            dataRange = max(abs(data(:)));
            if dataRange < 1e-3
                data = data * 1e6;
            elseif dataRange < 1
                data = data * 1e3;
            end

            target = app.getTargetAmplitude();
            switch lower(app.ScaleMode)
                case 'none'
                    f = 1;
                case 'std'
                    s = std(data, 0, 2); s(s==0) = 1;
                    f = target ./ s;
                case 'minmax'
                    rng = (max(data,[],2) - min(data,[],2))/2;  rng(rng==0)=1;
                    f = target ./ rng;
                case 'percentile'
                    lo = prctile(data, app.ScalePercentiles(1), 2);
                    hi = prctile(data, app.ScalePercentiles(2), 2);
                    h = (hi-lo)/2; h(h==0)=1;
                    f = target ./ h;
                case 'zscore'
                    s = std(data,0,2); s(s==0)=1;
                    f = target ./ s;
                case 'global'
                    lo = prctile(data(:), app.ScalePercentiles(1));
                    hi = prctile(data(:), app.ScalePercentiles(2));
                    h = (hi-lo)/2; if h==0, h=1; end
                    f = target / h;
                otherwise
                    f = 1;
            end
            app.AutoScaleFactor = f;
        end


        function importEEGLABEvents(app, eventStruct)
            % 将 EEGLAB 风格 event(latency/duration 为样本点)转为内部格式(秒)
            if isempty(eventStruct) || ~isfield(eventStruct,'latency') || ~isfield(eventStruct,'type')
                return;
            end

            hasEpochField = isfield(eventStruct, 'epoch');
            is3D = ~isempty(app.TrialIndices) && ...
                isfield(app.TrialIndices, 'start_index') && ...
                ~isempty([app.TrialIndices.start_index]);

            baseUr = numel(app.EventMarkers);

            for i = 1:numel(eventStruct)
                lat = eventStruct(i).latency;
                % 必须是非空、有限的标量数值
                if isempty(lat) || ~isnumeric(lat) || ~isscalar(lat) || ~isfinite(lat)
                    continue;
                end

                % epoch 字段(若存在)
                epNum = [];
                if hasEpochField && ~isempty(eventStruct(i).epoch)
                    epNum = double(eventStruct(i).epoch);
                end

                % 若原数据是三维,且事件 latency 是 epoch 内样本号,则加上 epoch 偏移
                if is3D && ~isempty(epNum) && epNum >= 1 && epNum <= numel(app.TrialIndices)
                    nPts = app.TrialIndices(1).points_per_trial;
                    if double(lat) <= nPts
                        lat = app.TrialIndices(epNum).start_index + double(lat) - 1;
                    end
                end

                % 样本点 -> 秒(EEGLAB 规范:latency=1 对应 t=0)
                latSec = (double(lat) - 1) / app.srate;

                % type
                t = eventStruct(i).type;
                if isnumeric(t)
                    typeStr = num2str(t);
                elseif ischar(t) || isstring(t)
                    typeStr = char(t);
                else
                    typeStr = 'Event';
                end

                % duration:样本点 -> 秒
                durVal = 0;
                if isfield(eventStruct, 'duration') && ~isempty(eventStruct(i).duration)
                    d = eventStruct(i).duration;
                    if isnumeric(d) && isscalar(d) && isfinite(d)
                        durVal = double(d) / app.srate;
                    end
                end

                % urevent
                urVal = baseUr + i;
                if isfield(eventStruct, 'urevent') && ~isempty(eventStruct(i).urevent)
                    urVal = double(eventStruct(i).urevent);
                end

                newEvent = struct( ...
                    'type',     typeStr, ...
                    'latency',  latSec, ...
                    'duration', durVal, ...
                    'urevent',  urVal, ...
                    'epoch',    epNum ...
                    );

                % 去重
                if ~isempty(app.EventMarkers)
                    latAll  = [app.EventMarkers.latency];
                    typeAll = {app.EventMarkers.type};
                    if any(abs(latAll - latSec) < 1e-9 & strcmp(typeAll, typeStr))
                        continue;
                    end
                end

                app.EventMarkers = app.appendEvent(app.EventMarkers, newEvent);
            end
        end

        function jumpToTime(app, targetTime, rangeEnd)
            % 统一跳转函数：
            %   jumpToTime(t)          -> 将窗口居中到 t，并在 t 处画选择线
            %   jumpToTime(tStart,tEnd)-> 把窗口调整为 [tStart, tEnd]
            if nargin < 3, rangeEnd = []; end

            maxTime = app.frames / app.srate;
            targetTime = max(0, min(targetTime, maxTime));

            if ~isempty(rangeEnd) && rangeEnd > targetTime
                % --- 范围模式 ---
                rangeEnd  = max(0, min(rangeEnd, maxTime));
                app.winlength = rangeEnd - targetTime;
                app.WinlenthEditField.Value = app.winlength;
                app.time = targetTime;
                app.SelectedTime = 0;              % 范围模式不画单点线
                app.redrawPlot();
            else
                % --- 单点跳转模式：把窗口尽量居中 ---
                newStart = targetTime - app.winlength/2;
                newStart = max(0, min(newStart, max(0, maxTime - app.winlength)));
                app.time = newStart;
                app.SelectedTime = targetTime;
                app.redrawPlot();
                app.drawTimeSelectionLine();       % 在 targetTime 处画红线
            end
        end

        function vals = askFilterParams(app, dlgTitle, labels, defaults)
            % labels  : cell, e.g. {'Fc (Hz)','Rs (dB)','Order N (even)'}
            % defaults: numeric vector
            % vals    : numeric vector (同长度); 用户取消返回 []

            n    = numel(labels);
            rowH = 32;
            figW = 320;
            figH = 50 + n*rowH + 50;

            sz = get(0,'ScreenSize');
            d  = uifigure('Name', dlgTitle, ...
                'Position', [sz(3)/2-figW/2, sz(4)/2-figH/2, figW, figH], ...
                'WindowStyle','modal','Resize','off');

            eds = gobjects(n,1);
            for i = 1:n
                y = figH - 30 - i*rowH;
                uilabel(d, 'Text', labels{i}, ...
                    'HorizontalAlignment','right', ...
                    'Position', [15, y, 150, 22]);
                eds(i) = uieditfield(d, 'numeric', ...
                    'Value', defaults(i), ...
                    'Position', [175, y, 125, 22]);
            end

            vals = [];
            uibutton(d,'Text','OK',    'Position',[60,15,80,28], ...
                'ButtonPushedFcn',@(~,~) onOK());
            uibutton(d,'Text','Cancel','Position',[180,15,80,28], ...
                'ButtonPushedFcn',@(~,~) onCancel());
            d.CloseRequestFcn = @(~,~) onCancel();

            uiwait(d);
            if isvalid(d), delete(d); end

            function onOK()
                vals = arrayfun(@(e) e.Value, eds);
                uiresume(d);
            end
            function onCancel()
                vals = [];
                uiresume(d);
            end
        end

        function applyFilterAndUpdate(app, firCoeff, paramStr, cutoffs, N, Rs)
            % paramStr : 例如 'LowPass'、'BandPass [1-40]'
            % cutoffs  : 传给 visualizeFilterResponse 标记用

            % 勾选的通道(索引为原始通道号); 未勾选则处理全部
            checkedNodes = app.ChannelTree.CheckedNodes;
            if isempty(checkedNodes)
                chIdx = 1:app.chans;
            else
                chIdx = arrayfun(@(n) n.NodeData, checkedNodes);
            end

            filtered = app.eegdata;          % 与 eegdata 同尺寸
            % 原位逐通道零相位滤波
            for k = 1:numel(chIdx)
                ch = chIdx(k);
                filtered(ch,:)= filtfilt(firCoeff, 1, double(app.eegdata(ch,:)));
            end

            % 刷新自动缩放并重绘
            app.computeAutoScaleFactor();
            app.redrawPlot();

            % 弹出滤波器频响 + 滤波后多通道频谱(与 bandpass App 的输出形式一致)
            app.visualizeFilterResponse(firCoeff, app.srate, paramStr, cutoffs);
            app.plotMultiChannelSpectrum(filtered, chIdx);

           
        end


        function visualizeFilterResponse(~, b, Fs, titleStr, cutoffs)
            % 手动画频响,避开有问题的 freqzplot
            nfft = 1024;
            [H, W] = freqz(b, 1, nfft, Fs);   % 带输出参数,不会触发 freqzplot

            fig = figure('Name', titleStr, 'Color', 'w');

            % --- 幅频响应 ---
            ax1 = subplot(2,1,1, 'Parent', fig);
            magdB = 20*log10(abs(H) + eps);
            plot(ax1, W, magdB, 'LineWidth', 1.2);
            grid(ax1, 'on'); hold(ax1, 'on');
            xlabel(ax1, 'Frequency (Hz)');
            ylabel(ax1, 'Magnitude (dB)');
            title(ax1, titleStr, 'Interpreter', 'none');
            xlim(ax1, [0 Fs/2]);

            % 合理的 y 范围(截掉极深的陷波避免标签挤到边)
            yl = [max(min(magdB), -120), max(magdB) + 5];
            ylim(ax1, yl);

            % 标记截止频率
            for k = 1:numel(cutoffs)
                plot(ax1, [cutoffs(k) cutoffs(k)], yl, 'r--');
                text(ax1, cutoffs(k), yl(1) + 0.1*diff(yl), ...
                    sprintf(' %.2f Hz', cutoffs(k)), 'Color', 'r');
            end

            % --- 相频响应 ---
            ax2 = subplot(2,1,2, 'Parent', fig);
            plot(ax2, W, unwrap(angle(H)) * 180/pi, 'LineWidth', 1.2);
            grid(ax2, 'on');
            xlabel(ax2, 'Frequency (Hz)');
            ylabel(ax2, 'Phase (deg)');
            xlim(ax2, [0 Fs/2]);
        end

  
        function evArr = appendEvent(~, evArr, newEvent)
            % 安全地把 newEvent 追加到 evArr,自动对齐字段
            if isempty(evArr)
                evArr = newEvent;
                return;
            end

            oldFields = fieldnames(evArr);
            newFields = fieldnames(newEvent);
            allFields = union(oldFields, newFields, 'stable');

            for i = 1:numel(allFields)
                f = allFields{i};
                if ~isfield(evArr, f)
                    [evArr.(f)] = deal([]);
                end
                if ~isfield(newEvent, f)
                    newEvent.(f) = [];
                end
            end

            evArr    = orderfields(evArr, allFields);
            newEvent = orderfields(newEvent, allFields);
            evArr(end+1) = newEvent;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dataNode, position)
            myPos = app.UIFigure.Position;   % 当前窗口自己的 [x y w h]
            w = myPos(3); h = myPos(4);

            if nargin >= 3 && ~isempty(position) && numel(position) == 4
                mainX = position(1);
                mainY = position(2);
                mainW = position(3);
                mainH = position(4);

                % 水平居中对齐主窗口,垂直贴在主窗口正上方
                newX = mainX + (mainW - w) / 2;
                newY = mainY + mainH - 10;     % 10 像素的间隙,可按喜好调

                % 防止飞出屏幕上沿:超出就改成贴屏幕顶,并向下摆
                screenSize = get(0,'ScreenSize');
                screenW = screenSize(3); screenH = screenSize(4);
                if newY + h > screenH
                    newY = max(1, screenH - h - 40);   % 留点任务栏空间
                end
                newX = max(1, min(newX, screenW - w));

                app.UIFigure.Position = [newX, newY, w, h];
            else
                % 兼容没传 position 的情况:沿用原来的兜底
                screenSize = get(0,'ScreenSize');
                app.UIFigure.Position(1) = screenSize(3) * 0.4;
                app.UIFigure.Position(2) = screenSize(3) * 0.3;
            end

            app.dataNode = dataNode;
            app.SavePath=dataNode.parent.parent.path;
            app.UIFigure.Name=dataNode.name;
            app.srate=double(dataNode.srate);
            tempdata = dataNode.data; 
            

            if ndims(tempdata)==3
                app.eegdata= app.concatenate_trials(tempdata);
            else
                app.eegdata= tempdata;
            end

            % 2. 基础参数初始化(关键:timeStart/timeEnd 提前设置)
            app.chans     = size(app.eegdata, 1);
            app.frames    = size(app.eegdata, 2);
            app.datapoints = app.frames;
            app.time      = 0;
            app.winlength = min(app.frames / app.srate, 10);
            app.timeStart = 0;
            app.timeEnd   = app.winlength;


            if ~isempty(dataNode.dataInfo.chanlocs)
                app.chanlocs = dataNode.dataInfo.chanlocs;
            end
            app.chans=size(app.eegdata,1);
            app.dispchans=min(app.chans,60);
            app.frames=size(app.eegdata,2);
            app.datapoints=size(app.eegdata,2);

           
            evt = dataNode.dataInfo.event;

            if ~isempty(evt)
                app.importEEGLABEvents(evt);
                
            end


            if isfield(dataNode.data,'segmentInfo')
                app.segmentInfo=dataNode.data.segmentInfo;
                app.addSeamEventsFromSegmentInfo();
            end
            if ndims(tempdata)==3
                app.addTrialStartEvents();
            end

            app.initializeChannelLabels();%初始化通道文件
            % 创建滑动条（竖向）
            if app.chans>60
                app.ChannelSlider = uislider(app.UIFigure, ...
                    'Orientation','vertical', ...
                    'Limits',[0, max(0, app.chans-60)+1], ...
                    'Value',max(0, app.chans-60+1), ...
                    'MinorTicks',[],'MajorTicks',[],...
                    'ValueChangingFcn',@(src,evt) onChannelSliderChanging(app, evt.Value), ...
                    'ValueChangedFcn', @(src,evt) onChannelSliderChanged(app, src.Value));
                %'MajorTicks',[], 'MinorTicks',[], ...
                % 定位（自行微调，单位像素）
                app.ChannelSlider.Position = [980, ...
                    150, 3, 500];
            end

            if ~isempty(app.EventMarkers)
                [~, idx] = sort([app.EventMarkers.latency]);
                app.EventMarkers = app.EventMarkers(idx);
            end

            app.updateEventList();
            app.redrawPlot();
            

            app.WinlenthEditField.Value=app.UIAxes.XLim(2)-app.UIAxes.XLim(1);
            app.winlength= app.WinlenthEditField.Value;

            app.computeAutoScaleFactor();% 计算全局scalefactor
        end

        % Button pushed function: LLButton
        function LLButtonPushed(app, event)
            %<<
            step = app.winlength ;
            app.time =max(0, app.time - step);  % 向左滚动
            app.redrawPlot;  % 重绘图形
        end

        % Button pushed function: smallerButton
        function smallerButtonPushed(app, event)
            %+
            if app.winlength==app.frames/app.srate
                app.winlength=fix(app.winlength*10)/10;
                app.WinlenthEditField.Value = app.winlength;
                app.redrawPlot;
                return;
            end
            if app.winlength>0.1
                app.winlength=app.winlength-0.1;
                app.WinlenthEditField.Value = app.winlength;
                app.redrawPlot;
            else
                return;
            end
        end

        % Button pushed function: RRButton
        function RRButtonPushed(app, event)
            %>>
            step = app.winlength ;
            rightbountry = app.time + step;
            rightbountry = min(app.frames/app.srate,rightbountry+step);
            app.time = rightbountry - step;


            app.redrawPlot;  % 重绘图形
        end

        % Button pushed function: biggerButton
        function biggerButtonPushed(app, event)
            %-
            app.winlength=app.winlength+0.1;
            if app.winlength<app.frames/app.srate
                app.WinlenthEditField.Value = app.winlength;
                app.redrawPlot;
            else
                app.winlength=app.frames/app.srate;
                app.WinlenthEditField.Value = app.winlength;
                app.redrawPlot;
            end

        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            %>
            step = app.winlength/2 ;
            rightbountry = app.time + step;
            rightbountry = min(app.frames/app.srate,rightbountry+step);
            app.time = rightbountry - step;


            app.redrawPlot;  % 重绘图形
        end

        % Button pushed function: LButton
        function LButtonPushed(app, event)
            %<
            step = app.winlength/2 ;
            app.time = max(0, app.time - step);  % 向左滚动

            app.redrawPlot;  % 重绘图形
        end

        % Button pushed function: AddEventButton
        function AddEventButtonPushed(app, event)
            eventTimeSec = app.SelectedTimeEditField.Value;
            eventLabel   = strtrim(app.EventLabelEditField.Value);

            % 1. 校验:时间必须在数据范围内
            maxT = (app.frames - 1) / app.srate;
            if eventTimeSec < 0 || eventTimeSec > maxT
                uialert(app.UIFigure, ...
                    sprintf('Latency %.3fs 超出数据范围 [0, %.3f]s', eventTimeSec, maxT), ...
                    'Invalid latency');
                return;
            end

            % 2. 校验:type 不能为空(EEGLAB 行为)
            if isempty(eventLabel)
                uialert(app.UIFigure, 'Event type 不能为空', 'Invalid type');
                return;
            end

            % 3. 根据 latency 反推 epoch(若是三维拼接数据)
            epNum = [];
            if ~isempty(app.TrialIndices) && isfield(app.TrialIndices,'points_per_trial')
                sampleIdx = round(eventTimeSec * app.srate) + 1;
                nPts = app.TrialIndices(1).points_per_trial;
                epCandidate = ceil(sampleIdx / nPts);
                if epCandidate >= 1 && epCandidate <= numel(app.TrialIndices)
                    epNum = epCandidate;
                end
            end

            newEvent = struct( ...
                'type',     eventLabel, ...
                'latency',  eventTimeSec, ...
                'duration', 0, ...
                'urevent',  numel(app.EventMarkers) + 1, ...
                'epoch',    epNum ...
                );

            % 4. 追加(字段自动对齐)
            app.EventMarkers = app.appendEvent(app.EventMarkers, newEvent);
            app.EventData    = app.appendEvent(app.EventData,    newEvent);

            % 5. 按 latency 升序排序
            if ~isempty(app.EventMarkers)
                [~, idx]  = sort([app.EventMarkers.latency]);
                app.EventMarkers = app.EventMarkers(idx);
            end
            if ~isempty(app.EventData)
                [~, idx2] = sort([app.EventData.latency]);
                app.EventData = app.EventData(idx2);
            end
            % 6. 清空输入框(方便连续添加,也是 EEGLAB GUI 的风格)
            app.EventLabelEditField.Value = '';

            % 7. 重绘和刷新列表
            app.updateEventList();
            app.redrawPlot();
        end

        % Value changed function: StackingmodeButton
        function StackingmodeButtonValueChanged(app, event)
            value = app.StackingmodeButton.Value;
            if value==1
                app.duidie=0;
                app.redrawPlot;
                for i = 1:length(app.TextHandles)
                    set(app.TextHandles(i), 'Visible', 'off');
                end
            else
                app.duidie=1;
                app.redrawPlot();
            end

        end

        % Callback function: ChannelTree
        function ChannelTreeCheckedNodesChanged(app, event)
            checkedNodes = app.ChannelTree.CheckedNodes;
            app.CheckedData=[];
            app.CheckedData=[app.CheckedData,checkedNodes.NodeData];
            if ~ismember(app.ChannelTree.SelectedNodes.NodeData,app.CheckedData)
                app.SelectedChannel=0;
            end
          
            app.dispchans=min(60,length(checkedNodes));
            app.spacing= 580/app.dispchans;
            app.redrawPlot();
      % 删除现有的时间选择线
            if ishandle(app.TimeSelectionLine)
                delete(app.TimeSelectionLine);
            end

            if ishandle(app.SelectionRectangle)
                delete(app.SelectionRectangle);
            end

            if ishandle(app.ChannelSelectionText)
                delete(app.ChannelSelectionText);
            end
        end

        % Menu selected function: FFTMenu
        function FFTMenuSelected(app, event)
            % 获取当前勾选通道(原始通道号); 未勾选则默认对全部通道做 FFT
            checkedNodes = app.ChannelTree.CheckedNodes;
            if isempty(checkedNodes)
                chIdx = 1:app.chans;
            else
                chIdx = arrayfun(@(n) n.NodeData, checkedNodes);
            end
            app.CheckedData = chIdx;   % 与类内其他逻辑保持一致

            if isempty(chIdx)
                uialert(app.UIFigure, '请先勾选至少一个通道。', 'No channel selected');
                return;
            end

            app.plotMultiChannelSpectrum(app.eegdata, chIdx);
        end

        % Menu selected function: LowPassMenu
        function LowPassMenuSelected(app, event)
            p = app.askFilterParams('Low Pass Filter', ...
                {'Fc (Hz)','Rs (dB)','Order N (even)'}, ...
                [30, 40, 100]);
            if isempty(p), return; end
            Fc = p(1); Rs = p(2); N = round(p(3));
            if mod(N,2) ~= 0, N = N + 1; end    % 自动补成偶数
            try
                b = designFIRLowpass(app.srate, Fc, Rs, N);
            catch ME
                uialert(app.UIFigure, ME.message, 'Low pass design failed'); return;
            end
            app.applyFilterAndUpdate(b, sprintf('Low Pass  Fc=%.2f Hz', Fc), Fc, N, Rs);
        end

        % Menu selected function: HighPassMenu
        function HighPassMenuSelected(app, event)
            p = app.askFilterParams('High Pass Filter', ...
                {'Fc (Hz)','Rs (dB)','Order N (even)'}, ...
                [1, 40, 100]);
            if isempty(p), return; end
            Fc = p(1); Rs = p(2); N = round(p(3));
            if mod(N,2) ~= 0, N = N + 1; end
            try
                b = designFIRHighpass(app.srate, Fc, Rs, N);
            catch ME
                uialert(app.UIFigure, ME.message, 'High pass design failed'); return;
            end
            app.applyFilterAndUpdate(b, sprintf('High Pass  Fc=%.2f Hz', Fc), Fc, N, Rs);
        end

        % Menu selected function: BandPassMenu
        function BandPassMenuSelected(app, event)
            p = app.askFilterParams('Band Pass Filter', ...
                {'Flow (Hz)','Fhigh (Hz)','Rs (dB)','Order N (even)'}, ...
                [1, 40, 40, 100]);
            if isempty(p), return; end
            Flow = p(1); Fhigh = p(2); Rs = p(3); N = round(p(4));
            if mod(N,2) ~= 0, N = N + 1; end
            try
                b = designFIRBandpass(app.srate, Flow, Fhigh, Rs, N);
            catch ME
                uialert(app.UIFigure, ME.message, 'Band pass design failed'); return;
            end
            app.applyFilterAndUpdate(b, ...
                sprintf('Band Pass  [%.2f-%.2f] Hz', Flow, Fhigh), ...
                [Flow Fhigh], N, Rs);
        end

        % Menu selected function: BandSuppressionMenu
        function BandSuppressionMenuSelected(app, event)
            p = app.askFilterParams('Band Suppression (Notch)', ...
                {'Flow (Hz)','Fhigh (Hz)','Rs (dB)','Order N (even)'}, ...
                [49, 51, 40, 200]);
            if isempty(p), return; end
            Flow = p(1); Fhigh = p(2); Rs = p(3); N = round(p(4));
            if mod(N,2) ~= 0, N = N + 1; end
            try
                b = designFIRBandstop(app.srate, Flow, Fhigh, Rs, N);
            catch ME
                uialert(app.UIFigure, ME.message, 'Band stop design failed'); return;
            end
            app.applyFilterAndUpdate(b, ...
                sprintf('Band Stop  [%.2f-%.2f] Hz', Flow, Fhigh), ...
                [Flow Fhigh], N, Rs);
        end

        % Menu selected function: FiltersMenu_2
        function FiltersMenu_2Selected(app, event)
            data=app.eegdata;
            if ~isempty(app.CheckedData)
                data = data(app.CheckedData, :);
            end
            checkedNodes = app.ChannelTree.CheckedNodes;
            app.CheckedData=[];
            app.CheckedData=[app.CheckedData,checkedNodes.NodeData];
            app.DialogApp=Filters(app,app.srate,data,app.CheckedData);

            waitfor(app.DialogApp.UIFigure);

            filename = sprintf('FilteredEEG_%03d',app.currentSaveIndex);
            app.historydatamanu=uimenu(app.HistoryMenu);
            app.historydatamanu.Text=filename;
            app.historydatamanu.UserData=app.filteredDataHistory(end);
            app.historydatamanu.MenuSelectedFcn = @(src,event) historyDataMenuSelected(app, src, event);
            % 更新索引
            app.currentSaveIndex = app.currentSaveIndex + 1;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            % 处理键盘按键事件
            isAltPressed = any(strcmp(event.Modifier, 'alt'));

            if isAltPressed
                % Alt键被按下，处理组合键
                switch event.Key
                    case 'leftarrow'  % Alt+左箭头
                        app.moveTimeSelection(-0.001);  % 移动0.1秒

                    case 'rightarrow' % Alt+右箭头
                        app.moveTimeSelection(0.001);   % 移动0.1秒
                end
            else
                % 没有Alt键，处理普通按键
                switch event.Key
                    case 'leftarrow'  % 左箭头键
                        app.moveTimeSelection(-0.01);  % 移动0.01秒

                    case 'rightarrow' % 右箭头键
                        app.moveTimeSelection(0.01);   % 移动0.01秒
                end
            end

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.ChannelTree.CheckedNodes=[];

            app.CheckedData=[];

            app.dispchans=0;
            % 删除现有的时间选择线
            if ishandle(app.TimeSelectionLine)
                delete(app.TimeSelectionLine);
            end

            if ishandle(app.SelectionRectangle)
                delete(app.SelectionRectangle);
            end
            % 删除现有的文本
            if ishandle(app.ChannelSelectionText)
                delete(app.ChannelSelectionText);
            end
            app.redrawPlot();
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            app.ChannelTree.CheckedNodes=[];
            app.CheckedData=[];
            for i=1:length(app.ChannelNode)
                app.ChannelTree.CheckedNodes=[app.ChannelTree.CheckedNodes;app.ChannelNode{i}];
                app.CheckedData=[app.CheckedData,app.ChannelTree.CheckedNodes(i).NodeData];
            end
            app.dispchans=min(length(app.ChannelTree.CheckedNodes),60);
            disp(app.dispchans);
            app.spacing= 580/app.dispchans;
            app.redrawPlot();
        end

        % Selection changed function: ChannelTree
        function ChannelTreeSelectionChanged(app, event)
            selNodes = app.ChannelTree.SelectedNodes;
            if isempty(selNodes), return; end
            selNode  = selNodes(1);          % 若允许多选，取第一个
            origIdx  = selNode.NodeData;     % 原始通道号

            % 如果已有定时器在等，先停掉
            if ~isempty(app.SelDebounceTimer) && isvalid(app.SelDebounceTimer)
                stop(app.SelDebounceTimer);
                delete(app.SelDebounceTimer);
            end

            % 建立一次性定时器：极短延时（让 CheckedNodesChanged 先执行完）
            app.SelDebounceTimer = timer( ...
                'ExecutionMode','singleShot', ...
                'StartDelay', 0.2, ...                  % 10ms 足够，必要时可调大到 0.05
                'TimerFcn', @(~,~) app.applySelectionHighlight(origIdx));

            start(app.SelDebounceTimer);
        end

        % Value changed function: WinlenthEditField
        function WinlenthEditFieldValueChanged(app, event)
            app.winlength = app.WinlenthEditField.Value;
            if app.winlength<app.frames/app.srate
                app.redrawPlot;
            else
                app.winlength=app.frames/app.srate;
                app.WinlenthEditField.Value = app.winlength;
                app.redrawPlot;
            end
            
        end

        % Value changed function: ScaleEditField
        function ScaleEditFieldValueChanged(app, event)
            value = app.ScaleEditField.Value;
            app.ScaleFactor = value;
            if app.ScaleFactor == 0
                app.computeAutoScaleFactor();
            end
            app.redrawPlot();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isobject(app.dataNode)
                % 保存前标记事件的 latency 单位是"秒"
                evtToSave = app.EventMarkers;
                for i = 1:numel(evtToSave)
                    evtToSave(i).latency  = evtToSave(i).latency  * app.srate + 1;
                    if isfield(evtToSave, 'duration') && ~isempty(evtToSave(i).duration)
                        evtToSave(i).duration = evtToSave(i).duration * app.srate;
                    end
                end
                app.dataNode.dataInfo.event = evtToSave;
                app.dataNode.save();
            end
            delete(app)
        end

        % Value changed function: RangeEditField
        function RangeEditFieldValueChanged(app, event)
            raw = strtrim(app.RangeEditField.Value);
            if isempty(raw), return; end

            % 支持 "1.5"、"1.5-3.0"、"1.5,3.0"、"1.5 3.0"、"1.5:3.0" 等
            raw = regexprep(raw, '[,\-~:]', ' ');
            nums = sscanf(raw, '%f');

            if isempty(nums)
                uialert(app.UIFigure, ...
                    'Invalid format. Use "t" for a point or "t1-t2" for a range.', ...
                    'Invalid Input');
                return;
            end

            if isscalar(nums)
                app.jumpToTime(nums(1));
            else
                a = min(nums(1), nums(2));
                b = max(nums(1), nums(2));
                app.jumpToTime(a, b);
            end
        end

        % Double-clicked callback: EventListListBox
        function EventListListBoxDoubleClicked(app, event)
            selectedItem = app.EventListListBox.Value;
            if isempty(selectedItem), return; end

            % 用 Items 的位置反查到 EventMarkers 的索引
            idx = find(strcmp(app.EventListListBox.Items, selectedItem), 1);
            if isempty(idx) || idx > numel(app.EventMarkers), return; end

            targetTime = app.EventMarkers(idx).latency;   % EventMarkers 里存的是秒
            app.jumpToTime(targetTime);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.FiltersMenu = uimenu(app.UIFigure);
            app.FiltersMenu.Text = 'Filters';
            app.FFTMenu = uimenu(app.FiltersMenu);
            app.FFTMenu.MenuSelectedFcn = createCallbackFcn(app, @FFTMenuSelected, true);
            app.FFTMenu.Text = 'FFT';
            app.LowPassMenu = uimenu(app.FiltersMenu);
            app.LowPassMenu.MenuSelectedFcn = createCallbackFcn(app, @LowPassMenuSelected, true);
            app.LowPassMenu.Text = 'Low Pass';
            app.HighPassMenu = uimenu(app.FiltersMenu);
            app.HighPassMenu.MenuSelectedFcn = createCallbackFcn(app, @HighPassMenuSelected, true);
            app.HighPassMenu.Text = 'High Pass';
            app.BandPassMenu = uimenu(app.FiltersMenu);
            app.BandPassMenu.MenuSelectedFcn = createCallbackFcn(app, @BandPassMenuSelected, true);
            app.BandPassMenu.Text = 'Band Pass';
            app.BandSuppressionMenu = uimenu(app.FiltersMenu);
            app.BandSuppressionMenu.MenuSelectedFcn = createCallbackFcn(app, @BandSuppressionMenuSelected, true);
            app.BandSuppressionMenu.Text = 'Band Suppression ';
            app.FiltersMenu_2 = uimenu(app.FiltersMenu);
            app.FiltersMenu_2.MenuSelectedFcn = createCallbackFcn(app, @FiltersMenu_2Selected, true);
            app.FiltersMenu_2.Text = 'Filters';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ChennalsPanel = uipanel(app.UIFigure);
            app.ChennalsPanel.Title = 'Chennals';
            app.ChannelTree = uitree(app.ChennalsPanel, 'checkbox');
            app.ChannelTree.SelectionChangedFcn = createCallbackFcn(app, @ChannelTreeSelectionChanged, true);
            % Assign Checked Nodes
            app.ChannelTree.CheckedNodesChangedFcn = createCallbackFcn(app, @ChannelTreeCheckedNodesChanged, true);
            app.ClearButton = uibutton(app.ChennalsPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SelectAllButton = uibutton(app.ChennalsPanel, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.EventListPanel = uipanel(app.UIFigure);
            app.EventListPanel.Title = 'Event List';
            app.EventListListBox = uilistbox(app.EventListPanel);
            app.EventListListBox.Items = {};
            app.EventListListBox.DoubleClickedFcn = createCallbackFcn(app, @EventListListBoxDoubleClicked, true);
            app.EventListListBox.Value = {};
            app.MarkneweventPanel = uipanel(app.UIFigure);
            app.MarkneweventPanel.Title = 'Mark new event';
            app.AddEventButton = uibutton(app.MarkneweventPanel, 'push');
            app.AddEventButton.ButtonPushedFcn = createCallbackFcn(app, @AddEventButtonPushed, true);
            app.AddEventButton.Text = 'AddEvent';
            app.EventNameLabel = uilabel(app.MarkneweventPanel);
            app.EventNameLabel.Text = 'Event Name';
            app.EventLabelEditField = uieditfield(app.MarkneweventPanel, 'text');
            app.EventLabelEditField.Value = 'Event';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.StackingmodeButton = uibutton(app.ControlPanel, 'state');
            app.StackingmodeButton.ValueChangedFcn = createCallbackFcn(app, @StackingmodeButtonValueChanged, true);
            app.StackingmodeButton.Text = 'Stacking mode';
            app.SelectedTimeEditField_2Label_2 = uilabel(app.ControlPanel);
            app.SelectedTimeEditField_2Label_2.Text = 'Selected Time';
            app.SelectedTimeEditField = uieditfield(app.ControlPanel, 'numeric');
            app.LButton = uibutton(app.ControlPanel, 'push');
            app.LButton.ButtonPushedFcn = createCallbackFcn(app, @LButtonPushed, true);
            app.LButton.Text = '<';
            app.RButton = uibutton(app.ControlPanel, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = '>';
            app.RRButton = uibutton(app.ControlPanel, 'push');
            app.RRButton.ButtonPushedFcn = createCallbackFcn(app, @RRButtonPushed, true);
            app.RRButton.Text = '>>';
            app.LLButton = uibutton(app.ControlPanel, 'push');
            app.LLButton.ButtonPushedFcn = createCallbackFcn(app, @LLButtonPushed, true);
            app.LLButton.Text = '<<';
            app.smallerButton = uibutton(app.ControlPanel, 'push');
            app.smallerButton.ButtonPushedFcn = createCallbackFcn(app, @smallerButtonPushed, true);
            app.smallerButton.Text = '+';
            app.biggerButton = uibutton(app.ControlPanel, 'push');
            app.biggerButton.ButtonPushedFcn = createCallbackFcn(app, @biggerButtonPushed, true);
            app.biggerButton.Text = '-';
            app.rangestart = uilabel(app.ControlPanel);
            app.rangestart.Text = '';
            app.rangeend = uilabel(app.ControlPanel);
            app.rangeend.Text = '';
            app.Label = uilabel(app.ControlPanel);
            app.Label.Text = '-';
            app.WinlenthEditFieldLabel = uilabel(app.ControlPanel);
            app.WinlenthEditFieldLabel.Text = 'Winlenth';
            app.WinlenthEditField = uieditfield(app.ControlPanel, 'numeric');
            app.WinlenthEditField.ValueChangedFcn = createCallbackFcn(app, @WinlenthEditFieldValueChanged, true);
            app.ScaleEditFieldLabel = uilabel(app.ControlPanel);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.ControlPanel, 'numeric');
            app.ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleEditFieldValueChanged, true);
            app.RangeEditFieldLabel = uilabel(app.ControlPanel);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.ControlPanel, 'text');
            app.RangeEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SEAL_showEEG(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end