classdef Filters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        HzLabel_3               matlab.ui.control.Label
        HzLabel_2               matlab.ui.control.Label
        HzLabel                 matlab.ui.control.Label
        OKButton                matlab.ui.control.Button
        NotchEditField          matlab.ui.control.NumericEditField
        NotchEditFieldLabel     matlab.ui.control.Label
        LowpassEditField        matlab.ui.control.NumericEditField
        LowpassEditFieldLabel   matlab.ui.control.Label
        CheckBox_3              matlab.ui.control.CheckBox
        CheckBox_2              matlab.ui.control.CheckBox
        HighpassEditField       matlab.ui.control.NumericEditField
        HighpassEditFieldLabel  matlab.ui.control.Label
        CheckBox                matlab.ui.control.CheckBox
    end


    properties (Access = private)
        Callingapp
        Fs
        filteredEEG
        data
        checkeddataindex
    end

    methods (Access = private)

        function firFilter = designFIRBandstop(app,Fs, Flow,Fhigh, Rs, N)
            % 设计FIR低通滤波器
            % 输入:
            %   Fs - 采样率 (Hz)
            %   Flow - 低频截止频率 (Hz)
            %   Fhigh - 高频截止频率 (Hz)
            %   Rp - 通带波纹 (dB)
            %   Rs - 阻带衰减 (dB)
            %   N - 滤波器阶数 (必须为偶数)
            % 输出:
            %   firFilter - FIR滤波器系数

            % 参数验证
            assert(Flow > 0, '低频截止频率必须大于0');
            assert(Fhigh > Flow, '高频截止频率必须大于低频截止频率');
            assert(Fhigh < Fs/2, '高频截止频率必须小于奈奎斯特频率(%.1f Hz)', Fs/2);
            assert(mod(N,2) == 0, '阶数N必须是偶数');

            % 归一化频率
            normFlow = Flow / (Fs/2);   % 归一化低频截止频率
            normFhigh = Fhigh / (Fs/2); % 归一化高频截止频率

            % 设计滤波器 (使用Kaiser窗方法)
            beta = app.kaiserbeta(Rs); % 计算Kaiser窗β参数
            firFilter = fir1(N, [normFlow, normFhigh], 'stop', kaiser(N+1, beta), 'noscale');

            % 可选：频率响应可视化
            app.visualizeFilterResponse1(firFilter, Fs, Flow, Fhigh);
        end

        function firFilter = designFIRHighpass(app,Fs, Fc, Rs, N)
            % 设计FIR低通滤波器
            % 输入:
            %   Fs - 采样率 (Hz)
            %   Fc - 截止频率 (Hz)
            %   Rp - 通带波纹 (dB)
            %   Rs - 阻带衰减 (dB)
            %   N - 滤波器阶数 (必须为偶数)
            % 输出:
            %   firFilter - FIR滤波器系数

            % 参数验证
            assert(Fc > 0, '截止频率必须大于0');
            assert(Fc < Fs/2, '截止频率必须小于奈奎斯特频率(%.1f Hz)', Fs/2);
            assert(mod(N,2) == 0, '阶数N必须是偶数');

            % 归一化频率
            normFc = Fc / (Fs/2); % 归一化截止频率 (0~1)

            % 设计滤波器 (使用Kaiser窗方法)
            beta = app.kaiserbeta(Rs); % 计算Kaiser窗β参数
            firFilter = fir1(N, normFc, 'high', kaiser(N+1, beta), 'noscale');

            % 可选：频率响应可视化
            app.visualizeFilterResponse2(firFilter, Fs, Fc);
        end

        function firFilter = designFIRLowpass(app,Fs, Fc, Rs, N)
            % 设计FIR低通滤波器
            % 输入:
            %   Fs - 采样率 (Hz)
            %   Fc - 截止频率 (Hz)
            %   Rp - 通带波纹 (dB)
            %   Rs - 阻带衰减 (dB)
            %   N - 滤波器阶数 (必须为偶数)
            % 输出:
            %   firFilter - FIR滤波器系数

            % 参数验证
            assert(Fc < Fs/2, '截止频率必须小于奈奎斯特频率(%.1f Hz)', Fs/2);
            assert(mod(N,2) == 0, '阶数N必须是偶数');

            % 归一化频率
            normFc = Fc / (Fs/2); % 归一化截止频率 (0~1)

            % 设计滤波器 (使用Kaiser窗方法)
            beta = app.kaiserbeta(Rs); % 计算Kaiser窗β参数
            firFilter = fir1(N, normFc, 'low', kaiser(N+1, beta), 'noscale');

            % 可选：频率响应可视化
            app.visualizeFilterResponse2(firFilter, Fs, Fc);
        end

        function firFilter = designFIRBandpass(app,Fs, Flow,Fhigh, Rs, N)
            % 设计FIR低通滤波器
            % 输入:
            %   Fs - 采样率 (Hz)
            %   Flow - 低频截止频率 (Hz)
            %   Fhigh - 高频截止频率 (Hz)
            %   Rp - 通带波纹 (dB)
            %   Rs - 阻带衰减 (dB)
            %   N - 滤波器阶数 (必须为偶数)
            % 输出:
            %   firFilter - FIR滤波器系数

            % 参数验证
            assert(Flow > 0, '低频截止频率必须大于0');
            assert(Fhigh > Flow, '高频截止频率必须大于低频截止频率');
            assert(Fhigh < Fs/2, '高频截止频率必须小于奈奎斯特频率(%.1f Hz)', Fs/2);
            assert(mod(N,2) == 0, '阶数N必须是偶数');

            % 归一化频率
            normFlow = Flow / (Fs/2);   % 归一化低频截止频率
            normFhigh = Fhigh / (Fs/2); % 归一化高频截止频率

            % 设计滤波器 (使用Kaiser窗方法)
            beta = app.kaiserbeta(Rs); % 计算Kaiser窗β参数
            firFilter = fir1(N, [normFlow, normFhigh], 'bandpass', kaiser(N+1, beta), 'noscale');

            % 可选：频率响应可视化
            app.visualizeFilterResponse1(firFilter, Fs, Flow, Fhigh);
        end

        function beta = kaiserbeta(app,Rs)
            % 根据阻带衰减计算Kaiser窗的β参数
            if Rs > 50
                beta = 0.1102 * (Rs - 8.7);
            elseif Rs >= 21
                beta = 0.5842 * (Rs - 21)^0.4 + 0.07886 * (Rs - 21);
            else
                beta = 0;
            end
        end

        function visualizeFilterResponse1(app,b, Fs,  Flow, Fhigh)
            % 绘制滤波器频率响应
            figure;
            freqz(b, 1, 1024, Fs);
            % 标记截止频率
            hold on;
            plot([Flow Flow], [-200 5], 'r--');
            plot([Fhigh Fhigh], [-200 5], 'r--');
            text(Flow+5, -50, sprintf('Flow: %.1f Hz', Flow), 'Color', 'r');
            text(Fhigh+5, -50, sprintf('Fhigh: %.1f Hz', Fhigh), 'Color', 'r');

            % 标记阻带区域
            patch([Flow, Fhigh, Fhigh, Flow], [-200, -200, 5, 5], 'r', 'FaceAlpha', 0.1, 'EdgeColor', 'none');

        end

        function visualizeFilterResponse2(app,b, Fs, Fc)
            % 绘制滤波器频率响应
            figure;
            freqz(b, 1, 1024, Fs);

            % 标记截止频率
            hold on;
            plot([Fc Fc], [-200 5], 'r--');
            text(Fc+5, -50, sprintf('Cutoff: %.1f Hz', Fc), 'Color', 'r');
        end

        function [Flow, Fhigh] = calculateStopband(app, centerFreq, width)
            % 根据中心频率和宽度计算阻带范围
            Flow = centerFreq - width/2;
            Fhigh = centerFreq + width/2;

            % 确保频率不超出有效范围
            Flow = max(Flow, 0.1); % 最小0.1Hz
            Fhigh = min(Fhigh, app.Fs/2 * 0.99); % 最大略小于奈奎斯特频率
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGapp, SR, data, CheckeddataIndex)
            app.HighpassEditField.Enable='off';
            app.LowpassEditField.Enable='off';
            app.NotchEditField.Enable='off';
            app.Callingapp=EEGapp;
            app.Fs=SR;
            % 设计滤波器
            app.data=data;
            app.checkeddataindex=CheckeddataIndex;
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value==1
                app.HighpassEditField.Enable='on';
            else
                app.HighpassEditField.Enable='off';
            end
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            if value==1
                app.LowpassEditField.Enable='on';
            else
                app.LowpassEditField.Enable='off';
            end
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            value = app.CheckBox_3.Value;
            if value==1
                app.NotchEditField.Enable='on';
            else
                app.NotchEditField.Enable='off';
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if app.CheckBox.Value==1&&app.CheckBox_2.Value==0&&app.CheckBox_3.Value==0
                %highpass
                Fc=app.HighpassEditField.Value;
                Rs=40;N=100;
                firCoeff = app.designFIRHighpass(app.Fs, Fc, Rs, N);
            elseif app.CheckBox.Value==0&&app.CheckBox_2.Value==1&&app.CheckBox_3.Value==0
                %lowpass
                Fc=app.LowpassEditField.Value;
                Rs=40;N=10;
                firCoeff = app.designFIRLowpass(app.Fs, Fc, Rs, N);
            elseif app.CheckBox.Value==1&&app.CheckBox_2.Value==1&&app.CheckBox_3.Value==0 &&...
                    app.HighpassEditField.Value<app.LowpassEditField.Value
                %bandpass
                Fhigh =app.LowpassEditField.Value;
                Flow =app.HighpassEditField.Value;
                Fc=[Flow,Fhigh];
                Rs=40;N=100;
                firCoeff = app.designFIRBandpass(app.Fs,  Flow, Fhigh, Rs, N);
            elseif app.CheckBox.Value==0&&app.CheckBox_2.Value==0&&app.CheckBox_3.Value==1
                %bandstop
                centerFreq=app.NotchEditField.Value;
                width = 4;
                [Flow, Fhigh] = app.calculateStopband(centerFreq, width);
                Fc=[Flow,Fhigh];
                Rs=60;N=120;
                firCoeff = app.designFIRBandstop(app.Fs,  Flow, Fhigh, Rs, N);
            elseif app.CheckBox.Value==1&&app.CheckBox_2.Value==1&&app.CheckBox_3.Value==1
                %级联
            elseif app.CheckBox.Value==0&&app.CheckBox_2.Value==0&&app.CheckBox_3.Value==0
                %cancel
            end

            % 应用滤波
            % 获取原始数据维度
            [nChannels, nTimePoints] = size(app.data);

            % 预分配滤波后数据矩阵
            app.filteredEEG = zeros(nChannels, nTimePoints);

            % 逐通道滤波
            for i = 1:nChannels
                % 对第i行数据进行零相位滤波
                app.filteredEEG(i, :) = filtfilt(firCoeff, 1, app.data(i, :));
            end


            newEntry = struct(...
                'data', app.filteredEEG, ...
                'params', struct('Fs', app.Fs, 'Fc', Fc, 'N', N, 'Rs', Rs), ...
                'index', app.Callingapp.currentSaveIndex);
            app.Callingapp.filteredDataHistory(end+1) = newEntry;


            %    app.Callingapp.saveFilteredEEG(app.filteredEEG,app.Fs, Fc, N, Rs);
            app.Callingapp.plotMultiChannelSpectrum(app.filteredEEG,app.checkeddataindex);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.HighpassEditFieldLabel = uilabel(app.UIFigure);
            app.HighpassEditFieldLabel.Text = 'High-pass';
            app.HighpassEditField = uieditfield(app.UIFigure, 'numeric');
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.LowpassEditFieldLabel = uilabel(app.UIFigure);
            app.LowpassEditFieldLabel.Text = 'Low-pass';
            app.LowpassEditField = uieditfield(app.UIFigure, 'numeric');
            app.NotchEditFieldLabel = uilabel(app.UIFigure);
            app.NotchEditFieldLabel.Text = 'Notch';
            app.NotchEditField = uieditfield(app.UIFigure, 'numeric');
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = 'Hz';
            app.HzLabel_3 = uilabel(app.UIFigure);
            app.HzLabel_3.Text = 'Hz';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Filters(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end