classdef bandsuppress < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        HzLabel_4                 matlab.ui.control.Label
        HzLabel_3                 matlab.ui.control.Label
        HzLabel_2                 matlab.ui.control.Label
        HzLabel                   matlab.ui.control.Label
        centerFreqEditField       matlab.ui.control.NumericEditField
        centerFreqEditFieldLabel  matlab.ui.control.Label
        winlengthEditField        matlab.ui.control.NumericEditField
        winlengthEditFieldLabel   matlab.ui.control.Label
        FlowEditField             matlab.ui.control.NumericEditField
        FlowEditFieldLabel        matlab.ui.control.Label
        OKButton                  matlab.ui.control.Button
        NEditField                matlab.ui.control.NumericEditField
        NLabel                    matlab.ui.control.Label
        RsEditField               matlab.ui.control.NumericEditField
        RsLabel                   matlab.ui.control.Label
        FhighEditField            matlab.ui.control.NumericEditField
        FhighEditFieldLabel       matlab.ui.control.Label
    end


    properties (Access = private)
        Callingapp % Description
        Fs
        filteredEEG
        data
        checkeddataindex

    end

    methods (Access = private)

        function firFilter = designFIRBandstop(app,Fs, Flow,Fhigh, Rs, N)
            % 设计FIR低通滤波器
            % 输入:
            %   Fs - 采样率 (Hz)
            %   Flow - 低频截止频率 (Hz)
            %   Fhigh - 高频截止频率 (Hz)
            %   Rp - 通带波纹 (dB)
            %   Rs - 阻带衰减 (dB)
            %   N - 滤波器阶数 (必须为偶数)
            % 输出:
            %   firFilter - FIR滤波器系数

            % 参数验证
            assert(Flow > 0, '低频截止频率必须大于0');
            assert(Fhigh > Flow, '高频截止频率必须大于低频截止频率');
            assert(Fhigh < Fs/2, '高频截止频率必须小于奈奎斯特频率(%.1f Hz)', Fs/2);
            assert(mod(N,2) == 0, '阶数N必须是偶数');

            % 归一化频率
            normFlow = Flow / (Fs/2);   % 归一化低频截止频率
            normFhigh = Fhigh / (Fs/2); % 归一化高频截止频率

            % 设计滤波器 (使用Kaiser窗方法)
            beta = app.kaiserbeta(Rs); % 计算Kaiser窗β参数
            firFilter = fir1(N, [normFlow, normFhigh], 'stop', kaiser(N+1, beta), 'noscale');

            % 可选：频率响应可视化
            app.visualizeFilterResponse(firFilter, Fs, Flow, Fhigh);
        end

        function beta = kaiserbeta(app,Rs)
            % 根据阻带衰减计算Kaiser窗的β参数
            if Rs > 50
                beta = 0.1102 * (Rs - 8.7);
            elseif Rs >= 21
                beta = 0.5842 * (Rs - 21)^0.4 + 0.07886 * (Rs - 21);
            else
                beta = 0;
            end
        end

        function visualizeFilterResponse(app,b, Fs,  Flow, Fhigh)
            % 绘制滤波器频率响应
            figure;
            freqz(b, 1, 1024, Fs);
            title(sprintf('FIR Bandstop Filter Response  [%.1f-%.1f] Hz, Fs=%.1f Hz', Flow, Fhigh, Fs));

            % 标记截止频率
            hold on;
            plot([Flow Flow], [-200 5], 'r--');
            plot([Fhigh Fhigh], [-200 5], 'r--');
            text(Flow+5, -50, sprintf('Flow: %.1f Hz', Flow), 'Color', 'r');
            text(Fhigh+5, -50, sprintf('Fhigh: %.1f Hz', Fhigh), 'Color', 'r');
            
            % 标记阻带区域
            patch([Flow, Fhigh, Fhigh, Flow], [-200, -200, 5, 5], 'r', 'FaceAlpha', 0.1, 'EdgeColor', 'none');
       
        end
        
         function [Flow, Fhigh] = calculateStopband(app, centerFreq, width)
            % 根据中心频率和宽度计算阻带范围
            Flow = centerFreq - width/2;
            Fhigh = centerFreq + width/2;
            
            % 确保频率不超出有效范围
            Flow = max(Flow, 0.1); % 最小0.1Hz
            Fhigh = min(Fhigh, app.Fs/2 * 0.99); % 最大略小于奈奎斯特频率
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGapp, SR, data, checkeddataindex)
            app.Callingapp=EEGapp;
            app.Fs=SR;
            % 设计滤波器
            % 设置默认滤波器参数
            app.centerFreqEditField.Value=50;% 默认中心频率(常用于去除工频干扰)
            centerFreq=app.centerFreqEditField.Value;
            app.winlengthEditField.Value=4;  % 默认阻带宽度
            width = app.winlengthEditField.Value;     
            [Flow, Fhigh] = app.calculateStopband(centerFreq, width);
            
            app.FlowEditField.Value = Flow;
            app.FhighEditField.Value = Fhigh;            
            app.NEditField.Value = 120;      % 默认滤波器阶数(带阻需要更高阶数)
            app.RsEditField.Value = 60;      % 默认阻带衰减(带阻通常需要更高衰减)
            app.data=data;
            app.checkeddataindex=checkeddataindex;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            Flow = app.FlowEditField.Value;
            Fhigh = app.FhighEditField.Value;
            N=app.NEditField.Value;
            Rs=app.RsEditField.Value;
            firCoeff = app.designFIRBandstop(app.Fs,  Flow, Fhigh, Rs, N);
            % 应用滤波
            % 获取原始数据维度
            [nChannels, nTimePoints] = size(app.data);

            % 预分配滤波后数据矩阵
            app.filteredEEG = zeros(nChannels, nTimePoints);

            % 逐通道滤波
            for i = 1:nChannels
                % 对第i行数据进行零相位滤波
                app.filteredEEG(i, :) = filtfilt(firCoeff, 1, app.data(i, :));
            end
            app.Callingapp.saveFilteredEEG(app.filteredEEG,app.Fs,  [Flow, Fhigh], N, Rs);
            app.Callingapp.plotMultiChannelSpectrum(app.filteredEEG,app.checkeddataindex);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FhighEditFieldLabel = uilabel(app.UIFigure);
            app.FhighEditFieldLabel.Text = 'Fhigh';
            app.FhighEditField = uieditfield(app.UIFigure, 'numeric');
            app.RsLabel = uilabel(app.UIFigure);
            app.RsLabel.Text = '阻带衰减Rs';
            app.RsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NLabel = uilabel(app.UIFigure);
            app.NLabel.Text = '滤波器阶数N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.FlowEditFieldLabel = uilabel(app.UIFigure);
            app.FlowEditFieldLabel.Text = 'Flow';
            app.FlowEditField = uieditfield(app.UIFigure, 'numeric');
            app.winlengthEditFieldLabel = uilabel(app.UIFigure);
            app.winlengthEditFieldLabel.Text = 'winlength';
            app.winlengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.centerFreqEditFieldLabel = uilabel(app.UIFigure);
            app.centerFreqEditFieldLabel.Text = 'centerFreq';
            app.centerFreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = 'Hz';
            app.HzLabel_3 = uilabel(app.UIFigure);
            app.HzLabel_3.Text = 'Hz';
            app.HzLabel_4 = uilabel(app.UIFigure);
            app.HzLabel_4.Text = 'Hz';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bandsuppress(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end