classdef highpass < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        HzLabel           matlab.ui.control.Label
        OKButton          matlab.ui.control.Button
        NEditField        matlab.ui.control.NumericEditField
        EditField_2Label  matlab.ui.control.Label
        RsEditField       matlab.ui.control.NumericEditField
        EditField_3Label  matlab.ui.control.Label
        FcEditField       matlab.ui.control.NumericEditField
        FcEditFieldLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        Callingapp % Description
        Fs
        filteredEEG
        data
        checkeddataindex
      
    end

    methods (Access = private)

        function firFilter = designFIRHighpass(app,Fs, Fc, Rs, N)
            % 设计FIR低通滤波器
            % 输入:
            %   Fs - 采样率 (Hz)
            %   Fc - 截止频率 (Hz)
            %   Rp - 通带波纹 (dB)
            %   Rs - 阻带衰减 (dB)
            %   N - 滤波器阶数 (必须为偶数)
            % 输出:
            %   firFilter - FIR滤波器系数

            % 参数验证
            assert(Fc > 0, '截止频率必须大于0');
            assert(Fc < Fs/2, '截止频率必须小于奈奎斯特频率(%.1f Hz)', Fs/2);
            assert(mod(N,2) == 0, '阶数N必须是偶数');         

            % 归一化频率
            normFc = Fc / (Fs/2); % 归一化截止频率 (0~1)

            % 设计滤波器 (使用Kaiser窗方法)
            beta = app.kaiserbeta(Rs); % 计算Kaiser窗β参数
            firFilter = fir1(N, normFc, 'high', kaiser(N+1, beta), 'noscale');

            % 可选：频率响应可视化
            app.visualizeFilterResponse(firFilter, Fs, Fc);
        end

        function beta = kaiserbeta(app,Rs)
            % 根据阻带衰减计算Kaiser窗的β参数
            if Rs > 50
                beta = 0.1102 * (Rs - 8.7);
            elseif Rs >= 21
                beta = 0.5842 * (Rs - 21)^0.4 + 0.07886 * (Rs - 21);
            else
                beta = 0;
            end
        end

        function visualizeFilterResponse(app,b, Fs, Fc)
            % 绘制滤波器频率响应
            figure;
            freqz(b, 1, 1024, Fs);
            title(sprintf('FIR Highpass Filter Response  Fc=%.1f Hz, Fs=%.1f Hz', Fc, Fs));

            % 标记截止频率
            hold on;
            plot([Fc Fc], [-200 5], 'r--');
            text(Fc+5, -50, sprintf('Cutoff: %.1f Hz', Fc), 'Color', 'r');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGapp, SR, data, checkeddataindex)
            app.Callingapp=EEGapp;
            app.Fs=SR;
            % 设计滤波器
            app.FcEditField.Value=0.1;
            app.NEditField.Value=100;
            app.RsEditField.Value=40;
            app.data=data;
            app.checkeddataindex=checkeddataindex;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            Fc=app.FcEditField.Value;
            N=app.NEditField.Value;
            Rs=app.RsEditField.Value;
            firCoeff = app.designFIRHighpass(app.Fs, Fc, Rs, N);
            % 应用滤波
            % 获取原始数据维度
            [nChannels, nTimePoints] = size(app.data);

            % 预分配滤波后数据矩阵
            app.filteredEEG = zeros(nChannels, nTimePoints);

            % 逐通道滤波
            for i = 1:nChannels
                % 对第i行数据进行零相位滤波
                app.filteredEEG(i, :) = filtfilt(firCoeff, 1, app.data(i, :));
            end
            app.Callingapp.saveFilteredEEG(app.filteredEEG,app.Fs, Fc, N, Rs); 
            app.Callingapp.plotMultiChannelSpectrum(app.filteredEEG,app.checkeddataindex);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FcEditFieldLabel = uilabel(app.UIFigure);
            app.FcEditFieldLabel.Text = 'Fc';
            app.FcEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = '阻带衰减';
            app.RsEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '滤波器阶数';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = highpass(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end